classdef Cadence < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Panel                     matlab.ui.container.Panel
        CONDUCTIONVELOCITYButton  matlab.ui.control.Button
        SIGNALANALYSISButton      matlab.ui.control.Button
        FEATUREEXTRACTIONButton   matlab.ui.control.Button
        SIGNALCONDITIONINGButton  matlab.ui.control.Button
        Image                     matlab.ui.control.Image
        DATACONVERSIONButton      matlab.ui.control.Button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if ~isdeployed
                root = pwd;
                p = strsplit(genpath(root), pathsep);
                rel = erase(p, root);
                skip = startsWith(rel, filesep + ["distribution" "release" "notarize"]);
                addpath(strjoin(p(~skip), pathsep));
            end
        end

        % Button pushed function: DATACONVERSIONButton
        function DATACONVERSIONButtonPushed(app, event)
            Cadence_Data_Conversion;
        end

        % Button pushed function: SIGNALCONDITIONINGButton
        function SIGNALCONDITIONINGButtonPushed(app, event)
            Cadence_Signal_Conditioning;
        end

        % Button pushed function: FEATUREEXTRACTIONButton
        function FEATUREEXTRACTIONButtonPushed(app, event)
            Cadence_Feature_Extraction
        end

        % Button pushed function: SIGNALANALYSISButton
        function SIGNALANALYSISButtonPushed(app, event)
            Cadence_Signal_Analysis
        end

        % Button pushed function: CONDUCTIONVELOCITYButton
        function CONDUCTIONVELOCITYButtonPushed(app, event)
            Cadence_Conduction_Velocity
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.Panel = uipanel(app.UIFigure);
            app.DATACONVERSIONButton = uibutton(app.Panel, 'push');
            app.DATACONVERSIONButton.ButtonPushedFcn = createCallbackFcn(app, @DATACONVERSIONButtonPushed, true);
            app.DATACONVERSIONButton.Text = 'DATA CONVERSION';
            app.Image = uiimage(app.Panel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Logo_v3.png');
            app.SIGNALCONDITIONINGButton = uibutton(app.Panel, 'push');
            app.SIGNALCONDITIONINGButton.ButtonPushedFcn = createCallbackFcn(app, @SIGNALCONDITIONINGButtonPushed, true);
            app.SIGNALCONDITIONINGButton.Text = 'SIGNAL CONDITIONING';
            app.FEATUREEXTRACTIONButton = uibutton(app.Panel, 'push');
            app.FEATUREEXTRACTIONButton.ButtonPushedFcn = createCallbackFcn(app, @FEATUREEXTRACTIONButtonPushed, true);
            app.FEATUREEXTRACTIONButton.Text = 'FEATURE EXTRACTION';
            app.SIGNALANALYSISButton = uibutton(app.Panel, 'push');
            app.SIGNALANALYSISButton.ButtonPushedFcn = createCallbackFcn(app, @SIGNALANALYSISButtonPushed, true);
            app.SIGNALANALYSISButton.Text = 'SIGNAL ANALYSIS';
            app.CONDUCTIONVELOCITYButton = uibutton(app.Panel, 'push');
            app.CONDUCTIONVELOCITYButton.ButtonPushedFcn = createCallbackFcn(app, @CONDUCTIONVELOCITYButtonPushed, true);
            app.CONDUCTIONVELOCITYButton.Text = 'CONDUCTION VELOCITY';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Cadence

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end