classdef Cadence_Feature_Extraction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        FEATUREEXTRACTIONTab            matlab.ui.container.Tab
        STEP3SELECTOUTPUTDIRECTORYTOSAVEPROCESSEDFILESPanel_2  matlab.ui.container.Panel
        BATCHPROCESSINGButton           matlab.ui.control.Button
        SAVEDATAButton                  matlab.ui.control.Button
        EXTRACTFEATURESButton           matlab.ui.control.Button
        Label_9                         matlab.ui.control.Label
        SELECTOUTPUTDIRECTORYButton_2   matlab.ui.control.Button
        EditField_2                     matlab.ui.control.EditField
        EditField_2Label                matlab.ui.control.Label
        STEP2SELECTWINDOWFROMREPRESENTATIVESIGNALPanel  matlab.ui.container.Panel
        INVERTSIGNALSButton             matlab.ui.control.Button
        ENSEMBLECHECKBOX                matlab.ui.control.CheckBox
        LINKDATACHECKBOX                matlab.ui.control.CheckBox
        PACINGCHECKBOX                  matlab.ui.control.CheckBox
        TabGroup3                       matlab.ui.container.TabGroup
        ActionPotentialsVmTab           matlab.ui.container.Tab
        FOVmmEditField                  matlab.ui.control.NumericEditField
        FOVmmEditField_2Label           matlab.ui.control.Label
        Label_27                        matlab.ui.control.Label
        CONDUCTIONVELOCITYDropDown      matlab.ui.control.DropDown
        ERISETIMEDropDownLabel_7        matlab.ui.control.Label
        RepDropDown                     matlab.ui.control.DropDown
        Label_25                        matlab.ui.control.Label
        RepolarizationDropDown          matlab.ui.control.DropDown
        CACTIONPOTENTIALDURATIONDropDownLabel_5  matlab.ui.control.Label
        APRiseDropDown_2                matlab.ui.control.DropDown
        APRiseDropDown_1                matlab.ui.control.DropDown
        APDDropDown                     matlab.ui.control.DropDown
        Label_12                        matlab.ui.control.Label
        RISETIMEDropDown                matlab.ui.control.DropDown
        ERISETIMEDropDownLabel          matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        DURATIONDropDown                matlab.ui.control.DropDown
        CACTIONPOTENTIALDURATIONDropDownLabel  matlab.ui.control.Label
        NOTEActivationTimereferstothemaximalslopeoftheupstrokeLabel  matlab.ui.control.Label
        ACTIVATIONTIMEDropDown          matlab.ui.control.DropDown
        AACTIVATIONTIMEDropDownLabel    matlab.ui.control.Label
        CalciumTransientsCaTab          matlab.ui.container.Tab
        TauDropDown_2                   matlab.ui.control.DropDown
        TauDropDown                     matlab.ui.control.DropDown
        CaDTDropDown                    matlab.ui.control.DropDown
        Label_26                        matlab.ui.control.Label
        CALCIUMDTDropDown               matlab.ui.control.DropDown
        CACTIONPOTENTIALDURATIONDropDownLabel_6  matlab.ui.control.Label
        CaRiseDropDown_2                matlab.ui.control.DropDown
        CaRiseDropDown_1                matlab.ui.control.DropDown
        CaDropDown                      matlab.ui.control.DropDown
        Label_16                        matlab.ui.control.Label
        TDropDown                       matlab.ui.control.DropDown
        FCONDUCTIONVELOCITYDropDownLabel_2  matlab.ui.control.Label
        Label_15                        matlab.ui.control.Label
        CALCIUMRISETIMEDropDown         matlab.ui.control.DropDown
        ERISETIMEDropDownLabel_2        matlab.ui.control.Label
        Label_14                        matlab.ui.control.Label
        CALCIUMDURATIONDropDown         matlab.ui.control.DropDown
        CACTIONPOTENTIALDURATIONDropDownLabel_2  matlab.ui.control.Label
        NOTEActivationTimereferstothemaximalslopeoftheupstrokeLabel_2  matlab.ui.control.Label
        DELAYDropDown                   matlab.ui.control.DropDown
        AACTIVATIONTIMEDropDownLabel_2  matlab.ui.control.Label
        ArrhythmiaDynamicsTab           matlab.ui.container.Tab
        ALTERNANSDropDown_2             matlab.ui.control.DropDown
        Label_24                        matlab.ui.control.Label
        ALTERNANSDropDown               matlab.ui.control.DropDown
        FCONDUCTIONVELOCITYDropDownLabel_3  matlab.ui.control.Label
        Label_18                        matlab.ui.control.Label
        PHASEDropDown                   matlab.ui.control.DropDown
        ERISETIMEDropDownLabel_3        matlab.ui.control.Label
        Label_17                        matlab.ui.control.Label
        WAVEFRONTDropDown               matlab.ui.control.DropDown
        CACTIONPOTENTIALDURATIONDropDownLabel_3  matlab.ui.control.Label
        NOTEActivationTimereferstothemaximalslopeoftheupstrokeLabel_3  matlab.ui.control.Label
        COMPLEXITYDropDown              matlab.ui.control.DropDown
        AACTIVATIONTIMEDropDownLabel_3  matlab.ui.control.Label
        ImageAlignmentTab               matlab.ui.container.Tab
        ALIGNIMAGEButton                matlab.ui.control.Button
        ROTATECCWButton                 matlab.ui.control.Button
        RESETIMAGEButton                matlab.ui.control.Button
        FLIPUDButton                    matlab.ui.control.Button
        FLIPLRButton                    matlab.ui.control.Button
        ROTATECWButton                  matlab.ui.control.Button
        Label_19                        matlab.ui.control.Label
        CACTIONPOTENTIALDURATIONDropDownLabel_4  matlab.ui.control.Label
        NOTEActivationTimereferstothemaximalslopeoftheupstrokeLabel_4  matlab.ui.control.Label
        AACTIVATIONTIMEDropDownLabel_4  matlab.ui.control.Label
        DataMaskTab                     matlab.ui.container.Tab
        SetSNRMaskThreshold0autoEditField  matlab.ui.control.NumericEditField
        SetSNRMaskThreshold0autoEditFieldLabel  matlab.ui.control.Label
        DELETEUSERMASKButton            matlab.ui.control.Button
        CREATEUSERMASKButton            matlab.ui.control.Button
        USRSwitch                       matlab.ui.control.Switch
        Label_23                        matlab.ui.control.Label
        ERISETIMEDropDownLabel_6        matlab.ui.control.Label
        SNRSwitch                       matlab.ui.control.Switch
        DISPLAYSNRMAPButton             matlab.ui.control.Button
        Label_22                        matlab.ui.control.Label
        ERISETIMEDropDownLabel_5        matlab.ui.control.Label
        LINKCAMERASCHECKBOX             matlab.ui.control.CheckBox
        SELECTWINDOWButton              matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        CAM_1Tab                        matlab.ui.container.Tab
        UIAxes_cam1                     matlab.ui.control.UIAxes
        CAM_2Tab                        matlab.ui.container.Tab
        UIAxes_cam2                     matlab.ui.control.UIAxes
        CAM_3Tab                        matlab.ui.container.Tab
        UIAxes_cam3                     matlab.ui.control.UIAxes
        CAM_4Tab                        matlab.ui.container.Tab
        UIAxes_cam4                     matlab.ui.control.UIAxes
        Panel                           matlab.ui.container.Panel
        UIAxes_sig                      matlab.ui.control.UIAxes
        TextArea_2                      matlab.ui.control.TextArea
        TextArea_2Label                 matlab.ui.control.Label
        STEP1SELECTFILESFORFEATUREEXTRACTIONPanel  matlab.ui.container.Panel
        LOADButton                      matlab.ui.control.Button
        ListBox_2                       matlab.ui.control.ListBox
        ListBox_2Label                  matlab.ui.control.Label
        REFRESHDIRECTORYButton          matlab.ui.control.Button
        SELECTDIRECTORYButton           matlab.ui.control.Button
    end


    properties (Access = public)
        select_input_directory_help = [];
        dir_name = [];
        mat_file_name = [];
        output_dir_name = [];

        data = [];
        x_axis = [];
        y_axis = [];
        plot_marker = [];
        plot_signal = [];
        pacing_signal = [];
        mouse_coordinates = [];
        mouse_x = [];
        mouse_y = [];
        mouse_drag = [];
        active_tab = [];
        active_axes = [];
        active_data = [];
        active_ensemble_data = [];
        active_snr = [];
        active_image = [];
        active_camera = 1;
        active_color = 'r';
        oap_window = [];
        pixel = [];
        data_masks = cell(4,2);
        combo_masks = cell(4,1);
        data_masks_tab = zeros(4,3);
        image_alignment = cell(4,2);
        arrhythmia_tab = zeros(4,4,2);
        calcium_tab = zeros(4,5,3);
        voltage_tab = zeros(4,5,3);
        % smooth_value = 0;
        % smooth_cmos_data = [];
        time  = [];
        batch_mode = false;
    end

    methods (Access = private)

        function reset_default_state(app)
            %RESET_DEFAULT_STATE  Reset mouse/marker/mask/selection state for a new recording.
            try

                app.data = [];
                app.pixel = [];
                app.mat_file_name = [];
                app.oap_window = [];
                app.data_masks = cell(4,2);
                app.combo_masks = cell(4,1);
                app.data_masks_tab = zeros(4,3);
                app.image_alignment = cell(4,2);
                app.arrhythmia_tab = zeros(4,4,2);
                app.calcium_tab = zeros(4,5,3);
                app.voltage_tab = zeros(4,5,3);
                app.time;
                cla(app.UIAxes_cam1);
                cla(app.UIAxes_cam2);
                cla(app.UIAxes_cam3);
                cla(app.UIAxes_cam4);
                cla(app.UIAxes_sig);
                delete(app.plot_marker);
                app.plot_signal = [];
                app.pacing_signal = [];
                app.active_camera = 1;
                app.active_color = 'r';


                app.SetSNRMaskThreshold0autoEditField.Value = 0;
                app.FOVmmEditField.Value = 10;
                app.ACTIVATIONTIMEDropDown.Value = "NO";
                app.RISETIMEDropDown.Value = "NO";
                app.DURATIONDropDown.Value = "NO";

                app.ALTERNANSDropDown.Value = "NO";
                app.DELAYDropDown.Value = "NO";
                app.CALCIUMRISETIMEDropDown.Value = "NO";
                app.CALCIUMDURATIONDropDown.Value = "NO";
                app.TDropDown.Value = "NO";

                app.COMPLEXITYDropDown.Value = "NO";
                app.WAVEFRONTDropDown.Value = "NO";
                app.PHASEDropDown.Value = "NO";

                app.SNRSwitch.Value = "Off";
                app.USRSwitch.Value = "Off";

                app.LINKCAMERASCHECKBOX.Value = false;
                app.PACINGCHECKBOX.Value = false;
                app.LINKDATACHECKBOX.Value = false;
                app.ENSEMBLECHECKBOX.Value = false;


                app.TextArea_2.Value = "";
                app.batch_mode = false;

            catch ME
                error_message = strcat('ERROR in function reset_default_state(app) ', ME.message);
                update_console(app,error_message);
            end
        end

        function reset_default_UI(app)
            try
                %set CAM1 to be voltage with all features selected YES
                app.voltage_tab(1,:,1) = 1;
                app.voltage_tab(1,2:3,2) = 80;
                app.voltage_tab(1,4,2) = 20;
                app.voltage_tab(1,4,3) = 90;
                app.voltage_tab(1,5,2) = 0;

                %set CAM2 to be calcium with all features selected YES
                app.calcium_tab(2,:,1) = 1;
                app.calcium_tab(2,2:3,2) = 80;
                app.calcium_tab(2,4,2) = 20;
                app.calcium_tab(2,4,3) = 90;
                app.calcium_tab(2,5,2) = 100;
                app.calcium_tab(2,5,3) = 20;

                update_feature_selection(app);

            catch ME
                error_message = strcat('ERROR in function reset_default_UI(app) ', ME.message);
                update_console(app,error_message);
            end
        end


        function update_console(app, message)
            %UPDATE_CONSOLE  Prepend a message to the status console (TextArea_2).
            try
                existing = app.TextArea_2.Value;
                if numel(existing) > 200
                    existing = existing(1:200);
                end
                app.TextArea_2.Value = [message; ''; existing];
            catch ME
                error_message = strcat('ERROR in function update_console(app) ', ME.message);
                disp(error_message);
            end
        end


        function valid_action = update_active_tab_axes_data(app)
            try
                app.active_tab = app.TabGroup2.SelectedTab.Title;
                switch app.active_tab
                    case 'CAM_1'
                        app.active_axes = app.UIAxes_cam1;
                        app.active_data = app.data.CAM1;
                        if isfield(app.data, 'CAM1_average')
                            app.active_ensemble_data = app.data.CAM1_average;
                        end
                        app.active_image = app.data.CAM1_image;
                        app.active_camera = 1;
                        app.active_color = 'r';
                        valid_action = 1;
                    case 'CAM_2'
                        if app.data.num_files >= 2
                            app.active_axes = app.UIAxes_cam2;
                            app.active_data = app.data.CAM2;
                            if isfield(app.data, 'CAM2_average')
                                app.active_ensemble_data = app.data.CAM2_average;
                            end
                            app.active_image = app.data.CAM2_image;
                            app.active_camera = 2;
                            app.active_color = 'b';
                            valid_action = 1;
                        else
                            valid_action = 0; % no data loaded
                        end
                    case 'CAM_3'
                        if app.data.num_files >=3
                            app.active_axes = app.UIAxes_cam3;
                            app.active_data = app.data.CAM3;
                            if isfield(app.data, 'CAM3_average')
                                app.active_ensemble_data = app.data.CAM3_average;
                            end
                            app.active_image = app.data.CAM3_image;
                            app.active_camera = 3;
                            app.active_color = 'm';
                            valid_action = 1;
                        else
                            valid_action = 0; % no data loaded
                        end
                    case 'CAM_4'
                        if app.data.num_files ==4
                            app.active_axes = app.UIAxes_cam4;
                            app.active_data = app.data.CAM4;
                            if isfield(app.data, 'CAM4_average')
                                app.active_ensemble_data = app.data.CAM4_average;
                            end
                            app.active_image = app.data.CAM4_image;
                            app.active_camera = 4;
                            app.active_color = 'g';
                            valid_action = 1;
                        else
                            valid_action = 0; % no data loaded
                        end
                end
            catch ME
                error_message = strcat('ERROR in function update_active_tab_axes_data(app) ', ME.message);
                update_console(app,error_message);
            end
        end


        function valid_location = update_plot_marker(app,mouse_action)
            %UPDATE_PLOT_MARKER  Place/move the pixel marker on the active camera image.
            %   valid_location = update_plot_marker(app, mouse_action)
            try
                app.mouse_coordinates = get(app.active_axes,'CurrentPoint');
                app.mouse_x = round(app.mouse_coordinates(1,1));
                app.mouse_y = round(app.mouse_coordinates(1,2));

                if app.mouse_x >=1 && app.mouse_x <=app.x_axis && app.mouse_y >=1 && app.mouse_y <= app.y_axis
                    if ~isempty(app.plot_marker)
                        delete(app.plot_marker);
                    end
                    app.plot_marker = line(app.active_axes, app.mouse_x,app.mouse_y, 'Marker','square', ...
                        'MarkerSize',10, 'MarkerEdgeColor','w', 'MarkerFaceColor',app.active_color);

                    if isequal(mouse_action, 'button_down')
                        app.mouse_drag = 1;
                    end
                    valid_location = 1;
                else
                    valid_location = 0;
                end
            catch ME
                error_message = strcat('ERROR in function update_plot_marker(app) ', ME.message);
                update_console(app,error_message);
            end
        end

        function data = transform_data(app, orig_data, tform)
            try
                % For 2-D input the output is returned as 2-D (not [rows × cols × 1]).
                is2D = ismatrix(orig_data);

                [num_rows, num_cols, T] = size(orig_data);

                % ── Build destination ↔ source pixel mapping ──────────────────────
                [jj, ii] = meshgrid(1:num_cols, 1:num_rows);
                [xT, yT] = transformPointsForward(tform, ii(:), jj(:));
                xT = round(reshape(xT, num_rows, num_cols));
                yT = round(reshape(yT, num_rows, num_cols));

                % Keep only pixels whose transformed location falls inside the frame
                valid   = xT >= 1 & xT <= num_rows & yT >= 1 & yT <= num_cols;
                src_lin = sub2ind([num_rows, num_cols], ii(valid),  jj(valid));
                dst_lin = sub2ind([num_rows, num_cols], xT(valid), yT(valid));

                % ── Extend 2-D indices across all T frames (no loop needed) ───────
                % For a [rows × cols × T] array, the stride between frames is
                % num_rows*num_cols, so adding (t-1)*stride shifts each 2-D linear
                % index into the correct frame of the 3-D array.
                frame_offsets = (0:T-1) * (num_rows * num_cols);  % 1 × T
                src_all = src_lin(:) + frame_offsets;              % n_valid × T
                dst_all = dst_lin(:) + frame_offsets;              % n_valid × T

                % Single vectorized assignment — no frame loop
                data = zeros(size(orig_data), 'like', orig_data);
                data(dst_all(:)) = orig_data(src_all(:));

                % Restore original shape for 2-D input
                if is2D
                    data = squeeze(data);
                end

            catch ME
                update_console(app, strcat('ERROR in transform_data: ', ME.message));
            end

        end


        function update_plot_signal(app)
            %UPDATE_PLOT_SIGNAL  Plot the optical signal at the current marker pixel.
            try
                col = app.mouse_x;
                row = app.mouse_y;
                app.pixel = [row, col];
                link_signals = app.LINKDATACHECKBOX.Value;
                % smooth_signals = app.ENSEMBLECHECKBOX.Value;
                % smooth_val = app.SMOOTHTHRESHOLD.Value;

                if link_signals
                    link_oap = [];
                    oap_1 = squeeze(app.data.CAM1(row,col,:))';

                    %if~isnan(oap_1(1,1))
                    link_oap = oap_1;
                    %end

                    if app.data.num_files >=2
                        oap_2 = squeeze(app.data.CAM2(row,col,:))';
                        % if smooth_signals
                        %     oap_2 = smooth(oap_2, smooth_val, 'loess')';
                        % end
                        %if~isnan(oap_2(1,1))
                        link_oap = [link_oap; oap_2];
                        %end
                    end

                    if app.data.num_files >=3
                        oap_3 = squeeze(app.data.CAM3(row,col,:))';
                        % if smooth_signals
                        %     oap_3 = smooth(oap_3, smooth_val, 'loess')';
                        % end
                        %if~isnan(oap_3(1,1))
                        link_oap = [link_oap; oap_3];
                        %end
                    end

                    if app.data.num_files >=4
                        oap_4 = squeeze(app.data.CAM4(row,col,:))';
                        % if smooth_signals
                        %     oap_4 = smooth(oap_4, smooth_val, 'loess')';
                        % end
                        %if~isnan(oap_4(1,1))
                        link_oap = [link_oap; oap_4];
                        %end
                    end

                    if ~isempty(app.plot_signal)
                        delete(app.plot_signal);
                    end

                    app.time = 1:size(oap_1,2);
                    app.plot_signal = line(app.UIAxes_sig, app.time, link_oap, 'LineWidth',1.5);

                else
                    oap = squeeze(app.active_data(row,col,:));
                    %if ~isnan(oap(1,1))
                    if ~isempty(app.plot_signal)
                        % app.plot_signal.YData = oap;
                        % app.plot_signal.Color = app.active_color;
                        delete(app.plot_signal);
                    end

                    app.time = 1:size(oap,1);
                    % if smooth_signals
                    %     oap = smooth(oap, smooth_val, 'loess')';
                    % end
                    app.plot_signal = line(app.UIAxes_sig, app.time, oap, 'LineWidth',1.5, 'color',app.active_color);
                    %end
                end

            catch ME
                error_message = strcat('ERROR in function update_plot_signal(app) ', ME.message);
                update_console(app,error_message);
            end
        end


        %SELECT_WINDOW  Draw a draggable signal-window ROI over the plotted signal.
        function select_window(app)
            try
                valid_action = update_active_tab_axes_data(app);
                if ~isempty(app.plot_signal) && valid_action
                    tag_id = generate_unique_id;
                    pause(0.001);
                    window = drawrectangle(app.UIAxes_sig,'Color', 'k', 'Tag', tag_id, 'FaceAlpha', 0.1);
                    addlistener(window,'ROIMoved', @(varargin)ROIChanged(app,window, 1)); % update if there is a change to the signal window;
                    addlistener(window,'ObjectBeingDestroyed', @(varargin)ROIDeleted(app,window)); %update if the user removes signal window;
                    update_signal_window(app,window);
                    pause(0.001);
                else
                    update_console(app, 'No representative signal or data loaded to select signal window from')
                end

            catch ME
                error_message = strcat('ERROR in function select_window(app) ', ME.message);
                update_console(app,error_message);
            end
        end


        %ROICHANGED  Listener: refresh a signal window or mask after it is edited.
        %   ROIChanged(app, window, window_type)
        function ROIChanged(app,window,window_type)
            try
                if window_type %signal window
                    update_signal_window(app,window);
                else
                    update_mask(app, window);
                end
            catch ME
                error_message = strcat('ERROR in function ROIChanged(app) ', ME.message);
                update_console(app,error_message);
            end
        end


        %ROIDELETED  Listener: clear the stored signal window when its ROI is deleted.
        function ROIDeleted(app,window)
            try
                if isvalid(app)
                    [~,index] = window_exists(app,window.Tag);
                    if index > 0
                        app.oap_window(index,:) = [];
                    end
                end
            catch ME
                error_message = strcat('ERROR in function ROIDeleted(app) ', ME.message);
                update_console(app,error_message);
            end
        end


        %UPDATE_SIGNAL_WINDOW  Record a signal window's frame span, handle and Tag.
        %   [start_frame, end_frame] = update_signal_window(app, window)
        function [start_frame, end_frame] = update_signal_window(app, window)
            try
                position = window.Position;
                start_frame = round(position(1));
                end_frame = start_frame + round(position(3));

                app.oap_window{1,1} = [start_frame, end_frame];
                app.oap_window{1,2} = window;
                app.oap_window{1,3} = window.Tag;

            catch ME
                error_message = strcat('ERROR in function update_signal_window(app) ', ME.message);
                update_console(app,error_message);
            end
        end


        function rep = pct_to_threshold(~, val)
            % Converts integer percentage (10,20,...,100) to repolarization threshold.
            % Relationship is always: threshold = 1 - pct/100
            % e.g. APD80 means "find where signal drops to 20% of peak" → rep = 0.20
            rep = max(0, 1 - val / 100);
        end


        %LOAD_FILE  Load a conditioned recording's cmos_all_data from a .mat.
        %   cmos_all_data = load_file(app, file_name)
        %   (Add a schema_gate("conditioned") check here per the validation plan.)
        function cmos_all_data = load_file(app, file_name)
            try
                app.mat_file_name = file_name;
                file_data = load(fullfile(app.dir_name, file_name));
                cmos_all_data = file_data.cmos_all_data;
                [ok, msgs] = schema_gate(cmos_all_data, "conditioned", file_name);
                for k = 1:numel(msgs), update_console(app, msgs{k}); end
                if ~ok
                    update_console(app, 'Schema FAIL — file not loaded.');
                    cmos_all_data = [];
                    return;
                end
            catch ME
                update_console(app, strcat('ERROR in load_file: ', ME.message));
            end
        end


        %COMPILE_DATA  Assemble the 'metrics' struct saved after feature extraction.
        %   [fe_data] = compile_data(app)
        %   Packs num_files, signal windows, acqFreq, data_masks, analog1 and the
        %   extracted ep_metrics together with each camera's stack and preview image.
        %   This is the struct written by SAVEDATA and consumed by Signal Analysis / CV.
        function [fe_data] = compile_data(app)
            try
                fe_data = app.data;
                fe_data.window = app.oap_window;
                fe_data.data_masks = app.data_masks;
                fe_data.FOV = app.FOVmmEditField.Value;

            catch ME
                error_message = strcat('ERROR in function save_file(app) ', ME.message);
                update_console(app,error_message);
            end
        end


        %WINDOW_EXISTS  Find a signal window by Tag in the oap_window list.
        %   [camera_index, window_index] = window_exists(app, tag_ID)  (0 if none).
        function [camera_index, window_index] = window_exists(app, tag_ID)
            try
                window_index = 0;%default - window does not exist
                camera_index = app.active_camera;

                signal_window = app.oap_window;
                if ~isempty(signal_window)
                    num_sig = size(signal_window,1);
                    for i=1:num_sig %iterate through each window list
                        if strcmp(tag_ID, signal_window{i,3})
                            window_index = i;
                            return;
                        end
                    end
                end

            catch ME
                error_message = strcat('ERROR in function window_exists(app) ', ME.message);
                update_console(app,error_message);
            end
        end


        %EXTRACT_COMBO_MASKS  Build the per-camera analysis mask used by all features.
        %   extract_combo_masks(app, d)
        %   Combines the user ROI mask and/or the SNR mask (per the mask toggles) into
        %   one combo mask per camera; falls back to an SNR>3 mask when neither is set.
        function extract_combo_masks(app, d)
            try
                d.Message = '1B) Generating data masks for feature extraction, if available';
                for i=1:app.data.num_files
                    combo_mask = [];

                    if app.data_masks_tab(i,2) && ~isempty(app.data_masks{i,2}) %user mask
                        user_mask = app.data_masks{i,2}{1,2};
                        if ~isempty(combo_mask)
                            combo_mask = combo_mask .* user_mask;
                        else
                            combo_mask = user_mask;
                        end
                    end

                    if app.data_masks_tab(i,1) && ~isempty(app.data_masks{i,1}) %snr_mask
                        snr_mask = app.data_masks{i,1}{1,1};
                        snr_mask(~isnan(snr_mask)) = 1;
                        if ~isempty(combo_mask)
                            combo_mask = combo_mask .* snr_mask;
                        else
                            combo_mask = snr_mask;
                        end
                    end

                    if ~isempty(combo_mask)
                        app.combo_masks{i,1} = combo_mask;

                    else
                        % No user/SNR mask set -> per-recording ADAPTIVE SNR mask:
                        % max(1.5, 0.5*median tissue SNR). Tracks each heart's brightness;
                        % a fixed absolute floor over-cuts dim hearts / under-cuts bright ones.
                        snr = get_snr_data(app, i);
                        [snr_mask, snr_info] = create_snr_mask(snr, [], true);
                        snr_mask = double(snr_mask);
                        snr_mask(~snr_mask) = NaN;
                        app.combo_masks{i,1} = snr_mask;
                        update_console(app, sprintf('CAM%d: adaptive SNR mask, threshold %.2f (%.0f%% tissue).', ...
                            i, snr_info.threshold, 100*snr_info.tissue_fraction));
                    end
                end

                d.Message = '1B) Generating data masks for feature extraction... Done';

            catch ME
                error_message = strcat('ERROR in function extract_combo_masks(app, d) ', ME.message);
                update_console(app,error_message);
            end
        end


        %DISPLAY_MAP  Overlay a scalar map on the active camera image (in-app axes).
        %   display_map(app, map, color_scale)
        function display_map(app, map, color_scale)
            try

                delete(app.active_axes.Children(:,1));
                imagesc(app.active_axes, real2rgb(app.active_image,'gray'));
                hold(app.active_axes, "on");
                imagesc(app.active_axes, map, 'AlphaData', ~isnan(map));
                set(app.UIAxes_cam1, 'XTick', [], 'YTick', []);

                if ~isempty(color_scale)
                    colormap(app.active_axes,"jet");
                    colorbar(app.active_axes, "southoutside");
                    clim(app.active_axes,color_scale);
                else
                    colorbar(app.active_axes, "off");
                end

            catch ME
                error_message = strcat('ERROR in function display_map(app, title, map) ', ME.message);
                update_console(app,error_message);
            end
        end


        function valid = interior_valid_mask(app, Vmag, Vx, Vy, cap)
            % Valid CV pixels for directional pooling: finite, below cap, and >=
            % EDGE_MARGIN px inside the tissue boundary (edge/curvature pixels are
            % excluded because the inverse-gradient method inflates CV there).
            try
                edge_margin = 8;
                tissue = imfill(isfinite(Vmag), 'holes');   % fill reject_over_cap NaN holes so erosion only trims the true boundary
                interior = imerode(tissue, true(2*edge_margin + 1));
                valid = interior & isfinite(Vmag) & isfinite(Vx) & isfinite(Vy) & (Vmag < cap) & (Vmag > 1);
            catch ME
                valid = isfinite(Vmag) & isfinite(Vx) & isfinite(Vy) & (Vmag < cap);
                update_console(app, strcat('ERROR in interior_valid_mask(app) ', ME.message));
            end
        end


        %DISPLAY_CV_MAP  Pop-out figure(s) of the conduction-velocity map per camera.
        %   figs_array = display_cv_map(app, map_data, label, figs_array)
        %   Appends one uifigure per camera with data to figs_array and returns it.
        function figs_array = display_cv_map(app, map_data, label, figs_array)
            try
                [num_cam, ~] = size(map_data);
                cam_counter  = 1;

                % ---- figure sizing (computed once) ----
                scr = get(groot, 'ScreenSize');
                W = 1100;  H = 700;  minW = 850;  minH = 500;
                win_idx = 0;

                while cam_counter <= num_cam
                    if ~isempty(map_data{cam_counter,1})
                        fig_title = label + ": CAM" + string(map_data{cam_counter,4});
                        fig = uifigure("Name", fig_title);
                        figs_array(end+1) = fig;

                        % generous default size, cascade per window, clamped minimum
                        off = mod(win_idx, 10) * 28;  win_idx = win_idx + 1;
                        fig.Position = [max(1,(scr(3)-W)/2)+off, max(1,(scr(4)-H)/2)-off, W, H];
                        fig.AutoResizeChildren = 'off';                 % required for SizeChangedFcn
                        fig.SizeChangedFcn = @(src,~) set(src, 'Position', ...
                            [src.Position(1), src.Position(2), ...
                            max(src.Position(3), minW), max(src.Position(4), minH)]);

                        g = uigridlayout(fig, [1 2]);
                        g.RowHeight     = {'1x'};
                        g.ColumnWidth   = {'1x','1x'};
                        g.Padding       = [10 10 10 10];
                        g.ColumnSpacing = 10;

                        % ---- CV map + quiver overlay (left) ----
                        ax1 = uiaxes(g);  ax1.Layout.Row = 1;  ax1.Layout.Column = 1;
                        cvmag = map_data{cam_counter,1}{1,1};
                        Vx = map_data{cam_counter,1}{1,2};
                        Vy = map_data{cam_counter,1}{1,3};
                        imagesc(ax1, real2rgb(map_data{cam_counter,2}, 'gray'));
                        hold(ax1, 'on');
                        imagesc(ax1, cvmag, 'AlphaData', ~isnan(cvmag));

                        
                        % Color scale from INTERIOR pixels only (same 8-px erosion gate as the
                        % histogram and ellipse fit); edge pixels are inflated by the inverse-gradient
                        % method and would otherwise drive the 98th percentile. Capped at 100 cm/s.
                        valid = interior_valid_mask(app, cvmag, Vx, Vy, 249.9);
                        vals  = cvmag(valid & cvmag > 0);
                        if nnz(vals) < 50                                   % interior starved -> full field
                            vals = cvmag(isfinite(cvmag) & cvmag > 0);
                        end
                        color_scale = [floor(prctile(vals, 2)), min(100, ceil(prctile(vals, 98)))];

                        set(ax1, 'XTick', [], 'YTick', []);
                        colormap(ax1, 'jet');
                        cb = colorbar(ax1, 'southoutside');
                        cb.Label.String = 'CV (cm/s)';
                        clim(ax1, color_scale);

                        map  = map_data{cam_counter,1};
                        Vx   = map{1,2};
                        Vy   = map{1,3};
                        T    = map{1,7};
                        meta = map{1,6};

                        % Downsample quiver on a PHYSICAL grid (~1 mm between arrows) so density is
                        % consistent across species/FOV instead of tied to pixel count.
                        px_mm = app.FOVmmEditField.Value / size(T,1);            % mm per pixel
                        step  = max(2, round(1.0 / max(px_mm, eps))); % ~1 mm spacing
                        [xx, yy] = meshgrid(1:size(T,2), 1:size(T,1));
                        if isstruct(meta) && isfield(meta,'good') && ~isempty(meta.good)
                            good = meta.good;
                        else
                            good = isfinite(Vx) & isfinite(Vy);       % fallback if engine lacks meta.good
                        end
                        mask = good & mod(xx,step)==0 & mod(yy,step)==0;
                        % Arrow length encodes speed (clipped to a robust reference so a few fast
                        % pixels don't dominate), instead of forcing every arrow to unit length.
                        mag  = hypot(Vx, Vy);
                        vref = prctile(mag(mask), 90);
                        if ~isfinite(vref) || vref <= 0, vref = 1; end
                        Vxs = Vx ./ vref;  Vys = Vy ./ vref;
                        quiver(ax1, xx(mask), yy(mask), Vxs(mask), Vys(mask), 0.9, 'm', 'LineWidth', 0.5);
                        axis(ax1, 'image');

                        % ---- CV distribution histogram (right) ----
                        ax2 = uiaxes(g);  ax2.Layout.Row = 1;  ax2.Layout.Column = 2;
                        histogram(ax2, map{1,1}, 40);
                        xlabel(ax2, 'Speed (cm/s)');
                        ylabel(ax2, 'Count');
                        title(ax2, 'Conduction velocity distribution');
                    end
                    cam_counter = cam_counter + 1;
                end

            catch ME
                error_message = strcat('ERROR in function display_cv_map(app, data, label) ', ME.message);
                update_console(app, error_message);
            end

        end


        %DISPLAY_ALTERNANS_MAP  Pop-out figure(s) of the APD-alternans map per camera.
        %   figs_array = display_alternans_map(app, map_data, label, figs_array)
        function figs_array = display_alternans_map(app, map_data, label, figs_array)
            try
                [num_cam, ~] = size(map_data);
                cam_counter  = 1;
                [row, col]   = get_pixel_location(app, 1);

                % ---- figure sizing (computed once) ----
                scr = get(groot, 'ScreenSize');
                W = 1200;  H = 880;  minW = 950;  minH = 700;
                win_idx = 0;

                while cam_counter <= num_cam
                    if ~isempty(map_data{cam_counter,1})
                        alternans_data = map_data{cam_counter,1};
                        substrate_data = map_data{cam_counter,5};
                        ap_alternans   = strcmp(map_data{cam_counter,6}, 'AP');

                        fig_title = label + ": CAM" + string(map_data{cam_counter,4});
                        fig = uifigure("Name", fig_title);
                        figs_array(end+1) = fig;

                        % generous default size, cascade per window, clamped minimum
                        off = mod(win_idx, 10) * 28;  win_idx = win_idx + 1;
                        fig.Position = [max(1,(scr(3)-W)/2)+off, max(1,(scr(4)-H)/2)-off, W, H];
                        fig.AutoResizeChildren = 'off';                 % required for SizeChangedFcn
                        fig.SizeChangedFcn = @(src,~) set(src, 'Position', ...
                            [src.Position(1), src.Position(2), ...
                            max(src.Position(3), minW), max(src.Position(4), minH)]);

                        g = uigridlayout(fig, [3 3]);
                        g.RowHeight     = {'1x','1x','1x'};
                        g.ColumnWidth   = {'1x','1x','1x'};
                        g.Padding       = [10 10 10 10];
                        g.RowSpacing    = 10;
                        g.ColumnSpacing = 10;

                        % ---- per-panel data + titles (branch-specific) ----
                        if ap_alternans
                            panel_data  = { substrate_data.gradient_mag, ...
                                substrate_data.restitution_map, ...
                                alternans_data.APD50_ratio_map, ...
                                alternans_data.dvdt_ratio_map, ...
                                substrate_data.risk_map, ...
                                alternans_data.phase_map };
                            panel_title = {'APD50 Spatial Gradient', 'APD50 Restitution Slope', ...
                                'APD50 Alternans Ratio Map', 'Conduction (dv/dt ratio map)', ...
                                'AP Alternans Risk Map', 'Alternans Phase Map'};
                        else
                            if ~isempty(substrate_data), coupling = substrate_data.coupling_map; else, coupling = []; end
                            panel_data  = { coupling, ...
                                alternans_data.release_alt_map, ...
                                alternans_data.load_alt_map, ...
                                alternans_data.D50_alt_map, ...
                                alternans_data.D50_ratio_map, ...
                                alternans_data.phase_map };
                            panel_title = {'Ca-AP Pearson r map', 'SR Ca Release Alternans Map', ...
                                'SR Ca Load Alternans Map', 'CaTD50 Alternans Map', ...
                                'CaTD50 Alternans Ratio Map', 'CaT Amplitude Alternans Phase Map'};
                        end

                        % ---- render the 6 map panels ----
                        table_data = cell(6, 4);   % [mean std median sample] per panel
                        for k = 1:6
                            r = ceil(k/3);  c = mod(k-1,3) + 1;
                            ax = uiaxes(g);  ax.Layout.Row = r;  ax.Layout.Column = c;

                            imagesc(ax, real2rgb(map_data{cam_counter,2}, 'gray'));
                            hold(ax, 'on');

                            d = panel_data{k};
                            if ~isempty(d)
                                imagesc(ax, d, 'AlphaData', ~isnan(d));
                            end
                            title(ax, panel_title{k});
                            line(ax, col, row, 'Marker','square', 'MarkerSize',10, ...
                                'MarkerEdgeColor','w', 'MarkerFaceColor','r');
                            set(ax, 'XTick', [], 'YTick', []);
                            colormap(ax, 'jet');
                            colorbar(ax, 'southoutside');

                            % Robust color scale: median +/- 3 robust-sigma (MAD-based). Unlike mean/std it
                            % ignores wrong-beat / speckle outliers, so the true isochrone band fills the
                            % colormap and the elliptical wavefront + pacing site become visible.
                            vals = d(isfinite(d));
                            med  = median(vals);
                            rsig = 1.4826 * median(abs(vals - med));      % robust sigma
                            if rsig > 0
                                color_scale = [floor(med - 3*rsig), ceil(med + 3*rsig)];
                            else
                                color_scale = [min(vals), max(vals)];
                            end
                            clim(ax,color_scale);
                            axis(ax, 'image');

                            [table_data{k,1}, table_data{k,2}, table_data{k,3}, table_data{k,4}] = ...
                                get_table_values(app, d);
                        end

                        % ---- bottom-left: trace + pacing (spans columns 1-2) ----
                        ax7 = uiaxes(g);  ax7.Layout.Row = 3;  ax7.Layout.Column = [1 2];
                        plot(ax7, map_data{cam_counter,3}, 'LineWidth', 1.5);
                        hold(ax7, 'on');
                        if isfield(app.data, 'analog1')
                            app.time = 1:size(app.data.analog1,1)';
                            line(ax7, app.time, app.data.analog1, 'LineWidth', 1, 'Color', '#777777');
                        end

                        % ---- bottom-right: stats table ----
                        col_names = {'Mean'; 'STD'; 'Median'; 'Sample'};
                        if ap_alternans
                            row_names = {'APD50 Spatial Gradient'; 'APD50 Resitution Slope'; 'APD50_Alt_Ratio'; ...
                                'Conduction'; 'AP_Amp_Ratio'; 'APD50 Phase'};
                        else
                            row_names = {'AP_Ca Coupling'; 'SR Ca Release'; 'SR Ca Load'; ...
                                'CaD50_Alt'; 'CaD50_Alt_Ratio'; 'CaT Amp Phase'};
                        end

                        t = uitable(g, 'Data', table_data, 'ColumnName', col_names, 'RowName', row_names);
                        t.Layout.Row = 3;  t.Layout.Column = 3;
                    end
                    cam_counter = cam_counter + 1;
                end

            catch ME
                error_message = strcat('ERROR in function display_alternans_map(app, title, map) ', ME.message);
                update_console(app, error_message);
            end

        end


        %DISPLAY_FEATURE_MAP  Pop-out figure(s) of a scalar feature map per camera.
        %   figs_array = display_feature_map(app, map_data, label, figs_array)
        %   Generic map+signal figure used for LAT/repolarization/APD/rise/etc.
        function figs_array = display_feature_map(app, map_data, label, figs_array)
            try
                [num_cam, ~] = size(map_data);
                cam_counter  = 1;
                [row, col]   = get_pixel_location(app, 1);

                % ---- figure sizing (computed once) ----
                scr = get(groot, 'ScreenSize');
                W = 1100;  H = 800;  minW = 850;  minH = 600;
                win_idx = 0;

                while cam_counter <= num_cam
                    if ~isempty(map_data{cam_counter,1})
                        fig_title = label + ": CAM" + string(map_data{cam_counter,5});
                        fig = uifigure("Name", fig_title);
                        figs_array(end+1) = fig;

                        % generous default size, cascade per window, clamped minimum
                        off = mod(win_idx, 10) * 28;  win_idx = win_idx + 1;
                        fig.Position = [max(1,(scr(3)-W)/2)+off, max(1,(scr(4)-H)/2)-off, W, H];
                        fig.AutoResizeChildren = 'off';                 % required for SizeChangedFcn
                        fig.SizeChangedFcn = @(src,~) set(src, 'Position', ...
                            [src.Position(1), src.Position(2), ...
                            max(src.Position(3), minW), max(src.Position(4), minH)]);

                        g = uigridlayout(fig, [2 2]);
                        g.RowHeight     = {'1x','1x'};
                        g.ColumnWidth   = {'1x','1x'};
                        g.Padding       = [10 10 10 10];
                        g.RowSpacing    = 10;
                        g.ColumnSpacing = 10;

                        % ---- map panel (top-left) ----
                        ax1 = uiaxes(g);  ax1.Layout.Row = 1;  ax1.Layout.Column = 1;
                        imagesc(ax1, real2rgb(map_data{cam_counter,2}, 'gray'));
                        hold(ax1, 'on');
                        imagesc(ax1, map_data{cam_counter,1}, 'AlphaData', ~isnan(map_data{cam_counter,1}));
                        line(ax1, col, row, 'Marker','square', 'MarkerSize',10, ...
                            'MarkerEdgeColor','w', 'MarkerFaceColor','r');
                        set(ax1, 'XTick', [], 'YTick', []);

                        % Robust color scale: median +/- 3 robust-sigma (MAD-based). Unlike mean/std it
                        % ignores wrong-beat / speckle outliers, so the true isochrone band fills the
                        % colormap and the elliptical wavefront + pacing site become visible.
                        cardiac_map = map_data{cam_counter,1};
                        vals = cardiac_map(isfinite(cardiac_map));
                        med  = median(vals);
                        rsig = 1.4826 * median(abs(vals - med));      % robust sigma
                        if rsig > 0
                            color_scale = [floor(med - 3*rsig), ceil(med + 3*rsig)];
                        else
                            color_scale = [min(vals), max(vals)];
                        end
                        clim(ax1,color_scale);
                        colormap(ax1, 'jet');
                        colorbar(ax1, 'southoutside');
                        axis(ax1, 'image');

                        % ---- histogram (top-right) ----
                        ax2 = uiaxes(g);  ax2.Layout.Row = 1;  ax2.Layout.Column = 2;
                        x  = map_data{cam_counter,1}(row, col);
                        h1 = histogram(ax2, map_data{cam_counter,1});
                        hold(ax2, 'on');
                        if ~isnan(x)   % highlight the sampled pixel's bin
                            edges = h1.BinEdges;
                            [r1,c1,~] = find(h1.BinEdges(1:end) >= x, 1);
                            [r2,c2,~] = find(h1.BinEdges(1:end) <= x, 1, "last");
                            x_min = edges(r2,c2);
                            x_max = edges(r1,c1);
                            mask_red = (map_data{cam_counter,1} >= x_min & map_data{cam_counter,1} <= x_max);
                            histogram(ax2, map_data{cam_counter,1}(mask_red), h1.BinEdges, 'FaceColor', 'red');
                        end
                        mean_val   = round(mean(map_data{cam_counter,1}, "all", "omitmissing"));
                        std_val    = round(std(map_data{cam_counter,1}(:), "omitmissing"));
                        median_val = round(median(map_data{cam_counter,1}, "all", "omitmissing"));

                        % ---- OAP / feature trace (bottom-left) ----
                        ax3 = uiaxes(g);  ax3.Layout.Row = 2;  ax3.Layout.Column = 1;
                        if strcmp(map_data{cam_counter,5}, 'VCDelay')
                            v_c_oap = map_data{cam_counter,3};
                            plot(ax3, v_c_oap{1,1}, 'LineWidth', 2.5);
                            hold(ax3, 'on');
                            plot(ax3, v_c_oap{2,1}, 'LineWidth', 2.5, 'LineStyle', '-.', 'Color', 'r');
                        else
                            oap = map_data{cam_counter,3};
                            plot(ax3, oap, 'LineWidth', 2.5);
                            hold(ax3, 'on');
                            x_loc = map_data{cam_counter,4};
                            if isscalar(x_loc)
                                y = oap(round(x_loc), 1);
                                line(ax3, round(x_loc), y, 'Marker','square', 'MarkerSize',5, ...
                                    'MarkerEdgeColor','w', 'MarkerFaceColor','r');   % act / rep location
                            else
                                plot(ax3, x_loc, 'LineWidth', 2.5, 'Color', 'r');     %  APD / rise time
                            end
                        end

                        % ---- stats table (bottom-right) ----
                        row_names  = {'Mean'; 'STD'; 'Median'; 'Sample'};
                        col_names  = {char(fig_title)};
                        table_data = {mean_val; std_val; median_val; x};
                        t = uitable(g, 'Data', table_data, 'ColumnName', col_names, 'RowName', row_names);
                        t.Layout.Row = 2;  t.Layout.Column = 2;
                    end
                    cam_counter = cam_counter + 1;
                end

            catch ME
                error_message = strcat('ERROR in function display_feature_map(app, data, label) ', ME.message);
                update_console(app, error_message);
            end

        end


        %DISPLAY_ARRHYTHMIA_MAP  Pop-out figure(s) of arrhythmia maps (phase/PS/wavefront).
        %   figs_array = display_arrhythmia_map(app, map_data, label, figs_array)
        function figs_array = display_arrhythmia_map(app, map_data, label, figs_array)
            try
                [num_cam, ~] = size(map_data);
                cam_counter  = 1;
                [row, col]   = get_pixel_location(app, 1);

                % ---- figure sizing (computed once) ----
                scr = get(groot, 'ScreenSize');
                W = 1200;  H = 880;  minW = 950;  minH = 700;
                win_idx = 0;

                map_titles = {'Dominant Frequency (DF) Hz', 'Regularity Index (RI)', 'Organization Index (OI)'};

                while cam_counter <= num_cam
                    fig_title = label + ": CAM" + string(map_data{cam_counter,5});
                    fig = uifigure("Name", fig_title);
                    figs_array(end+1) = fig;

                    % generous default size, cascade per window, clamped minimum
                    off = mod(win_idx, 10) * 28;  win_idx = win_idx + 1;
                    fig.Position = [max(1,(scr(3)-W)/2)+off, max(1,(scr(4)-H)/2)-off, W, H];
                    fig.AutoResizeChildren = 'off';                 % required for SizeChangedFcn
                    fig.SizeChangedFcn = @(src,~) set(src, 'Position', ...
                        [src.Position(1), src.Position(2), ...
                        max(src.Position(3), minW), max(src.Position(4), minH)]);

                    g = uigridlayout(fig, [3 3]);
                    g.RowHeight     = {'1x','1x','1x'};
                    g.ColumnWidth   = {'1x','1x','1x'};
                    g.Padding       = [10 10 10 10];
                    g.RowSpacing    = 10;
                    g.ColumnSpacing = 10;

                    % ---- row 1: DF / RI / OI map panels ----
                    for k = 1:3
                        ax = uiaxes(g);  ax.Layout.Row = 1;  ax.Layout.Column = k;
                        imagesc(ax, real2rgb(map_data{cam_counter,2}, 'gray'));
                        hold(ax, 'on');
                        d = map_data{cam_counter,1}{1,k};
                        imagesc(ax, d, 'AlphaData', ~isnan(d));
                        line(ax, col, row, 'Marker','square', 'MarkerSize',10, ...
                            'MarkerEdgeColor','w', 'MarkerFaceColor','r');
                        set(ax, 'XTick', [], 'YTick', []);

                        % Robust color scale: median +/- 3 robust-sigma (MAD-based). Unlike mean/std it
                        % ignores wrong-beat / speckle outliers, so the true isochrone band fills the
                        % colormap and the elliptical wavefront + pacing site become visible.
                        vals = d(isfinite(d));
                        med  = median(vals);
                        rsig = 1.4826 * median(abs(vals - med));      % robust sigma
                        if rsig > 0
                            color_scale = [floor(med - 3*rsig), ceil(med + 3*rsig)];
                        else
                            color_scale = [min(vals), max(vals)];
                        end
                        if k > 1
                            color_scale = [0 1];
                        end

                        clim(ax,color_scale);
                        colormap(ax, 'jet');
                        colorbar(ax, 'southoutside');
                        axis(ax, 'image');
                        title(ax, map_titles{k});
                    end

                    % ---- row 2: DF / RI / OI histograms with sampled-bin highlight ----
                    hist_mean = zeros(1,3);  hist_std = zeros(1,3);  hist_med = zeros(1,3);
                    for k = 1:3
                        ax = uiaxes(g);  ax.Layout.Row = 2;  ax.Layout.Column = k;
                        d  = map_data{cam_counter,1}{1,k};
                        xs = map_data{cam_counter,4}(1,k);

                        h1 = histogram(ax, d);
                        hold(ax, 'on');
                        if ~isnan(xs)   % bad data guard
                            edges = h1.BinEdges;
                            [r1,c1,~] = find(h1.BinEdges(1:end) > xs, 1);
                            [r2,c2,~] = find(h1.BinEdges(1:end) < xs, 1, "last");
                            x_min = edges(r2,c2);
                            x_max = edges(r1,c1);
                            mask_red = (d >= x_min & d < x_max);
                            histogram(ax, d(mask_red), h1.BinEdges, 'FaceColor', 'red');
                        end
                        hist_mean(k) = mean(d, "all", "omitmissing");
                        hist_std(k)  = std(d(:), "omitmissing");
                        hist_med(k)  = median(d, "all", "omitmissing");
                    end

                    % ---- row 3: trace (spans cols 1-2) + table (col 3) ----
                    ax7 = uiaxes(g);  ax7.Layout.Row = 3;  ax7.Layout.Column = [1 2];
                    plot(ax7, map_data{cam_counter,3}, 'LineWidth', 1.5);
                    hold(ax7, 'on');
                    if isfield(app.data, 'analog1')
                        app.time = 1:size(app.data.analog1,1)';
                        line(ax7, app.time, app.data.analog1, 'LineWidth', 1, 'Color', '#777777');
                    end
                    row_names = {'Mean'; 'STD'; 'Median'; 'Sample'};
                    col_names = {'DF'; 'RI'; 'OI'};
                    table_data = {hist_mean(1), hist_mean(2), hist_mean(3); ...
                        hist_std(1),  hist_std(2),  hist_std(3); ...
                        hist_med(1),  hist_med(2),  hist_med(3); ...
                        map_data{cam_counter,4}(1,1), map_data{cam_counter,4}(1,2), map_data{cam_counter,4}(1,3)};

                    t = uitable(g, 'Data', table_data, 'ColumnName', col_names, 'RowName', row_names);
                    t.Layout.Row = 3;  t.Layout.Column = 3;

                    cam_counter = cam_counter + 1;
                end

            catch ME
                error_message = strcat('ERROR in function display_arrhythmia_map(app, data, label) ', ME.message);
                update_console(app, error_message);
            end


        end


        %UPDATE_MASK  Create/update a user ROI data mask for the active camera.
        %   update_mask(app, mask_region)
        function update_mask(app, mask_region)
            try
                if ~isempty(mask_region)

                    mask = double(createMask(mask_region));
                    mask(mask == 0) = nan;

                    app.data_masks{app.active_camera,2}{1,1} = 'Default';
                    app.data_masks{app.active_camera,2}{1,2} = mask;
                    %app.data_masks{app.active_camera,2}{1,3} = 'Default';


                end

            catch ME
                error_message = strcat('ERROR in function update_mask(app) ', ME.message);
                update_console(app,error_message);
            end
        end


        %STORE_IMAGE_DATA  Snapshot the active camera's image (for undo of transforms).
        function store_image_data(app)
            try
                cam = app.active_camera;
                app.image_alignment{cam,1} = app.data.(sprintf('CAM%d_image', cam));
                app.image_alignment{cam,2} = app.data.(sprintf('CAM%d', cam));
                app.image_alignment{cam,3} = app.data.(sprintf('CAM%d_average', cam));
                app.image_alignment{cam,4} = app.data.(sprintf('CAM%d_SNR', cam));
            catch ME
                update_console(app, strcat('ERROR in store_image_data: ', ME.message));
            end
        end


        %UPDATE_IMAGE_DATA  Re-render the active camera image after an edit/transform.
        function update_image_data(app)
            try
                cam = app.active_camera;
                app.data.(sprintf('CAM%d_image', cam)) = app.active_image;
                app.data.(sprintf('CAM%d', cam)) = app.active_data;
                app.data.(sprintf('CAM%d_average', cam)) = app.active_ensemble_data;
                app.data.(sprintf('CAM%d_SNR', cam)) = app.active_snr;
            catch ME
                update_console(app, strcat('ERROR in update_image_data: ', ME.message));
            end
        end


        %UPDATE_FEATURE_SELECTION  Sync all feature dropdowns to the active camera's choices.
        %   Calls the AP / calcium / arrhythmia / mask selection updaters.
        function update_feature_selection(app)
            try

                update_action_potential_selection(app);
                update_calcium_transient_selection(app);
                update_arrhythmia_selection(app);
                update_mask_selection(app);

            catch ME
                error_message = strcat('ERROR in function update_feature_selection(app, link_cameras) ', ME.message);
                update_console(app,error_message);
            end
        end


        %UPDATE_ACTION_POTENTIAL_SELECTION  Restore the Vm feature dropdowns for the active camera.
        %   Reflects voltage_tab (ACT/repolarization/APD/rise/CV and their percentages)
        %   into the corresponding dropdowns.
        function update_action_potential_selection(app)
            try

                if app.voltage_tab(app.active_camera, 1, 1)
                    app.ACTIVATIONTIMEDropDown.Value = 'YES';
                else
                    app.ACTIVATIONTIMEDropDown.Value = 'NO';
                end

                if app.voltage_tab(app.active_camera, 2, 1)
                    app.RepolarizationDropDown.Value = 'YES';
                    app.RepDropDown.Value = num2str(app.voltage_tab(app.active_camera,2,2));
                else
                    app.RepolarizationDropDown.Value = 'NO';
                end

                if app.voltage_tab(app.active_camera, 3, 1)
                    app.DURATIONDropDown.Value = 'YES';
                    app.APDDropDown.Value = num2str(app.voltage_tab(app.active_camera,3,2));
                else
                    app.DURATIONDropDown.Value = 'NO';
                end

                if app.voltage_tab(app.active_camera, 4, 1)
                    app.RISETIMEDropDown.Value = 'YES';
                    app.APRiseDropDown_1.Value = num2str(app.voltage_tab(app.active_camera, 4, 2));
                    app.APRiseDropDown_2.Value = num2str(app.voltage_tab(app.active_camera, 4, 3));
                else
                    app.RISETIMEDropDown.Value = 'NO';
                end

                if app.voltage_tab(app.active_camera, 5, 1)
                    app.CONDUCTIONVELOCITYDropDown.Value = 'YES';
                else
                    app.CONDUCTIONVELOCITYDropDown.Value = 'NO';
                end

            catch ME
                error_message = strcat('ERROR in function update_action_potential_selection(app, link_cameras) ', ME.message);
                update_console(app,error_message);
            end
        end


        %UPDATE_CALCIUM_TRANSIENT_SELECTION  Restore the Ca feature dropdowns for the active camera.
        function update_calcium_transient_selection(app)
            try

                if app.calcium_tab(app.active_camera, 1, 1)
                    app.DELAYDropDown.Value = 'YES';
                else
                    app.DELAYDropDown.Value = 'NO';
                end

                if app.calcium_tab(app.active_camera, 2, 1)
                    app.CALCIUMDTDropDown.Value = 'YES';
                    app.CaDTDropDown.Value = num2str(app.calcium_tab(app.active_camera, 2, 2));
                else
                    app.CALCIUMDTDropDown.Value = 'NO';
                end

                if app.calcium_tab(app.active_camera, 3, 1)
                    app.CALCIUMDURATIONDropDown.Value = 'YES';
                    app.CaDropDown.Value = num2str(app.calcium_tab(app.active_camera, 3, 2));
                else
                    app.CALCIUMDURATIONDropDown.Value = 'NO';
                end

                if app.calcium_tab(app.active_camera, 4, 1)
                    app.CALCIUMRISETIMEDropDown.Value = 'YES';
                    app.CaRiseDropDown_1.Value = num2str(app.calcium_tab(app.active_camera, 4, 2));
                    app.CaRiseDropDown_2.Value = num2str(app.calcium_tab(app.active_camera, 4, 3));
                else
                    app.CALCIUMRISETIMEDropDown.Value = 'NO';
                end

                if app.calcium_tab(app.active_camera, 5, 1)
                    app.TDropDown.Value = 'YES';
                else
                    app.TDropDown.Value = 'NO';
                end

            catch ME
                error_message = strcat('ERROR in function update_calcium_transient_selection(app, link_cameras) ', ME.message);
                update_console(app,error_message);
            end

        end


        %UPDATE_ARRHYTHMIA_SELECTION  Restore the arrhythmia feature toggles for the active camera.
        function update_arrhythmia_selection(app)
            try

                if app.arrhythmia_tab(app.active_camera, 1,1)
                    app.COMPLEXITYDropDown.Value = 'YES';
                else
                    app.COMPLEXITYDropDown.Value = 'NO';
                end

                if app.arrhythmia_tab(app.active_camera, 2,1)
                    app.WAVEFRONTDropDown.Value = 'YES';
                else
                    app.WAVEFRONTDropDown.Value = 'NO';
                end

                if app.arrhythmia_tab(app.active_camera, 3,1)
                    app.PHASEDropDown.Value = 'YES';
                else
                    app.PHASEDropDown.Value = 'NO';
                end

                if app.arrhythmia_tab(app.active_camera, 4,1)
                    app.ALTERNANSDropDown.Value = 'YES';
                    type = app.arrhythmia_tab(app.active_camera,4,2);
                    if type
                        app.ALTERNANSDropDown_2.Value = 'CALCIUM';
                    else
                        app.ALTERNANSDropDown_2.Value = 'VOLTAGE';
                    end

                else
                    app.ALTERNANSDropDown.Value = 'NO';
                end

            catch ME
                error_message = strcat('ERROR in function update_arrhythmia_selection(app, link_cameras) ', ME.message);
                update_console(app,error_message);
            end

        end


        %UPDATE_MASK_SELECTION  Restore the SNR/user mask switches for the active camera.
        %   Reflects data_masks_tab (mask on/off and SNR threshold) into the switches.
        function update_mask_selection(app)
            try

                if app.data_masks_tab(app.active_camera, 1)
                    app.SNRSwitch.Value = 'On';
                    app.SetSNRMaskThreshold0autoEditField.Value = app.data_masks_tab(app.active_camera,3);
                else
                    app.SNRSwitch.Value = 'Off';
                    app.SetSNRMaskThreshold0autoEditField.Value = 0;
                end

                if app.data_masks_tab(app.active_camera, 2)
                    app.USRSwitch.Value = 'On';
                else
                    app.USRSwitch.Value = 'Off';
                end


            catch ME
                error_message = strcat('ERROR in function update_mask_selection(app, link_cameras) ', ME.message);
                update_console(app,error_message);
            end
        end


        %GET_ARR_CMOS_DATA  Return the raw CAM<cam_index> stack (for arrhythmia analysis).
        function cmos_data = get_arr_cmos_data(app, cam_index)
            try
                cmos_data = app.data.(sprintf('CAM%d', cam_index));
            catch ME
                update_console(app, strcat('ERROR in get_arr_cmos_data: ', ME.message));
            end
        end


        %GET_PIXEL_LOCATION  Pixel for the representative signal (cached, else image centre).
        %   [row, col] = get_pixel_location(app, cam_index)
        function [row, col] = get_pixel_location(app, cam_index)
            try
                if ~isempty(app.pixel)
                    row = app.pixel(1,1);
                    col = app.pixel(1,2);
                else
                    cam_data = get_snr_data(app, cam_index);
                    [r,c,] = size(cam_data);
                    % [~, linear_idx] = find (cam_data > median(cam_data, [], 'all'), 1);
                    % [col, row] = ind2sub(size(cam_data), linear_idx);
                    row = round(r/2);
                    col = round(c/2);
                    app.pixel(1,1) = row;
                    app.pixel(1,2) = col;
                end

            catch ME
                update_console(app, strcat('ERROR in get_pixel_location: ', ME.message));
            end
        end


        %GET_TABLE_VALUES  Mean/std/median over a map plus the sample-pixel value.
        %   [mean_val, std_val, median_val, sample_val] = get_table_values(app, table_data)
        function [mean_val, std_val, median_val, sample_val] = get_table_values(app, table_data)
            try
                mean_val = NaN;
                std_val = NaN;
                median_val = NaN;
                sample_val = NaN;

                [row, col] = get_pixel_location(app, 1);
                if ~isempty(table_data)
                    mean_val = round(mean(table_data, "all", "omitmissing"),1);
                    std_val = round(std(table_data(:),"omitmissing"),1);
                    median_val = round(median(table_data,"all","omitmissing"),1);
                    sample_val = round(table_data(row,col),1);
                end
            catch ME
                update_console(app, strcat('ERROR in get_table_values: ', ME.message));
            end
        end


        %GET_CMOS_DATA  Return the CAM<cam_index> stack and its ensemble average (if any).
        %   [cmos_data, avg_cmos_data] = get_cmos_data(app, cam_index)
        function [cmos_data, avg_cmos_data] = get_cmos_data(app, cam_index)
            try
                cam_f     = sprintf('CAM%d', cam_index);
                avg_f     = sprintf('CAM%d_average', cam_index);
                cmos_data = app.data.(cam_f);
                avg_cmos_data = [];
                if isfield(app.data, avg_f)
                    avg_cmos_data = app.data.(avg_f);
                end
            catch ME
                error_message = strcat('ERROR in function get_cmos_data:', ME.message);
                update_console(app,error_message);
            end
        end


        %GET_SNR_DATA  Return the CAM<cam_index>_SNR map ([] if absent).
        function [snr_data] = get_snr_data(app, cam_index)
            try
                snr_f     = sprintf('CAM%d_SNR', cam_index);
                snr_data = [];
                if isfield(app.data, snr_f)
                    snr_data = app.data.(snr_f);
                end
            catch ME
                error_message = strcat('ERROR in function get_snr_data:', ME.message);
                update_console(app,error_message);
            end
        end


        %SET_CMOS_DATA  Write back the active camera's stack (and average, if given).
        %   set_cmos_data(app, cmos_data, avg_cmos_data)
        function set_cmos_data(app, cmos_data, avg_cmos_data)
            try
                cam_f = sprintf('CAM%d', app.active_camera);
                avg_f = sprintf('CAM%d_average', app.active_camera);
                app.data.(cam_f) = cmos_data;
                if ~isempty(avg_cmos_data)
                    app.data.(avg_f) = avg_cmos_data;
                end
            catch ME
                update_console(app, strcat('ERROR in set_cmos_data: ', ME.message));
            end
        end


        %GET_BG_IMAGE  Return the CAM<i> preview/background image.
        function bgimage = get_bg_image(app, i)
            try
                bgimage = app.data.(sprintf('CAM%d_image', i));
            catch ME
                update_console(app, strcat('ERROR in get_bg_image: ', ME.message));
            end
        end


        %EXTRACT_CV  Extract a local conduction-velocity map per camera (if selected).
        %   [feature_extracted] = extract_cv(app, d)
        %   For each camera with CV selected: estimates activation from the steepest
        %   upstroke (max of the temporal derivative) over the ensemble beat or the
        %   signal window, masks it, and converts to CV using the FOV-derived pixel
        %   size. Stores into ep_metrics; d is the progress dialog. Returns 1 if any
        %   camera produced a result.
        function [feature_extracted] = extract_cv(app,d)
            try
                feature_extracted = 0;
                d.Message = 'Extracting Local Conduction Velocity, if selected';
                ensemble = app.ENSEMBLECHECKBOX.Value;

                for i=1:app.data.num_files
                    if app.voltage_tab(i,5,1)
                        [cmos_data, avg_cmos_data] = get_cmos_data(app,i);
                        cv_data = [];
                        if ensemble || isempty(app.oap_window)
                            win_data = avg_cmos_data;
                            %diff_data = diff(win_data,1,3);
                        else
                            start_frame = app.oap_window{1,1}(1,1);
                            end_frame = app.oap_window{1,1}(1,2);
                            %mid_frame = start_frame + round((end_frame - start_frame)/2);
                            win_data = cmos_data(:,:,start_frame:end_frame);
                            %diff_data = diff(cmos_data(:,:,start_frame:mid_frame),1,3);
                        end

                        %[~, act_times] = max(diff_data,[],3);
                        act_times = compute_lat_50(win_data);
                        mask_act_times = act_times;
                        if ~isempty(app.combo_masks{i,1})
                            mask_act_times = act_times .* app.combo_masks{i,1};
                        end

                        field_of_view = app.FOVmmEditField.Value;
                        num_pixels = size(act_times,1);
                        pixel_resolution = (field_of_view/num_pixels); % in mm
                        mask_act_times = mask_act_times * (1000 / app.data.acqFreq);   % frames -> ms
                        mask_act_times = despeckle_act_map(app, mask_act_times);
                        [Vmag,Vx, Vy, Gx, Gy, meta] = conduction_velocity(mask_act_times, pixel_resolution, pixel_resolution, []);
                        Vmag(meta.smooth_support < 0.75) = NaN;   % drop edge/hole-biased pixels before saving

                        cv_data{1,1} = Vmag*100;
                        cv_data{1,2} = Vx;
                        cv_data{1,3} = Vy;
                        cv_data{1,4} = Gx;
                        cv_data{1,5} = Gy;
                        cv_data{1,6} = meta;
                        cv_data{1,7} = mask_act_times;
                        app.data.ep_metrics.local_cv{i,1} = cv_data;
                        app.data.ep_metrics.local_cv{i,2} = get_bg_image(app, i);
                        app.data.ep_metrics.local_cv{i,3} = mask_act_times;
                        app.data.ep_metrics.local_cv{i,4} = num2str(i);

                        if ~isempty(cv_data)
                            feature_extracted = 1;
                            update_console(app,strcat('Local Conduction Velocity extracted successfully for CAM', num2str(i)));
                        end


                        if ~feature_extracted
                            update_console(app,strcat('Local Conduction Velocity could NOT be extracted for CAM', num2str(i)));
                        end
                    end
                end

            catch ME
                error_message = strcat('ERROR in function extract_cv(app, d) ', ME.message);
                update_console(app,error_message);
            end

        end


        %EXTRACT_ACT_TIME  Extract the local activation-time (LAT) map per camera.
        %   [feature_extracted] = extract_act_time(app, d)
        %   Computes LAT at 50% of the upstroke (compute_lat_50) over the ensemble
        %   beat or the signal window, masks it, and stores it in ep_metrics.act_times.
        function [feature_extracted] = extract_act_time(app, d)
            try
                feature_extracted = 0;
                d.Message = 'Extracting Activation Times, if selected';
                ensemble = app.ENSEMBLECHECKBOX.Value;

                [row, col] = get_pixel_location(app, 1);

                for i=1:app.data.num_files
                    if app.voltage_tab(i,1,1)
                        [cmos_data, avg_cmos_data] = get_cmos_data(app,i);
                        act_times_data = [];
                        if ensemble || isempty(app.oap_window)
                            win_data = avg_cmos_data;
                            %diff_data = diff(win_data,1,3);
                        else
                            start_frame = app.oap_window{1,1}(1,1);
                            end_frame = app.oap_window{1,1}(1,2);
                            %mid_frame = start_frame + round((end_frame - start_frame)/2);
                            win_data = cmos_data(:,:,start_frame:end_frame);
                            %diff_data = diff(cmos_data(:,:,start_frame:mid_frame),1,3);
                        end

                        %[~, act_times] = max(diff_data,[],3);
                        act_times = compute_lat_50(win_data);
                        act_times = act_times * (1000 / app.data.acqFreq);   % frames -> ms
                        mask_act_times = act_times;
                        if ~isempty(app.combo_masks{i,1})
                            mask_act_times = act_times .* app.combo_masks{i,1};
                        end

                        [mask_act_times, n_spk] = despeckle_act_map(app, mask_act_times);
                        if n_spk > 0
                            update_console(app, sprintf('CAM%d activation map: despeckled %d isolated early/late pixel(s).', i, n_spk));
                        end


                        act_times_data{1,1} = mask_act_times;
                        act_times_data{1,2} = get_bg_image(app, i);
                        act_times_data{1,3} = squeeze(normalize_data(win_data(row, col,:)));
                        act_times_data{1,4} =  mask_act_times(row, col);
                        act_times_data{1,5} = num2str(i);
                        act_times_data{1,6} = act_times;

                        if ~isempty(act_times_data)
                            app.data.ep_metrics.act_times{i,1} = act_times_data{1,1};
                            app.data.ep_metrics.act_times{i,2} = act_times_data{1,2};
                            app.data.ep_metrics.act_times{i,3} = act_times_data{1,3};
                            app.data.ep_metrics.act_times{i,4} = act_times_data{1,4};
                            app.data.ep_metrics.act_times{i,5} = act_times_data{1,5};
                            app.data.ep_metrics.act_times{i,6} = act_times_data{1,6};

                            feature_extracted = 1;
                            update_console(app,strcat('Activation Times extracted successfully for CAM', num2str(i)));
                        end

                        if ~feature_extracted
                            update_console(app,strcat('Activation Times could NOT be extracted for CAM', num2str(i)));
                        end
                    end
                end

            catch ME
                error_message = strcat('ERROR in function extract_act_time(app, d) ', ME.message);
                update_console(app,error_message);
            end
        end


        function [clean_map, n_speckle] = despeckle_act_map(app, act_map)
            % Replace isolated early/late speckle pixels (> SPECKLE_MS from their local
            % 3x3 median) with the local median. Cleans the activation map at the source
            % so every downstream metric (CV, dispersion) sees the corrected map.
            try
                speckle_ms = 5;
                [nr, nc] = size(act_map);
                a = double(act_map);  a(a == 0) = NaN;
                clean_map = act_map;
                [rr, cc] = find(isfinite(a));
                n_speckle = 0;
                for k = 1:numel(rr)
                    r = rr(k);  c = cc(k);
                    r0 = max(1,r-1); r1 = min(nr,r+1);
                    c0 = max(1,c-1); c1 = min(nc,c+1);
                    w  = a(r0:r1, c0:c1);
                    mw = median(w(isfinite(w)), 'omitnan');
                    if isfinite(mw) && abs(a(r,c) - mw) > speckle_ms
                        n_speckle = n_speckle + 1;
                        clean_map(r,c) = mw;
                    end
                end
            catch ME
                clean_map = act_map;  n_speckle = 0;
                update_console(app, strcat('ERROR in function despeckle_act_map(app) ', ME.message));
            end
        end


        %EXTRACT_V_REP  Extract the voltage repolarization-time map per camera.
        %   [feature_extracted] = extract_v_rep(app, d)
        %   Converts the selected repolarization % to a threshold and delegates to
        %   extract_rep; stores the result in ep_metrics.rep_data.
        function [feature_extracted] = extract_v_rep(app, d)
            try
                feature_extracted = 0;
                d.Message = 'Extracting Repolarization Time, if selected';

                for i=1:app.data.num_files
                    if app.voltage_tab(i,2,1)
                        rep_val = pct_to_threshold(app, app.voltage_tab(i,2,2));
                        apd_data = extract_apd(app,i, rep_val);
                        if ~isempty(apd_data)
                            app.data.ep_metrics.rep_data{i,1} = apd_data{1,7};
                            app.data.ep_metrics.rep_data{i,2} = apd_data{1,2};
                            app.data.ep_metrics.rep_data{i,3} = apd_data{1,3};
                            app.data.ep_metrics.rep_data{i,4} = apd_data{1,8};
                            app.data.ep_metrics.rep_data{i,5} = apd_data{1,5};
                            app.data.ep_metrics.rep_data{i,6} = apd_data{1,9};

                            feature_extracted = 1;
                            update_console(app,strcat('Repolarization Times extracted successfully for CAM', num2str(i)));
                        end

                        if ~feature_extracted
                            update_console(app,strcat('Repolarization Times could NOT be extracted for CAM', num2str(i)));
                        end
                    end
                end

            catch ME
                error_message = strcat('ERROR in function extract_v_rep(app, d) ', ME.message);
                update_console(app,error_message);
            end
        end


        %EXTRACT_V_APD  Extract the action-potential-duration (APD) map per camera.
        %   [feature_extracted] = extract_v_apd(app, d)
        %   Delegates to extract_apd at the selected APD %; stores APD in
        %   ep_metrics.apd_data and the matching repolarization map in rep_data.
        function [feature_extracted] = extract_v_apd(app, d)
            try
                feature_extracted = 0;
                d.Message = 'Extracting Action Potential Duration, if selected';

                for i=1:app.data.num_files
                    if app.voltage_tab(i,3,1)
                        %rep_val = get_apd_percentage(app,i);
                        rep_val = pct_to_threshold(app, app.voltage_tab(i, 3, 2));
                        apd_data = extract_apd(app,i, rep_val);
                        if ~isempty(apd_data)
                            app.data.ep_metrics.apd_data{i,1} = apd_data{1,1};
                            app.data.ep_metrics.apd_data{i,2} = apd_data{1,2};
                            app.data.ep_metrics.apd_data{i,3} = apd_data{1,3};
                            app.data.ep_metrics.apd_data{i,4} = apd_data{1,4};
                            app.data.ep_metrics.apd_data{i,5} = apd_data{1,5};
                            app.data.ep_metrics.apd_data{i,6} = apd_data{1,6};

                            app.data.ep_metrics.rep_data{i,1} = apd_data{1,7};
                            app.data.ep_metrics.rep_data{i,2} = apd_data{1,2};
                            app.data.ep_metrics.rep_data{i,3} = apd_data{1,3};
                            app.data.ep_metrics.rep_data{i,4} = apd_data{1,8};
                            app.data.ep_metrics.rep_data{i,5} = apd_data{1,5};
                            app.data.ep_metrics.rep_data{i,6} = apd_data{1,9};

                            feature_extracted = 1;
                            update_console(app,strcat('Action Potential Duration extracted successfully for CAM', num2str(i)));
                        end

                        if ~feature_extracted
                            update_console(app,strcat('Action Potential Duration could NOT be extracted for CAM', num2str(i)));
                        end
                    end
                end

            catch ME
                error_message = strcat('ERROR in function extract_v_apd(app, d) ', ME.message);
                update_console(app,error_message);
            end
        end


        %EXTRACT_V_RISE  Extract the AP upstroke rise-time map per camera.
        %   [feature_extracted] = extract_v_rise(app, d)
        %   Uses the selected rise window (get_v_rise_time_range) and extract_rise_time;
        %   stores ep_metrics.ap_rise_times.
        function [feature_extracted] = extract_v_rise(app, d)
            try
                feature_extracted = 0;
                d.Message = 'Extracting Action Potential Rise Time, if selected';

                for i=1:app.data.num_files
                    if app.voltage_tab(i,4,1)
                        [rise_min, rise_max] = get_v_rise_time_range(app,i);
                        rise_data = extract_rise_time(app,i, rise_min, rise_max);
                        if ~isempty(rise_data)
                            app.data.ep_metrics.ap_rise_times{i,1} = rise_data{1,1};
                            app.data.ep_metrics.ap_rise_times{i,2} = rise_data{1,2};
                            app.data.ep_metrics.ap_rise_times{i,3} = rise_data{1,3};
                            app.data.ep_metrics.ap_rise_times{i,4} = rise_data{1,4};
                            app.data.ep_metrics.ap_rise_times{i,5} = rise_data{1,5};
                            app.data.ep_metrics.ap_rise_times{i,6} = rise_data{1,6};

                            feature_extracted = 1;
                            update_console(app,strcat('Action Potential Rise Time extracted successfully for CAM', num2str(i)));
                        end

                        if ~feature_extracted
                            update_console(app,strcat('Action Potential Rise Time could NOT be extracted for CAM', num2str(i)));
                        end
                    end
                end

            catch ME
                error_message = strcat('ERROR in function extract_v_rise(app, d) ', ME.message);
                update_console(app,error_message);
            end
        end


        %EXTRACT_ALTERNANS  Extract beat-to-beat APD/Ca alternans per camera.
        %   [feature_extracted] = extract_alternans(app, d)
        %   Segments beats (from analog1 pacing or auto-detected peaks) and runs the
        %   voltage (extract_v_alternans) or calcium (extract_c_alternans) analysis per
        %   the camera's choice. If both Vm and Ca alternans were computed, also
        %   assesses the arrhythmia substrate. Stores ep_metrics.alternans_data.
        function [feature_extracted] = extract_alternans(app, d)
            try
                feature_extracted = 0;
                voltage_alternans = 0;
                calcium_alternans = 0;
                d.Message = 'Extracting Alternans, if selected';

                extract_combo_masks(app,d);

                for i=1:app.data.num_files
                    if app.arrhythmia_tab(i,4,1) %&& ~isempty(app.oap_window)
                        if isfield(app.data, 'analog1')
                            pacing = app.data.analog1;
                            [beat_frames(:,1), beat_frames(:,2)] = extract_beat_frames(pacing, 'FixedLength', true);
                        else
                            pacing = auto_detect_peaks(app.data.CAM1);
                            [beat_frames(:,1), beat_frames(:,2)] = extract_beat_frames(pacing, 'PreWindow', 10, 'FixedLength', true);
                        end

                        if app.arrhythmia_tab(i,4,2)
                            alternans_data = extract_c_alternans(app, beat_frames, i);
                            calcium_alternans = i;
                        else
                            [alternans_data] = extract_v_alternans(app, beat_frames, i);
                            voltage_alternans = i;
                        end

                        if ~isempty(alternans_data)
                            app.data.ep_metrics.alternans_data{i,1} = alternans_data{1,1};
                            app.data.ep_metrics.alternans_data{i,2} = alternans_data{1,2};
                            app.data.ep_metrics.alternans_data{i,3} = alternans_data{1,3};
                            app.data.ep_metrics.alternans_data{i,4} = alternans_data{1,4};
                            app.data.ep_metrics.alternans_data{i,5} = alternans_data{1,5};
                            app.data.ep_metrics.alternans_data{i,6} = alternans_data{1,6};
                            feature_extracted = 1;
                            update_console(app,strcat('Alternans data extracted successfully for CAM', num2str(i)));
                        end
                    end
                end

                if voltage_alternans > 0  && calcium_alternans > 0
                    v_alternans = app.data.ep_metrics.alternans_data{voltage_alternans,1};
                    ca_alternans = app.data.ep_metrics.alternans_data{calcium_alternans,1};
                    substrate = assess_arrhythmia_substrate(v_alternans, 'CaResult',ca_alternans, 'CaData', get_cmos_data(app,calcium_alternans), 'BeatFrames', beat_frames);
                    app.data.ep_metrics.alternans_data{calcium_alternans,5} = substrate;
                end

            catch ME
                error_message = strcat('ERROR in function extract_alternans(app, d) ', ME.message);
                update_console(app,error_message);
            end
        end


        %EXTRACT_V_C_DELAY  Extract the voltage-to-calcium activation delay map.
        %   [feature_extracted] = extract_v_c_delay(app, d)
        %   With one voltage and one calcium camera, computes activation (steepest
        %   upstroke) for each and stores their per-pixel delay (electromechanical
        %   coupling). Requires both a Vm and a Ca camera.
        function [feature_extracted] = extract_v_c_delay(app, d)
             try
                feature_extracted = 0;
                d.Message = 'Extracting Voltage Activation - Calcium Transient Delay, if selected';

                ensemble = app.ENSEMBLECHECKBOX.Value;
                [row, col] = get_pixel_location(app, 1);

                % ---- camera roles come from the RIG, not the checkboxes -----
                % A V-Ca delay needs one voltage and one calcium view of the
                % same tissue, which is the two-camera configuration: CAM1
                % voltage, CAM2 calcium.  Three- and four-camera rigs are
                % all-voltage or all-calcium and have no delay to measure.
                if app.data.num_files ~= 2
                    update_console(app, sprintf( ...
                        ['Voltage-Calcium Delay skipped: needs a dual-camera Vm/Ca setup ' ...
                         '(CAM1 voltage, CAM2 calcium) but this recording has %d camera(s). ' ...
                         'Three- and four-camera rigs are single-modality, so there is no ' ...
                         'V-Ca delay to compute.'], app.data.num_files));
                    return;
                end
                v_camera  = 1;
                ca_camera = 2;

                % Cross-check against the feature selections.  This can only be
                % informative with Link Cameras OFF, since linking copies every
                % selection to every camera; when it is uninformative both sides
                % look identical and no warning fires.
                cam1_is_ca = any(app.calcium_tab(1, 2:5, 1));
                cam1_is_v  = any(app.voltage_tab(1, 1:5, 1));
                cam2_is_ca = any(app.calcium_tab(2, 2:5, 1));
                cam2_is_v  = any(app.voltage_tab(2, 1:5, 1));
                if (cam1_is_ca && ~cam1_is_v) && (cam2_is_v && ~cam2_is_ca)
                    update_console(app, ...
                        ['WARNING: calcium features are selected on CAM1 and voltage features ' ...
                         'on CAM2, which is the reverse of the assumed CAM1=voltage, ' ...
                         'CAM2=calcium convention. Proceeding on the convention; if the map ' ...
                         'comes out negative the cameras are swapped.']);
                end

                [v_cmos_data,  v_avg_data]  = get_cmos_data(app, v_camera);
                [ca_cmos_data, ca_avg_data] = get_cmos_data(app, ca_camera);
                if isempty(v_cmos_data) || isempty(ca_cmos_data)
                    update_console(app, 'Voltage-Calcium Delay skipped: could not load both camera stacks.');
                    return;
                end

                if ensemble || isempty(app.oap_window)
                    % Both averages must share a time origin, which only holds
                    % when one pacing trace drove both.  With no analog1,
                    % ensembleAverageFull auto-detects peaks PER CAMERA, so the
                    % voltage average aligns to the Vm upstroke and the calcium
                    % average to the Ca upstroke -- subtracting out exactly the
                    % quantity being measured.
                    if ~isfield(app.data, 'analog1') || isempty(app.data.analog1)
                        update_console(app, ...
                            ['Voltage-Calcium Delay skipped: ensemble averaging with no analog1 ' ...
                             'pacing trace. Each camera was aligned to its own upstroke, which ' ...
                             'removes the very delay being measured. Re-run with a drawn analysis ' ...
                             'window, or with a pacing trace present.']);
                        return;
                    end
                    v_win_data  = v_avg_data;
                    ca_win_data = ca_avg_data;
                else
                    start_frame = app.oap_window{1,1}(1,1);
                    end_frame   = app.oap_window{1,1}(1,2);
                    v_win_data  = v_cmos_data(:,:,start_frame:end_frame);
                    ca_win_data = ca_cmos_data(:,:,start_frame:end_frame);
                end

                v_act_times  = compute_lat_50(v_win_data);
                ca_act_times = compute_lat_50(ca_win_data);

                v_act_times  = v_act_times  * (1000 / app.data.acqFreq);   % frames -> ms
                ca_act_times = ca_act_times * (1000 / app.data.acqFreq);   % frames -> ms

                mask_v_act_times  = v_act_times;
                mask_ca_act_times = ca_act_times;
                if ~isempty(app.combo_masks{v_camera,1})
                    mask_v_act_times = v_act_times .* app.combo_masks{v_camera,1};
                end
                if ~isempty(app.combo_masks{ca_camera,1})
                    mask_ca_act_times = ca_act_times .* app.combo_masks{ca_camera,1};
                end

                v_c_oap{1,1} = squeeze(normalize_data(v_win_data(row, col,:)));
                v_c_oap{2,1} = squeeze(normalize_data(ca_win_data(row, col,:)));

                % SIGNED: Ca minus Vm. Positive = calcium follows depolarisation.
                ca_act_times_data{1,1} = mask_ca_act_times - mask_v_act_times;
                ca_act_times_data{1,2} = get_bg_image(app, ca_camera);
                ca_act_times_data{1,3} = v_c_oap;
                ca_act_times_data{1,4} = mask_ca_act_times(row, col);
                ca_act_times_data{1,5} = 'VCDelay';
                ca_act_times_data{1,6} = ca_act_times - v_act_times;

                % A predominantly negative map means calcium is leading voltage,
                % which is not physiological.  This is the real guard against a
                % swapped camera assignment, and it works whatever the checkbox
                % state -- which is why the convention above is safe to assume.
                dl = ca_act_times_data{1,1};
                dl = dl(isfinite(dl));
                if ~isempty(dl) && median(dl) < 0
                    update_console(app, sprintf( ...
                        ['WARNING: median Voltage-Calcium delay is %.1f ms (negative) - calcium ' ...
                         'appears to LEAD voltage. CAM1 is assumed voltage and CAM2 calcium; ' ...
                         'check that assignment, and that the two views are registered.'], ...
                        median(dl)));
                end

                app.data.ep_metrics.vc_delay{ca_camera,1} = ca_act_times_data{1,1};
                app.data.ep_metrics.vc_delay{ca_camera,2} = ca_act_times_data{1,2};
                app.data.ep_metrics.vc_delay{ca_camera,3} = ca_act_times_data{1,3};
                app.data.ep_metrics.vc_delay{ca_camera,4} = ca_act_times_data{1,4};
                app.data.ep_metrics.vc_delay{ca_camera,5} = ca_act_times_data{1,5};
                app.data.ep_metrics.vc_delay{ca_camera,6} = ca_act_times_data{1,6};
                feature_extracted = 1;
                update_console(app, strcat('Voltage-Calcium Delay extracted successfully for CAM', ...
                                           num2str(ca_camera)));

            catch ME
                error_message = strcat('ERROR in function extract_v_c_delay(app, d) ', ME.message);
                update_console(app, error_message);
            end
        end

        function report_feature_medians(app)
            %REPORT_FEATURE_MEDIANS  Echo median of each extracted feature to the console.
            %   Runs after single-file extraction so the user can copy summary values
            %   (e.g. median APD80) as text without reading them off the map figures.
            try
                if isempty(app.data) || ~isfield(app.data, 'ep_metrics'); return; end
                m = app.data.ep_metrics;
                % display name -> field, units
                specs = { ...
                    'Act time',        'act_times',     'ms'; ...
                    'APD',             'apd_data',      'ms'; ...
                    'Repolarization',  'rep_data',      'ms'; ...
                    'AP rise time',    'ap_rise_times', 'ms'; ...
                    'CV',              'local_cv',      'cm/s'; ...
                    'V-Ca delay',      'vc_delay',      'ms'; ...
                    'CaT duration',    'ca_data',       'ms'; ...
                    'Ca decay time',   'ca_rep_data',   'ms'; ...
                    'Ca rise time',    'ca_rise_times', 'ms'; ...
                    'Ca tau',          'ca_tau',        'ms'; ...
                    'Alternans',       'alternans_data','au'};
                update_console(app, sprintf('--- Median values: %s ---', app.mat_file_name));
                for k = 1:size(specs,1)
                    f = specs{k,2};
                    if isfield(m, f) && ~isempty(m.(f)) && isnumeric(m.(f))
                        v = double(m.(f)(:));
                        med = median(v(isfinite(v)), 'omitnan');   % ignore background zeros
                        if ~isnan(med)
                            update_console(app, sprintf('  %-16s %8.2f %s', specs{k,1}, med, specs{k,3}));
                        end
                    end
                end
            catch ME
                update_console(app, strcat('report_feature_medians: ', ME.message));
            end
        end


        %EXTRACT_APD  Per-pixel action-potential duration at a given repolarization %.
        %   [data] = extract_apd(app, camera, rep_val)
        %   APD = repolarization frame minus activation frame per pixel; also returns
        %   the underlying repolarization map. Cell layout parallels extract_rep.
        function [data] = extract_apd(app, camera, rep_val)
            try
                ensemble = app.ENSEMBLECHECKBOX.Value;
                [row, col] = get_pixel_location(app, 1);
                [cmos_data, avg_cmos_data] = get_cmos_data(app, camera);
                if ensemble || isempty(app.oap_window)
                    win_data  = avg_cmos_data;
                    
                else
                    sf = app.oap_window{1,1}(1,1);
                    ef = app.oap_window{1,1}(1,2);
                    win_data  = cmos_data(:,:,sf:ef);
                    
                end

                [~, ~, T] = size(win_data);
                win_norm  = normalize_data(win_data, [0 100]);
                t_idx     = reshape(1:T, 1, 1, T);

                act_times = compute_lat_50(win_data);     % frames, sub-frame, NaN where invalid
                act3      = reshape(act_times, size(act_times,1), size(act_times,2), 1);

                % Reference the repolarization threshold to THIS pixel's own
                % baseline and amplitude rather than to an absolute level in the
                % globally-normalized trace.  With a wandering baseline the
                % resting level varies across the record, so a fixed level sits
                % ABOVE the diastolic baseline of some beats and can never be
                % reached -- those pixels silently became NaN.  Local referencing
                % is also immune to beat-to-beat amplitude variation, which an
                % absolute threshold cannot handle even after a perfect detrend.
                pre_act                = win_norm;
                pre_act(t_idx >= act3) = NaN;
                base                   = median(pre_act, 3, 'omitnan');  % diastolic level

                % --- censor pixels whose baseline rests on too few frames ---
                n_pre   = sum(~isnan(pre_act), 3);                    % frames feeding base
                min_pre = max(3, round(0.005 * app.data.acqFreq));    % >= 3 frames or 5 ms
                base(n_pre < min_pre) = NaN;                          % -> censored below via isnan(base)

                post_act               = win_norm;
                post_act(t_idx < act3) = NaN;
                [pk_val, pk]           = max(post_act, [], 3);
                amp                    = pk_val - base;

                % --- incomplete-recovery check (ensemble window only: its
                %     last ~10% is next-beat diastole, so it is a second,
                %     independent estimate of the resting level) ---
                if ensemble || isempty(app.oap_window)
                    tail_n   = max(3, round(0.1 * T));
                    tail_lvl = median(win_norm(:,:,end-tail_n+1:end), 3);
                    base_err = (base - tail_lvl) ./ amp;              % fraction of amplitude
                    base_err(isnan(act_times)) = NaN;                 % tissue only
                    med_err  = median(abs(base_err(:)), 'omitnan');
                    if med_err > 0.05
                        update_console(app, sprintf( ...
                            'WARNING: pre-activation baseline differs from end-window diastole by %.1f%% of amplitude (median); APD may be biased short.', ...
                            100 * med_err));
                    end
                end

                % rep_val is the FRACTION OF AMPLITUDE still remaining, ...
                thr = base + rep_val .* amp;

                [hit, rep_frame] = max(cumsum((win_norm <= thr) & (t_idx > pk), 3) == 1, [], 3);
                rep              = double(rep_frame);

                % Sub-frame crossing by linear interpolation between the bracketing samples,
                % matching findCrossMs / compute_lat_50 so APD is not frame-quantised.
                [Rn, Cn]   = size(rep_frame);
                [rr, cc]   = ndgrid(1:Rn, 1:Cn);
                prevf      = max(rep_frame - 1, 1);
                v_hi       = win_norm(sub2ind([Rn Cn T], rr, cc, rep_frame));
                v_lo       = win_norm(sub2ind([Rn Cn T], rr, cc, prevf));
                den        = v_lo - v_hi;
                ok         = hit & (rep_frame > 1) & (den > 0);
                frac       = zeros(Rn, Cn);
                frac(ok)   = (v_lo(ok) - thr(ok)) ./ den(ok);
                rep(ok)    = (rep_frame(ok) - 1) + frac(ok);

                rep(~hit | ~(amp > 0) | isnan(base)) = NaN;

                apd         = rep - act_times;
                bad         = apd <= 0 | isnan(apd);
                apd(bad)    = NaN;
                rep(bad)    = NaN;

                oap        = squeeze(win_norm(row, col, :));
                act_loc    = round(act_times(row, col));
                rep_loc    = round(rep(row, col));
                fitted_oap = nan(T, 1);
                if ~isnan(act_loc) && ~isnan(rep_loc)
                    fitted_oap(act_loc:rep_loc) = oap(act_loc:rep_loc);
                end

                rep = rep * (1000 / app.data.acqFreq);   % frames -> ms
                apd = apd * (1000 / app.data.acqFreq);   % frames -> ms

                data{1,1} = apd;  data{1,7} = rep;
                if ~isempty(app.combo_masks{camera,1})
                    m = ~isnan(act_times .* app.combo_masks{camera,1});
                    data{1,1}(~m) = NaN;
                    data{1,7}(~m) = NaN;
                end
                data{1,2} = get_bg_image(app, camera);
                data{1,3} = oap;
                data{1,4} = fitted_oap;
                data{1,5} = num2str(camera);
                data{1,6} = apd;
                data{1,8} = rep(row, col);
                data{1,9} = rep;


                % Censoring stat over pixels that were eligible (on tissue, valid LAT)
                elig = ~isnan(act_times);
                if ~isempty(app.combo_masks{camera,1})
                    elig = elig & ~isnan(app.combo_masks{camera,1});
                end
                n_all = nnz(elig);
                n_bad = nnz(elig & isnan(data{1,1}));
                update_console(app, sprintf( ...
                    'APD%d: %d of %d activated pixels (%.1f%%) censored (no crossing or APD<=0).', ...
                    round(100 * (1 - rep_val)), n_bad, n_all, 100 * n_bad / max(n_all, 1)));

            catch ME
                update_console(app, strcat('ERROR in extract_apd: ', ME.message));
            end
        end


        %EXTRACT_C_REP  Extract the calcium-transient decay-time map per camera.
        %   [feature_extracted] = extract_c_rep(app, d)
        %   Same engine as extract_rep, stored in ep_metrics.ca_rep_data.
        function [feature_extracted] = extract_c_rep(app, d)
            try
                feature_extracted = 0;
                d.Message = 'Extracting Calcium Decay Time, if selected';
                for i=1:app.data.num_files
                    if app.calcium_tab(i,2,1)
                        rep_val = pct_to_threshold(app, app.calcium_tab(i,2,2));
                        ca_data = extract_apd(app,i, rep_val);

                        if ~isempty(ca_data)
                            app.data.ep_metrics.ca_rep_data{i,1} = ca_data{1,7};
                            app.data.ep_metrics.ca_rep_data{i,2} = ca_data{1,2};
                            app.data.ep_metrics.ca_rep_data{i,3} = ca_data{1,3};
                            app.data.ep_metrics.ca_rep_data{i,4} = ca_data{1,8};
                            app.data.ep_metrics.ca_rep_data{i,5} = ca_data{1,5};
                            app.data.ep_metrics.ca_rep_data{i,6} = ca_data{1,9};

                            feature_extracted = 1;
                            update_console(app,strcat('Calcium Decay Times extracted successfully for CAM', num2str(i)));
                        end

                        if ~feature_extracted
                            update_console(app,strcat('Calcium Decay Times could NOT be extracted for CAM', num2str(i)));
                        end
                    end
                end
            catch ME
                error_message = strcat('ERROR in function extract_c_rep(app, d) ', ME.message);
                update_console(app,error_message);
            end
        end


        %EXTRACT_C_APD  Extract the calcium-transient duration map per camera.
        %   [feature_extracted] = extract_c_apd(app, d)
        %   Calcium analogue of extract_v_apd, stored in ep_metrics.ca_apd_data.
        function [feature_extracted] = extract_c_apd(app, d)
            try
                feature_extracted = 0;
                d.Message = 'Extracting Calcium Transient Duration, if selected';

                for i=1:app.data.num_files
                    if app.calcium_tab(i,3,1)
                        rep_val = pct_to_threshold(app, app.calcium_tab(i,3,2));
                        ca_data = extract_apd(app,i, rep_val);

                        if ~isempty(ca_data)
                            app.data.ep_metrics.ca_data{i,1} = ca_data{1,1};
                            app.data.ep_metrics.ca_data{i,2} = ca_data{1,2};
                            app.data.ep_metrics.ca_data{i,3} = ca_data{1,3};
                            app.data.ep_metrics.ca_data{i,4} = ca_data{1,4};
                            app.data.ep_metrics.ca_data{i,5} = ca_data{1,5};
                            app.data.ep_metrics.ca_data{i,6} = ca_data{1,6};

                            app.data.ep_metrics.ca_rep_data{i,1} = ca_data{1,7};
                            app.data.ep_metrics.ca_rep_data{i,2} = ca_data{1,2};
                            app.data.ep_metrics.ca_rep_data{i,3} = ca_data{1,3};
                            app.data.ep_metrics.ca_rep_data{i,4} = ca_data{1,8};
                            app.data.ep_metrics.ca_rep_data{i,5} = ca_data{1,5};
                            app.data.ep_metrics.ca_rep_data{i,6} = ca_data{1,9};

                            feature_extracted = 1;
                            update_console(app,strcat('Calcium Transient Duration extracted successfully for CAM', num2str(i)));
                        end

                        if ~feature_extracted
                            update_console(app,strcat('Calcium Transient Duration could NOT be extracted for CAM', num2str(i)));
                        end
                    end
                end

            catch ME
                error_message = strcat('ERROR in function extract_c_apd(app, d) ', ME.message);
                update_console(app,error_message);
            end
        end


        %EXTRACT_C_RISE  Extract the calcium-transient rise-time map per camera.
        %   [feature_extracted] = extract_c_rise(app, d)
        %   Uses get_c_rise_time_range + extract_rise_time; stores ep_metrics.ca_rise_times.
        function [feature_extracted] = extract_c_rise(app, d)
            try
                feature_extracted = 0;
                d.Message = 'Extracting Calcium Transient Rise Time, if selected';

                for i=1:app.data.num_files
                    if app.calcium_tab(i,4,1)
                        [rise_min, rise_max] = get_c_rise_time_range(app,i);
                        rise_data = extract_rise_time(app,i, rise_min, rise_max);

                        if ~isempty(rise_data)
                            app.data.ep_metrics.ca_rise_times{i,1} = rise_data{1,1};
                            app.data.ep_metrics.ca_rise_times{i,2} = rise_data{1,2};
                            app.data.ep_metrics.ca_rise_times{i,3} = rise_data{1,3};
                            app.data.ep_metrics.ca_rise_times{i,4} = rise_data{1,4};
                            app.data.ep_metrics.ca_rise_times{i,5} = rise_data{1,5};
                            app.data.ep_metrics.ca_rise_times{i,6} = rise_data{1,6};

                            feature_extracted = 1;
                            update_console(app,strcat('Calcium Rise Times extracted successfully for CAM', num2str(i)));
                        end

                        if ~feature_extracted
                            update_console(app,strcat('Calcium Rise Times could NOT be extracted for CAM', num2str(i)));
                        end
                    end
                end

            catch ME
                error_message = strcat('ERROR in function extract_c_rise(app, d) ', ME.message);
                update_console(app,error_message);
            end
        end


        %GET_TAU_RANGE  Map the Tau start/end dropdowns to amplitude fractions.
        %   [tau_start, tau_end] = get_tau_range(app)
        %   e.g. start 100/90/80/70% -> 1.0..0.7, end 30/20/10/0% -> 0.3..0.
        function [tau_start, tau_end] = get_tau_range(app)
            try
                tau_start = 1.0;
                tau_end = 0.2;

                tau_start_val = app.TauDropDown.Value;
                tau_end_val = app.TauDropDown_2.Value;

                switch tau_end_val
                    case '30'
                        tau_end = 0.3;
                    case '20'
                        tau_end = 0.2;
                    case '10'
                        tau_end = 0.1;
                    case '0'
                        tau_end = 0;
                end

                switch tau_start_val
                    case '100'
                        tau_start = 1;
                    case '90'
                        tau_start = 0.9;
                    case '80'
                        tau_start = 0.8;
                    case '70'
                        tau_start = 0.7;
                end

            catch ME
                error_message = strcat('ERROR in function get_tau_range(app)', ME.message);
                update_console(app,error_message);
            end
        end


        function [feature_extracted] = extract_tau(app, d)
            %EXTRACT_TAU  Calcium-decay time constant (tau) per pixel, two ways.
            %   [feature_extracted] = extract_tau(app, d)
            %   For each selected calcium camera, over the start..end amplitude window
            %   from get_tau_range.  d is the progress dialog.
            %
            %     slot 1 (tau_constant)      - fitted tau, ms: baseline-subtracted
            %                                  single exponential, weighted log-linear
            %                                  seed refined by damped Gauss-Newton
            %     slot 6 (tau_mode_constant) - model-free tau, ms: interpolated 1/e
            %                                  decay time measured from the peak
            %
            %   Levels are referenced to each pixel's OWN baseline and amplitude from
            %   the raw window - no normalization - so the result does not depend on
            %   window length, and therefore not on pacing rate.  Both estimators are
            %   sub-frame interpolated and neither is rounded.  Pixels failing the
            %   noise-derived amplitude gate, the minimum fit points or duration, or
            %   the fit itself return NaN; the two slots are never substituted for one
            %   another.
            try
                feature_extracted = 0;
                d.Message = 'Extracting Calcium Decay Time Constant (Tau), if selected';
                ensemble = app.ENSEMBLECHECKBOX.Value;
                [row, col] = get_pixel_location(app, 1);

                acqFreq = app.data.acqFreq;
                dt      = 1 / acqFreq;

                for i=1:app.data.num_files
                    tau_data = [];
                    if app.calcium_tab(i,5,1)

                        [cmos_data, avg_cmos_data] = get_cmos_data(app,i);
                        if ensemble || isempty(app.oap_window)
                            win_data = avg_cmos_data;
                        else
                            start_frame = app.oap_window{1,1}(1,1);
                            end_frame = app.oap_window{1,1}(1,2);
                            win_data = cmos_data(:,:,start_frame:end_frame);
                        end

                        [tau_start, tau_end] = get_tau_range(app);
                        [num_rows, num_cols, num_frames] = size(win_data);

                        data_mask = ones(num_rows, num_cols);
                        if ~isempty(app.combo_masks{i,1})
                            data_mask = data_mask .* app.combo_masks{i,1};
                        end

                        % ---- flatten -------------------------------------------------
                        % No normalize_data.  Every level below is referenced to THIS
                        % pixel's own baseline and amplitude, taken from this same
                        % array, so a global rescaling would cancel -- and the default
                        % percentile band CLIPS, which does not cancel and made the
                        % result depend on window length (see the header).
                        Y  = double(reshape(win_data, num_rows * num_cols, num_frames));
                        N  = size(Y, 1);
                        T  = num_frames;
                        tt = 1:T;
                        idx = (1:N)';

                        % Running MEDIANS, for landmarks and the level crossing only;
                        % both estimates themselves are taken from the raw samples.  A
                        % running median is exactly unbiased on a monotone stretch --
                        % the median of a monotone window is its centre sample -- so it
                        % removes noise from the decay without moving the crossing.
                        ws = max(3, round(0.005 * acqFreq));               % 5 ms
                        wc = max(5, 2*floor(0.015 * acqFreq / 2) + 1);     % 15 ms, odd
                        Ys = movmedian(Y, ws, 2);
                        Yc = movmedian(Y, wc, 2);

                        % ---- landmarks: upstroke anchor and diastolic foot -----------
                        b0  = prctile(Ys,  5, 2);
                        p0  = prctile(Ys, 99, 2);
                        rg  = p0 - b0;
                        mid = b0 + 0.5 * rg;
                        low = b0 + 0.1 * rg;

                        hi_mid    = Ys >= mid;
                        sustained = hi_mid(:, 1:end-1) & hi_mid(:, 2:end);
                        [has_anchor, anchor] = max(sustained, [], 2);
                        anchor(~has_anchor)  = T;

                        % The FOOT, not the mid-amplitude anchor, bounds the baseline
                        % sample set.  Taking the median of everything before the
                        % anchor lets the lower half of the upstroke into the estimate,
                        % and how much gets in depends on where the percentile band put
                        % `mid` -- i.e. on the window length.  That alone moved tau by
                        % 1% across a 150-2000 ms window.
                        cand = (Ys <= low) & (tt < anchor);
                        [has_foot, j_rev] = max(fliplr(cand), [], 2);
                        foot = T - j_rev + 1;
                        foot(~has_foot) = max(anchor(~has_foot) - 1, 1);

                        pre     = Y;  pre(tt > foot) = NaN;
                        base    = median(pre, 2, 'omitnan');
                        n_pre   = sum(~isnan(pre), 2);
                        min_pre = max(3, round(0.005 * acqFreq));
                        base(n_pre < min_pre) = NaN;      % same censor as extract_apd
                        clear pre

                        % ---- noise, from first differences over the WHOLE trace ------
                        % A short diastolic segment gives a MAD that is itself noisy,
                        % and an underestimate there opens the amplitude gate.
                        % Successive differences are dominated by noise wherever the
                        % signal is smooth, and the median is robust to the upstroke.
                        sigma = 1.4826 * median(abs(diff(Y, 1, 2)), 2, 'omitnan') / sqrt(2);

                        % ---- peak on the filtered trace, height from the raw sample --
                        post         = Ys;  post(tt < anchor) = NaN;
                        [pk_val, pk] = max(post, [], 2);
                        amp          = pk_val - base;                       % for gating
                        pk_amp       = Y(sub2ind([N T], idx, pk)) - base;   % height
                        clear post Ys

                        % ---- amplitude / SNR gate -----------------------------------
                        % sqrt(2*log n) * sigma is the expected largest excursion of n
                        % zero-mean samples; the running median leaves ~T/ws independent
                        % ones, each with sd ~ sigma*sqrt(pi/(2*ws)).  Three further
                        % sigma of margin puts the gate clear of the noise maximum's own
                        % spread, so a pixel with no transient cannot clear it (0 of 400
                        % pure-noise pixels do), while a transient at SNR 5 still can
                        % (97% do).
                        sigma_f  = sigma * sqrt(pi / (2 * ws));
                        amp_gate = (sqrt(2 * log(max(T / ws, 3))) + 3) * sigma_f;
                        ok_amp   = isfinite(base) & isfinite(sigma) & (sigma > 0) & ...
                                   (amp > 0) & (amp > amp_gate) & (pk_amp > 0) & ...
                                   ~isnan(data_mask(:));

                        amp_safe = amp;     amp_safe(~ok_amp) = NaN;
                        pk_safe  = pk_amp;  pk_safe(~ok_amp)  = NaN;
                        Yn       = (Y - base) ./ amp_safe;      % own-amplitude units

                        % ======== slot 6: model-free 1/e, FIRST and independent ======
                        % A slot-1 failure must not be able to discard this, and vice
                        % versa; at fast pacing the transient may never relax to
                        % tau_end at all, and that is not a reason to lose the 1/e time.
                        lev  = 1 / exp(1);
                        labs = base + lev .* pk_safe;
                        [hit1, ix] = max(cumsum((Yc <= labs) & (tt > pk), 2) == 1, [], 2);
                        ix   = max(ix, 2);
                        y1   = Yc(sub2ind([N T], idx, ix - 1));
                        y2   = Yc(sub2ind([N T], idx, ix));
                        den1 = y1 - y2;
                        frac = (y1 - labs) ./ den1;       % findCrossMs, exactly
                        frac(~(den1 > 0)) = 0;            % y1 == y2 -> frac 0
                        frac = min(max(frac, 0), 1);
                        t1e  = ((ix - 1 + frac) - pk) * dt * 1000;
                        tau_mode_constant = nan(N, 1);
                        g1e  = hit1 & ok_amp & isfinite(t1e) & (t1e > 0);
                        tau_mode_constant(g1e) = t1e(g1e);          % ms, NOT rounded
                        clear Yc

                        % ======== slot 1: decay fit with a baseline term ==============
                        if tau_start >= 1
                            rep_start = pk;
                            has_start = ok_amp;
                        else
                            [has_start, rep_start] = max(cumsum((Yn <= tau_start) & ...
                                                     (tt >= pk), 2) == 1, [], 2);
                            rep_start(~has_start)  = T;
                        end
                        % If the trace never reaches tau_end (fast pacing, incomplete
                        % relaxation) fit the decay that IS present rather than
                        % discarding the pixel -- but only if it still covers a
                        % halving, so the estimate comes from real decay and not from
                        % noise on a plateau.
                        [has_end, rep_end] = max(cumsum((Yn <= tau_end) & ...
                                             (tt >= rep_start), 2) == 1, [], 2);
                        rep_end(~has_end) = T;
                        rep_end = max(rep_end, rep_start);

                        v_s = Yn(sub2ind([N T], idx, rep_start));
                        v_e = Yn(sub2ind([N T], idx, rep_end));
                        ok_span = (v_s > 0) & (v_e <= 0.5 * v_s);

                        % Amplitude above the pixel's own diastolic level.  Fitting the
                        % raw trace instead assumes the decay asymptotes to ZERO; a
                        % positive residual asymptote flattens the decay and
                        % overestimates tau without limit.
                        D     = Y - base;
                        inwin = (tt >= rep_start) & (tt <= rep_end);
                        Trel  = ((tt - 1) - (rep_start - 1)) * dt;   % s, per-pixel origin

                        n_win = sum(inwin, 2);
                        [hf, i_first] = max(inwin, [], 2);
                        [~,  j2]      = max(fliplr(inwin), [], 2);
                        span_ms = (T - j2 + 1 - i_first) * dt * 1000;
                        min_pts     = max(4, round(0.005 * acqFreq));
                        min_span_ms = 5;

                        % ---- seed: weighted log-linear regression -------------------
                        valid = inwin & (D > 0) & isfinite(D);
                        Z = zeros(N, T);  Z(valid) = log(D(valid));
                        w = zeros(N, T);  w(valid) = D(valid);
                        S0  = sum(w, 2);
                        S1  = sum(w .* Trel, 2);
                        S2  = sum(w .* Trel.^2, 2);
                        Sy  = sum(w .* Z, 2);
                        Sty = sum(w .* Trel .* Z, 2);
                        den = S0 .* S2 - S1.^2;
                        den(~(den > 0)) = NaN;
                        tau_seed_s = -1 ./ ((S0 .* Sty - S1 .* Sy) ./ den);   % seconds
                        clear Z w valid

                        % ---- refinement: least squares in the domain the noise
                        %      actually lives in --------------------------------------
                        % The log transform inflates the variance of the small tail
                        % samples and, worse, censors the ones that go negative -- both
                        % bias the slope, by ~+15% at SNR 5.  Gauss-Newton (variable
                        % projection: the amplitude is linear, so it is profiled out
                        % and the residual is orthogonal to it) removes that.  The log
                        % fit is only the starting point, which is what the original
                        % variable name tau_seed_s already implied.  Samples at or
                        % below baseline are KEPT here; that is what makes it unbiased.
                        Dm = D;     Dm(~inwin) = 0;
                        Tm = Trel;  Tm(~inwin) = 0;
                        tau_s = tau_seed_s;
                        tau_s(~isfinite(tau_s) | tau_s <= 0) = 20 * dt;   % fallback seed
                        tau_s = min(max(tau_s, dt), T * dt);
                        for it = 1:8
                            E    = exp(-Tm ./ tau_s) .* inwin;
                            Aa   = sum(Dm .* E, 2) ./ max(sum(E.^2, 2), eps);
                            Rr   = (Dm - Aa .* E) .* inwin;
                            G    = Aa .* E .* Tm ./ tau_s.^2;
                            step = sum(Rr .* G, 2) ./ max(sum(G.^2, 2), eps);
                            step = min(max(step, -0.5 * tau_s), 0.5 * tau_s);  % damped
                            tau_s = min(max(tau_s + step, dt), T * dt);
                        end
                        clear Dm Tm E Rr G

                        % A failed fit returns NaN.  No floor, no fallback, and in
                        % particular no silent substitution of the model-free estimator
                        % into this slot: max(2*dt, NaN) returns 2*dt, which is how the
                        % old code turned a failure into a 2 ms tau.
                        ok_fit = ok_amp & ok_span & has_start & hf & ...
                                 (n_win >= min_pts) & (span_ms >= min_span_ms) & ...
                                 isfinite(tau_s) & (tau_s > dt) & (tau_s < T * dt);
                        tau_constant = nan(N, 1);
                        tau_constant(ok_fit) = tau_s(ok_fit) * 1000;   % ms, NOT rounded

                        % ---- trace and overlay for the single displayed pixel --------
                        % Expressed as a fraction of THIS pixel's amplitude, so the
                        % tau_start / tau_end levels the measurement used correspond to
                        % what is plotted.
                        p = sub2ind([num_rows num_cols], row, col);
                        if ok_amp(p)
                            oap = (Y(p, :)' - base(p)) / amp(p);
                        else
                            oap = nan(T, 1);
                        end
                        fitted_oap = nan(T, 1);
                        if ok_fit(p)
                            fitted_oap(rep_start(p):rep_end(p)) = oap(rep_start(p):rep_end(p));
                        end
                        clear Y Yn D inwin Trel

                        tau_constant      = reshape(tau_constant,      num_rows, num_cols);
                        tau_mode_constant = reshape(tau_mode_constant, num_rows, num_cols);

                        tau_data{1,1} = tau_constant;
                        tau_data{1,2} = get_bg_image(app, i);
                        tau_data{1,3} = oap;
                        tau_data{1,4} = fitted_oap;
                        tau_data{1,5} = num2str(i);
                        tau_data{1,6} = tau_mode_constant;

                        if ~isempty(tau_data)
                            app.data.ep_metrics.ca_tau{i,1} = tau_data{1,1};
                            app.data.ep_metrics.ca_tau{i,6} = tau_data{1,6};

                            data_mask = ones(num_rows, num_cols);
                            if ~isempty(app.combo_masks{i,1})
                                data_mask = data_mask .* app.combo_masks{i,1};
                                app.data.ep_metrics.ca_tau{i,1} = tau_data{1,1} .* data_mask;
                                app.data.ep_metrics.ca_tau{i,6} = tau_data{1,6} .* data_mask;   % mask slot 6 for parity with slot 1
                            end

                            app.data.ep_metrics.ca_tau{i,2} = tau_data{1,2};
                            app.data.ep_metrics.ca_tau{i,3} = tau_data{1,3};
                            app.data.ep_metrics.ca_tau{i,4} = tau_data{1,4};
                            app.data.ep_metrics.ca_tau{i,5} = tau_data{1,5};

                            feature_extracted = 1;
                            update_console(app,strcat('Calcium Decay Constant (Tau) extracted successfully for CAM', num2str(i)));

                            % Censoring stat over pixels that were eligible (on tissue).
                            % A high figure here means the amplitude gate is rejecting
                            % real signal, not that tau is missing at random.
                            elig  = ~isnan(data_mask);
                            n_all = nnz(elig);
                            n_bad = nnz(elig & isnan(app.data.ep_metrics.ca_tau{i,1}));
                            n_bd6 = nnz(elig & isnan(app.data.ep_metrics.ca_tau{i,6}));
                            update_console(app, sprintf( ...
                                'Tau CAM%d: %d of %d masked pixels (%.1f%%) censored in the fit, %.1f%% in the 1/e estimate.', ...
                                i, n_bad, n_all, 100 * n_bad / max(n_all,1), ...
                                100 * n_bd6 / max(n_all,1)));
                        end

                        if ~feature_extracted
                            update_console(app,strcat('Calcium Decay Constant (Tau) could NOT be extracted for CAM', num2str(i)));
                        end

                    end
                end

            catch ME
                error_message = strcat('ERROR in function extract_tau(app, d) ', ME.message);
                update_console(app,error_message);
            end
        end


        %EXTRACT_V_ALTERNANS  Per-pixel AP-alternans analysis for one camera.
        %   [data] = extract_v_alternans(app, pacing, camera)
        %   Runs analyzeAPAlternans over the masked stack with the given beat frames
        %   and assesses substrate; returns the {result, bg, signal, camera, substrate,
        %   'AP'} cell.
        function [data] = extract_v_alternans(app, pacing, camera)
           try

                mask = app.combo_masks{camera,1};
                cmos_data = get_cmos_data(app,camera);
                [row, col] = get_pixel_location(app, 1);
                % Real milliseconds, not frame indices: the analysis derives
                % dt = mean(diff(time)) from this, so a 1:N axis silently fixes
                % dt at 1 ms and every "ms" output is frames unless fs is 1 kHz.
                app.time = (0:size(cmos_data,3)-1) * (1000 / app.data.acqFreq);
                alternans = analyzeAPAlternans(app.time, cmos_data, 'BeatFrames', pacing, 'Mask', mask);
                substrate = assess_arrhythmia_substrate(alternans, 'BeatFrames', pacing);
                data{1,1} = alternans;
                data{1,2} = get_bg_image(app, camera);
                data{1,3} = squeeze(normalize_data(cmos_data(row, col,:)));
                data{1,4} = num2str(camera);
                data{1,5} = substrate;
                data{1,6} = 'AP';

            catch ME
                error_message = strcat('ERROR in function extract_v_alternans(app, camera) ', ME.message);
                update_console(app,error_message);
            end
        end


        %EXTRACT_C_ALTERNANS  Per-pixel calcium-transient alternans analysis for one camera.
        %   [data] = extract_c_alternans(app, pacing, camera)
        %   Calcium analogue of extract_v_alternans (analyzeCaTransientAlternans).
        function [data] = extract_c_alternans(app, pacing, camera)
            try
                mask = app.combo_masks{camera,1};
                [row, col] = get_pixel_location(app, 1);
                cmos_data = get_cmos_data(app,camera);
                % Real milliseconds, not frame indices -- see extract_v_alternans.
                app.time = (0:size(cmos_data,3)-1) * (1000 / app.data.acqFreq);
                alternans = analyzeCaTransientAlternans(app.time, cmos_data, 'BeatFrames', pacing, 'Mask', mask);
                data{1,1} = alternans;
                data{1,2} = get_bg_image(app, camera);
                data{1,3} = squeeze(normalize_data(cmos_data(row, col,:)));
                data{1,4} = num2str(camera);
                data{1,5} = [];
                data{1,6} = 'Ca';

            catch ME
                error_message = strcat('ERROR in function extract_c_alternans(app, camera) ', ME.message);
                update_console(app,error_message);
            end
        end


        %GET_V_RISE_TIME_RANGE  Map the AP rise-time dropdowns to amplitude fractions.
        %   [rise_min, rise_max] = get_v_rise_time_range(app, camera)  (e.g. 0.2..0.8).
        function [rise_min, rise_max] = get_v_rise_time_range(app, camera)
            try
                rise_min = 0.2;
                rise_max = 0.8;

                rise_min_val = num2str(app.voltage_tab(camera,4,2));
                rise_max_val = num2str(app.voltage_tab(camera,4,3));

                switch rise_min_val
                    case '10'
                        rise_min = 0.1;
                    case '20'
                        rise_min = 0.2;
                    case '30'
                        rise_min = 0.3;
                end

                switch rise_max_val
                    case '70'
                        rise_max = 0.7;
                    case '80'
                        rise_max = 0.8;
                    case '90'
                        rise_max = 0.9;
                    case '100'
                        rise_max = 1;
                end

            catch ME
                error_message = strcat('ERROR in function get_v_rise_time(app)', ME.message);
                update_console(app,error_message);
            end
        end


        %GET_C_RISE_TIME_RANGE  Map the calcium rise-time dropdowns to amplitude fractions.
        %   [rise_min, rise_max] = get_c_rise_time_range(app, camera).
        function [rise_min, rise_max] = get_c_rise_time_range(app, camera)
            try
                rise_min = 0.2;
                rise_max = 0.8;

                rise_min_val = num2str(app.calcium_tab(camera,4,2));
                rise_max_val = num2str(app.calcium_tab(camera,4,3));

                switch rise_min_val
                    case '10'
                        rise_min = 0.1;
                    case '20'
                        rise_min = 0.2;
                    case '30'
                        rise_min = 0.3;
                end

                switch rise_max_val
                    case '70'
                        rise_max = 0.7;
                    case '80'
                        rise_max = 0.8;
                    case '90'
                        rise_max = 0.9;
                    case '100'
                        rise_max = 1;
                end

            catch ME
                error_message = strcat('ERROR in function get_v_rise_time(app)', ME.message);
                update_console(app,error_message);
            end
        end


        %EXTRACT_RISE_TIME  Per-pixel rise time between two amplitude thresholds.
        %   [data] = extract_rise_time(app, camera, rise_min, rise_max)
        %   Time (ms) to climb from rise_min to rise_max of THIS PIXEL'S OWN
        %   baseline-to-peak amplitude, by interpolated threshold crossing.
        %   Shared by AP and calcium rise-time extraction.
        function [data] = extract_rise_time(app, camera, rise_min, rise_max)
             try
                ensemble = app.ENSEMBLECHECKBOX.Value;
                [row, col] = get_pixel_location(app, 1);
                [cmos_data, avg_cmos_data] = get_cmos_data(app, camera);
                if ensemble || isempty(app.oap_window)
                    win_data = avg_cmos_data;
                else
                    sf = app.oap_window{1,1}(1,1);
                    ef = app.oap_window{1,1}(1,2);
                    win_data = cmos_data(:,:,sf:ef);
                end

                [num_rows, num_cols, T] = size(win_data);

                % No normalize_data.  Thresholds below are referenced to each
                % pixel's own baseline and amplitude, taken from this same
                % array, so any rescaling would cancel anyway -- and the
                % default percentile band CLIPS, which does not cancel and made
                % the result depend on window length (see the header).
                Y  = double(reshape(win_data, num_rows * num_cols, T));
                N  = size(Y, 1);
                tt = 1:T;

                % ---- landmark: first SUSTAINED crossing of mid-amplitude ----
                % A level crossing, not a derivative: max(diff(...)) is noise-
                % dominated at the SNR of real recordings.  Two consecutive
                % samples are required so a lone noise spike cannot pass for an
                % upstroke.  The level is per-pixel, from a coarse robust
                % amplitude estimate, so it does not assume any global scaling.
                b0  = prctile(Y,  5, 2);
                p0  = prctile(Y, 99, 2);
                mid = b0 + 0.5 * (p0 - b0);

                hi_mid    = Y >= mid;
                sustained = hi_mid(:, 1:end-1) & hi_mid(:, 2:end);
                [has_anchor, anchor] = max(sustained, [], 2);
                anchor(~has_anchor)  = T;

                % ---- refine baseline and peak against that landmark ---------
                % Same split extract_apd uses about activation: diastole before,
                % peak after.  A median before the upstroke is robust to the
                % noise a single sample would carry.
                pre  = Y;  pre(tt >= anchor)  = NaN;
                base = median(pre, 2, 'omitnan');
                post = Y;  post(tt <  anchor) = NaN;
                pk   = max(post, [], 2);
                amp  = pk - base;

                lo_thr = base + rise_min * amp;
                hi_thr = base + rise_max * amp;

                % ---- brackets, both tied to that one landmark ---------------
                [has_end,   i_end] = max((Y >= hi_thr) & (tt >= anchor), [], 2);
                [has_start, j_rev] = max(fliplr((Y <= lo_thr) & (tt <= anchor)), [], 2);
                i_start = T - j_rev + 1;

                % ---- sub-frame crossings by linear interpolation ------------
                % The measured quantity is where the trace CROSSES the level,
                % which lies between samples; taking the bracketing sample
                % instead is what biased the original version high.
                idx = (1:N)';

                ya    = Y(sub2ind([N T], idx, max(i_end - 1, 1)));
                yb    = Y(sub2ind([N T], idx, i_end));
                den   = yb - ya;
                f_end = (hi_thr - ya) ./ den;
                f_end(den <= 0) = 0;                       % flat or falling
                t_end = (i_end - 1) + min(max(f_end, 0), 1);

                yc      = Y(sub2ind([N T], idx, i_start));
                yd      = Y(sub2ind([N T], idx, min(i_start + 1, T)));
                den2    = yd - yc;
                f_start = (lo_thr - yc) ./ den2;
                f_start(den2 <= 0) = 0;
                t_start = i_start + min(max(f_start, 0), 1);

                % ---- assemble, dropping pixels with no usable bracket -------
                rt = t_end - t_start;
                ok = has_anchor & has_end & has_start & isfinite(base) & ...
                     (amp > 0) & (i_end > 1) & (i_start < i_end) & (rt > 0);

                rise_frames     = nan(N, 1);
                rise_frames(ok) = rt(ok);
                rise_time       = reshape(rise_frames, num_rows, num_cols) * ...
                                  (1000 / app.data.acqFreq);      % frames -> ms

                % ---- trace and overlay for the single displayed pixel -------
                % Expressed as a fraction of THIS pixel's amplitude, so the
                % rise_min / rise_max levels the measurement used correspond to
                % what is plotted.
                p = sub2ind([num_rows num_cols], row, col);
                if isfinite(base(p)) && amp(p) > 0
                    oap = (Y(p, :)' - base(p)) / amp(p);
                else
                    oap = nan(T, 1);
                end
                rise_plot = nan(T, 1);
                if ok(p)
                    rs = max(1, floor(t_start(p)));
                    re = min(T, ceil(t_end(p)));
                    rise_plot(rs:re) = oap(rs:re);
                end

                data{1,1} = rise_time;
                if ~isempty(app.combo_masks{camera,1})
                    data{1,1} = rise_time .* app.combo_masks{camera,1};
                end
                data{1,2} = get_bg_image(app, camera);
                data{1,3} = oap;
                data{1,4} = rise_plot;
                data{1,5} = num2str(camera);
                data{1,6} = rise_time;
            catch ME
                update_console(app, strcat('ERROR in extract_rise_time: ', ME.message));
            end
        end


        %EXTRACT_PHASE_DATA  Hilbert-transform phase movie of a masked optical stack.
        %   [phase] = extract_phase_data(app, cmos_data, mask)
        %   Masks the stack, removes each pixel's temporal mean, and takes the
        %   Hilbert-transform phase (-angle), giving the rows x cols x frames phase
        %   map that drives phase-singularity / rotor detection.
        function [phase] = extract_phase_data(app, cmos_data, mask)
            try
                [R, C, T] = size(cmos_data);
                cmos_data = cmos_data .* mask;          % broadcasting: mask is [R x C]
                flat      = reshape(cmos_data, R*C, T);
                flat      = flat - mean(flat, 2);        % broadcasting: mean is [R*C x 1]
                h         = hilbert(flat');
                phase     = reshape(-angle(h)', R, C, T);
            catch ME
                update_console(app, strcat('ERROR in extract_phase_data: ', ME.message));
            end
        end


        %EXTRACT_ARR_COMPLEXITY  Spectral arrhythmia-complexity maps per camera.
        %   [feature_extracted] = extract_arr_complexity(app, d)
        %   Computes dominant frequency (DF), regularity (RI) and organization (OI)
        %   indices (cardiacSpectralMetrics) over the masked stack; stores
        %   ep_metrics.complexity_data. The DF map seeds wavefront/rotor analysis.
        function [feature_extracted] = extract_arr_complexity(app, d)
            try
                feature_extracted = 0;
                d.Message = 'Extracting Arrhythmia Complexity metrics, if selected';
                [row, col] = get_pixel_location(app, 1);

                counter = 1;
                %complexity_data = cell(app.data.num_files,1);
                for i=1:app.data.num_files
                    if app.arrhythmia_tab(i,1,1)

                        if ~isempty(app.combo_masks{i,1})
                            mask = app.combo_masks{i,1};
                        else
                            mask = extract_image_mask(get_bg_image(app, i), 50);
                        end

                        cmos_data = get_arr_cmos_data(app,i);
                        freq = app.data.acqFreq;
                        bg_idx   = find(isnan(mask));
                        n_pixels = size(cmos_data, 1) * size(cmos_data, 2);
                        bg_3d  = bg_idx + (0:size(cmos_data,3)-1) * n_pixels;
                        cmos_data(bg_3d) = NaN;
                        [DF, RI, OI, ~] = cardiacSpectralMetrics(cmos_data,freq);

                        complexity{1,1} = DF;
                        complexity{1,2} = RI;
                        complexity{1,3} = OI;
                        if ~isempty(DF)
                            app.data.ep_metrics.complexity_data{i,1} = complexity;
                            app.data.ep_metrics.complexity_data{i,2} = get_bg_image(app, i);
                            app.data.ep_metrics.complexity_data{i,3} = squeeze(normalize_data(cmos_data(row, col, :)));
                            app.data.ep_metrics.complexity_data{i,4} = [DF(row, col), RI(row, col), OI(row, col)];
                            app.data.ep_metrics.complexity_data{i,5} = num2str(i);
                            counter = counter + 1;
                            feature_extracted = 1;
                            update_console(app,strcat('Arrhythmia complexity extracted successfully for CAM', num2str(i)));
                        end
                    end
                end

            catch ME
                error_message = strcat('ERROR in function extract_arr_complexity(app, d) ', ME.message);
                update_console(app,error_message);
            end
        end


        %EXTRACT_ARR_WAVE_DYNAMICS  Track activation wavefronts per camera.
        %   [feature_extracted] = extract_arr_wave_dynamics(app, d)
        %   Builds the phase movie, segments wavefronts >= 3 mm (count_wavefronts), and
        %   tracks them via extract_wavefront_dynamics using a rotor-frequency-derived
        %   lifespan threshold (estimate_rotor_frequency on the DF map). Stores
        %   ep_metrics.wavefront_data and phase_data.
        function [feature_extracted] = extract_arr_wave_dynamics(app, d)
            try
                feature_extracted = 0;
                d.Message = 'Extracting Arrhythmia Wavefront Dynamics, if selected';

                [row, col] = get_pixel_location(app, 1);
                wavefront = cell(app.data.num_files,3);
                for i=1:app.data.num_files
                    if app.arrhythmia_tab(i,2,1)
                        %feature_selected = 1;
                        if ~isempty(app.data_masks{i,2})
                            mask = app.data_masks{i,2}{1,2};
                        elseif ~isempty(app.combo_masks{i,1})
                            mask = app.combo_masks{i,1};
                        else
                            mask = extract_image_mask(get_bg_image(app, i), 100);
                        end

                        if isfield(app.data, 'ep_metrics') && isfield(app.data.ep_metrics, 'phase_data') && (size(app.data.ep_metrics.phase_data,1) >=i)
                            phase = app.data.ep_metrics.phase_data{i,1};
                        else
                            phase = extract_phase_data(app, get_arr_cmos_data(app,i), mask);
                        end

                        [num_rows, ~,~] = size(phase);
                        min_wf_length_mm = 3; % reject wavefronts shorter than 3 mm
                        pixel_size_mm     = app.FOVmmEditField.Value / num_rows;         % mm/px
                        cmos_all_data.pixel_size = pixel_size_mm;
                        wf_size_threshold = round(min_wf_length_mm / pixel_size_mm);     % mm / (mm/px) = px
                        [wavefronts, wf_count] = count_wavefronts(phase, wf_size_threshold, pixel_size_mm, min_wf_length_mm);
                        cmos_all_data.wf_count = wf_count;
                        cmos_all_data.wavefronts = wavefronts;
                        if ~isempty(app.data.ep_metrics.complexity_data{i,1})
                            complexity_data = app.data.ep_metrics.complexity_data{i,1};
                            cmos_all_data.df_map = complexity_data{1,1};
                        end

                        debug_mode = 0;

                        df_median = estimate_rotor_frequency(cmos_all_data.df_map, 75);

                        cmos_all_data.frame_rate = app.data.acqFreq;
                        frame_rate          = cmos_all_data.frame_rate;
                        life_span_threshold = round(1.0 * frame_rate / df_median);          % 1 rotation cycle in frames
                        threshold = [0.3, 0.5, 20, 10, 30, 25, wf_size_threshold, life_span_threshold];
                        wf_dynamics = extract_wavefront_dynamics(cmos_all_data,threshold, debug_mode);

                        if ~isempty(wavefronts)
                            wavefront{i,1} = wavefronts;
                            wavefront{i,2} = wf_count;
                            wavefront{i,3} = wf_dynamics;
                            wavefront{i,4} = get_bg_image(app,i);
                            app.data.ep_metrics.wavefront_data{i,1} = wavefront;
                            app.data.ep_metrics.phase_data{i,1} = phase;
                            app.data.ep_metrics.phase_data{i,2} = get_bg_image(app,i);
                            feature_extracted = 1;
                            update_console(app,strcat('Wavefront dynamics extracted successfully for CAM', num2str(i)));
                        end
                    end
                end

            catch ME
                error_message = strcat('ERROR in function extract_arr_wave_dynamics(app, d) ', ME.message);
                update_console(app,error_message);
            end
        end


        %EXTRACT_ARR_PS_DYNAMICS  Detect and track phase singularities (rotors) per camera.
        %   [feature_extracted] = extract_arr_ps_dynamics(app, d)
        %   From the phase movie, finds phase singularities (extract_phase_singularity),
        %   assembles a cmos_all_data bundle (PS, wavefronts, DF map, pixel size, frame
        %   rate) and runs extract_ps_dynamics to track rotors. Stores ep_metrics.rotor_data.
        function [feature_extracted] = extract_arr_ps_dynamics(app, d)
            try
                feature_extracted = 0;
                d.Message = 'Extracting Arrhythmia Rotor Dynamics, if selected';
                counter = 1;
                rotor = cell(app.data.num_files,4);
                for i=1:app.data.num_files
                    if app.arrhythmia_tab(i,3,1)
                        %feature_selected = 1;
                        if ~isempty(app.data_masks{i,2})
                            mask = app.data_masks{i,2}{1,2};
                        elseif ~isempty(app.combo_masks{i,1})
                            mask = app.combo_masks{i,1};
                        else
                            mask = extract_image_mask(get_bg_image(app, i), 100);
                        end

                        if isfield(app.data, 'ep_metrics') && isfield(app.data.ep_metrics, 'phase_data') && (size(app.data.ep_metrics.phase_data,1) >=i)
                            phase = app.data.ep_metrics.phase_data{i,1};
                        else
                            phase_data{i,1} = extract_phase_data(app, get_arr_cmos_data(app,i), mask);
                            phase_data{i,2} = get_bg_image(app,i);
                            phase = phase_data{i,1};
                        end

                        cmos_all_data.ps_data = extract_phase_singularity(phase);
                        cmos_all_data.frame_rate = app.data.acqFreq;

                        field_of_view = app.FOVmmEditField.Value;
                        num_pixels = size(phase,1);
                        cmos_all_data.pixel_size = (field_of_view/num_pixels); % in mm

                        if isfield(app.data, 'ep_metrics') && isfield(app.data.ep_metrics, 'wavefront_data') && ~isempty(app.data.ep_metrics.wavefront_data{i,1})
                            wavefront_data = app.data.ep_metrics.wavefront_data{i,1};
                            cmos_all_data.wavefronts = wavefront_data{1,1};
                        else
                            [num_rows, ~,~] = size(phase);
                            pixel_size_mm     = app.FOVmmEditField.Value / num_rows;         % mm/px
                            min_wf_length_mm = 3; % reject wavefronts shorter than 3 mm
                            wf_size_threshold = round(min_wf_length_mm / pixel_size_mm);     % mm / (mm/px) = px
                            [wavefronts, ~] = count_wavefronts(phase, wf_size_threshold, pixel_size_mm, min_wf_length_mm);
                            cmos_all_data.wavefronts = wavefronts;
                        end


                        if ~isempty(app.data.ep_metrics.complexity_data{i,1})
                            complexity_data = app.data.ep_metrics.complexity_data{i,1};
                            cmos_all_data.df_map = complexity_data{1,1};
                        end

                        cmos_all_data = extract_ps_dynamics(cmos_all_data);

                        if ~isempty(cmos_all_data)
                            rotor{i,1} = cmos_all_data.ps_data;
                            rotor{i,2} = cmos_all_data.ps;
                            rotor{i,3} = cmos_all_data.ps_count;
                            rotor{i,4} = cmos_all_data.ps_dynamics;
                            rotor{i,5} = get_bg_image(app,i);
                            app.data.ep_metrics.rotor_data{i,1} = rotor;
                            app.data.ep_metrics.phase_data{i,1} = phase;
                            counter = counter + 1;
                            feature_extracted = 1;
                            update_console(app,strcat('Phase singularity dynamics extracted successfully for CAM', num2str(i)));
                        end
                    end
                end

            catch ME
                error_message = strcat('ERROR in function extract_arr_ps_dynamics(app, d) ', ME.message);
                update_console(app,error_message);
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %STARTUPFCN  App initialisation — add the CADENCE code tree to the path.
            if ~isdeployed
                cadence_bin_path = genpath(pwd);
                addpath(cadence_bin_path);
            end

        end

        % Button pushed function: SELECTOUTPUTDIRECTORYButton_2
        function SELECTOUTPUTDIRECTORYButtonPushed(app, event)
            %SELECTOUTPUTDIRECTORYBUTTONPUSHED  Choose where extracted *-metrics files are written.
            try
                app.UIFigure.Visible = 'off';
                app.output_dir_name = uigetdir;
                app.UIFigure.Visible = 'on';
                if app.output_dir_name ~= 0
                    app.EditField_2.Value = app.output_dir_name;
                    completed_message = strcat('Output directory selected. ');
                    update_console(app,completed_message);
                end

            catch ME
                error_message = strcat('ERROR - SELECTOUTPUTDIRECTORYButtonPushed(app, event) ', ME.message);
                update_console(app,error_message);
            end
        end

        % Button pushed function: SELECTDIRECTORYButton
        function SELECTDIRECTORYButtonPushed(app, event)
            %SELECTDIRECTORYBUTTONPUSHED  Choose the input directory and list its conditioned *.mat files.
            try
                app.UIFigure.Visible = 'off';
                app.dir_name = uigetdir;
                app.UIFigure.Visible = 'on';
                if app.dir_name ~= 0
                    search_name = [app.dir_name,'/*.mat'];
                    files = struct2cell(dir(search_name));
                    if ~isempty(files)
                        app.ListBox_2.Items = files(1,:);
                        tot_num_files = num2str(size(files,2));
                        completed_message = strcat(tot_num_files, ' files found in the selected directory. ');
                        update_console(app,completed_message);
                        app.LOADButton.Enable ="on";
                    else
                        warning_message = 'Did not find any *.mat files in the selected directory. ';
                        update_console(app,warning_message);
                        app.LOADButton.Enable ="off";
                    end
                end
            catch ME
                error_message = strcat('ERROR - FEATURE EXTRACTION - SELECTINPUTDIRECTORYButtonPushed(app, event) ', ME.message);
                update_console(app,error_message);
            end
        end

        % Button pushed function: REFRESHDIRECTORYButton
        function REFRESHDIRECTORYButtonPushed(app, event)
            %REFRESHDIRECTORYBUTTONPUSHED  Re-scan the current directory for *.mat files.
            try
                if app.dir_name ~= 0
                    search_name = [app.dir_name,'/*.mat'];
                    files = struct2cell(dir(search_name));
                    if ~isempty(files)
                        app.ListBox_2.Items = files(1,:);
                        completed_message = 'Refreshed selected directory.  ';
                        update_console(app,completed_message);
                        app.LOADButton.Enable ="on";
                    else
                        warning_message = 'Did not find any *.mat files in the selected directory after refresh.  ';
                        update_console(app,warning_message);
                        app.LOADButton.Enable ="off";
                    end
                end

            catch ME
                error_message = strcat('ERROR - REFRESHINPUTDIRECTORYButtonPushed(app, event) ', ME.message);
                update_console(app,error_message);
            end
        end

        % Button pushed function: LOADButton
        function LOADButtonPushed(app, event)
            %LOADBUTTONPUSHED  Load the selected conditioned recording and render its cameras.
            %   Resets state, loads cmos_all_data (load_file), draws each present camera's
            %   image and sizes its axes.
            try

                d = uiprogressdlg(app.UIFigure, 'Title', 'Loading data file', 'Indeterminate','on');
                reset_default_state(app);
                file_name = [];
                file_list = app.ListBox_2.Value;
                if ~isempty(file_list)
                    file_name = file_list{1,1};
                end
                if ~isempty(file_name)

                    d.Message = strcat('Loading data file ...', file_name);

                    app.data = load_file(app,file_name);

                    [app.x_axis, app.y_axis] = size(app.data.CAM1(:,:,1));
                    app.UIAxes_cam1.XLim = [1, app.x_axis];
                    app.UIAxes_cam1.YLim = [1, app.y_axis];
                    imagesc(app.UIAxes_cam1, real2rgb(app.data.CAM1_image,'gray'));
                    set(app.UIAxes_cam1, 'XTick', [], 'YTick', []);
                    colorbar(app.UIAxes_cam1, "off");

                    if app.data.num_files >=2
                        app.UIAxes_cam2.XLim = [1, app.x_axis];
                        app.UIAxes_cam2.YLim = [1, app.y_axis];
                        imagesc(app.UIAxes_cam2, real2rgb(app.data.CAM2_image,'gray'));
                        set(app.UIAxes_cam2, 'XTick', [], 'YTick', []);
                        colorbar(app.UIAxes_cam2, "off");
                    end

                    if app.data.num_files >=3
                        app.UIAxes_cam3.XLim = [1, app.x_axis];
                        app.UIAxes_cam3.YLim = [1, app.y_axis];
                        imagesc(app.UIAxes_cam3, real2rgb(app.data.CAM3_image,'gray'));
                        set(app.UIAxes_cam3, 'XTick', [], 'YTick', []);
                        colorbar(app.UIAxes_cam3, "off");
                    end

                    if app.data.num_files ==4
                        app.UIAxes_cam4.XLim = [1, app.x_axis];
                        app.UIAxes_cam4.YLim = [1, app.y_axis];
                        imagesc(app.UIAxes_cam4, real2rgb(app.data.CAM4_image,'gray'));
                        set(app.UIAxes_cam4, 'XTick', [], 'YTick', []);
                        colorbar(app.UIAxes_cam4, "off");
                    end

                    close(d);
                    completed_message = strcat(file_name, ' ....loaded successfully ');
                    update_console(app,completed_message);

                    %SPECIFIC TO ARAS LAB
                    if app.data.num_files == 2
                        reset_default_UI(app);
                    end

                    app.EXTRACTFEATURESButton.Enable ="on";
                    app.SAVEDATAButton.Enable = "on";
                    app.SELECTWINDOWButton.Enable = "on";
                    app.INVERTSIGNALSButton.Enable = "on";

                end


            catch ME
                error_message = strcat('ERROR - FEATUREEXTRACTION- LOADButtonPushed(app, event) ', ME.message);
                update_console(app,error_message);
                close(d);
            end

        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            %UIFIGUREWINDOWBUTTONDOWN  Right-click on a camera image: place a marker, plot its signal.
            try

                mouse_click = get(app.UIFigure,'SelectionType'); % The result can be 'normal', 'extend', 'alt', 'open'
                if isequal(mouse_click, 'alt') && app.data.num_files >0 % mouse right click
                    valid_action = update_active_tab_axes_data(app); %data loaded in the active tab
                    if valid_action
                        valid_location = update_plot_marker(app, 'button_down'); % mouse in valid location on the bgimage
                        if valid_location
                            update_plot_signal(app); %
                        end
                    end
                end

            catch ME
                error_message = strcat('ERROR - FEATUREEXTRACTION- WindowBUttonDown(app, event) ', ME.message);
                update_console(app,error_message);
            end
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            %UIFIGUREWINDOWBUTTONMOTION  Right-drag: move the marker and live-update the signal.
            try
                if ~isempty(app.mouse_drag) %mouse in motion while button pushed down
                    mouse_click = get(app.UIFigure,'SelectionType'); % The result can be 'normal', 'extend', 'alt', 'open'
                    if isequal(mouse_click, 'alt') && app.data.num_files >0 % mouse right click and data file(s) loaded
                        valid_action = update_active_tab_axes_data(app); %data loaded in the active tab
                        if valid_action
                            valid_location = update_plot_marker(app, 'button_motion'); % mouse in valid location on the bgimage
                            if valid_location
                                update_plot_signal(app); %
                            end
                        end
                    end
                end

            catch ME
                error_message = strcat('ERROR - FEATUREEXTRACTION- WindowBUttonMotion(app, event) ', ME.message);
                update_console(app,error_message);
            end
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            %UIFIGUREWINDOWBUTTONUP  End the marker drag and advance the signal-slot index.
            try
                app.mouse_drag = [];
            catch ME
                error_message = strcat('ERROR - FEATUREEXTRACTION- WindowBUttonUP(app, event) ', ME.message);
                update_console(app,error_message);
            end
        end

        % Button pushed function: SELECTWINDOWButton
        function SELECTWINDOWButtonPushed(app, event)
            %SELECTWINDOWBUTTONPUSHED  Add a signal-window ROI over the plotted signal.
            try
                if ~isempty(app.data)
                    select_window(app);
                else
                    message = strcat("Data not loaded");
                    update_console(app, message);
                end
            catch ME
                error_message = strcat('ERROR - FEATUREEXTRACTION- SELECTWINDOWButtonPushed(app, event) ', ME.message);
                update_console(app,error_message);
            end
        end

        % Button pushed function: EXTRACTFEATURESButton
        function EXTRACTFEATURESButtonPushed(app, event)
            %EXTRACTFEATURESBUTTONPUSHED  Run every selected feature extractor for the recording.
            %   Builds the combo masks, then runs each enabled extractor in turn — LAT,
            %   APD/repolarization, AP rise, CV, V-Ca delay, calcium duration/decay/rise,
            %   tau, alternans, and the arrhythmia complexity/wavefront/rotor dynamics —
            %   popping up a feature-map figure for each. Progress shows in a dialog.
            %   (Repolarization is derived from APD when APD is selected, to avoid
            %   recomputing; same for calcium duration vs decay.)
            try
                if ~isempty(app.data)

                    d = uiprogressdlg(app.UIFigure, 'Title', 'Extracting Features', 'Indeterminate','on');
                    warning('off', 'all');
                    

                    extract_combo_masks(app, d);
                    counter = 0;
                    figs_array = gobjects(0);

                    if extract_act_time(app, d)
                        figs_array = display_feature_map(app, app.data.ep_metrics.act_times, "Act Times", figs_array);
                        pause(0.2);
                        counter = counter + 1;
                    end

                    if extract_v_apd(app, d)
                        
                        figs_array = display_feature_map(app, app.data.ep_metrics.apd_data, "APD Data", figs_array);
                        pause(0.2);
                        counter = counter + 1;
                    end


                    if extract_v_rep(app, d)
                        figs_array = display_feature_map(app, app.data.ep_metrics.rep_data, "Repolarization Data", figs_array);
                        pause(0.2);
                        counter = counter + 1;
                    end


                    if extract_v_rise(app, d)
                        figs_array = display_feature_map(app, app.data.ep_metrics.ap_rise_times, "AP Rise Time", figs_array);
                        pause(0.2);
                        counter = counter + 1;
                    end

                    if extract_cv(app, d)
                        figs_array = display_cv_map(app, app.data.ep_metrics.local_cv, "CV", figs_array);
                        pause(0.2);
                        counter = counter + 1;
                    end

                    if extract_v_c_delay(app, d)
                        figs_array = display_feature_map(app, app.data.ep_metrics.vc_delay, "Voltage-Ca Delay", figs_array);
                        pause(0.2);
                        counter = counter + 1;
                    end

                    if extract_c_apd(app, d)
                        
                        figs_array = display_feature_map(app, app.data.ep_metrics.ca_data, "Ca Transient Duration", figs_array);
                        pause(0.2);
                        counter = counter + 1;
                    end


                    if extract_c_rep(app, d)
                        figs_array = display_feature_map(app, app.data.ep_metrics.ca_rep_data, "Ca Decay Time", figs_array);
                        pause(0.2);
                        counter = counter + 1;
                    end
                    

                    if extract_c_rise(app, d)
                        figs_array = display_feature_map(app, app.data.ep_metrics.ca_rise_times, "Ca Rise Time", figs_array);
                        pause(0.2);
                        counter = counter + 1;
                    end

                    if extract_tau(app, d)
                        figs_array = display_feature_map(app, app.data.ep_metrics.ca_tau, "Ca Decay Constant", figs_array);
                        pause(0.2);
                        counter = counter + 1;
                    end

                    if extract_arr_complexity(app, d)
                        figs_array = display_arrhythmia_map(app, app.data.ep_metrics.complexity_data, "Arrhythmia Complexity", figs_array);
                        pause(0.2);
                        counter = counter + 1;
                    end

                    if extract_alternans(app, d)
                        figs_array = display_alternans_map(app, app.data.ep_metrics.alternans_data, "Alternans", figs_array);
                        pause(0.2);
                        counter = counter + 1;
                    end

                    if extract_arr_wave_dynamics(app, d)
                        counter = counter + 1;
                    end

                    if extract_arr_ps_dynamics(app, d)
                        counter = counter + 1;
                    end

                    if counter == 0
                        d.Message = 'No features were extracted';
                        update_console(app, d.Message);

                    else
                        if ~isempty(app.output_dir_name)
                            dir_path = app.output_dir_name;
                        else
                            dir_path = app.dir_name;
                        end
                        pdf_file_name = strrep(app.mat_file_name, '.mat', '.pdf');
                        pdf_file_path = strcat(dir_path, '/', pdf_file_name);
                        if ~app.batch_mode
                            report_feature_medians(app);   % copy-pasteable summary for single-file runs
                        end
                        save_figures_to_pdf(figs_array, pdf_file_path, app.batch_mode);
                    end

                    close(d);
                else
                    message = "Data not loaded";
                    update_console(app, message);
                end

            catch ME
                error_message = strcat('ERROR - FEATUREEXTRACTIONButtonPushed(app, event) ', ME.message);
                update_console(app,error_message);
                close(d);
            end
        end

        % Value changed function: SNRSwitch
        function SNRMASK_SWITCH(app, event)
            %SNRMASK_SWITCH  Toggle the SNR mask on/off for the active camera (+ threshold).
            try

                value = app.SNRSwitch.Value;
                valid_action = update_active_tab_axes_data(app); %data loaded in the active tab
                if valid_action && ~isempty(app.data_masks{app.active_camera,2})
                    switch value
                        case 'On'
                            app.data_masks_tab(app.active_camera,2) = 1;
                            app.SetSNRMaskThreshold0autoEditField.Value = app.data_masks_tab(app.active_camera,3);

                        case 'Off'
                            app.data_masks_tab(app.active_camera,2) = 0;
                            app.SetSNRMaskThreshold0autoEditField.Value = 0;
                    end
                else
                    % warning_message = 'No data/image or snr data loaded for the current tab. Please select a valid data/image tab.';
                    % update_console(app,warning_message);
                    app.SNRSwitch.Value = 'Off';
                end
            catch ME
                error_message = strcat('ERROR - SNRMASK_SWITCH(app, event) ', ME.message);
                update_console(app,error_message);
            end


        end

        % Value changed function: USRSwitch
        function USERMASK_SWITCH(app, event)
            %USERMASK_SWITCH  Toggle the user ROI mask on/off for the active camera.
            try
                value = app.USRSwitch.Value;
                valid_action = update_active_tab_axes_data(app); %data loaded in the active tab
                if valid_action && ~isempty(app.data_masks{app.active_camera,2})
                    switch value
                        case 'On'
                            app.data_masks_tab(app.active_camera,2) = 1;

                        case 'Off'
                            app.data_masks_tab(app.active_camera,2) = 0;
                    end
                else
                    % warning_message = 'No data/image or user mask data loaded for the current tab. Please select a valid data/image tab.';
                    % update_console(app,warning_message);
                    app.USRSwitch.Value = 'Off';
                end
            catch ME
                error_message = strcat('ERROR - USRMASK_SWITCH(app, event) ', ME.message);
                update_console(app,error_message);
            end

        end

        % Button pushed function: DISPLAYSNRMAPButton
        function DISPLAYSNRMAPButtonPushed(app, event)
            %DISPLAYSNRMAPBUTTONPUSHED  Display the active camera's SNR map.
            try
                if ~isempty(app.data)
                    valid_action = update_active_tab_axes_data(app); %data loaded in the active tab
                    if valid_action
                        snr = get_snr_data(app, app.active_camera);

                        usr_threshold = app.SetSNRMaskThreshold0autoEditField.Value;
                        if usr_threshold <= 0
                            [~, snr_info] = create_snr_mask(snr, [], false);
                            usr_threshold = snr_info.threshold;
                            app.SetSNRMaskThreshold0autoEditField.Value = usr_threshold;   % show what was chosen
                            update_console(app, sprintf('SNR mask: adaptive threshold %.2f (0.5 x median tissue SNR, floor 1.5).', usr_threshold));
                        end
                        snr(snr < usr_threshold) = nan;
                        app.data_masks_tab(app.active_camera,3) = usr_threshold;

                        snr_threshold = median(snr, "all", "omitnan");
                        display_map(app, snr, [0, 2*snr_threshold]);
                        app.data_masks{app.active_camera,1}{1,1} = snr;
                        app.data_masks_tab(app.active_camera,1) = 1;
                        app.SNRSwitch.Value = 'On';
                    end
                else
                    message = strcat("Data not loaded");
                    update_console(app,message);
                end

            catch ME
                error_message = strcat('ERROR - DISPLAYSNRMAPButtonPushed(app, event) ', ME.message);
                update_console(app,error_message);
            end
        end

        % Button pushed function: CREATEUSERMASKButton
        function CREATEUSERMASKButtonPushed(app, event)
            %CREATEUSERMASKBUTTONPUSHED  Draw a new ROI data mask on the active camera image.
            try
                if ~isempty(app.data)
                    valid_action = update_active_tab_axes_data(app); %data loaded in the active tab
                    if valid_action
                        tag_id = generate_unique_id;
                        delete(app.active_axes.Children(:,1));
                        imagesc(app.active_axes, real2rgb(app.active_image,'gray'));
                        colorbar(app.active_axes, 'off');
                        mask_region = drawfreehand('Parent', app.active_axes,'Color', app.active_color, 'Tag', tag_id);
                        addlistener(mask_region,'ROIMoved', @(varargin)ROIChanged(app,mask_region, -1)); % update if there is a change to mask;
                        %addlistener(mask_region,'ObjectBeingDestroyed', @(varargin)ROIDeleted(app,mask_region, -1)); %update if the user removes the mask;
                        update_mask(app,mask_region);
                        app.USRSwitch.Value = 'On';
                        app.data_masks_tab(app.active_camera,2) = 1;
                    end
                else
                    message = strcat("Data not loaded");
                    update_console(app,message);
                end
            catch ME
                error_message = strcat('ERROR - CREATEUSERMASKButtonPushed(app, event) ', ME.message);
                update_console(app,error_message);
            end
        end

        % Selection change function: TabGroup2
        function CAMERASelectionChanged(app, event)
            %CAMERASELECTIONCHANGED  React to a camera-tab switch (refresh active_* + UI selections).
            try
                if ~isempty(app.data)
                    update_active_tab_axes_data(app);
                else
                    app.active_tab = app.TabGroup2.SelectedTab.Title;
                    switch app.active_tab
                        case 'CAM_1'
                            app.active_camera = 1;
                        case 'CAM_2'
                            app.active_camera = 2;
                        case 'CAM_3'
                            app.active_camera = 3;
                        case 'CAM_4'
                            app.active_camera = 4;
                    end
                end
                update_feature_selection(app);%new active tab

            catch ME
                error_message = strcat('ERROR - CAMERASelectionChanged(app, event) ', ME.message);
                update_console(app,error_message);
            end
        end

        % Value changed function: PACINGCHECKBOX
        function PACINGCHECKBOXValueChanged(app, event)
            %PACINGCHECKBOXVALUECHANGED  Toggle use of the analog1 pacing trace in plots/extraction.
            try
                pacing = app.PACINGCHECKBOX.Value;
                valid_action = update_active_tab_axes_data(app); %data loaded in the active tab
                if isfield(app.data, 'analog1') %pacing data available
                    if valid_action
                        if pacing %show pacing signal
                            if ~isempty(app.pacing_signal)
                                app.pacing_signal.Visible = "on";
                                app.pacing_signal.YData = app.data.analog1;
                            else
                                hold(app.UIAxes_sig,"on");
                                app.pacing_signal = line(app.UIAxes_sig, app.time, app.data.analog1, 'LineWidth',1, 'color','#777777');
                            end
                        else %hide pacing signal
                            if ~isempty(app.pacing_signal)
                                app.pacing_signal.Visible = "off";
                            end
                        end
                    else
                        app.pacing_signal.Visible = "off";
                    end
                else
                    if pacing
                        message = strcat('Pacing signal not available for this recording');
                        update_console(app, message);
                    end
                end

            catch ME
                error_message = strcat('ERROR - PACINGCHECKBOXValueChanged(app, event) ', ME.message);
                update_console(app,error_message);
            end


        end

        % Button pushed function: DELETEUSERMASKButton
        function DELETEUSERMASKButtonPushed(app, event)
            %DELETEUSERMASKBUTTONPUSHED  Remove the selected user mask and its table row.
            try
                if ~isempty(app.data)
                    valid_action = update_active_tab_axes_data(app); %data loaded in the active tab
                    if valid_action
                        if ~isempty(app.data_masks{app.active_camera,2})
                            try
                                app.data_masks{app.active_camera,2}{1,1}.Visible = 'off';
                            catch
                            end
                            app.data_masks{app.active_camera,2}(1,:) = [];
                            app.USRSwitch.Value = 'Off';
                        end
                    end
                else
                    message = strcat("Data not loaded");
                    update_console(app,message);
                end

            catch ME
                error_message = strcat('ERROR - DELETEUSERMASKButtonPushed(app, event) ', ME.message);
                update_console(app,error_message);
            end

        end

        % Button pushed function: ROTATECWButton
        function ROTATECWButtonPushed(app, event)
            %ROTATECWBUTTONPUSHED  Rotate the active camera image/data 90 deg clockwise.
            try
                if ~isempty(app.data)
                    valid_action = update_active_tab_axes_data(app); %data loaded in the active tab
                    if valid_action
                        if isempty(app.image_alignment{app.active_camera,1})
                            store_image_data(app); %in case we need to reset the image
                        end
                        app.active_image = rot90(app.active_image,1);
                        app.active_data = rot90(app.active_data,1);
                        app.active_ensemble_data = rot90(app.active_ensemble_data,1);
                        app.active_snr = rot90(app.active_snr,1);
                        update_image_data(app); %update active image and data
                        delete(app.active_axes.Children(:,1));
                        imagesc(app.active_axes, real2rgb(app.active_image,'gray'));
                        colorbar(app.active_axes, 'off');

                    end
                else
                    message = strcat("Data not loaded");
                    update_console(app,message);
                end

            catch ME
                error_message = strcat('ERROR - ROTATECWButtonPushed(app, event) ', ME.message);
                update_console(app,error_message);
            end
        end

        % Button pushed function: ROTATECCWButton
        function ROTATECCWButtonPushed(app, event)
            %ROTATECCWBUTTONPUSHED  Rotate the active camera image/data 90 deg counter-clockwise.
            try
                if ~isempty(app.data)
                    valid_action = update_active_tab_axes_data(app); %data loaded in the active tab
                    if valid_action
                        if isempty(app.image_alignment{app.active_camera,1})
                            store_image_data(app); %in case we need to reset the image
                        end
                        app.active_image = rot90(app.active_image,-1);
                        app.active_data = rot90(app.active_data,-1);
                        app.active_ensemble_data = rot90(app.active_ensemble_data, -1);
                        app.active_snr = rot90(app.active_snr, -1);
                        update_image_data(app); %update active image and data
                        delete(app.active_axes.Children(:,1));
                        imagesc(app.active_axes, real2rgb(app.active_image,'gray'));
                        colorbar(app.active_axes, 'off');
                    end
                else
                    message = strcat("Data not loaded");
                    update_console(app,message);
                end

            catch ME
                error_message = strcat('ERROR - ROTATECCWButtonPushed(app, event) ', ME.message);
                update_console(app,error_message);
            end
        end

        % Button pushed function: FLIPLRButton
        function FLIPLRButtonPushed(app, event)
            %FLIPLRBUTTONPUSHED  Mirror the active camera image/data left-right.
            try
                if ~isempty(app.data)
                    valid_action = update_active_tab_axes_data(app); %data loaded in the active tab
                    if valid_action
                        if isempty(app.image_alignment{app.active_camera,1})
                            store_image_data(app); %in case we need to reset the image
                        end
                        app.active_image = fliplr(app.active_image);
                        app.active_data = fliplr(app.active_data);
                        app.active_ensemble_data = fliplr(app.active_ensemble_data);
                        app.active_snr = fliplr(app.active_snr);
                        update_image_data(app); %update active image and data
                        delete(app.active_axes.Children(:,1));
                        imagesc(app.active_axes, real2rgb(app.active_image,'gray'));
                        colorbar(app.active_axes, 'off');
                    end
                else
                    message = strcat("Data not loaded");
                    update_console(app,message);
                end

            catch ME
                error_message = strcat('ERROR - FLIPLRButtonPushed(app, event) ', ME.message);
                update_console(app,error_message);
            end
        end

        % Button pushed function: FLIPUDButton
        function FLIPUDButtonPushed(app, event)
            %FLIPUDBUTTONPUSHED  Mirror the active camera image/data up-down.
            try
                if ~isempty(app.data)
                    valid_action = update_active_tab_axes_data(app); %data loaded in the active tab
                    if valid_action
                        if isempty(app.image_alignment{app.active_camera,1})
                            store_image_data(app); %in case we need to reset the image
                        end
                        app.active_image = flipud(app.active_image);
                        app.active_data = flipud(app.active_data);
                        app.active_ensemble_data = flipud(app.active_ensemble_data);
                        app.active_snr = flipud(app.active_snr);
                        update_image_data(app); %update active image and data
                        delete(app.active_axes.Children(:,1));
                        imagesc(app.active_axes, real2rgb(app.active_image,'gray'));
                        colorbar(app.active_axes, 'off');
                    end
                else
                    message = strcat("Data not loaded");
                    update_console(app,message);
                end

            catch ME
                error_message = strcat('ERROR - FLIPUDButtonPushed(app, event) ', ME.message);
                update_console(app,error_message);
            end
        end

        % Button pushed function: RESETIMAGEButton
        function RESETIMAGEButtonPushed(app, event)
            %RESETIMAGEBUTTONPUSHED  Restore the active camera image/data to its stored original.
            try
                if ~isempty(app.data)
                    valid_action = update_active_tab_axes_data(app); %data loaded in the active tab
                    if valid_action
                        if ~isempty(app.image_alignment{app.active_camera,1})
                            app.active_image = app.image_alignment{app.active_camera,1};
                            app.active_data = app.image_alignment{app.active_camera,2};
                            app.active_ensemble_data = app.image_alignment{app.active_camera,3};
                            app.active_snr = app.image_alignment{app.active_camera,4};
                            update_image_data(app);
                            delete(app.active_axes.Children(:,1));
                            imagesc(app.active_axes, real2rgb(app.active_image,'gray'));
                            colorbar(app.active_axes, 'off');
                            message = strcat("Image and data reset successfully");
                            update_console(app,message);
                        end
                    end
                else
                    message = strcat("Data not loaded");
                    update_console(app,message);
                end

            catch ME
                error_message = strcat('ERROR - RESETIMAGEButtonPushed(app, event) ', ME.message);
                update_console(app,error_message);
            end
        end

        % Value changed function: ACTIVATIONTIMEDropDown
        function ACTChanged(app, event)
            %ACTCHANGED  Enable/disable activation-time extraction for the active camera (or all, if linked).
            try
                act_value = app.ACTIVATIONTIMEDropDown.Value;
                link_cameras = app.LINKCAMERASCHECKBOX.Value;

                switch act_value
                    case 'YES'
                        if link_cameras
                            app.voltage_tab(:,1,1) = 1;
                        else
                            app.voltage_tab(app.active_camera,1,1) = 1;
                        end

                    case 'NO'
                        if link_cameras
                            app.voltage_tab(:,1,1) = 0;
                        else
                            app.voltage_tab(app.active_camera,1,1) = 0;
                        end
                end

            catch ME
                error_message = strcat('ERROR - Activation Time Value Changed(app, event) ', ME.message);
                update_console(app,error_message);
            end

        end

        % Value changed function: DURATIONDropDown
        function APDChanged(app, event)
            %APDCHANGED  Enable/disable APD extraction for the active camera (or all, if linked).
            try
                apd_value = app.DURATIONDropDown.Value;
                apd_percentage_value = str2double(app.APDDropDown.Value);
                link_cameras = app.LINKCAMERASCHECKBOX.Value;

                switch apd_value
                    case 'YES'
                        if link_cameras
                            app.voltage_tab(:,3,1) = 1;
                            app.voltage_tab(:,3,2) = apd_percentage_value;
                        else
                            app.voltage_tab(app.active_camera,3,1) = 1;
                            app.voltage_tab(app.active_camera,3,2) = apd_percentage_value;
                        end

                    case 'NO'
                        if link_cameras
                            app.voltage_tab(:,3,1) = 0;
                        else
                            app.voltage_tab(app.active_camera,3,1) = 0;
                        end
                end

            catch ME
                error_message = strcat('ERROR - APD Value Changed(app, event) ', ME.message);
                update_console(app,error_message);
            end


        end

        % Value changed function: RISETIMEDropDown
        function RiseTimeChanged(app, event)
            %RISETIMECHANGED  Enable/disable AP rise-time extraction for the active camera.
            try
                rt_value = app.RISETIMEDropDown.Value;
                rt_min_value = str2double(app.APRiseDropDown_1.Value);
                rt_max_value = str2double(app.APRiseDropDown_2.Value);
                link_cameras = app.LINKCAMERASCHECKBOX.Value;

                switch rt_value
                    case 'YES'
                        if link_cameras
                            app.voltage_tab(:,4,1) = 1;
                            app.voltage_tab(:,4,2) = rt_min_value;
                            app.voltage_tab(:,4,3) = rt_max_value;

                        else
                            app.voltage_tab(app.active_camera,4,1) = 1;
                            app.voltage_tab(app.active_camera,4,2) = rt_min_value;
                            app.voltage_tab(app.active_camera,4,3) = rt_max_value;
                        end

                    case 'NO'
                        if link_cameras
                            app.voltage_tab(:,4,1) = 0;
                        else
                            app.voltage_tab(app.active_camera,4,1) = 0;
                        end
                end

            catch ME
                error_message = strcat('ERROR - Rise Time Value Changed(app, event) ', ME.message);
                update_console(app,error_message);
            end

        end

        % Value changed function: DELAYDropDown
        function VCDelayChanged(app, event)
            %VCDELAYCHANGED  Enable/disable voltage-calcium delay extraction for the active camera.
            try
                vcd_value = app.DELAYDropDown.Value;
                link_cameras = app.LINKCAMERASCHECKBOX.Value;

                switch vcd_value
                    case 'YES'
                        if link_cameras
                            app.calcium_tab(:,1,1) = 1;
                        else
                            app.calcium_tab(app.active_camera,1,1) = 1;
                        end

                    case 'NO'
                        if link_cameras
                            app.calcium_tab(:,1,1) = 0;
                        else
                            app.calcium_tab(app.active_camera,1,1) = 0;
                        end
                end

            catch ME
                error_message = strcat('ERROR - Voltage Calcium Delay Value Changed(app, event) ', ME.message);
                update_console(app,error_message);
            end

        end

        % Value changed function: CALCIUMDURATIONDropDown
        function CTDChanged(app, event)
            %CTDCHANGED  Enable/disable calcium-transient duration extraction for the active camera.
            try
                ctd_value = app.CALCIUMDURATIONDropDown.Value;
                ctd_percentage_value = str2double(app.CaDropDown.Value);
                link_cameras = app.LINKCAMERASCHECKBOX.Value;

                switch ctd_value
                    case 'YES'
                        if link_cameras
                            app.calcium_tab(:,3,1) = 1;
                            app.calcium_tab(:,3,2) = ctd_percentage_value;

                        else
                            app.calcium_tab(app.active_camera,3,1) = 1;
                            app.calcium_tab(app.active_camera,3,2) = ctd_percentage_value;
                        end

                    case 'NO'
                        if link_cameras
                            app.calcium_tab(:,3,1) = 0;
                        else
                            app.calcium_tab(app.active_camera,3,1) = 0;
                        end
                end

            catch ME
                error_message = strcat('ERROR - Voltage Calcium Delay Value Changed(app, event) ', ME.message);
                update_console(app,error_message);
            end

        end

        % Value changed function: CALCIUMRISETIMEDropDown
        function CRiseTimeChanged(app, event)
            %CRISETIMECHANGED  Enable/disable calcium rise-time extraction for the active camera.
            try
                crt_value = app.CALCIUMRISETIMEDropDown.Value;
                crt_min_value = str2double(app.CaRiseDropDown_1.Value);
                crt_max_value = str2double(app.CaRiseDropDown_2.Value);
                link_cameras = app.LINKCAMERASCHECKBOX.Value;

                switch crt_value
                    case 'YES'
                        if link_cameras
                            app.calcium_tab(:,4,1) = 1;
                            app.calcium_tab(:,4,2) = crt_min_value;
                            app.calcium_tab(:,4,3) = crt_max_value;

                        else
                            app.calcium_tab(app.active_camera,4,1) = 1;
                            app.calcium_tab(app.active_camera,4,2) = crt_min_value;
                            app.calcium_tab(app.active_camera,4,3) = crt_max_value;
                        end

                    case 'NO'
                        if link_cameras
                            app.calcium_tab(:,4,1) = 0;
                        else
                            app.calcium_tab(app.active_camera,4,1) = 0;
                        end
                end

            catch ME
                error_message = strcat('ERROR - Calcium Rise Time Value Changed(app, event) ', ME.message);
                update_console(app,error_message);
            end

        end

        % Value changed function: TDropDown
        function TauValueChanged(app, event)
            %TAUVALUECHANGED  Enable/disable calcium decay-tau extraction for the active camera.
            try
                tau_value = app.TDropDown.Value;
                tau_max_value = str2double(app.TauDropDown.Value);
                tau_min_value = str2double(app.TauDropDown_2.Value);
                link_cameras = app.LINKCAMERASCHECKBOX.Value;

                switch tau_value
                    case 'YES'
                        if link_cameras
                            app.calcium_tab(:,5,1) = 1;
                            app.calcium_tab(:,5,2) = tau_max_value;
                            app.calcium_tab(:,5,3) = tau_min_value;
                        else
                            app.calcium_tab(app.active_camera,5,1) = 1;
                            app.calcium_tab(app.active_camera,5,2) = tau_max_value;
                            app.calcium_tab(app.active_camera,5,3) = tau_min_value;
                        end

                    case 'NO'
                        if link_cameras
                            app.calcium_tab(:,5,1) = 0;
                        else
                            app.calcium_tab(app.active_camera,5,1) = 0;
                        end
                end

            catch ME
                error_message = strcat('ERROR - Calcium Tau Value Changed(app, event) ', ME.message);
                update_console(app,error_message);
            end

        end

        % Value changed function: COMPLEXITYDropDown
        function ComplexityChanged(app, event)
            %COMPLEXITYCHANGED  Enable/disable arrhythmia-complexity extraction for the active camera.
            try
                complexity_value = app.COMPLEXITYDropDown.Value;
                link_cameras = app.LINKCAMERASCHECKBOX.Value;

                switch complexity_value
                    case 'YES'
                        if link_cameras
                            app.arrhythmia_tab(:,1,1) = 1;
                        else
                            app.arrhythmia_tab(app.active_camera,1,1) = 1;
                        end

                    case 'NO'
                        if link_cameras
                            app.arrhythmia_tab(:,1,1) = 0;
                        else
                            app.arrhythmia_tab(app.active_camera,1,1) = 0;
                        end
                end

            catch ME
                error_message = strcat('ERROR - Arrhythmia Complexity Value Changed(app, event) ', ME.message);
                update_console(app,error_message);
            end

        end

        % Value changed function: WAVEFRONTDropDown
        function WavefrontChanged(app, event)
            %WAVEFRONTCHANGED  Enable/disable wavefront-dynamics extraction for the active camera.
            try
                wf_value = app.WAVEFRONTDropDown.Value;
                link_cameras = app.LINKCAMERASCHECKBOX.Value;

                switch wf_value
                    case 'YES'
                        if link_cameras
                            app.arrhythmia_tab(:,2,1) = 1;
                            app.arrhythmia_tab(:,1,1) = 1;
                        else
                            app.arrhythmia_tab(app.active_camera,2,1) = 1;
                            app.arrhythmia_tab(app.active_camera,1,1) = 1;
                        end

                    case 'NO'
                        if link_cameras
                            app.arrhythmia_tab(:,2,1) = 0;
                        else
                            app.arrhythmia_tab(app.active_camera,2,1) = 0;
                        end
                end

            catch ME
                error_message = strcat('ERROR - Arrhythmia Wavefront Value Changed(app, event) ', ME.message);
                update_console(app,error_message);
            end

        end

        % Value changed function: PHASEDropDown
        function PhaseChanged(app, event)
            %PHASECHANGED  Enable/disable phase-singularity (rotor) extraction for the active camera.
            try
                phase_value = app.PHASEDropDown.Value;
                link_cameras = app.LINKCAMERASCHECKBOX.Value;

                switch phase_value
                    case 'YES'
                        if link_cameras
                            app.arrhythmia_tab(:,3,1) = 1;
                            app.arrhythmia_tab(:,1,1) = 1;
                        else
                            app.arrhythmia_tab(app.active_camera,3,1) = 1;
                            app.arrhythmia_tab(app.active_camera,1,1) = 1;
                        end

                    case 'NO'
                        if link_cameras
                            app.arrhythmia_tab(:,3,1) = 0;
                        else
                            app.arrhythmia_tab(app.active_camera,3,1) = 0;
                        end
                end

            catch ME
                error_message = strcat('ERROR - Arrhythmia Wavefront Value Changed(app, event) ', ME.message);
                update_console(app,error_message);
            end

        end

        % Button pushed function: INVERTSIGNALSButton
        function InvertSignalsButtonPushed(app, event)
            %INVERTSIGNALSBUTTONPUSHED  Manually invert the active camera's signals (CAM and average).
            try
                if ~isempty(app.data)
                    valid_action = update_active_tab_axes_data(app); %data loaded in the active tab
                    if valid_action
                        [cmos_data, cmos_data_avg] = get_cmos_data(app, app.active_camera);
                        inverted_cmos_data =  normalize_data(-1 .* cmos_data);
                        inverted_cmos_data_avg = normalize_data(-1 .* cmos_data_avg);
                        set_cmos_data(app, inverted_cmos_data, inverted_cmos_data_avg);
                        update_active_tab_axes_data(app);
                        update_plot_signal(app);
                    end
                else
                    message = strcat("Data not loaded");
                    update_console(app, message);
                end

            catch ME
                error_message = strcat('ERROR - InvertSignalsButtonPushed(app, event) ', ME.message);
                update_console(app,error_message);
            end
        end

        % Value changed function: ALTERNANSDropDown, ALTERNANSDropDown_2
        function ALTERNANSChanged(app, event)
            %ALTERNANSCHANGED  Enable/disable alternans extraction for the active camera.
            try
                alternans_value = app.ALTERNANSDropDown.Value;
                %alt_percentage_value = str2double(app.AltDropDown.Value);
                alt_type_value = app.ALTERNANSDropDown_2.Value;
                link_cameras = app.LINKCAMERASCHECKBOX.Value;

                if strcmp(alt_type_value, "VOLTAGE")
                    type = 0;
                else
                    type = 1;
                end


                switch alternans_value
                    case 'YES'
                        if link_cameras
                            app.arrhythmia_tab(:,4,1) = 1;
                            %app.arrhythmia_tab(:,4,2) = alt_percentage_value;
                            app.arrhythmia_tab(:,4,2) = type;
                        else
                            app.arrhythmia_tab(app.active_camera,4,1) = 1;
                            %app.arrhythmia_tab(app.active_camera,4,2) = alt_percentage_value;
                            app.arrhythmia_tab(app.active_camera,4,2) = type;
                        end

                    case 'NO'
                        if link_cameras
                            app.arrhythmia_tab(:,4,1) = 0;
                        else
                            app.arrhythmia_tab(app.active_camera,4,1) = 0;
                        end
                end

            catch ME
                error_message = strcat('ERROR - Alternans Value Changed(app, event) ', ME.message);
                update_console(app,error_message);
            end

        end

        % Button pushed function: SAVEDATAButton
        function SAVEDATAButtonPushed(app, event)
            %SAVEDATABUTTONPUSHED  Save the extracted features to a *-metrics .mat.
            %   Packs the results with compile_data and writes the 'metrics' struct to the
            %   output directory for downstream Signal Analysis / CV.
            try
                if ~isempty(app.data)
                    d = uiprogressdlg(app.UIFigure, 'Title', 'Extracting Features', 'Indeterminate','on');
                    d.Message = 'Saving the processed metrics data file';
                    dir_path = app.dir_name;
                    app.output_dir_name = app.EditField_2.Value;
                    if ~isempty(app.output_dir_name)
                        dir_path = app.output_dir_name;
                    end
                    [~, base_name, ~] = fileparts(app.mat_file_name);
                    base_name = regexprep(base_name, '-conditioned$', '');
                    if ~endsWith(base_name, '-metrics')
                        base_name = strcat(base_name, '-metrics');
                    end
                    metrics_file_name = strcat(base_name, '.mat');
                    file_path = strcat(dir_path, '/', metrics_file_name);
                    cmos_all_data = compile_data(app);

                    save(file_path,"cmos_all_data", '-v7.3');

                    d.Message = 'Saved the data file.. sucessfully';
                    update_console(app, d.Message);

                else
                    message = strcat("Data not loaded");
                    update_console(app,message);
                end
            catch ME
                error_message = strcat('ERROR - Save Data(app, event) ', ME.message);
                update_console(app,error_message);
            end
        end

        % Value changed function: RepolarizationDropDown
        function RepolarizationDropDownValueChanged(app, event)
            %REPOLARIZATIONDROPDOWNVALUECHANGED  Enable repolarization + store the selected % for the active camera.
            try
                rep_value = app.RepolarizationDropDown.Value;
                rep_percentage_value = str2double(app.RepDropDown.Value);
                link_cameras = app.LINKCAMERASCHECKBOX.Value;

                switch rep_value
                    case 'YES'
                        if link_cameras
                            app.voltage_tab(:,2,1) = 1;
                            app.voltage_tab(:,2,2) = rep_percentage_value;
                        else
                            app.voltage_tab(app.active_camera,2,1) = 1;
                            app.voltage_tab(app.active_camera,2,2) = rep_percentage_value;
                        end

                    case 'NO'
                        if link_cameras
                            app.voltage_tab(:,2,1) = 0;
                        else
                            app.voltage_tab(app.active_camera,2,1) = 0;
                        end
                end

            catch ME
                error_message = strcat('ERROR - Repolarization Value Changed(app, event) ', ME.message);
                update_console(app,error_message);
            end

        end

        % Value changed function: CALCIUMDTDropDown
        function CALCIUMDTDropDownValueChanged(app, event)
            %CALCIUMDTDROPDOWNVALUECHANGED  Enable calcium-duration + store the selected % for the active camera.
            try
                ctd_value = app.CALCIUMDTDropDown.Value;
                ctd_percentage_value = str2double(app.CaDTDropDown.Value);
                link_cameras = app.LINKCAMERASCHECKBOX.Value;

                switch ctd_value
                    case 'YES'
                        if link_cameras
                            app.calcium_tab(:,2,1) = 1;
                            app.calcium_tab(:,2,2) = ctd_percentage_value;
                        else
                            app.calcium_tab(app.active_camera,2,1) = 1;
                            app.calcium_tab(app.active_camera,2,2) = ctd_percentage_value;
                        end

                    case 'NO'
                        if link_cameras
                            app.calcium_tab(:,2,1) = 0;
                        else
                            app.calcium_tab(app.active_camera,2,1) = 0;
                        end
                end

            catch ME
                error_message = strcat('ERROR - Calcium Decay Time Changed(app, event) ', ME.message);
                update_console(app,error_message);
            end

        end

        % Value changed function: RepDropDown
        function RepDropDownValueChanged(app, event)
            %REPDROPDOWNVALUECHANGED  Update the stored repolarization % for the active camera.
            try
                rep_value = app.RepolarizationDropDown.Value;
                rep_percentage_value = str2double(app.RepDropDown.Value);
                link_cameras = app.LINKCAMERASCHECKBOX.Value;

                switch rep_value
                    case 'YES'
                        if link_cameras
                            app.voltage_tab(:,2,1) = 1;
                            app.voltage_tab(:,2,2) = rep_percentage_value;
                        else
                            app.voltage_tab(app.active_camera,2,1) = 1;
                            app.voltage_tab(app.active_camera,2,2) = rep_percentage_value;
                        end
                end

            catch ME
                error_message = strcat('ERROR - Repolarization Percentage Value Changed(app, event) ', ME.message);
                update_console(app,error_message);
            end

        end

        % Value changed function: APDDropDown
        function APDDropDownValueChanged(app, event)
            %APDDROPDOWNVALUECHANGED  Update the stored APD % for the active camera.
            try
                apd_value = app.DURATIONDropDown.Value;
                apd_percentage_value = str2double(app.APDDropDown.Value);
                link_cameras = app.LINKCAMERASCHECKBOX.Value;

                switch apd_value
                    case 'YES'
                        if link_cameras
                            app.voltage_tab(:,3,1) = 1;
                            app.voltage_tab(:,3,2) = apd_percentage_value;
                        else
                            app.voltage_tab(app.active_camera,3,1) = 1;
                            app.voltage_tab(app.active_camera,3,2) = apd_percentage_value;
                        end
                end

            catch ME
                error_message = strcat('ERROR - APD Percentage Value Changed(app, event) ', ME.message);
                update_console(app,error_message);
            end

        end

        % Value changed function: APRiseDropDown_1
        function APRiseDropDown_1ValueChanged(app, event)
            %APRISEDROPDOWN_1VALUECHANGED  Update the AP rise-time lower bound (%) for the active camera.
            try
                rt_value = app.RISETIMEDropDown.Value;
                rt_min_value = str2double(app.APRiseDropDown_1.Value);
                rt_max_value = str2double(app.APRiseDropDown_2.Value);
                link_cameras = app.LINKCAMERASCHECKBOX.Value;

                switch rt_value
                    case 'YES'
                        if link_cameras
                            app.voltage_tab(:,4,1) = 1;
                            app.voltage_tab(:,4,2) = rt_min_value;
                            app.voltage_tab(:,4,3) = rt_max_value;

                        else
                            app.voltage_tab(app.active_camera,4,1) = 1;
                            app.voltage_tab(app.active_camera,4,2) = rt_min_value;
                            app.voltage_tab(app.active_camera,4,3) = rt_max_value;
                        end
                end

            catch ME
                error_message = strcat('ERROR - AP Rise Time Min Range Value Changed(app, event) ', ME.message);
                update_console(app,error_message);
            end

        end

        % Value changed function: APRiseDropDown_2
        function APRiseDropDown_2ValueChanged(app, event)
            %APRISEDROPDOWN_2VALUECHANGED  Update the AP rise-time upper bound (%) for the active camera.
            try
                rt_value = app.RISETIMEDropDown.Value;
                rt_min_value = str2double(app.APRiseDropDown_1.Value);
                rt_max_value = str2double(app.APRiseDropDown_2.Value);
                link_cameras = app.LINKCAMERASCHECKBOX.Value;

                switch rt_value
                    case 'YES'
                        if link_cameras
                            app.voltage_tab(:,4,1) = 1;
                            app.voltage_tab(:,4,2) = rt_min_value;
                            app.voltage_tab(:,4,3) = rt_max_value;

                        else
                            app.voltage_tab(app.active_camera,4,1) = 1;
                            app.voltage_tab(app.active_camera,4,2) = rt_min_value;
                            app.voltage_tab(app.active_camera,4,3) = rt_max_value;
                        end
                end

            catch ME
                error_message = strcat('ERROR - AP Rise Time Max Range Value Changed(app, event) ', ME.message);
                update_console(app,error_message);
            end

        end

        % Value changed function: CaDTDropDown
        function CaDTDropDownValueChanged(app, event)
            %CADTDROPDOWNVALUECHANGED  Update the stored calcium-duration % for the active camera.
            try
                ctd_value = app.CALCIUMDTDropDown.Value;
                ctd_percentage_value = str2double(app.CaDTDropDown.Value);
                link_cameras = app.LINKCAMERASCHECKBOX.Value;

                switch ctd_value
                    case 'YES'
                        if link_cameras
                            app.calcium_tab(:,2,1) = 1;
                            app.calcium_tab(:,2,2) = ctd_percentage_value;
                        else
                            app.calcium_tab(app.active_camera,2,1) = 1;
                            app.calcium_tab(app.active_camera,2,2) = ctd_percentage_value;
                        end
                end

            catch ME
                error_message = strcat('ERROR - Calcium Decay Time Percentage Changed(app, event) ', ME.message);
                update_console(app,error_message);
            end

        end

        % Value changed function: CaDropDown
        function CaDropDownValueChanged(app, event)
            %CADROPDOWNVALUECHANGED  Update the stored calcium repolarization/decay % for the active camera.
            try
                ctd_value = app.CALCIUMDURATIONDropDown.Value;
                ctd_percentage_value = str2double(app.CaDropDown.Value);
                link_cameras = app.LINKCAMERASCHECKBOX.Value;

                switch ctd_value
                    case 'YES'
                        if link_cameras
                            app.calcium_tab(:,3,1) = 1;
                            app.calcium_tab(:,3,2) = ctd_percentage_value;

                        else
                            app.calcium_tab(app.active_camera,3,1) = 1;
                            app.calcium_tab(app.active_camera,3,2) = ctd_percentage_value;
                        end
                end

            catch ME
                error_message = strcat('ERROR - Voltage Calcium Transient Duration Percentage Value Changed(app, event) ', ME.message);
                update_console(app,error_message);
            end

        end

        % Value changed function: CaRiseDropDown_1
        function CaRiseDropDown_1ValueChanged(app, event)
            %CARISEDROPDOWN_1VALUECHANGED  Update the calcium rise-time lower bound (%) for the active camera.
            try
                crt_value = app.CALCIUMRISETIMEDropDown.Value;
                crt_min_value = str2double(app.CaRiseDropDown_1.Value);
                crt_max_value = str2double(app.CaRiseDropDown_2.Value);
                link_cameras = app.LINKCAMERASCHECKBOX.Value;

                switch crt_value
                    case 'YES'
                        if link_cameras
                            app.calcium_tab(:,4,1) = 1;
                            app.calcium_tab(:,4,2) = crt_min_value;
                            app.calcium_tab(:,4,3) = crt_max_value;

                        else
                            app.calcium_tab(app.active_camera,4,1) = 1;
                            app.calcium_tab(app.active_camera,4,2) = crt_min_value;
                            app.calcium_tab(app.active_camera,4,3) = crt_max_value;
                        end
                end

            catch ME
                error_message = strcat('ERROR - Calcium Rise Time Min Range Value Changed(app, event) ', ME.message);
                update_console(app,error_message);
            end

        end

        % Value changed function: CaRiseDropDown_2
        function CaRiseDropDown_2ValueChanged(app, event)
            %CARISEDROPDOWN_2VALUECHANGED  Update the calcium rise-time upper bound (%) for the active camera.
            try
                crt_value = app.CALCIUMRISETIMEDropDown.Value;
                crt_min_value = str2double(app.CaRiseDropDown_1.Value);
                crt_max_value = str2double(app.CaRiseDropDown_2.Value);
                link_cameras = app.LINKCAMERASCHECKBOX.Value;

                switch crt_value
                    case 'YES'
                        if link_cameras
                            app.calcium_tab(:,4,1) = 1;
                            app.calcium_tab(:,4,2) = crt_min_value;
                            app.calcium_tab(:,4,3) = crt_max_value;

                        else
                            app.calcium_tab(app.active_camera,4,1) = 1;
                            app.calcium_tab(app.active_camera,4,2) = crt_min_value;
                            app.calcium_tab(app.active_camera,4,3) = crt_max_value;
                        end
                end

            catch ME
                error_message = strcat('ERROR - Calcium Rise Time Max Range Value Changed(app, event) ', ME.message);
                update_console(app,error_message);
            end

        end

        % Button pushed function: ALIGNIMAGEButton
        function ALIGNIMAGEButtonPushed(app, event)
            %ALIGNIMAGEBUTTONPUSHED  Rigidly register the active camera to the reference camera.
            %   Intensity-based rigid registration (alignImages) of CAM1<->CAM2; applies the
            %   transform to the active image, stack, ensemble and SNR so multi-camera data
            %   is spatially aligned. Snapshots the original first (for RESET).
            try
                if ~isempty(app.data)
                    valid_action = update_active_tab_axes_data(app); %data loaded in the active tab
                    if valid_action
                        if isempty(app.image_alignment{app.active_camera,1})
                            store_image_data(app); %in case we need to reset the image
                        end
                        if app.active_camera == 1
                            fixed_image = app.data.CAM2_image;
                            moving_image = app.data.CAM1_image;
                        else
                            fixed_image = app.data.CAM1_image;
                            moving_image = app.data.CAM2_image;
                        end
                        [aligned_image, tform] = alignImages(moving_image, fixed_image, 'Method','intensity', 'Transform','rigid', 'ShowPlot', false);

                        app.active_image = aligned_image;
                        app.active_data = transform_data(app, app.active_data, tform);
                        app.active_ensemble_data = transform_data(app, app.active_ensemble_data, tform);
                        app.active_snr = transform_data(app, app.active_snr, tform);

                        update_image_data(app); %update active image and data
                        delete(app.active_axes.Children(:,1));
                        imagesc(app.active_axes, real2rgb(app.active_image,'gray'));
                        colorbar(app.active_axes, 'off');

                        message = strcat("Image registered and data aligned successfully");
                        update_console(app,message);
                    end
                else
                    message = strcat("Data not loaded");
                    update_console(app,message);
                end

            catch ME
                error_message = strcat('ERROR - ALIGNIMAGEButtonPushed(app, event) ', ME.message);
                update_console(app,error_message);
            end
        end

        % Selection change function: TabGroup3
        function FEATURESelectionChanged(app, event)
            %FEATURESELECTIONCHANGED  React to switching the feature tab (Vm/Ca/Arrhythmia/Mask).
            %   Re-syncs the dropdowns to the active camera (update_feature_selection).
            try
                if ~isempty(app.data)

                    update_feature_selection(app);%new active tab

                end

            catch ME
                error_message = strcat('ERROR - CAMERASelectionChanged(app, event) ', ME.message);
                update_console(app,error_message);
            end

        end

        % Value changed function: TauDropDown
        function TauDropDownValueChanged(app, event)
            %TAUDROPDOWNVALUECHANGED  Update the tau-fit start amplitude (%) for the active camera.
            try
                tau_value = app.TDropDown.Value;
                tau_max_value = str2double(app.TauDropDown.Value);
                tau_min_value = str2double(app.TauDropDown_2.Value);
                link_cameras = app.LINKCAMERASCHECKBOX.Value;

                switch tau_value
                    case 'YES'
                        if link_cameras
                            app.calcium_tab(:,5,1) = 1;
                            app.calcium_tab(:,5,2) = tau_max_value;
                            app.calcium_tab(:,5,3) = tau_min_value;

                        else
                            app.calcium_tab(app.active_camera,5,1) = 1;
                            app.calcium_tab(app.active_camera,5,2) = tau_max_value;
                            app.calcium_tab(app.active_camera,5,3) = tau_min_value;
                        end
                end

            catch ME
                error_message = strcat('ERROR - Tau Max Range Value Changed(app, event) ', ME.message);
                update_console(app,error_message);
            end

        end

        % Value changed function: TauDropDown_2
        function TauDropDown_2ValueChanged(app, event)
            %TAUDROPDOWN_2VALUECHANGED  Update the tau-fit end amplitude (%) for the active camera.
            try
                tau_value = app.TDropDown.Value;
                tau_max_value = str2double(app.TauDropDown.Value);
                tau_min_value = str2double(app.TauDropDown_2.Value);
                link_cameras = app.LINKCAMERASCHECKBOX.Value;

                switch tau_value
                    case 'YES'
                        if link_cameras
                            app.calcium_tab(:,5,1) = 1;
                            app.calcium_tab(:,5,2) = tau_max_value;
                            app.calcium_tab(:,5,3) = tau_min_value;

                        else
                            app.calcium_tab(app.active_camera,5,1) = 1;
                            app.calcium_tab(app.active_camera,5,2) = tau_max_value;
                            app.calcium_tab(app.active_camera,5,3) = tau_min_value;
                        end
                end

            catch ME
                error_message = strcat('ERROR - Tau Min Range Value Changed(app, event) ', ME.message);
                update_console(app,error_message);
            end

        end

        % Value changed function: CONDUCTIONVELOCITYDropDown
        function CONDUCTIONVELOCITYDropDownValueChanged(app, event)
            %CONDUCTIONVELOCITYDROPDOWNVALUECHANGED  Enable/disable CV extraction for the active camera.
            try
                cv_value = app.CONDUCTIONVELOCITYDropDown.Value;
                fov_value = app.FOVmmEditField.Value;
                link_cameras = app.LINKCAMERASCHECKBOX.Value;
                switch cv_value
                    case 'YES'
                        if link_cameras
                            app.voltage_tab(:,5,1) = 1;
                            app.voltage_tab(:,5,2) = str2double(fov_value);

                        else
                            app.voltage_tab(app.active_camera,5,1) = 1;
                            app.voltage_tab(app.active_camera,5,2) = str2double(fov_value);
                        end

                    case 'NO'
                        if link_cameras
                            app.voltage_tab(:,5,1) = 0;
                        else
                            app.voltage_tab(app.active_camera,5,1) = 0;
                        end
                end

            catch ME
                error_message = strcat('ERROR - ConductionVelocity Value Changed(app, event) ', ME.message);
                update_console(app,error_message);
            end

        end

        % Button pushed function: BATCHPROCESSINGButton
        function BATCHPROCESSINGButtonPushed(app, event)
            %BATCHPROCESSINGBUTTONPUSHED  Run feature extraction across every file in the directory.
            %   For each *.mat in the list: load it, rebuild the combo masks, run
            %   EXTRACT FEATURES, and SAVE — applying the current feature selections to the
            %   whole batch unattended, with per-file progress in the dialog/console.
            try
                app.batch_mode = true;
                d = uiprogressdlg(app.UIFigure, 'Title', 'Beginning Batch Processing', 'Indeterminate','on');
                file_list = app.ListBox_2.Value;
                file_separator = filesep;
                if ~isempty(file_list)
                    num_files = size(file_list,2);
                    for i= 1:num_files
                        note = strcat(int2str(i), ' ', 'of', ' ', int2str(num_files), ')', ' ', 'Processing', ' ', file_list{1,i});
                        d.Message = note;
                        app.mat_file_name = file_list{1,i};
                        dir_path = strcat(app.dir_name, file_separator, app.mat_file_name);
                        try
                            file_data = load(dir_path);
                            app.data = file_data.cmos_all_data;
                            [ok, msgs] = schema_gate(app.data, "conditioned", app.mat_file_name);
                            for k = 1:numel(msgs), update_console(app, msgs{k}); end
                            if ~ok
                                update_console(app, sprintf('  skipping %s (schema FAIL)', app.mat_file_name));
                                continue;
                            end
                        catch LE
                            update_console(app, sprintf('  skipping %s (%s)', app.mat_file_name, LE.message));
                            continue;
                        end

                        app.time = 1:size(app.data.CAM1,3);

                        app.combo_masks(:) = {[]};
                        app.data_masks(:) = {[]};

                        extract_combo_masks(app, d);

                        EXTRACTFEATURESButtonPushed(app, event);

                        SAVEDATAButtonPushed(app,event)


                        d.Message = sprintf('Processed %d of %d: %s', i, num_files, app.mat_file_name);
                        update_console(app, d.Message);

                    end
                    close(d);
                    update_console(app, 'BATCH PROCESSING COMPLETED SUCCESSFULLY.');
                else
                    warning_message = 'No file(s) selected for batch processing. Please select at least one file.  ';
                    update_console(app,warning_message);
                    close(d);
                end
                app.batch_mode = false;

            catch ME
                app.batch_mode = false;
                close(d);
                error_message = strcat('ERROR - BatchProcessing app, event) ', ME.message);
                update_console(app,error_message);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Cadence - Cardiac Signal Processing and Analysis Software';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FEATUREEXTRACTIONTab = uitab(app.TabGroup);
            app.FEATUREEXTRACTIONTab.Title = 'FEATURE EXTRACTION';
            app.STEP1SELECTFILESFORFEATUREEXTRACTIONPanel = uipanel(app.FEATUREEXTRACTIONTab);
            app.STEP1SELECTFILESFORFEATUREEXTRACTIONPanel.Title = 'STEP 1: SELECT FILE(S) FOR FEATURE EXTRACTION';
            app.SELECTDIRECTORYButton = uibutton(app.STEP1SELECTFILESFORFEATUREEXTRACTIONPanel, 'push');
            app.SELECTDIRECTORYButton.ButtonPushedFcn = createCallbackFcn(app, @SELECTDIRECTORYButtonPushed, true);
            app.SELECTDIRECTORYButton.Text = {'SELECT DIRECTORY'; ''};
            app.REFRESHDIRECTORYButton = uibutton(app.STEP1SELECTFILESFORFEATUREEXTRACTIONPanel, 'push');
            app.REFRESHDIRECTORYButton.ButtonPushedFcn = createCallbackFcn(app, @REFRESHDIRECTORYButtonPushed, true);
            app.REFRESHDIRECTORYButton.Text = {'REFRESH DIRECTORY'; ''};
            app.ListBox_2Label = uilabel(app.STEP1SELECTFILESFORFEATUREEXTRACTIONPanel);
            app.ListBox_2Label.Text = 'List Box';
            app.ListBox_2 = uilistbox(app.STEP1SELECTFILESFORFEATUREEXTRACTIONPanel);
            app.ListBox_2.Items = {};
            app.ListBox_2.Multiselect = 'on';
            app.ListBox_2.Value = {};
            app.LOADButton = uibutton(app.STEP1SELECTFILESFORFEATUREEXTRACTIONPanel, 'push');
            app.LOADButton.ButtonPushedFcn = createCallbackFcn(app, @LOADButtonPushed, true);
            app.LOADButton.Enable = 'off';
            app.LOADButton.Text = 'LOAD';
            app.TextArea_2Label = uilabel(app.FEATUREEXTRACTIONTab);
            app.TextArea_2Label.Text = 'Text Area';
            app.TextArea_2 = uitextarea(app.FEATUREEXTRACTIONTab);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Placeholder = ' ';
            app.TextArea_2.Value = {'Welcome to CADENCE 2026!! '; ''; 'STEP 1) Please select the appropriate input directory containing the Matlab data files (*.mat). If you haven''t already, please use the Signal Conditioning Module to process your data files before extracting features. Next, load a data file for feature extraction. '; ''; 'STEP 2) Use the mouse to click (right click) on the image (any arbitrary location) to display a representative signal. You can drag the mouse (while holding the right click down) around to find the appropriate signal as needed. Next, click on the  select window button to draw a rectangular signal window on the displayed signal. Ideally, it should capture one cycle length (e.g., one action potential).  Use the context menu to delete the window, if so inclined. Once the signal window is grabbed, select the features to extract from the drop down boxes. Alternately, you can choose to use Ensemble Averaging (check the box), in which case, the signal window is not needed. In case of multiple cameras, you can select Link signals to overlay representative signals (e.g., Vm and Ca). You can also select Link Feature settings to use the same feature selection configuration for all cameras (e.g., panoramic imaging). Pacing stimulus, if available can be displayed by selecting the Show Pacing Signal checkbox. If the signals appear inverted, you can click on the invert signals button to revert signals for the given Camera.'; ''; 'STEP 3) Select the output directory as needed and execute the script for feature extraction (click the blue button). The script will extract features and generate figures of signals and maps (e.g., activation map). You can also extracting features from multiple files by using the Batch Processing feature. In this case, first select multiple files, then choose the appropriate features for each camera (as needed), and then click on the Batch Processing button.  '; ''; 'STEP 4) Save the information (click the red button) in the .mat files. Batch Processing automatically saves the extracted features in .Mat files. In addition, the cardiac maps for each feature are also saved in a PDF file. '; ''; ''};
            app.STEP2SELECTWINDOWFROMREPRESENTATIVESIGNALPanel = uipanel(app.FEATUREEXTRACTIONTab);
            app.STEP2SELECTWINDOWFROMREPRESENTATIVESIGNALPanel.Title = 'STEP 2: SELECT FEATURES TO EXTRACT FROM A REPRESENTATIVE SIGNAL. DO NOT FORGET TO SELECT A SIGNAL WINDOW.';
            app.Panel = uipanel(app.STEP2SELECTWINDOWFROMREPRESENTATIVESIGNALPanel);
            app.UIAxes_sig = uiaxes(app.Panel);
            xlabel(app.UIAxes_sig, 'Time (s)')
            app.TabGroup2 = uitabgroup(app.STEP2SELECTWINDOWFROMREPRESENTATIVESIGNALPanel);
            app.TabGroup2.SelectionChangedFcn = createCallbackFcn(app, @CAMERASelectionChanged, true);
            app.CAM_1Tab = uitab(app.TabGroup2);
            app.CAM_1Tab.Title = 'CAM_1';
            app.UIAxes_cam1 = uiaxes(app.CAM_1Tab);
            app.UIAxes_cam1.XLim = [1 100];
            app.UIAxes_cam1.YLim = [1 100];
            app.UIAxes_cam1.YDir = 'reverse';
            app.UIAxes_cam1.XTick = [];
            app.UIAxes_cam1.XTickLabel = '';
            app.UIAxes_cam1.YTick = [];
            app.UIAxes_cam1.YTickLabel = '';
            app.UIAxes_cam1.ZTick = [];
            app.CAM_2Tab = uitab(app.TabGroup2);
            app.CAM_2Tab.Title = 'CAM_2';
            app.UIAxes_cam2 = uiaxes(app.CAM_2Tab);
            app.UIAxes_cam2.XLim = [1 100];
            app.UIAxes_cam2.YLim = [1 100];
            app.UIAxes_cam2.YDir = 'reverse';
            app.UIAxes_cam2.XTick = [];
            app.UIAxes_cam2.XTickLabel = '';
            app.UIAxes_cam2.YTick = [];
            app.UIAxes_cam2.YTickLabel = '';
            app.UIAxes_cam2.ZTick = [];
            app.CAM_3Tab = uitab(app.TabGroup2);
            app.CAM_3Tab.Title = 'CAM_3';
            app.UIAxes_cam3 = uiaxes(app.CAM_3Tab);
            app.UIAxes_cam3.XLim = [1 100];
            app.UIAxes_cam3.YLim = [1 100];
            app.UIAxes_cam3.YDir = 'reverse';
            app.UIAxes_cam3.XTick = [];
            app.UIAxes_cam3.XTickLabel = '';
            app.UIAxes_cam3.YTick = [];
            app.UIAxes_cam3.YTickLabel = '';
            app.UIAxes_cam3.ZTick = [];
            app.CAM_4Tab = uitab(app.TabGroup2);
            app.CAM_4Tab.Title = 'CAM_4';
            app.UIAxes_cam4 = uiaxes(app.CAM_4Tab);
            app.UIAxes_cam4.XLim = [1 100];
            app.UIAxes_cam4.YLim = [1 100];
            app.UIAxes_cam4.YDir = 'reverse';
            app.UIAxes_cam4.XTick = [];
            app.UIAxes_cam4.XTickLabel = '';
            app.UIAxes_cam4.YTick = [];
            app.UIAxes_cam4.YTickLabel = '';
            app.UIAxes_cam4.ZTick = [];
            app.SELECTWINDOWButton = uibutton(app.STEP2SELECTWINDOWFROMREPRESENTATIVESIGNALPanel, 'push');
            app.SELECTWINDOWButton.ButtonPushedFcn = createCallbackFcn(app, @SELECTWINDOWButtonPushed, true);
            app.SELECTWINDOWButton.Enable = 'off';
            app.SELECTWINDOWButton.Text = {'SELECT WINDOW'; ''};
            app.LINKCAMERASCHECKBOX = uicheckbox(app.STEP2SELECTWINDOWFROMREPRESENTATIVESIGNALPanel);
            app.LINKCAMERASCHECKBOX.Text = 'Link Feature Settings';
            app.TabGroup3 = uitabgroup(app.STEP2SELECTWINDOWFROMREPRESENTATIVESIGNALPanel);
            app.TabGroup3.SelectionChangedFcn = createCallbackFcn(app, @FEATURESelectionChanged, true);
            app.ActionPotentialsVmTab = uitab(app.TabGroup3);
            app.ActionPotentialsVmTab.Title = 'Action Potentials (Vm)';
            app.AACTIVATIONTIMEDropDownLabel = uilabel(app.ActionPotentialsVmTab);
            app.AACTIVATIONTIMEDropDownLabel.Text = 'A) ACTIVATION TIME';
            app.ACTIVATIONTIMEDropDown = uidropdown(app.ActionPotentialsVmTab);
            app.ACTIVATIONTIMEDropDown.Items = {'YES', 'NO'};
            app.ACTIVATIONTIMEDropDown.ValueChangedFcn = createCallbackFcn(app, @ACTChanged, true);
            app.ACTIVATIONTIMEDropDown.Value = 'YES';
            app.NOTEActivationTimereferstothemaximalslopeoftheupstrokeLabel = uilabel(app.ActionPotentialsVmTab);
            app.NOTEActivationTimereferstothemaximalslopeoftheupstrokeLabel.Text = 'NOTE: Activation Time refers to the maximal slope of the upstroke. Select a beat.';
            app.CACTIONPOTENTIALDURATIONDropDownLabel = uilabel(app.ActionPotentialsVmTab);
            app.CACTIONPOTENTIALDURATIONDropDownLabel.Text = 'C) ACTION POTENTIAL DURATION';
            app.DURATIONDropDown = uidropdown(app.ActionPotentialsVmTab);
            app.DURATIONDropDown.Items = {'YES', 'NO'};
            app.DURATIONDropDown.ValueChangedFcn = createCallbackFcn(app, @APDChanged, true);
            app.DURATIONDropDown.Value = 'YES';
            app.Label_11 = uilabel(app.ActionPotentialsVmTab);
            app.Label_11.Text = 'NOTE: APD refers to the time interval between activation and repolarization (e.g., 80%)';
            app.ERISETIMEDropDownLabel = uilabel(app.ActionPotentialsVmTab);
            app.ERISETIMEDropDownLabel.Text = 'D) ACTION POTENTIAL RISE TIME';
            app.RISETIMEDropDown = uidropdown(app.ActionPotentialsVmTab);
            app.RISETIMEDropDown.Items = {'YES', 'NO'};
            app.RISETIMEDropDown.ValueChangedFcn = createCallbackFcn(app, @RiseTimeChanged, true);
            app.RISETIMEDropDown.Value = 'YES';
            app.Label_12 = uilabel(app.ActionPotentialsVmTab);
            app.Label_12.Text = 'NOTE: Rise Time refers to time period (e.g., 20% to 90%) of the action potential upstroke';
            app.APDDropDown = uidropdown(app.ActionPotentialsVmTab);
            app.APDDropDown.Items = {'10', '20', '30', '40', '50', '60', '70', '80', '90', '100'};
            app.APDDropDown.ValueChangedFcn = createCallbackFcn(app, @APDDropDownValueChanged, true);
            app.APDDropDown.Value = '80';
            app.APRiseDropDown_1 = uidropdown(app.ActionPotentialsVmTab);
            app.APRiseDropDown_1.Items = {'10', '20', '30', '40'};
            app.APRiseDropDown_1.ValueChangedFcn = createCallbackFcn(app, @APRiseDropDown_1ValueChanged, true);
            app.APRiseDropDown_1.Value = '20';
            app.APRiseDropDown_2 = uidropdown(app.ActionPotentialsVmTab);
            app.APRiseDropDown_2.Items = {'70', '80', '90', '100'};
            app.APRiseDropDown_2.ValueChangedFcn = createCallbackFcn(app, @APRiseDropDown_2ValueChanged, true);
            app.APRiseDropDown_2.Value = '90';
            app.CACTIONPOTENTIALDURATIONDropDownLabel_5 = uilabel(app.ActionPotentialsVmTab);
            app.CACTIONPOTENTIALDURATIONDropDownLabel_5.Text = 'B) REPOLARIZATION TIME';
            app.RepolarizationDropDown = uidropdown(app.ActionPotentialsVmTab);
            app.RepolarizationDropDown.Items = {'YES', 'NO'};
            app.RepolarizationDropDown.ValueChangedFcn = createCallbackFcn(app, @RepolarizationDropDownValueChanged, true);
            app.RepolarizationDropDown.Value = 'YES';
            app.Label_25 = uilabel(app.ActionPotentialsVmTab);
            app.Label_25.Text = 'NOTE: Repolarization time refers to recovery time to return to baseline (e.g., 80%)';
            app.RepDropDown = uidropdown(app.ActionPotentialsVmTab);
            app.RepDropDown.Items = {'10', '20', '30', '40', '50', '60', '70', '80', '90', '100'};
            app.RepDropDown.ValueChangedFcn = createCallbackFcn(app, @RepDropDownValueChanged, true);
            app.RepDropDown.Value = '80';
            app.ERISETIMEDropDownLabel_7 = uilabel(app.ActionPotentialsVmTab);
            app.ERISETIMEDropDownLabel_7.Text = 'E) LOCAL CONDUCTION VELOCITY';
            app.CONDUCTIONVELOCITYDropDown = uidropdown(app.ActionPotentialsVmTab);
            app.CONDUCTIONVELOCITYDropDown.Items = {'YES', 'NO'};
            app.CONDUCTIONVELOCITYDropDown.ValueChangedFcn = createCallbackFcn(app, @CONDUCTIONVELOCITYDropDownValueChanged, true);
            app.CONDUCTIONVELOCITYDropDown.Value = 'YES';
            app.Label_27 = uilabel(app.ActionPotentialsVmTab);
            app.Label_27.Text = 'NOTE: Uses activation time and field of view (FOV) to calculate local conduction velocity';
            app.FOVmmEditField_2Label = uilabel(app.ActionPotentialsVmTab);
            app.FOVmmEditField_2Label.Text = 'FOV (mm)';
            app.FOVmmEditField = uieditfield(app.ActionPotentialsVmTab, 'numeric');
            app.FOVmmEditField.Limits = [10 Inf];
            app.FOVmmEditField.Value = 20;
            app.CalciumTransientsCaTab = uitab(app.TabGroup3);
            app.CalciumTransientsCaTab.Title = 'Calcium Transients (Ca)';
            app.AACTIVATIONTIMEDropDownLabel_2 = uilabel(app.CalciumTransientsCaTab);
            app.AACTIVATIONTIMEDropDownLabel_2.Text = 'A) VOLTAGE - CALCIUM DELAY';
            app.DELAYDropDown = uidropdown(app.CalciumTransientsCaTab);
            app.DELAYDropDown.Items = {'YES', 'NO'};
            app.DELAYDropDown.ValueChangedFcn = createCallbackFcn(app, @VCDelayChanged, true);
            app.DELAYDropDown.Value = 'NO';
            app.NOTEActivationTimereferstothemaximalslopeoftheupstrokeLabel_2 = uilabel(app.CalciumTransientsCaTab);
            app.NOTEActivationTimereferstothemaximalslopeoftheupstrokeLabel_2.Text = 'NOTE: Vm-Ca delay refers to time interval between Vm activation minus Ca activation';
            app.CACTIONPOTENTIALDURATIONDropDownLabel_2 = uilabel(app.CalciumTransientsCaTab);
            app.CACTIONPOTENTIALDURATIONDropDownLabel_2.Text = 'C) CALCIUM TRANSIENT DURATION';
            app.CALCIUMDURATIONDropDown = uidropdown(app.CalciumTransientsCaTab);
            app.CALCIUMDURATIONDropDown.Items = {'YES', 'NO'};
            app.CALCIUMDURATIONDropDown.ValueChangedFcn = createCallbackFcn(app, @CTDChanged, true);
            app.CALCIUMDURATIONDropDown.Value = 'NO';
            app.Label_14 = uilabel(app.CalciumTransientsCaTab);
            app.Label_14.Text = 'NOTE: CaTD refers to the time interval between activation and calcium transient decay';
            app.ERISETIMEDropDownLabel_2 = uilabel(app.CalciumTransientsCaTab);
            app.ERISETIMEDropDownLabel_2.Text = 'D) CALCIUM RISE TIME';
            app.CALCIUMRISETIMEDropDown = uidropdown(app.CalciumTransientsCaTab);
            app.CALCIUMRISETIMEDropDown.Items = {'YES', 'NO'};
            app.CALCIUMRISETIMEDropDown.ValueChangedFcn = createCallbackFcn(app, @CRiseTimeChanged, true);
            app.CALCIUMRISETIMEDropDown.Value = 'NO';
            app.Label_15 = uilabel(app.CalciumTransientsCaTab);
            app.Label_15.Text = 'NOTE: Rise Time refers to time period (e.g., 20% to 90%) of the calcium transient upstroke';
            app.FCONDUCTIONVELOCITYDropDownLabel_2 = uilabel(app.CalciumTransientsCaTab);
            app.FCONDUCTIONVELOCITYDropDownLabel_2.Text = 'E) CALCIUM DECAY TIME CONSTANT';
            app.TDropDown = uidropdown(app.CalciumTransientsCaTab);
            app.TDropDown.Items = {'YES', 'NO'};
            app.TDropDown.ValueChangedFcn = createCallbackFcn(app, @TauValueChanged, true);
            app.TDropDown.Value = 'NO';
            app.Label_16 = uilabel(app.CalciumTransientsCaTab);
            app.Label_16.Text = 'NOTE: Tau is calculated by fitting an exponential to phase (e.g., 90% to 20%) of Ca decay';
            app.CaDropDown = uidropdown(app.CalciumTransientsCaTab);
            app.CaDropDown.Items = {'10', '20', '30', '40', '50', '60', '70', '80', '90', '100'};
            app.CaDropDown.ValueChangedFcn = createCallbackFcn(app, @CaDropDownValueChanged, true);
            app.CaDropDown.Value = '80';
            app.CaRiseDropDown_1 = uidropdown(app.CalciumTransientsCaTab);
            app.CaRiseDropDown_1.Items = {'10', '20', '30', '40'};
            app.CaRiseDropDown_1.ValueChangedFcn = createCallbackFcn(app, @CaRiseDropDown_1ValueChanged, true);
            app.CaRiseDropDown_1.Value = '20';
            app.CaRiseDropDown_2 = uidropdown(app.CalciumTransientsCaTab);
            app.CaRiseDropDown_2.Items = {'70', '80', '90', '100'};
            app.CaRiseDropDown_2.ValueChangedFcn = createCallbackFcn(app, @CaRiseDropDown_2ValueChanged, true);
            app.CaRiseDropDown_2.Value = '90';
            app.CACTIONPOTENTIALDURATIONDropDownLabel_6 = uilabel(app.CalciumTransientsCaTab);
            app.CACTIONPOTENTIALDURATIONDropDownLabel_6.Text = 'B) CALCIUM DECAY TIME';
            app.CALCIUMDTDropDown = uidropdown(app.CalciumTransientsCaTab);
            app.CALCIUMDTDropDown.Items = {'YES', 'NO'};
            app.CALCIUMDTDropDown.ValueChangedFcn = createCallbackFcn(app, @CALCIUMDTDropDownValueChanged, true);
            app.CALCIUMDTDropDown.Value = 'NO';
            app.Label_26 = uilabel(app.CalciumTransientsCaTab);
            app.Label_26.Text = 'NOTE: CaTD refers to the time interval between activation and calcium transient decay';
            app.CaDTDropDown = uidropdown(app.CalciumTransientsCaTab);
            app.CaDTDropDown.Items = {'10', '20', '30', '40', '50', '60', '70', '80', '90', '100'};
            app.CaDTDropDown.ValueChangedFcn = createCallbackFcn(app, @CaDTDropDownValueChanged, true);
            app.CaDTDropDown.Value = '80';
            app.TauDropDown = uidropdown(app.CalciumTransientsCaTab);
            app.TauDropDown.Items = {'90', '80', '70'};
            app.TauDropDown.ValueChangedFcn = createCallbackFcn(app, @TauDropDownValueChanged, true);
            app.TauDropDown.Value = '90';
            app.TauDropDown_2 = uidropdown(app.CalciumTransientsCaTab);
            app.TauDropDown_2.Items = {'20', '10'};
            app.TauDropDown_2.ValueChangedFcn = createCallbackFcn(app, @TauDropDown_2ValueChanged, true);
            app.TauDropDown_2.Value = '20';
            app.ArrhythmiaDynamicsTab = uitab(app.TabGroup3);
            app.ArrhythmiaDynamicsTab.Title = 'Arrhythmia Dynamics';
            app.AACTIVATIONTIMEDropDownLabel_3 = uilabel(app.ArrhythmiaDynamicsTab);
            app.AACTIVATIONTIMEDropDownLabel_3.Text = 'B) ARRHYTHMIA COMPLEXITY';
            app.COMPLEXITYDropDown = uidropdown(app.ArrhythmiaDynamicsTab);
            app.COMPLEXITYDropDown.Items = {'YES', 'NO'};
            app.COMPLEXITYDropDown.ValueChangedFcn = createCallbackFcn(app, @ComplexityChanged, true);
            app.COMPLEXITYDropDown.Value = 'NO';
            app.NOTEActivationTimereferstothemaximalslopeoftheupstrokeLabel_3 = uilabel(app.ArrhythmiaDynamicsTab);
            app.NOTEActivationTimereferstothemaximalslopeoftheupstrokeLabel_3.Text = 'NOTE: Calculate dominant frequency, regularity index, and organization index metrics';
            app.CACTIONPOTENTIALDURATIONDropDownLabel_3 = uilabel(app.ArrhythmiaDynamicsTab);
            app.CACTIONPOTENTIALDURATIONDropDownLabel_3.Text = 'C) ARRHYTHMIA WAVEFRONT DYNAMICS';
            app.WAVEFRONTDropDown = uidropdown(app.ArrhythmiaDynamicsTab);
            app.WAVEFRONTDropDown.Items = {'YES', 'NO'};
            app.WAVEFRONTDropDown.ValueChangedFcn = createCallbackFcn(app, @WavefrontChanged, true);
            app.WAVEFRONTDropDown.Value = 'NO';
            app.Label_17 = uilabel(app.ArrhythmiaDynamicsTab);
            app.Label_17.Text = 'NOTE: Calculate wavefront counts etc..';
            app.ERISETIMEDropDownLabel_3 = uilabel(app.ArrhythmiaDynamicsTab);
            app.ERISETIMEDropDownLabel_3.Text = 'D) ARRHYTHMIA PHASE SINGULARITY DYNAMICS';
            app.PHASEDropDown = uidropdown(app.ArrhythmiaDynamicsTab);
            app.PHASEDropDown.Items = {'YES', 'NO'};
            app.PHASEDropDown.ValueChangedFcn = createCallbackFcn(app, @PhaseChanged, true);
            app.PHASEDropDown.Value = 'NO';
            app.Label_18 = uilabel(app.ArrhythmiaDynamicsTab);
            app.Label_18.Text = 'NOTE: Calculate phase singularity counts, duration etc.';
            app.FCONDUCTIONVELOCITYDropDownLabel_3 = uilabel(app.ArrhythmiaDynamicsTab);
            app.FCONDUCTIONVELOCITYDropDownLabel_3.Text = 'A) ALTERNANS';
            app.ALTERNANSDropDown = uidropdown(app.ArrhythmiaDynamicsTab);
            app.ALTERNANSDropDown.Items = {'YES', 'NO'};
            app.ALTERNANSDropDown.ValueChangedFcn = createCallbackFcn(app, @ALTERNANSChanged, true);
            app.ALTERNANSDropDown.Value = 'NO';
            app.Label_24 = uilabel(app.ArrhythmiaDynamicsTab);
            app.Label_24.Text = 'NOTE: Please select two consecutive action potentials/calcium transients for analysis';
            app.ALTERNANSDropDown_2 = uidropdown(app.ArrhythmiaDynamicsTab);
            app.ALTERNANSDropDown_2.Items = {'VOLTAGE', 'CALCIUM'};
            app.ALTERNANSDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ALTERNANSChanged, true);
            app.ALTERNANSDropDown_2.Value = 'VOLTAGE';
            app.ImageAlignmentTab = uitab(app.TabGroup3);
            app.ImageAlignmentTab.Title = 'Image Alignment';
            app.AACTIVATIONTIMEDropDownLabel_4 = uilabel(app.ImageAlignmentTab);
            app.AACTIVATIONTIMEDropDownLabel_4.Text = 'A) ROTATE IMAGE BY 90 DEGREES';
            app.NOTEActivationTimereferstothemaximalslopeoftheupstrokeLabel_4 = uilabel(app.ImageAlignmentTab);
            app.NOTEActivationTimereferstothemaximalslopeoftheupstrokeLabel_4.Text = 'NOTE: Rotate active image in clockwise (+90) or counter clockwise (e.g., -90) direction';
            app.CACTIONPOTENTIALDURATIONDropDownLabel_4 = uilabel(app.ImageAlignmentTab);
            app.CACTIONPOTENTIALDURATIONDropDownLabel_4.Text = 'B) FLIP IMAGE FROM LEFT RIGHT OR UPSIDE DOWN';
            app.Label_19 = uilabel(app.ImageAlignmentTab);
            app.Label_19.Text = 'NOTE: Flip active image from left to right. This will also flip the data matrix';
            app.ROTATECWButton = uibutton(app.ImageAlignmentTab, 'push');
            app.ROTATECWButton.ButtonPushedFcn = createCallbackFcn(app, @ROTATECWButtonPushed, true);
            app.ROTATECWButton.Text = 'ROTATE CW';
            app.FLIPLRButton = uibutton(app.ImageAlignmentTab, 'push');
            app.FLIPLRButton.ButtonPushedFcn = createCallbackFcn(app, @FLIPLRButtonPushed, true);
            app.FLIPLRButton.Text = 'FLIP-LR';
            app.FLIPUDButton = uibutton(app.ImageAlignmentTab, 'push');
            app.FLIPUDButton.ButtonPushedFcn = createCallbackFcn(app, @FLIPUDButtonPushed, true);
            app.FLIPUDButton.Text = 'FLIP-UD';
            app.RESETIMAGEButton = uibutton(app.ImageAlignmentTab, 'push');
            app.RESETIMAGEButton.ButtonPushedFcn = createCallbackFcn(app, @RESETIMAGEButtonPushed, true);
            app.RESETIMAGEButton.Text = 'RESET IMAGE';
            app.ROTATECCWButton = uibutton(app.ImageAlignmentTab, 'push');
            app.ROTATECCWButton.ButtonPushedFcn = createCallbackFcn(app, @ROTATECCWButtonPushed, true);
            app.ROTATECCWButton.Text = 'ROTATE CCW';
            app.ALIGNIMAGEButton = uibutton(app.ImageAlignmentTab, 'push');
            app.ALIGNIMAGEButton.ButtonPushedFcn = createCallbackFcn(app, @ALIGNIMAGEButtonPushed, true);
            app.ALIGNIMAGEButton.Text = 'ALIGN IMAGE';
            app.DataMaskTab = uitab(app.TabGroup3);
            app.DataMaskTab.Title = 'Data Mask';
            app.ERISETIMEDropDownLabel_5 = uilabel(app.DataMaskTab);
            app.ERISETIMEDropDownLabel_5.Text = 'A) SIGNAL-TO-NOISE RATIO MASK ';
            app.Label_22 = uilabel(app.DataMaskTab);
            app.Label_22.Text = 'Apply SNR mask based on the threshold to active data source';
            app.DISPLAYSNRMAPButton = uibutton(app.DataMaskTab, 'push');
            app.DISPLAYSNRMAPButton.ButtonPushedFcn = createCallbackFcn(app, @DISPLAYSNRMAPButtonPushed, true);
            app.DISPLAYSNRMAPButton.Text = 'DISPLAY SNR MAP';
            app.SNRSwitch = uiswitch(app.DataMaskTab, 'slider');
            app.SNRSwitch.ValueChangedFcn = createCallbackFcn(app, @SNRMASK_SWITCH, true);
            app.ERISETIMEDropDownLabel_6 = uilabel(app.DataMaskTab);
            app.ERISETIMEDropDownLabel_6.Text = 'B) USER DRAWN MASK';
            app.Label_23 = uilabel(app.DataMaskTab);
            app.Label_23.Text = 'Apply user drawn mask to the active data source.';
            app.USRSwitch = uiswitch(app.DataMaskTab, 'slider');
            app.USRSwitch.ValueChangedFcn = createCallbackFcn(app, @USERMASK_SWITCH, true);
            app.CREATEUSERMASKButton = uibutton(app.DataMaskTab, 'push');
            app.CREATEUSERMASKButton.ButtonPushedFcn = createCallbackFcn(app, @CREATEUSERMASKButtonPushed, true);
            app.CREATEUSERMASKButton.Text = 'CREATE USER MASK';
            app.DELETEUSERMASKButton = uibutton(app.DataMaskTab, 'push');
            app.DELETEUSERMASKButton.ButtonPushedFcn = createCallbackFcn(app, @DELETEUSERMASKButtonPushed, true);
            app.DELETEUSERMASKButton.Text = 'DELETE USER MASK';
            app.SetSNRMaskThreshold0autoEditFieldLabel = uilabel(app.DataMaskTab);
            app.SetSNRMaskThreshold0autoEditFieldLabel.Text = 'Set SNR Mask Threshold (0 = auto)';
            app.SetSNRMaskThreshold0autoEditField = uieditfield(app.DataMaskTab, 'numeric');
            app.SetSNRMaskThreshold0autoEditField.Limits = [0 Inf];
            app.PACINGCHECKBOX = uicheckbox(app.STEP2SELECTWINDOWFROMREPRESENTATIVESIGNALPanel);
            app.PACINGCHECKBOX.ValueChangedFcn = createCallbackFcn(app, @PACINGCHECKBOXValueChanged, true);
            app.PACINGCHECKBOX.Text = 'Show Pacing Signal';
            app.LINKDATACHECKBOX = uicheckbox(app.STEP2SELECTWINDOWFROMREPRESENTATIVESIGNALPanel);
            app.LINKDATACHECKBOX.Text = 'Link Signals';
            app.ENSEMBLECHECKBOX = uicheckbox(app.STEP2SELECTWINDOWFROMREPRESENTATIVESIGNALPanel);
            app.ENSEMBLECHECKBOX.Text = 'Ensemble Averaging';
            app.INVERTSIGNALSButton = uibutton(app.STEP2SELECTWINDOWFROMREPRESENTATIVESIGNALPanel, 'push');
            app.INVERTSIGNALSButton.ButtonPushedFcn = createCallbackFcn(app, @InvertSignalsButtonPushed, true);
            app.INVERTSIGNALSButton.Enable = 'off';
            app.INVERTSIGNALSButton.Text = 'INVERT SIGNALS';
            app.STEP3SELECTOUTPUTDIRECTORYTOSAVEPROCESSEDFILESPanel_2 = uipanel(app.FEATUREEXTRACTIONTab);
            app.STEP3SELECTOUTPUTDIRECTORYTOSAVEPROCESSEDFILESPanel_2.Title = 'STEP 3: SELECT OUTPUT DIRECTORY TO SAVE THE PROCESSED FILE(S) AND EXECUTE SCRIPT';
            app.EditField_2Label = uilabel(app.STEP3SELECTOUTPUTDIRECTORYTOSAVEPROCESSEDFILESPanel_2);
            app.EditField_2Label.Text = 'Edit Field';
            app.EditField_2 = uieditfield(app.STEP3SELECTOUTPUTDIRECTORYTOSAVEPROCESSEDFILESPanel_2, 'text');
            app.EditField_2.Tag = 'output_dir_field';
            app.SELECTOUTPUTDIRECTORYButton_2 = uibutton(app.STEP3SELECTOUTPUTDIRECTORYTOSAVEPROCESSEDFILESPanel_2, 'push');
            app.SELECTOUTPUTDIRECTORYButton_2.ButtonPushedFcn = createCallbackFcn(app, @SELECTOUTPUTDIRECTORYButtonPushed, true);
            app.SELECTOUTPUTDIRECTORYButton_2.Text = {'SELECT OUTPUT DIRECTORY'; ''};
            app.Label_9 = uilabel(app.STEP3SELECTOUTPUTDIRECTORYTOSAVEPROCESSEDFILESPanel_2);
            app.Label_9.Text = 'NOTE: By default the processed files will be saved in the input directory.';
            app.EXTRACTFEATURESButton = uibutton(app.STEP3SELECTOUTPUTDIRECTORYTOSAVEPROCESSEDFILESPanel_2, 'push');
            app.EXTRACTFEATURESButton.ButtonPushedFcn = createCallbackFcn(app, @EXTRACTFEATURESButtonPushed, true);
            app.EXTRACTFEATURESButton.Enable = 'off';
            app.EXTRACTFEATURESButton.Text = 'EXTRACT FEATURES';
            app.SAVEDATAButton = uibutton(app.STEP3SELECTOUTPUTDIRECTORYTOSAVEPROCESSEDFILESPanel_2, 'push');
            app.SAVEDATAButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEDATAButtonPushed, true);
            app.SAVEDATAButton.Enable = 'off';
            app.SAVEDATAButton.Text = 'SAVE DATA';
            app.BATCHPROCESSINGButton = uibutton(app.STEP3SELECTOUTPUTDIRECTORYTOSAVEPROCESSEDFILESPanel_2, 'push');
            app.BATCHPROCESSINGButton.ButtonPushedFcn = createCallbackFcn(app, @BATCHPROCESSINGButtonPushed, true);
            app.BATCHPROCESSINGButton.Text = 'BATCH PROCESSING';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Cadence_Feature_Extraction

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end