classdef Cadence_Signal_Analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        SIGNALANALYSISTab            matlab.ui.container.Tab
        DATAMASKPanel                matlab.ui.container.Panel
        Label_3                      matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        UpperBoundEditField          matlab.ui.control.NumericEditField
        LowerBoundEditField          matlab.ui.control.NumericEditField
        SETHISTOGRAMUPPERLOWERBOUNDSEditFieldLabel  matlab.ui.control.Label
        ERISETIMEDropDownLabel       matlab.ui.control.Label
        DELETEUSERMASKButton         matlab.ui.control.Button
        CREATEUSERMASKButton         matlab.ui.control.Button
        MaskLabelEditField           matlab.ui.control.EditField
        HistogramSwitch              matlab.ui.control.Switch
        MaskSwitch                   matlab.ui.control.Switch
        STATISTICALSUMMARYPanel      matlab.ui.container.Panel
        TabGroup3                    matlab.ui.container.TabGroup
        CAM_1TableTab                matlab.ui.container.Tab
        cam1_table                   matlab.ui.control.Table
        CAM_2TableTab                matlab.ui.container.Tab
        cam2_table                   matlab.ui.control.Table
        CAM_3TableTab                matlab.ui.container.Tab
        cam3_table                   matlab.ui.control.Table
        CAM_4TableTab                matlab.ui.container.Tab
        cam4_table                   matlab.ui.control.Table
        TextArea                     matlab.ui.control.TextArea
        Label_4                      matlab.ui.control.Label
        CARDIACMAPSTODISPLAYPanel    matlab.ui.container.Panel
        SAVEDATAButton               matlab.ui.control.Button
        TabGroup4                    matlab.ui.container.TabGroup
        ActionPotentialsVmTab        matlab.ui.container.Tab
        VmButtonGroup                matlab.ui.container.ButtonGroup
        LOCALCONDUCTIONVELOCITYMAPButton  matlab.ui.control.RadioButton
        ACTIONPOTENTIALRISETIMEMAPButton  matlab.ui.control.RadioButton
        ACTIONPOTENTIALDURATIONAPDMAPButton  matlab.ui.control.RadioButton
        REPOLARIZATIONTIMEMAPButton  matlab.ui.control.RadioButton
        LOCALACTIVATIONTIMELATMAPButton  matlab.ui.control.RadioButton
        CalciumTransientsCaTab       matlab.ui.container.Tab
        CaButtonGroup                matlab.ui.container.ButtonGroup
        CALCIUMDECAYCONSTANTTAUMAPButton  matlab.ui.control.RadioButton
        CALCIUMRISETIMEMAPButton     matlab.ui.control.RadioButton
        CALCIUMTRANSIENTDURATIONMAPButton  matlab.ui.control.RadioButton
        CALCIUMDECAYTIMEMAPButton    matlab.ui.control.RadioButton
        VOLTAGECALCIUMACTIVATIONDELAYMAPButton  matlab.ui.control.RadioButton
        ArrhythmiaDynamicsTab        matlab.ui.container.Tab
        ArrhythmiaButtonGroup        matlab.ui.container.ButtonGroup
        ALTERNANSVOLTAGEORCALCIUMButton  matlab.ui.control.RadioButton
        ORGANIZATIONINDEXOIButton    matlab.ui.control.RadioButton
        REGULARITYINDEXRIButton      matlab.ui.control.RadioButton
        DOMINANTFREQUENCYDFButton    matlab.ui.control.RadioButton
        DISPLAYCARDIACMAPButton      matlab.ui.control.Button
        STEP2SELECTWINDOWFROMREPRESENTATIVESIGNALPanel  matlab.ui.container.Panel
        PacingCheckBox               matlab.ui.control.CheckBox
        LinkSignalsCheckBox          matlab.ui.control.CheckBox
        SELECTWINDOWButton           matlab.ui.control.Button
        Slider                       matlab.ui.control.RangeSlider
        ButtonGroup_4                matlab.ui.container.ButtonGroup
        AnnotatedRotorPSButton       matlab.ui.control.RadioButton
        PhaseHilbertTransformButton  matlab.ui.control.RadioButton
        TimeVmorCaButton             matlab.ui.control.RadioButton
        EXPORTSIGNALSButton          matlab.ui.control.Button
        EXPORTMOVIEButton            matlab.ui.control.Button
        STOPMOVIEButton              matlab.ui.control.Button
        PLAYMOVIEButton              matlab.ui.control.Button
        TabGroup2                    matlab.ui.container.TabGroup
        CAM_1Tab                     matlab.ui.container.Tab
        VALLabel_4                   matlab.ui.control.Label
        COLLabel_4                   matlab.ui.control.Label
        ROWLabel_4                   matlab.ui.control.Label
        Val14                        matlab.ui.control.EditField
        Col14                        matlab.ui.control.EditField
        Row14                        matlab.ui.control.EditField
        VALLabel_3                   matlab.ui.control.Label
        COLLabel_3                   matlab.ui.control.Label
        ROWLabel_3                   matlab.ui.control.Label
        Val13                        matlab.ui.control.EditField
        Col13                        matlab.ui.control.EditField
        Row13                        matlab.ui.control.EditField
        VALLabel_2                   matlab.ui.control.Label
        COLLabel_2                   matlab.ui.control.Label
        ROWLabel_2                   matlab.ui.control.Label
        Val12                        matlab.ui.control.EditField
        Col12                        matlab.ui.control.EditField
        Row12                        matlab.ui.control.EditField
        VALLabel                     matlab.ui.control.Label
        COLLabel                     matlab.ui.control.Label
        ROWLabel                     matlab.ui.control.Label
        Val11                        matlab.ui.control.EditField
        Col11                        matlab.ui.control.EditField
        Row11                        matlab.ui.control.EditField
        UIAxes_cam1_sweep            matlab.ui.control.UIAxes
        plot_sig_14                  matlab.ui.control.UIAxes
        plot_sig_13                  matlab.ui.control.UIAxes
        plot_sig_12                  matlab.ui.control.UIAxes
        plot_sig_11                  matlab.ui.control.UIAxes
        UIAxes_cam1                  matlab.ui.control.UIAxes
        CAM_2Tab                     matlab.ui.container.Tab
        VALLabel_8                   matlab.ui.control.Label
        COLLabel_8                   matlab.ui.control.Label
        ROWLabel_8                   matlab.ui.control.Label
        Val24                        matlab.ui.control.EditField
        Col24                        matlab.ui.control.EditField
        Row24                        matlab.ui.control.EditField
        VALLabel_7                   matlab.ui.control.Label
        COLLabel_7                   matlab.ui.control.Label
        ROWLabel_7                   matlab.ui.control.Label
        Val23                        matlab.ui.control.EditField
        Col23                        matlab.ui.control.EditField
        Row23                        matlab.ui.control.EditField
        VALLabel_6                   matlab.ui.control.Label
        COLLabel_6                   matlab.ui.control.Label
        ROWLabel_6                   matlab.ui.control.Label
        Val22                        matlab.ui.control.EditField
        Col22                        matlab.ui.control.EditField
        Row22                        matlab.ui.control.EditField
        VALLabel_5                   matlab.ui.control.Label
        COLLabel_5                   matlab.ui.control.Label
        ROWLabel_5                   matlab.ui.control.Label
        Val21                        matlab.ui.control.EditField
        Col21                        matlab.ui.control.EditField
        Row21                        matlab.ui.control.EditField
        UIAxes_cam2_sweep            matlab.ui.control.UIAxes
        plot_sig_24                  matlab.ui.control.UIAxes
        plot_sig_23                  matlab.ui.control.UIAxes
        plot_sig_22                  matlab.ui.control.UIAxes
        plot_sig_21                  matlab.ui.control.UIAxes
        UIAxes_cam2                  matlab.ui.control.UIAxes
        CAM_3Tab                     matlab.ui.container.Tab
        VALLabel_12                  matlab.ui.control.Label
        COLLabel_12                  matlab.ui.control.Label
        ROWLabel_12                  matlab.ui.control.Label
        Val34                        matlab.ui.control.EditField
        Col34                        matlab.ui.control.EditField
        Row34                        matlab.ui.control.EditField
        VALLabel_11                  matlab.ui.control.Label
        COLLabel_11                  matlab.ui.control.Label
        ROWLabel_11                  matlab.ui.control.Label
        Val33                        matlab.ui.control.EditField
        Col33                        matlab.ui.control.EditField
        Row33                        matlab.ui.control.EditField
        VALLabel_10                  matlab.ui.control.Label
        COLLabel_10                  matlab.ui.control.Label
        ROWLabel_10                  matlab.ui.control.Label
        Val32                        matlab.ui.control.EditField
        Col32                        matlab.ui.control.EditField
        Row32                        matlab.ui.control.EditField
        VALLabel_9                   matlab.ui.control.Label
        COLLabel_9                   matlab.ui.control.Label
        ROWLabel_9                   matlab.ui.control.Label
        Val31                        matlab.ui.control.EditField
        Col31                        matlab.ui.control.EditField
        Row31                        matlab.ui.control.EditField
        UIAxes_cam3_sweep            matlab.ui.control.UIAxes
        plot_sig_34                  matlab.ui.control.UIAxes
        plot_sig_33                  matlab.ui.control.UIAxes
        plot_sig_32                  matlab.ui.control.UIAxes
        plot_sig_31                  matlab.ui.control.UIAxes
        UIAxes_cam3                  matlab.ui.control.UIAxes
        CAM_4Tab                     matlab.ui.container.Tab
        VALLabel_16                  matlab.ui.control.Label
        COLLabel_16                  matlab.ui.control.Label
        ROWLabel_16                  matlab.ui.control.Label
        Val44                        matlab.ui.control.EditField
        Col44                        matlab.ui.control.EditField
        Row44                        matlab.ui.control.EditField
        VALLabel_15                  matlab.ui.control.Label
        COLLabel_15                  matlab.ui.control.Label
        ROWLabel_15                  matlab.ui.control.Label
        Val43                        matlab.ui.control.EditField
        Col43                        matlab.ui.control.EditField
        Row43                        matlab.ui.control.EditField
        VALLabel_14                  matlab.ui.control.Label
        COLLabel_14                  matlab.ui.control.Label
        ROWLabel_14                  matlab.ui.control.Label
        Val42                        matlab.ui.control.EditField
        Col42                        matlab.ui.control.EditField
        Row42                        matlab.ui.control.EditField
        VALLabel_13                  matlab.ui.control.Label
        COLLabel_13                  matlab.ui.control.Label
        ROWLabel_13                  matlab.ui.control.Label
        Val41                        matlab.ui.control.EditField
        Col41                        matlab.ui.control.EditField
        Row41                        matlab.ui.control.EditField
        UIAxes_cam4_sweep            matlab.ui.control.UIAxes
        plot_sig_44                  matlab.ui.control.UIAxes
        plot_sig_43                  matlab.ui.control.UIAxes
        plot_sig_42                  matlab.ui.control.UIAxes
        plot_sig_41                  matlab.ui.control.UIAxes
        UIAxes_cam4                  matlab.ui.control.UIAxes
        SELECTFILEFORSIGNALANALYSISPanel  matlab.ui.container.Panel
        LOADButton                   matlab.ui.control.Button
        ListBox_2                    matlab.ui.control.ListBox
        Label_5                      matlab.ui.control.Label
        REFRESHDIRButton             matlab.ui.control.Button
        SELECTDIRButton              matlab.ui.control.Button
    end


    properties (Access = public)
        select_input_directory_help = [];
        dir_name = [];
        mat_file_name = [];
        stop_movie = 0;
        data = [];
        x_axis = [];
        y_axis = [];
        plot_markers = cell(16,2);
        cam1_marker_index = 1;
        cam2_marker_index = 1;
        cam3_marker_index = 1;
        cam4_marker_index = 1;
        plot_index = [];
        plot_signals = cell(16,1);
        oap_window = cell(4,3);
        mouse_coordinates = [];
        mouse_x = [];
        mouse_y = [];
        mouse_drag = [];
        mouse_down = [];
        active_camera_tab = 1;
        active_table_tab = 1;
        active_axes = [];
        active_data_masks = [];
        active_sweep = [];
        active_window_axes = [];
        active_data = [];
        active_cardiac_map = [];
        active_table = [];
        active_image = [];
        active_color = 'r';
        %data_offset = 0;
        data_masks = [];
        time  = [];

    end

    methods (Access = private)

        function reset_default_state(app)
            %RESET_DEFAULT_STATE  Reset movie/mouse/marker/mask state for a new recording.
            %   reset_default_state(app)  -> called at the start of each LOAD.
            try

                app.stop_movie = 0;
                app.mouse_down = [];
                app.mouse_drag = [];
                app.cam1_marker_index = 1;
                app.cam2_marker_index = 1;
                app.cam3_marker_index = 1;
                app.cam4_marker_index = 1;
                %app.data_offset = 0;
                app.data_masks = [];
                app.x_axis = [];
                app.y_axis = [];

                app.active_camera_tab = 1;
                app.active_table_tab = 1;
                app.active_axes = app.UIAxes_cam1;
                app.active_sweep = app.UIAxes_cam1_sweep;
                app.active_table = app.cam1_table;
                app.active_window_axes = app.plot_sig_11;
                app.active_color = 'r';
                app.active_image = [];
                app.active_data_masks = [];
                app.active_data = [];
                app.active_cardiac_map = [];

                app.TabGroup2.SelectedTab = app.CAM_1Tab;
                app.TabGroup4.SelectedTab = app.ActionPotentialsVmTab;
                app.TabGroup3.SelectedTab = app.CAM_1TableTab;

                app.TimeVmorCaButton.Value = true;
                app.Slider.Value = [0 100];


                app.LOCALACTIVATIONTIMELATMAPButton.Value = true;
                app.VOLTAGECALCIUMACTIVATIONDELAYMAPButton.Value = true;
                app.DOMINANTFREQUENCYDFButton.Value = true;
                app.MaskLabelEditField.Value = '';

                app.LowerBoundEditField.Value = 0;
                app.UpperBoundEditField.Value = 0;
                app.HistogramSwitch.Value = 'Off';
                app.MaskSwitch.Value = 'Off';

                app.TabGroup3.SelectedTab = app.CAM_1TableTab;
                app.cam1_table.Data = {};
                app.cam2_table.Data = {};
                app.cam3_table.Data = {};
                app.cam4_table.Data = {};

                for i=1:16
                    app.plot_markers{i,1} = [];
                    app.plot_signals{i,1} = [];
                end

                for i=1:4
                    app.oap_window{i,1} = [];
                    app.oap_window{i,2} = [];
                    app.oap_window{i,3} = [];
                end

                app.data = [];
                app.time = [];

                cla(app.UIAxes_cam1);
                cla(app.UIAxes_cam2);
                cla(app.UIAxes_cam3);
                cla(app.UIAxes_cam4);
                colormap(app.UIAxes_cam1, "jet");
                colormap(app.UIAxes_cam2, "jet");
                colormap(app.UIAxes_cam3, "jet");
                colormap(app.UIAxes_cam4, "jet");

                cla(app.UIAxes_cam1_sweep);
                cla(app.UIAxes_cam2_sweep);
                cla(app.UIAxes_cam3_sweep);
                cla(app.UIAxes_cam4_sweep);

                cla(app.plot_sig_11);
                cla(app.plot_sig_12);
                cla(app.plot_sig_13);
                cla(app.plot_sig_14);
                cla(app.plot_sig_21);
                cla(app.plot_sig_22);
                cla(app.plot_sig_23);
                cla(app.plot_sig_24);
                cla(app.plot_sig_31);
                cla(app.plot_sig_32);
                cla(app.plot_sig_33);
                cla(app.plot_sig_34);
                cla(app.plot_sig_41);
                cla(app.plot_sig_42);
                cla(app.plot_sig_43);
                cla(app.plot_sig_44);

                app.Row11.Value = '';
                app.Col11.Value = '';
                app.Val11.Value = '';
                app.Row12.Value = '';
                app.Col12.Value = '';
                app.Val12.Value = '';
                app.Row13.Value = '';
                app.Col13.Value = '';
                app.Val13.Value = '';
                app.Row14.Value = '';
                app.Col14.Value = '';
                app.Val14.Value = '';

                app.Row21.Value = '';
                app.Col21.Value = '';
                app.Val21.Value = '';
                app.Row22.Value = '';
                app.Col22.Value = '';
                app.Val22.Value = '';
                app.Row23.Value = '';
                app.Col23.Value = '';
                app.Val23.Value = '';
                app.Row24.Value = '';
                app.Col24.Value = '';
                app.Val24.Value = '';

                app.Row31.Value = '';
                app.Col31.Value = '';
                app.Val31.Value = '';
                app.Row32.Value = '';
                app.Col32.Value = '';
                app.Val32.Value = '';
                app.Row33.Value = '';
                app.Col33.Value = '';
                app.Val33.Value = '';
                app.Row34.Value = '';
                app.Col34.Value = '';
                app.Val34.Value = '';

                app.Row41.Value = '';
                app.Col41.Value = '';
                app.Val41.Value = '';
                app.Row42.Value = '';
                app.Col42.Value = '';
                app.Val42.Value = '';
                app.Row43.Value = '';
                app.Col43.Value = '';
                app.Val43.Value = '';
                app.Row44.Value = '';
                app.Col44.Value = '';
                app.Val44.Value = '';

            catch ME
                error_message = strcat('ERROR in function reset_default_state(app) ', ME.message);
                update_console(app,error_message);
            end
        end


        %UPDATE_CONSOLE  Prepend a message to the status console (TextArea).
        function update_console(app, message)
            try
                app.TextArea.Value = [message; ''; app.TextArea.Value];
            catch ME
                error_message = strcat('ERROR in function update_console(app) ', ME.message);
                disp(error_message);r
            end
        end


        %GET_MOVIE_FRAMES_RANGE  Resolve the movie's start/end frames from the slider.
        %   [start_frame, end_frame, max_frames] = get_movie_frames_range(app)
        %   Maps the two-handle Slider (% of the recording) to frame indices.
        function [start_frame, end_frame, max_frames] = get_movie_frames_range(app)
            try
                max_frames = size(app.active_data,3);
                start_frame = 1;
                end_frame = max_frames;

                slider_min = app.Slider.Value(1,1);
                slider_max = app.Slider.Value(1,2);

                if slider_min > 0
                    start_frame = round(max_frames*slider_min/100);
                end

                if slider_max < 100
                    end_frame = round(max_frames * slider_max/100);
                end

            catch ME
                error_message = strcat('ERROR in function get_movie_frames(app) ', ME.message);
                update_console(app,error_message);
            end

        end


        %GET_MOVIE_DATA  Assemble the data layers to animate for the active camera.
        %   [movie_data, data_mask, label] = get_movie_data(app)
        %   Returns the raw stack, or the Hilbert phase map, or phase + wavefront +
        %   rotor layers (Annotated Rotor mode), depending on the selected toggle;
        %   also returns the current user mask. Warns if a requested layer is absent.
        function [movie_data, data_mask, label] = get_movie_data(app)
            try

                movie_data{1,1} = app.active_data;
                label = '';
                data_mask = get_user_mask(app);

                app.PhaseHilbertTransformButton.Value;
                app.AnnotatedRotorPSButton.Value;
                if app.PhaseHilbertTransformButton.Value
                    if isfield(app.data.ep_metrics, 'phase_data')
                        movie_data{1,1} = app.data.ep_metrics.phase_data{app.active_camera_tab,1};
                        label = '-Phase';
                    else
                        update_console(app, 'Phase Data not available');
                    end

                elseif app.AnnotatedRotorPSButton.Value
                    if isfield(app.data.ep_metrics, 'phase_data') && ...
                            isfield(app.data.ep_metrics, 'wavefront_data') && ...
                            isfield(app.data.ep_metrics, 'rotor_data')
                        label = '-Annotated Rotor';
                        movie_data{1,1} = app.data.ep_metrics.phase_data{app.active_camera_tab,1};
                        movie_data{1,2} = app.data.ep_metrics.wavefront_data{app.active_camera_tab,1};
                        movie_data{1,3} = app.data.ep_metrics.rotor_data{app.active_camera_tab,1};
                    else
                        update_console(app, 'Annotated Rotor (PS) Data not available');
                    end

                end

            catch ME
                error_message = strcat('ERROR in function get_movie_data(app) ', ME.message);
                update_console(app,error_message);
            end

        end


        %UPDATE_ACTIVE_TAB_AXES_DATA  Point app.active_* at the selected camera tab.
        %   valid_action = update_active_tab_axes_data(app)
        %   Sets active axes/sweep/data/masks/image/table/colour for CAM_1..CAM_4;
        %   returns 0 if that camera is not present in the recording.
        function valid_action = update_active_tab_axes_data(app)
            try
                selected_tab = app.TabGroup2.SelectedTab.Title;
                switch selected_tab
                    case 'CAM_1'
                        app.active_camera_tab = 1;
                        app.active_axes = app.UIAxes_cam1;
                        app.active_sweep = app.UIAxes_cam1_sweep;
                        app.active_data = app.data.CAM1;
                        app.active_data_masks = app.data.data_masks{1,2};
                        app.active_image = app.data.CAM1_image;
                        app.active_window_axes = app.plot_sig_11;
                        app.active_table = app.cam1_table;
                        app.active_color = 'r';
                        valid_action = 1;
                    case 'CAM_2'
                        app.active_camera_tab = 2;
                        if app.data.num_files >= 2
                            app.active_axes = app.UIAxes_cam2;
                            app.active_sweep = app.UIAxes_cam2_sweep;
                            app.active_data = app.data.CAM2;
                            app.active_data_masks = app.data.data_masks{2,2};
                            app.active_image = app.data.CAM2_image;
                            app.active_window_axes = app.plot_sig_21;
                            app.active_table = app.cam2_table;
                            app.active_color = 'b';
                            valid_action = 1;
                        else
                            valid_action = 0; % no data loaded
                        end
                    case 'CAM_3'
                        app.active_camera_tab = 3;
                        if app.data.num_files >=3
                            app.active_axes = app.UIAxes_cam3;
                            app.active_sweep = app.UIAxes_cam3_sweep;
                            app.active_data = app.data.CAM3;
                            app.active_data_masks = app.data.data_masks{3,2};
                            app.active_image = app.data.CAM3_image;
                            app.active_window_axes = app.plot_sig_31;
                            app.active_table = app.cam3_table;
                            app.active_color = 'm';
                            valid_action = 1;
                        else
                            valid_action = 0; % no data loaded
                        end
                    case 'CAM_4'
                        app.active_camera_tab = 4;
                        if app.data.num_files ==4
                            app.active_axes = app.UIAxes_cam4;
                            app.active_sweep = app.UIAxes_cam4_sweep;
                            app.active_data = app.data.CAM4;
                            app.active_data_masks = app.data.data_masks{4,2};
                            app.active_image = app.data.CAM4_image;
                            app.active_window_axes = app.plot_sig_41;
                            app.active_table = app.cam4_table;
                            app.active_color = 'g';
                            valid_action = 1;
                        else
                            valid_action = 0; % no data loaded
                        end
                end

            catch ME
                error_message = strcat('ERROR in function update_active_tab_axes_data(app) ', ME.message);
                update_console(app,error_message);
            end
        end


        %UPDATE_PLOT_MARKER  Draw/move the pixel marker on the active map.
        %   update_plot_marker(app, mouse_action)  ('button_down' | 'button_motion').
        function update_plot_marker(app,mouse_action)
            try
                update_plot_index(app, mouse_action);
                if ~isempty(app.plot_markers{app.plot_index,1})
                    delete(app.plot_markers{app.plot_index,1});
                end
                app.plot_markers{app.plot_index,1} = line(app.active_axes, app.mouse_x,app.mouse_y, 'Marker','square', ...
                    'MarkerSize',10, 'MarkerEdgeColor','w', 'MarkerFaceColor',app.plot_markers{app.plot_index,2});
            catch ME
                error_message = strcat('ERROR in function update_plot_marker(app) ', ME.message);
                update_console(app,error_message);
            end
        end


        %GET_PLOT_INFO  Return the signal axes and Row/Col/Val fields for plot_index.
        %   [plot, row, col, val] = get_plot_info(app)
        %   Maps app.plot_index (1..16, four signal slots x four cameras) to the
        %   corresponding plot_sig_* axes and its Row/Col/Val display fields.
        function [plot, row, col, val] = get_plot_info(app)
            try
                plot = app.plot_sig_11;
                row = app.Row11;
                col = app.Col11;
                val = app.Val11;

                switch app.plot_index

                    case 2
                        plot = app.plot_sig_12;
                        row = app.Row12;
                        col = app.Col12;
                        val = app.Val12;


                    case 3
                        plot = app.plot_sig_13;
                        row = app.Row13;
                        col = app.Col13;
                        val = app.Val13;

                    case 4
                        plot = app.plot_sig_14;
                        row = app.Row14;
                        col = app.Col14;
                        val = app.Val14;

                    case 5
                        plot = app.plot_sig_21;
                        row = app.Row21;
                        col = app.Col21;
                        val = app.Val21;

                    case 6

                        plot = app.plot_sig_22;
                        row = app.Row22;
                        col = app.Col22;
                        val = app.Val22;

                    case 7
                        plot = app.plot_sig_23;
                        row = app.Row23;
                        col = app.Col23;
                        val = app.Val23;

                    case 8
                        plot = app.plot_sig_24;
                        row = app.Row24;
                        col = app.Col24;
                        val = app.Val24;

                    case 9
                        plot = app.plot_sig_31;
                        row = app.Row31;
                        col = app.Col31;
                        val = app.Val31;

                    case 10
                        plot = app.plot_sig_32;
                        row = app.Row32;
                        col = app.Col32;
                        val = app.Val32;

                    case 11
                        plot = app.plot_sig_33;
                        row = app.Row33;
                        col = app.Col33;
                        val = app.Val33;

                    case 12

                        plot = app.plot_sig_34;
                        row = app.Row34;
                        col = app.Col34;
                        val = app.Val34;

                    case 13
                        plot = app.plot_sig_41;
                        row = app.Row41;
                        col = app.Col41;
                        val = app.Val41;

                    case 14
                        plot = app.plot_sig_42;
                        row = app.Row42;
                        col = app.Col42;
                        val = app.Val42;

                    case 15
                        plot = app.plot_sig_43;
                        row = app.Row43;
                        col = app.Col43;
                        val = app.Val43;

                    case 16
                        plot = app.plot_sig_44;
                        row = app.Row44;
                        col = app.Col44;
                        val = app.Val44;
                end

            catch ME
                error_message = strcat('ERROR in function get_plot_info(app) ', ME.message);
                update_console(app,error_message);
            end

        end


        %UPDATE_PLOT_SIGNAL  Plot the optical AP at the current marker pixel.
        %   update_plot_signal(app)
        %   Extracts the time series at (mouse_y, mouse_x) of the active camera and
        %   draws it in the current signal slot, updating the Row/Col/Val readouts;
        %   reports NaN pixels.
        function update_plot_signal(app)
            try
                col = app.mouse_x;
                row = app.mouse_y;
                oap = squeeze(app.active_data(row,col,:));
                [plot_loc, row_loc, col_loc, val_loc] = get_plot_info(app);


                if ~isempty(app.plot_signals{app.plot_index,1})
                    cla(plot_loc);
                end


                if ~isnan(oap(1,1))

                    app.time = 1:size(oap,1);
                    app.plot_signals{app.plot_index,1} = line(plot_loc, app.time, oap, 'LineWidth',1.5, 'color', app.plot_markers{app.plot_index,2});
                    row_loc.Value = num2str(row);
                    col_loc.Value = num2str(col);
                    if ~isempty(app.active_cardiac_map)
                        val_loc.Value = num2str(app.active_cardiac_map{1,1}(row,col));
                    else
                        val_loc.Value = " ";
                    end


                else

                    row_loc.Value = num2str(row);
                    col_loc.Value = num2str(col);
                    val_loc.Value = "NaN";
                    update_console(app, 'No valid data(NAN)at the current plot marker location.');
                end

            catch ME
                error_message = strcat('ERROR in function update_plot_signal(app) ', ME.message);
                update_console(app,error_message);
            end
        end


        %ROICHANGED  Listener: refresh a signal window or a data mask after edit.
        %   ROIChanged(app, mask_region, signal)  (signal=1 -> window, else mask).
        function ROIChanged(app,mask_region, signal)
            try
                if signal
                    update_signal_window(app);
                else
                    update_mask(app,mask_region);
                    update_console(app, 'Mask sucessfully created or updated..');
                    pause(0.001);
                end
            catch ME
                error_message = strcat('ERROR in function ROIChanged(app) ', ME.message);
                update_console(app,error_message);
            end
        end


        %ROIDELETED  Listener: clear the stored signal window when its ROI is deleted.
        function ROIDeleted(app,window)
            try
                if ~isempty(app.oap_window{app.active_camera_tab,3})
                    if strcmp(window.Tag, app.oap_window{app.active_camera_tab,3})
                        app.oap_window(app.active_camera_tab,:) = [];
                    end
                end
            catch ME
                error_message = strcat('ERROR in function ROIDeleted(app) ', ME.message);
                update_console(app,error_message);
            end
        end


        %UPDATE_SIGNAL_WINDOW  Record a signal window's frame span and handle.
        %   [start_frame, end_frame] = update_signal_window(app, window)
        %   Stores [start end], the ROI handle and its Tag in oap_window for the
        %   active camera (used to restrict feature export to one beat/cycle).
        function [start_frame, end_frame] = update_signal_window(app, window)
            try
                position = window.Position;
                start_frame = round(position(1));
                end_frame = start_frame + round(position(3));

                app.oap_window{app.active_camera_tab,1} = [start_frame, end_frame];
                app.oap_window{app.active_camera_tab,2} = window;
                app.oap_window{app.active_camera_tab,3} = window.Tag;

            catch ME
                error_message = strcat('ERROR in function update_signal_window(app) ', ME.message);
                update_console(app,error_message);
            end
        end


        %IS_SIGNAL_AVAILABLE  Is a representative signal plotted for the active camera?
        %   [available, row, col] = is_signal_available(app)
        %   Returns 1 and the first signal's pixel (row,col) if slot 1 of the active
        %   camera holds a trace, else 0.
        function [available, row, col] = is_signal_available(app)
            try
                available = 0;
                row = 0;
                col = 0;
                switch app.active_camera_tab
                    case 1
                        if ~isempty(app.plot_signals{1,1})
                            available = 1;
                            row = str2double(app.Row11.Value);
                            col = str2double(app.Col11.Value);
                        end

                    case 2
                        if ~isempty(app.plot_signals{5,1})
                            available = 1;
                            row = str2double(app.Row21.Value);
                            col = str2double(app.Col21.Value);
                        end

                    case 3
                        if ~isempty(app.plot_signals{9,1})
                            available = 1;
                            row = str2double(app.Row31.Value);
                            col = str2double(app.Col31.Value);
                        end

                    case 4
                        if ~isempty(app.plot_signals{13,1})
                            available = 1;
                            row = str2double(app.Row41.Value);
                            col = str2double(app.Col41.Value);
                        end
                end

            catch ME
                error_message = strcat('ERROR in function is_signal_available(app) ', ME.message);
                update_console(app,error_message);
            end
        end


        %SELECT_WINDOW  Draw a draggable signal-window rectangle on the active signal.
        %   select_window(app)
        %   Adds a tagged ROI rectangle (with ROIMoved/destroy listeners) over the
        %   plotted signal so the user can isolate one cycle; refuses if no signal.
        function select_window(app)
            try
                [available, ~, ~] = is_signal_available(app);
                if available
                    tag_id = generate_unique_id;
                    pause(0.001);
                    window = drawrectangle(app.active_window_axes,'Color', 'k', 'Tag', tag_id, 'FaceAlpha', 0.1);
                    addlistener(window,'ROIMoved', @(varargin)ROIChanged(app,window, 1)); % update if there is a change to the signal window;
                    addlistener(window,'ObjectBeingDestroyed', @(varargin)ROIDeleted(app,window)); %update if the user removes signal window;
                    update_signal_window(app,window);
                    pause(0.001);
                else
                    update_console(app, 'No representative signal or data loaded to select signal window from')
                end

            catch ME
                error_message = strcat('ERROR in function select_window(app) ', ME.message);
                update_console(app,error_message);
            end
        end


        %UPDATE_MASK  Create or update a named region-of-interest data mask.
        %   update_mask(app, mask_region, mask_image)
        %   Rasterises the ROI to a NaN-outside mask, stores it (by Tag/label) in
        %   data_masks for the active camera, and adds a row to the stats table on
        %   first creation.
        function update_mask(app, mask_region, mask_image)
            try
                if ~isempty(mask_region)

                    mask = double(createMask(mask_region, mask_image));
                    mask(mask == 0) = nan;
                    label = app.MaskLabelEditField.Value;
                    if isempty(label)
                        label = mask_region.Tag;
                    end
                    update_table = 0;

                    %update data masks
                    mask_index = check_mask_exists(app, mask_region.Tag);
                    if mask_index == 0
                        if  ~isempty(app.data.data_masks{app.active_camera_tab,2})
                            row_index = size(app.data.data_masks{app.active_camera_tab,2},1) +1;
                        else
                            row_index = 1;
                        end
                        update_table = 1;
                    else
                        row_index = mask_index;
                    end
                    app.data.data_masks{app.active_camera_tab,2}{row_index,1} = label;
                    app.data.data_masks{app.active_camera_tab,2}{row_index,2} = mask;
                    app.data.data_masks{app.active_camera_tab,2}{row_index,3} = mask_region.Tag;
                    app.active_data_masks = app.data.data_masks{app.active_camera_tab,2};

                    %update table data
                    if update_table
                        table_data = app.active_table.Data;
                        table_data{row_index,1} = label;
                        table_data{row_index,2} = '';
                        table_data{row_index,3} = '';
                        table_data{row_index,4} = '';
                        table_data{row_index,5} = '';
                        table_data{row_index,6} = '';

                        app.active_table.Data = table_data;
                        app.active_table.Selection = row_index;
                        if app.active_camera_tab == 1
                            app.cam1_table = app.active_table;
                        elseif app.active_camera_tab == 2
                            app.cam2_table = app.active_table;
                        elseif app.active_camera_tab == 3
                            app.cam3_table = app.active_table;
                        elseif app.active_camera_tab == 4
                            app.cam4_table = app.active_table;
                        end
                    end
                end

            catch ME
                error_message = strcat('ERROR in function update_mask(app) ', ME.message);
                update_console(app,error_message);
            end
        end


        %IS_DUPLICATE_LABEL  True if a mask label already exists in the list box.
        function duplicate = is_duplicate_label(app, label)
            try
                duplicate = 0;
                if ~isempty(label) && ~isempty(app.ListBox_4.Items)
                    label_list = app.ListBox_4.Items;
                    num_items = size(label_list,2);
                    for i=1:num_items
                        if strcmp(label, label_list(1,i))
                            duplicate = 1;
                            break
                        end
                    end
                end
            catch ME
                error_message = strcat('ERROR in function is_duplicated_label(app) ', ME.message);
                update_console(app,error_message);
            end

        end


        %CHECK_MASK_EXISTS  Index of an existing mask by Tag (0 if not present).
        function mask_index = check_mask_exists(app, mask_label)
            try
                mask_index = 0;
                if ~isempty(app.data.data_masks{app.active_camera_tab,2})
                    mask_list = app.data.data_masks{app.active_camera_tab,2}(:,1);
                    if ~isempty(mask_list)
                        num_masks = size(mask_list,1);
                        for i=1:num_masks
                            if strcmp(mask_label, mask_list{i,1})
                                mask_index = i;
                                break;
                            end
                        end
                    end
                end

            catch ME
                error_message = strcat('ERROR in function check_mask_exists(app) ', ME.message);
                update_console(app,error_message);
            end
        end


        %CHECK_MARKER_EXISTS  Index of a plot marker within 4 px of the mouse (0 if none).
        function marker_index = check_marker_exists(app)
            try
                marker_index = 0;
                start_index = 4*(app.active_camera_tab-1)+ 1;
                for i=start_index:start_index+3
                    marker = app.plot_markers{i,1};
                    if ~isempty(marker)
                        if pdist2([app.mouse_x, app.mouse_y], [marker.XData, marker.YData]) < 4
                            marker_index = i;
                            break;
                        end
                    end
                end

            catch ME
                error_message = strcat('ERROR in function check_marker_exists(app) ', ME.message);
                update_console(app,error_message);
            end

        end


        %IS_VALID_LOCATION  Is the mouse inside the image? Caches mouse_x/mouse_y.
        function valid_location = is_valid_location(app)
            try
                app.mouse_coordinates = get(app.active_axes,'CurrentPoint');
                app.mouse_x = round(app.mouse_coordinates(1,1));
                app.mouse_y = round(app.mouse_coordinates(1,2));

                if app.mouse_x >=1 && app.mouse_x <=app.x_axis && app.mouse_y >=1 && app.mouse_y <= app.y_axis
                    valid_location = 1;
                else
                    %update_console(app, 'marker out of bounds')
                    valid_location = 0;
                end
            catch ME
                error_message = strcat('ERROR in function is_valid_location(app) ', ME.message);
                update_console(app,error_message);
            end
        end


        %INITIALIZE_PLOT_MARKERS  Assign the 16 signal-trace colours (4 per camera).
        %   initialize_plot_markers(app)  -> fills app.plot_markers with rgb() colours.
        function initialize_plot_markers(app)
            try

                app.plot_markers{1,2} = rgb('Red');
                app.plot_markers{2,2} = rgb('Rust');
                app.plot_markers{3,2} = rgb('Salmon');
                app.plot_markers{4,2} = rgb('FireBrick');
                app.plot_markers{5,2} = rgb('Green');
                app.plot_markers{6,2} = rgb('SeaGreen');
                app.plot_markers{7,2} = rgb('Olive');
                app.plot_markers{8,2} = rgb('Teal');
                app.plot_markers{9,2} = rgb('Blue');
                app.plot_markers{10,2} = rgb('LightBlue');
                app.plot_markers{11,2} = rgb('Turquoise');
                app.plot_markers{12,2} = rgb('Cyan');
                app.plot_markers{13,2} = rgb('Orange');
                app.plot_markers{14,2} = rgb('DarkOrange');
                app.plot_markers{15,2} = rgb('DarkKhaki');
                app.plot_markers{16,2} = rgb('OrangeRed');

            catch ME
                error_message = strcat('ERROR in function initialize_plot_markers(app) ', ME.message);
                update_console(app,error_message);
            end
        end


        %UPDATE_PLOT_INDEX  Choose which of the 4 signal slots the next click fills.
        %   update_plot_index(app, mouse_action)
        %   On button-down, reuses an existing nearby marker or the camera's
        %   next-free slot; on button-up, advances that camera's slot counter (wraps
        %   1..4).
        function update_plot_index(app, mouse_action)
            try

                if isequal(mouse_action, 'button_down') && ~isempty(app.mouse_down)

                    marker_index = check_marker_exists(app);
                    if marker_index > 0
                        app.plot_index = marker_index;
                    else
                        if app.active_camera_tab == 1
                            app.plot_index = app.cam1_marker_index;
                        elseif app.active_camera_tab == 2
                            app.plot_index = 4 + app.cam2_marker_index;
                        elseif app.active_camera_tab == 3
                            app.plot_index = 8 + app.cam3_marker_index;
                        elseif app.active_camera_tab == 4
                            app.plot_index = 12 + app.cam4_marker_index;
                        end
                    end

                elseif isequal(mouse_action, 'button_up') && isempty(app.mouse_drag)

                    if app.active_camera_tab == 1
                        if app.plot_index == 4
                            app.cam1_marker_index = 1;
                        elseif app.plot_index < 4
                            app.cam1_marker_index = app.cam1_marker_index + 1;
                        end
                    elseif app.active_camera_tab == 2
                        if app.plot_index == 8
                            app.cam2_marker_index = 1;
                        elseif app.plot_index > 4 &&  app.plot_index < 8
                            app.cam2_marker_index = app.cam2_marker_index + 1;
                        end
                    elseif app.active_camera_tab == 3
                        if app.plot_index == 12
                            app.cam3_marker_index = 1;
                        elseif app.plot_index > 8 && app.plot_index < 12
                            app.cam3_marker_index = app.cam3_marker_index + 1;
                        end
                    elseif app.active_camera_tab == 4
                        if app.plot_index == 16
                            app.cam4_marker_index = 1;
                        elseif app.plot_index > 12 && app.plot_index < 16
                            app.cam4_marker_index = app.cam4_marker_index + 1;
                        end
                    end
                end

            catch ME
                error_message = strcat('ERROR in function plot_correct_marker(app) ', ME.message);
                update_console(app,error_message);
            end

        end


        %LOAD_FILE  Load a *-metrics .mat and schema-check it at the boundary.
        %   metrics = load_file(app, file_name)
        %   Loads the 'metrics' struct and validates it with schema_gate("metrics");
        %   returns [] (logging failures) on a schema FAIL.
        function metrics = load_file(app, file_name)
            try
                dir_path = strcat(app.dir_name, '/');
                app.mat_file_name = file_name;
                matlab_file_name = strcat(dir_path, file_name);
                fclose('all');
                file_data = load(matlab_file_name);
                metrics = file_data.cmos_all_data;
                [ok, msgs] = schema_gate(metrics, "metrics", file_name);
                for k = 1:numel(msgs), update_console(app, msgs{k}); end
                if ~ok, metrics = []; return; end

            catch ME
                error_message = strcat('ERROR in function load_file(app) ', ME.message);
                update_console(app,error_message);
            end
        end


        %GET_USER_MASK  Return the currently-selected data mask for the active camera.
        %   mask = get_user_mask(app)  -> {Tag, mask matrix} from the selected table row.
        function mask = get_user_mask(app)
            try
                mask = [];
                if ~isempty(app.data.data_masks)
                    user_masks = app.data.data_masks{app.active_camera_tab,2};
                    if ~isempty(user_masks)
                        row_index = 1;
                        switch app.active_camera_tab
                            case 1
                                row_index = app.cam1_table.Selection;
                                app.active_table = app.cam1_table;
                            case 2
                                row_index = app.cam2_table.Selection;
                                app.active_table = app.cam2_table;
                            case 3
                                row_index = app.cam3_table.Selection;
                                app.active_table = app.cam3_table;
                            case 4
                                row_index = app.cam4_table.Selection;
                                app.active_table = app.cam4_table;
                        end

                        if isempty(row_index)
                            row_index = 1;
                        end
                        mask{1,1} = user_masks{row_index,1};
                        mask{1,2} = user_masks{row_index,2};
                    end
                end
            catch ME
                error_message = strcat('ERROR - get_user_mask(app, event) ', ME.message);
                update_console(app,error_message);
            end
        end


        %GET_CURATED_DATA  Apply mask + histogram bounds to a scalar map and compute stats.
        %   [curated_data, stat_data] = get_curated_data(app, map_data)
        %   Optionally multiplies by the user mask and NaNs values outside the
        %   lower/upper bounds, then returns per-mask min/max/mean/median/std.
        function [curated_data, stat_data] = get_curated_data(app, map_data)
            try
                curated_data = map_data;
                stat_data = [];
                m_data = map_data{1,1};
                mask_switch = app.MaskSwitch.Value;

                switch mask_switch
                    case 'On'
                        mask = get_user_mask(app);
                        if ~isempty(mask)
                            m_data = m_data .* mask{1,2};
                        end
                end

                hist_switch = app.HistogramSwitch.Value;
                switch hist_switch
                    case 'On'
                        min_threshold = app.LowerBoundEditField.Value;
                        if min_threshold > 0
                            m_data(m_data < min_threshold) = nan;
                        end
                        max_threshold = app.UpperBoundEditField.Value;
                        if max_threshold > min_threshold
                            m_data(m_data > max_threshold) = nan;
                        end
                end
                curated_data{1,1} = m_data;

                user_masks = app.data.data_masks{app.active_camera_tab,2};
                if ~isempty(user_masks)
                    num_masks = size(user_masks,1);
                    stat_data = cell(num_masks,6);
                    for i=1:num_masks
                        mask_data = map_data{1,1};
                        user_data_mask = user_masks{i,2};
                        mask_data = mask_data .* user_data_mask;
                        stat_data{i,1} = user_masks{i,1};
                        stat_data{i,2} = min(mask_data, [], 'all');
                        stat_data{i,3} = max(mask_data, [], 'all');
                        stat_data{i,4} = round(mean(mask_data, 'all', 'omitnan'));
                        stat_data{i,5} = median(mask_data,'all', 'omitnan');
                        stat_data{i,6} = round(std(mask_data, 0,'all', 'omitnan'));
                    end
                end

            catch ME
                error_message = strcat('ERROR - get_curated_data(app, event) ', ME.message);
                update_console(app,error_message);
            end
        end


        %GET_CV_CURATED_DATA  get_curated_data variant for nested CV map cells.
        %   [curated_data, stat_data] = get_cv_curated_data(app, map_data)
        %   Same masking/bounds/stats as get_curated_data but indexes the CV map at
        %   map_data{1,1}{1,1}.
        function [curated_data, stat_data] = get_cv_curated_data(app, map_data)
            try
                curated_data = map_data;
                stat_data = [];
                m_data = map_data{1,1}{1,1};
                % Interior margin: same gate as the Conduction Velocity module's
                % interior_valid_mask (imfill + 8 px erosion), so both modules pool the
                % same pixel set. The saved local_cv map includes boundary pixels whose
                % one-sided smoothing biases CV high; without this the whole-map stats
                % here run systematically hotter than the CV module's for the same file.
                edge_margin = 8;
                tissue   = imfill(isfinite(m_data), 'holes');
                interior = imerode(tissue, true(2*edge_margin + 1));
                m_data(~interior) = NaN;
                mask_switch = app.MaskSwitch.Value;

                switch mask_switch
                    case 'On'
                        mask = get_user_mask(app);
                        if ~isempty(mask)
                            m_data = m_data .* mask{1,2};
                        end
                end

                hist_switch = app.HistogramSwitch.Value;
                switch hist_switch
                    case 'On'
                        min_threshold = app.LowerBoundEditField.Value;
                        if min_threshold > 0
                            m_data(m_data < min_threshold) = nan;
                        end
                        max_threshold = app.UpperBoundEditField.Value;
                        if max_threshold > min_threshold
                            m_data(m_data > max_threshold) = nan;
                        end
                end
                curated_data{1,1}{1,1} = m_data;

                user_masks = app.data.data_masks{app.active_camera_tab,2};
                if ~isempty(user_masks)
                    num_masks = size(user_masks,1);
                    stat_data = cell(num_masks,6);
                    for i=1:num_masks
                        mask_data = map_data{1,1}{1,1};
                        mask_data(~interior) = NaN;
                        user_data_mask = user_masks{i,2};
                        mask_data = mask_data .* user_data_mask;
                        stat_data{i,1} = user_masks{i,1};
                        stat_data{i,2} = min(mask_data, [], 'all');
                        stat_data{i,3} = max(mask_data, [], 'all');
                        stat_data{i,4} = round(mean(mask_data, 'all', 'omitnan'));
                        stat_data{i,5} = median(mask_data,'all', 'omitnan');
                        stat_data{i,6} = round(std(mask_data, 0,'all', 'omitnan'));
                    end
                end

            catch ME
                error_message = strcat('ERROR - get_cv_curated_data(app, event) ', ME.message);
                update_console(app,error_message);
            end
        end


        %GET_ALTERNANS_CURATED_DATA  get_curated_data variant for the APD50 alternans map.
        %   [curated_data, stat_data] = get_alternans_curated_data(app, map_data)
        %   Masks/bounds the map_data{1,1}.APD50_alt field and returns per-mask stats.
        function [curated_data, stat_data] = get_alternans_curated_data(app, map_data, ap_alternans)
            try
                curated_data = map_data;
                stat_data = [];
                if ap_alternans
                    m_data = map_data{1,1}.APD50_alt;
                else
                    m_data = map_data{1,1}.D50_alt_map;
                end
                [r,c] = size(m_data);
                mask_switch = app.MaskSwitch.Value;

                switch mask_switch
                    case 'On'
                        mask = get_user_mask(app);
                        if ~isempty(mask)
                            m_data = m_data .* mask{1,2};
                            curated_data{1,5} = mask{1,2};
                        else
                            curated_data{1,5} = ones(r,c);
                        end
                end

                hist_switch = app.HistogramSwitch.Value;
                switch hist_switch
                    case 'On'
                        min_threshold = app.LowerBoundEditField.Value;
                        if min_threshold > 0
                            m_data(m_data < min_threshold) = nan;
                        end
                        max_threshold = app.UpperBoundEditField.Value;
                        if max_threshold > min_threshold
                            m_data(m_data > max_threshold) = nan;
                        end
                end
                curated_data{1,1}.APD50_alt = m_data;

                user_masks = app.data.data_masks{app.active_camera_tab,2};
                if ~isempty(user_masks)
                    num_masks = size(user_masks,1);
                    stat_data = cell(num_masks,6);
                    for i=1:num_masks
                        if ap_alternans
                            mask_data = map_data{1,1}.APD50_alt;
                        else
                            mask_data = map_data{1,1}.D50_alt_map;
                        end
                        user_data_mask = user_masks{i,2};
                        mask_data = mask_data .* user_data_mask;
                        stat_data{i,1} = user_masks{i,1};
                        stat_data{i,2} = round(min(mask_data, [], 'all'));
                        stat_data{i,3} = round(max(mask_data, [], 'all'));
                        stat_data{i,4} = round(mean(mask_data, 'all', 'omitnan'));
                        stat_data{i,5} = round(median(mask_data,'all', 'omitnan'));
                        stat_data{i,6} = round(std(mask_data, 0,'all', 'omitnan'));
                    end
                end

            catch ME
                error_message = strcat('ERROR - get_alternans_curated_data(app, event) ', ME.message);
                update_console(app,error_message);
            end
        end


        %IS_ACT_TIMES_AVAILABLE  True if activation-time (LAT) data exists for the active camera.
        function available = is_act_times_available(app)
            try
                available = 0;
                if isfield(app.data.ep_metrics, 'act_times')
                    if ~isempty(app.data.ep_metrics.act_times) && ...
                            size(app.data.ep_metrics.act_times, 1) >= app.active_camera_tab && ...
                            ~isempty(app.data.ep_metrics.act_times{app.active_camera_tab,1})
                        available = 1;
                    end
                end
            catch ME
                error_message = strcat('ERROR - is_act_times_available(app) ', ME.message);
                update_console(app,error_message);

            end
        end


        %IS_REP_TIMES_AVAILABLE  True if repolarization-time data exists for the active camera.
        function available = is_rep_times_available(app)
            try
                available = 0;
                if isfield(app.data.ep_metrics, 'rep_data')
                    if ~isempty(app.data.ep_metrics.rep_data) && ...
                            size(app.data.ep_metrics.rep_data, 1) >= app.active_camera_tab && ...
                            ~isempty(app.data.ep_metrics.rep_data{app.active_camera_tab,1})
                        available = 1;
                    end
                end
            catch ME
                error_message = strcat('ERROR - is_rep_times_available(app) ', ME.message);
                update_console(app,error_message);

            end
        end


        %IS_APD_AVAILABLE  True if action-potential-duration (APD) data exists for the active camera.
        function available = is_apd_available(app)
            try
                available = 0;
                if isfield(app.data.ep_metrics, 'apd_data')
                    if ~isempty(app.data.ep_metrics.apd_data) && ...
                            size(app.data.ep_metrics.apd_data, 1) >= app.active_camera_tab && ...
                            ~isempty(app.data.ep_metrics.apd_data{app.active_camera_tab,1})
                        available = 1;
                    end
                end

            catch ME
                error_message = strcat('ERROR - is_apd_available(app) ', ME.message);
                update_console(app,error_message);

            end
        end


        %IS_AP_RISE_AVAILABLE  True if AP upstroke/rise-time data exists for the active camera.
        function available = is_ap_rise_available(app)
            try
                available = 0;
                if isfield(app.data.ep_metrics, 'ap_rise_times')
                    if ~isempty(app.data.ep_metrics.ap_rise_times) && ...
                            size(app.data.ep_metrics.ap_rise_times, 1) >= app.active_camera_tab && ...
                            ~isempty(app.data.ep_metrics.ap_rise_times{app.active_camera_tab,1})
                        available = 1;
                    end
                end

            catch ME
                error_message = strcat('ERROR - is_ap_rise_available(app) ', ME.message);
                update_console(app,error_message);

            end
        end


        %IS_CV_AVAILABLE  True if local conduction-velocity data exists for the active camera.
        function available = is_cv_available(app)
            try
                available = 0;
                if isfield(app.data.ep_metrics, 'local_cv')
                    if ~isempty(app.data.ep_metrics.local_cv) && ...
                            size(app.data.ep_metrics.local_cv, 1) >= app.active_camera_tab && ...
                            ~isempty(app.data.ep_metrics.local_cv{app.active_camera_tab,1})
                        available = 1;
                    end
                end

            catch ME
                error_message = strcat('ERROR - is_cv_available(app) ', ME.message);
                update_console(app,error_message);

            end
        end


        %IS_VC_DELAY_AVAILABLE  True if voltage-calcium (electromechanical) delay data exists.
        function available = is_vc_delay_available(app)
            try
                available = 0;
                if isfield(app.data.ep_metrics, 'vc_delay')
                    if ~isempty(app.data.ep_metrics.vc_delay) && ...
                            size(app.data.ep_metrics.vc_delay, 1) >= app.active_camera_tab && ...
                            ~isempty(app.data.ep_metrics.vc_delay{app.active_camera_tab,1})
                        available = 1;
                    end
                end

            catch ME
                error_message = strcat('ERROR - is_vc_delay_available(app) ', ME.message);
                update_console(app,error_message);

            end
        end


        %IS_CA_DECAY_AVAILABLE  True if calcium-transient decay data exists for the active camera.
        function available = is_ca_decay_available(app)
            try
                available = 0;
                if isfield(app.data.ep_metrics, 'ca_rep_data')
                    if ~isempty(app.data.ep_metrics.ca_rep_data) && ...
                            size(app.data.ep_metrics.ca_rep_data, 1) >= app.active_camera_tab && ...
                            ~isempty(app.data.ep_metrics.ca_rep_data{app.active_camera_tab,1})
                        available = 1;
                    end
                end

            catch ME
                error_message = strcat('ERROR - is_ca_decay_available(app) ', ME.message);
                update_console(app,error_message);

            end
        end


        %IS_CA_APD_AVAILABLE  True if calcium-transient duration data exists for the active camera.
        function available = is_ca_apd_available(app)
            try
                available = 0;
                if isfield(app.data.ep_metrics, 'ca_data')
                    if ~isempty(app.data.ep_metrics.ca_data) && ...
                            size(app.data.ep_metrics.ca_data, 1) >= app.active_camera_tab && ...
                            ~isempty(app.data.ep_metrics.ca_data{app.active_camera_tab,1})
                        available = 1;
                    end
                end

            catch ME
                error_message = strcat('ERROR - is_ca_apd_available(app) ', ME.message);
                update_console(app,error_message);

            end
        end


        %IS_CA_RISE_AVAILABLE  True if calcium-transient rise-time data exists for the active camera.
        function available = is_ca_rise_available(app)
            try
                available = 0;
                if isfield(app.data.ep_metrics, 'ca_rise_times')
                    if ~isempty(app.data.ep_metrics.ca_rise_times) && ...
                            size(app.data.ep_metrics.ca_rise_times, 1) >= app.active_camera_tab && ...
                            ~isempty(app.data.ep_metrics.ca_rise_times{app.active_camera_tab,1})
                        available = 1;
                    end
                end

            catch ME
                error_message = strcat('ERROR - is_ca_rise_available(app) ', ME.message);
                update_console(app,error_message);

            end
        end


        %IS_TAU_AVAILABLE  True if calcium decay-tau data exists for the active camera.
        function available = is_tau_available(app)
            try
                available = 0;
                if isfield(app.data.ep_metrics, 'ca_tau')
                    if ~isempty(app.data.ep_metrics.ca_tau) && ...
                            size(app.data.ep_metrics.ca_tau, 1) >= app.active_camera_tab && ...
                            ~isempty(app.data.ep_metrics.ca_tau{app.active_camera_tab,1})
                        available = 1;
                    end
                end

            catch ME
                error_message = strcat('ERROR - is_tau_available(app) ', ME.message);
                update_console(app,error_message);

            end
        end


        %IS_COMPLEXITY_AVAILABLE  True if arrhythmia-complexity data exists for the active camera.
        function available = is_complexity_available(app)
            try
                available = 0;
                if isfield(app.data.ep_metrics, 'complexity_data')
                    if ~isempty(app.data.ep_metrics.complexity_data) && ...
                            size(app.data.ep_metrics.complexity_data, 1) >= app.active_camera_tab && ...
                            ~isempty(app.data.ep_metrics.complexity_data{app.active_camera_tab,1})
                        available = 1;
                    end
                end

            catch ME
                error_message = strcat('ERROR - is_df_available(app) ', ME.message);
                update_console(app,error_message);

            end
        end


        %IS_ALTERNANS_AVAILABLE  True if alternans (APD50) data exists for the active camera.
        function available = is_alternans_available(app)
            try
                available = 0;
                if isfield(app.data.ep_metrics, 'alternans_data')
                    if ~isempty(app.data.ep_metrics.alternans_data) && ...
                            size(app.data.ep_metrics.alternans_data, 1) >= app.active_camera_tab && ...
                            ~isempty(app.data.ep_metrics.alternans_data{app.active_camera_tab,1})
                        available = 1;
                    end
                end

            catch ME
                error_message = strcat('ERROR - is_alternans_available(app) ', ME.message);
                update_console(app,error_message);

            end
        end


        %GET_SELECTED_MAP  Resolve the feature map chosen in the UI to curated data.
        %   [curated_data, type, label, stat_data] = get_selected_map(app)
        %   Reads the selected tab/radio (Vm / Ca / Arrhythmia feature), pulls the
        %   matching ep_metrics map for the active camera if available, and runs the
        %   appropriate curation. type: 0 = scalar map, 1 = CV, 2 = alternans.
        function [curated_data, type, label, stat_data] = get_selected_map(app)
            try
                curated_data = [];
                stat_data = [];
                app.active_cardiac_map = [];
                label = [];
                type = 0;
                selected_tab = app.TabGroup4.SelectedTab.Title;

                switch selected_tab
                    case "Action Potentials (Vm)"
                        selected_option = app.VmButtonGroup.SelectedObject.Text;
                        label = string(selected_option);
                        switch selected_option
                            case "LOCAL ACTIVATION TIME (LAT) MAP"
                                if is_act_times_available(app)
                                    map_data{1,1} = app.data.ep_metrics.act_times{app.active_camera_tab,1};
                                    map_data{1,2} = app.data.ep_metrics.act_times{app.active_camera_tab,2};
                                    app.active_cardiac_map{1,1} = app.data.ep_metrics.act_times{app.active_camera_tab,1};
                                    app.active_cardiac_map{1,2} = app.data.ep_metrics.act_times{app.active_camera_tab,6};
                                    [curated_data, stat_data] = get_curated_data(app, map_data);
                                end

                            case "REPOLARIZATION TIME MAP"
                                if is_rep_times_available(app)
                                    map_data{1,1} = app.data.ep_metrics.rep_data{app.active_camera_tab,1};
                                    map_data{1,2} = app.data.ep_metrics.rep_data{app.active_camera_tab,2};
                                    app.active_cardiac_map{1,1} = app.data.ep_metrics.rep_data{app.active_camera_tab,1};
                                    app.active_cardiac_map{1,2} = app.data.ep_metrics.rep_data{app.active_camera_tab,6};
                                    [curated_data, stat_data] = get_curated_data(app, map_data);
                                end

                            case "ACTION POTENTIAL DURATION (APD) MAP"
                                if is_apd_available(app)
                                    map_data{1,1} = app.data.ep_metrics.apd_data{app.active_camera_tab,1};
                                    map_data{1,2} = app.data.ep_metrics.apd_data{app.active_camera_tab,2};
                                    app.active_cardiac_map{1,1} = app.data.ep_metrics.apd_data{app.active_camera_tab,1};
                                    app.active_cardiac_map{1,2} = app.data.ep_metrics.apd_data{app.active_camera_tab,6};
                                    [curated_data, stat_data] = get_curated_data(app, map_data);
                                end

                            case "ACTION POTENTIAL RISE TIME MAP"
                                if is_ap_rise_available(app)
                                    map_data{1,1} = app.data.ep_metrics.ap_rise_times{app.active_camera_tab,1};
                                    map_data{1,2} = app.data.ep_metrics.ap_rise_times{app.active_camera_tab,2};
                                    app.active_cardiac_map{1,1} = app.data.ep_metrics.ap_rise_times{app.active_camera_tab,1};
                                    app.active_cardiac_map{1,2} = app.data.ep_metrics.ap_rise_times{app.active_camera_tab,6};
                                    [curated_data, stat_data] = get_curated_data(app, map_data);
                                end

                            case "LOCAL CONDUCTION VELOCITY MAP"
                                if is_cv_available(app)
                                    map_data{1,1} = app.data.ep_metrics.local_cv{app.active_camera_tab,1};
                                    map_data{1,2} = app.data.ep_metrics.local_cv{app.active_camera_tab,2};
                                    app.active_cardiac_map{1,1} = app.data.ep_metrics.act_times{app.active_camera_tab,1};
                                    app.active_cardiac_map{1,2} = app.data.ep_metrics.act_times{app.active_camera_tab,6};
                                    [curated_data, stat_data] = get_cv_curated_data(app, map_data);
                                    type = 1;
                                end
                        end

                    case "Calcium Transients (Ca)"
                        selected_option = app.CaButtonGroup.SelectedObject.Text;
                        switch selected_option
                            case "VOLTAGE-CALCIUM ACTIVATION DELAY MAP"
                                if is_vc_delay_available(app)
                                    map_data{1,1} = app.data.ep_metrics.vc_delay{app.active_camera_tab,1};
                                    map_data{1,2} = app.data.ep_metrics.vc_delay{app.active_camera_tab,2};
                                    app.active_cardiac_map{1,1} = app.data.ep_metrics.vc_delay{app.active_camera_tab,1};
                                    app.active_cardiac_map{1,2} = app.data.ep_metrics.vc_delay{app.active_camera_tab,6};
                                    [curated_data, stat_data] = get_curated_data(app, map_data);
                                end

                            case "CALCIUM DECAY TIME MAP"
                                if is_ca_decay_available(app)
                                    map_data{1,1} = app.data.ep_metrics.ca_rep_data{app.active_camera_tab,1};
                                    map_data{1,2} = app.data.ep_metrics.ca_rep_data{app.active_camera_tab,2};
                                    app.active_cardiac_map{1,1} = app.data.ep_metrics.ca_rep_data{app.active_camera_tab,1};
                                    app.active_cardiac_map{1,2} = app.data.ep_metrics.ca_rep_data{app.active_camera_tab,6};
                                    [curated_data, stat_data] = get_curated_data(app, map_data);
                                end

                            case "CALCIUM TRANSIENT DURATION MAP"
                                if is_ca_apd_available(app)
                                    map_data{1,1} = app.data.ep_metrics.ca_data{app.active_camera_tab,1};
                                    map_data{1,2} = app.data.ep_metrics.ca_data{app.active_camera_tab,2};
                                    app.active_cardiac_map{1,1} = app.data.ep_metrics.ca_data{app.active_camera_tab,1};
                                    app.active_cardiac_map{1,2} = app.data.ep_metrics.ca_data{app.active_camera_tab,6};
                                    [curated_data, stat_data] = get_curated_data(app, map_data);
                                end

                            case "CALCIUM RISE TIME MAP"
                                if is_ca_rise_available(app)
                                    map_data{1,1} = app.data.ep_metrics.ca_rise_times{app.active_camera_tab,1};
                                    map_data{1,2} = app.data.ep_metrics.ca_rise_times{app.active_camera_tab,2};
                                    app.active_cardiac_map{1,1} = app.data.ep_metrics.ca_rise_times{app.active_camera_tab,1};
                                    app.active_cardiac_map{1,2} = app.data.ep_metrics.ca_rise_times{app.active_camera_tab,6};
                                    [curated_data, stat_data] = get_curated_data(app, map_data);
                                end

                            case "CALCIUM DECAY CONSTANT (TAU) MAP"
                                if is_tau_available(app)
                                    map_data{1,1} = app.data.ep_metrics.ca_tau{app.active_camera_tab,1};
                                    map_data{1,2} = app.data.ep_metrics.ca_tau{app.active_camera_tab,2};
                                    app.active_cardiac_map{1,1} = app.data.ep_metrics.ca_tau{app.active_camera_tab,1};
                                    app.active_cardiac_map{1,2} = app.data.ep_metrics.ca_tau{app.active_camera_tab,6};
                                    [curated_data, stat_data] = get_curated_data(app, map_data);
                                end
                        end


                    case "Arrhythmia Dynamics"
                        selected_option = app.ArrhythmiaButtonGroup.SelectedObject.Text;
                        switch selected_option
                            case "DOMINANT FREQUENCY (DF)"
                                if is_complexity_available(app)
                                    map_data{1,1} = app.data.ep_metrics.complexity_data{app.active_camera_tab,1}{1,1};
                                    map_data{1,2} = app.data.ep_metrics.complexity_data{app.active_camera_tab,2};
                                    app.active_cardiac_map{1,1} = app.data.ep_metrics.complexity_data{app.active_camera_tab,1}{1,1};
                                    app.active_cardiac_map{1,2} = [];
                                    [curated_data, stat_data] = get_curated_data(app, map_data);
                                end

                            case "REGULARITY INDEX (RI)"
                                if is_complexity_available(app)
                                    map_data{1,1} = app.data.ep_metrics.complexity_data{app.active_camera_tab,1}{1,2};
                                    map_data{1,2} = app.data.ep_metrics.complexity_data{app.active_camera_tab,2};
                                    app.active_cardiac_map{1,1} = app.data.ep_metrics.complexity_data{app.active_camera_tab,1}{1,2};
                                    app.active_cardiac_map{1,2} = [];
                                    [curated_data, stat_data] = get_curated_data(app, map_data);
                                end

                            case "ORGANIZATION INDEX (OI)"
                                if is_complexity_available(app)
                                    map_data{1,1} = app.data.ep_metrics.complexity_data{app.active_camera_tab,1}{1,3};
                                    map_data{1,2} = app.data.ep_metrics.complexity_data{app.active_camera_tab,2};
                                    app.active_cardiac_map{1,1} = app.data.ep_metrics.complexity_data{app.active_camera_tab,1}{1,3};
                                    app.active_cardiac_map{1,2} = [];
                                    [curated_data, stat_data] = get_curated_data(app, map_data);
                                end

                            case "ALTERNANS (VOLTAGE OR CALCIUM)"
                                if is_alternans_available(app)
                                    map_data{1,1} = app.data.ep_metrics.alternans_data{app.active_camera_tab,1};
                                    map_data{1,2} = app.data.ep_metrics.alternans_data{app.active_camera_tab,2};
                                    map_data{1,3} = app.data.ep_metrics.alternans_data{app.active_camera_tab,3};
                                    map_data{1,4} = app.data.ep_metrics.alternans_data{app.active_camera_tab,6};
                                    ap_alternans   = strcmp(map_data{1,4}, 'AP');
                                    if ap_alternans
                                        app.active_cardiac_map{1,1} = app.data.ep_metrics.alternans_data{app.active_camera_tab,1}.APD50_alt;
                                        app.active_cardiac_map{1,2} = app.data.ep_metrics.alternans_data{app.active_camera_tab,1}.APD80_ratio_map;
                                    else
                                        app.active_cardiac_map{1,1} = app.data.ep_metrics.alternans_data{app.active_camera_tab,1}.D50_alt_map;
                                        app.active_cardiac_map{1,2} = app.data.ep_metrics.alternans_data{app.active_camera_tab,1}.D50_ratio_map;
                                    end
                                    [curated_data, stat_data] = get_alternans_curated_data(app, map_data, ap_alternans);
                                    type = 2;
                                end
                        end
                end

                % test = app.active_cardiac_map;

            catch ME
                error_message = strcat('ERROR - get_selected_map(app) ', ME.message);
                update_console(app,error_message);
            end
        end


        %GET_SIGNAL_LOCATIONS  Collect the selected pixel (row,col) pairs for the active camera.
        %   row_cols = get_signal_locations(app)  -> from the Row*/Col* readout fields.
        function row_cols = get_signal_locations(app)
            try
                row_cols = [];
                index = 1;
                switch app.active_camera_tab

                    case 1
                        if ~isempty(app.Row11.Value)
                            row_cols(index,1) = str2num(app.Row11.Value);
                            row_cols(index,2) = str2num(app.Col11.Value);
                            index = index +1;
                        end
                        if ~isempty(app.Row12.Value)
                            row_cols(index,1) = str2num(app.Row12.Value);
                            row_cols(index,2) = str2num(app.Col12.Value);
                            index = index +1;

                        end
                        if ~isempty(app.Row13.Value)
                            row_cols(index,1) = str2num(app.Row13.Value);
                            row_cols(index,2) = str2num(app.Col13.Value);
                            index = index +1;

                        end
                        if ~isempty(app.Row14.Value)
                            row_cols(index,1) = str2num(app.Row14.Value);
                            row_cols(index,2) = str2num(app.Col14.Value);
                            index = index +1;

                        end

                    case 2
                        if ~isempty(app.Row21.Value)
                            row_cols(index,1) = str2num(app.Row21.Value);
                            row_cols(index,2) = str2num(app.Col21.Value);
                            index = index +1;
                        end
                        if ~isempty(app.Row22.Value)
                            row_cols(index,1) = str2num(app.Row22.Value);
                            row_cols(index,2) = str2num(app.Col22.Value);
                            index = index +1;

                        end
                        if ~isempty(app.Row23.Value)
                            row_cols(index,1) = str2num(app.Row23.Value);
                            row_cols(index,2) = str2num(app.Col23.Value);
                            index = index +1;

                        end
                        if ~isempty(app.Row24.Value)
                            row_cols(index,1) = str2num(app.Row24.Value);
                            row_cols(index,2) = str2num(app.Col24.Value);
                            index = index +1;

                        end

                    case 3
                        if ~isempty(app.Row31.Value)
                            row_cols(index,1) = str2num(app.Row31.Value);
                            row_cols(index,2) = str2num(app.Col31.Value);
                            index = index +1;
                        end
                        if ~isempty(app.Row32.Value)
                            row_cols(index,1) = str2num(app.Row32.Value);
                            row_cols(index,2) = str2num(app.Col32.Value);
                            index = index +1;

                        end
                        if ~isempty(app.Row33.Value)
                            row_cols(index,1) = str2num(app.Row33.Value);
                            row_cols(index,2) = str2num(app.Col33.Value);
                            index = index +1;

                        end
                        if ~isempty(app.Row34.Value)
                            row_cols(index,1) = str2num(app.Row34.Value);
                            row_cols(index,2) = str2num(app.Col34.Value);
                            index = index +1;

                        end

                    case 4
                        if ~isempty(app.Row41.Value)
                            row_cols(index,1) = str2num(app.Row41.Value);
                            row_cols(index,2) = str2num(app.Col41.Value);
                            index = index +1;
                        end
                        if ~isempty(app.Row42.Value)
                            row_cols(index,1) = str2num(app.Row42.Value);
                            row_cols(index,2) = str2num(app.Col42.Value);
                            index = index +1;

                        end
                        if ~isempty(app.Row43.Value)
                            row_cols(index,1) = str2num(app.Row43.Value);
                            row_cols(index,2) = str2num(app.Col43.Value);
                            index = index +1;

                        end
                        if ~isempty(app.Row44.Value)
                            row_cols(index,1) = str2num(app.Row44.Value);
                            row_cols(index,2) = str2num(app.Col44.Value);
                            index = index +1;

                        end
                end

            catch ME
                error_message = strcat('ERROR - get_signal_locations(app) ', ME.message);
                update_console(app,error_message);
            end
        end


        %GET_OAP_DATA  Extract normalized optical-AP traces at the given pixels.
        %   [oap_data, range] = get_oap_data(app, locations, link_signals, signal_window)
        %   Returns one normalized trace per location over the (optional) signal
        %   window; with link_signals, also pulls the same pixel from every camera.
        function [oap_data, range] = get_oap_data(app, locations, link_signals, signal_window)
            try
                oap_data = [];
                sig_start = 1;
                sig_end = size(app.data.CAM1,3);
                if ~isempty(signal_window)
                    sig_start = signal_window(1,1);
                    sig_end = signal_window(1,2);
                end
                range(1,1) = sig_start;
                range(1,2) = sig_end;

                num_signals = size(locations,1);
                for i=1:num_signals
                    row = locations(i,1);
                    col = locations(i,2);
                    oap_data{i,1} = squeeze(normalize_data(app.active_data(row,col,sig_start:sig_end)));

                    if link_signals
                        for j=1:app.data.num_files
                            switch j
                                case 1
                                    oap_data{i,j} = squeeze(normalize_data(app.data.CAM1(row,col,sig_start:sig_end)));
                                case 2
                                    oap_data{i,j} = squeeze(normalize_data(app.data.CAM2(row,col,sig_start:sig_end)));
                                case 3
                                    oap_data{i,j} = squeeze(normalize_data(app.data.CAM3(row,col,sig_start:sig_end)));
                                case 4
                                    oap_data{i,j} = squeeze(normalize_data(app.data.CAM4(row,col,sig_start:sig_end)));
                            end
                        end
                    end
                end

            catch ME
                error_message = strcat('ERROR - get_oap_data(app) ', ME.message);
                update_console(app,error_message);
            end

        end


        %DISPLAY_SIGNALS  Pop-out figure of the selected AP traces (with optional pacing).
        %   display_signals(app, oap_data, range)
        %   Lays out one stacked subplot per signal; overlays the analog1 pacing
        %   trace when the Pacing checkbox is on. Used by Export Signals.
        function display_signals(app, oap_data, range)
            try
                [num_plots, num_cam] = size(oap_data);
                pacing      = app.PacingCheckBox.Value;
                pacing_data = [];
                time_data   = [];
                plot_pacing = 0;
                if pacing && isfield(app.data, 'analog1')
                    pacing_data = app.data.analog1(range(1,1):range(1,2));
                    interval    = range(1,2) - range(1,1) + 1;
                    time_data   = 1:interval;
                    plot_pacing = 1;
                end

                fig = figure("Name", "Export Signals");   % classic figure -> full interactive plot tools

                % ---- size: scale height to #signals, centered, clamped minimum ----
                scr = get(groot, 'ScreenSize');
                W = 1000;  H = min(scr(4)-80, max(400, num_plots*180 + 60));
                fig.Position = [max(1,(scr(3)-W)/2), max(1,(scr(4)-H)/2), W, H];
                minW = 700;  minH = 400;
                fig.AutoResizeChildren = 'off';                 % required for SizeChangedFcn to fire
                fig.SizeChangedFcn = @(src,~) set(src, 'Position', ...
                    [src.Position(1), src.Position(2), ...
                    max(src.Position(3), minW), max(src.Position(4), minH)]);

                % ---- one row per signal ----
                tl = tiledlayout(fig, num_plots, 1, 'TileSpacing', 'compact', 'Padding', 'compact');

                axs = gobjects(1, num_plots);
                for i = 1:num_plots
                    ax = nexttile(tl);
                    hold(ax, 'on');
                    for j = 1:num_cam
                        if j == 2
                            plot(ax, oap_data{i,j}, 'LineWidth', 1.5, 'LineStyle', '-.', 'Color', get_plot_color(app, j));
                        else
                            plot(ax, oap_data{i,j}, 'LineWidth', 1.5, 'Color', get_plot_color(app, j));
                        end
                    end
                    if plot_pacing
                        line(ax, time_data, pacing_data, 'LineWidth', 1, 'Color', '#777777');
                    end
                    title(ax, "Signal " + i);
                    axs(i) = ax;
                end

                drawnow;                    % flush so axes have real data limits before linking
                if num_plots > 1
                    linkaxes(axs, 'x');     % zoom/pan x on one panel -> all panels follow
                end

            catch ME
                error_message = strcat('ERROR - display_signals(app) ', ME.message);
                update_console(app, error_message);
            end

        end


        %GET_PLOT_COLOR  Per-camera plot colour for linked signals (1 b,2 r,3 g,4 m).
        function color_code = get_plot_color(app, cam_index)
            try
                color_code = 'b';
                switch cam_index
                    case 2
                        color_code = 'r';
                    case 3
                        color_code = 'g';
                    case 4
                        color_code = 'm';
                end

            catch ME
                error_message = strcat('ERROR - get_plot_color(app) ', ME.message);
                update_console(app, error_message);
            end

        end


        %DISPLAY_CARDIAC_MAP  Pop-out figure of a scalar feature map + signal + stats table.
        %   display_cardiac_map(app, label, map_data)
        %   Tiled figure: the curated map overlaid on the camera image, a
        %   representative signal, and the per-mask statistics.
        function display_cardiac_map(app, label, map_data)
            try
                [row, col] = get_pixel_location(app);
                fig_title  = label + ": CAM" + app.active_camera_tab;   % string concat, no strcat

                fig = figure("Name", fig_title);   % classic figure -> full interactive plot tools

                % ---- generous, centered default size + enforced minimum ----
                W = 1100;  H = 800;
                scr = get(groot, 'ScreenSize');
                fig.Position = [max(1,(scr(3)-W)/2), max(1,(scr(4)-H)/2), W, H];
                minW = 850;  minH = 600;
                fig.AutoResizeChildren = 'off';                 % required for SizeChangedFcn to fire
                fig.SizeChangedFcn = @(src,~) set(src, 'Position', ...
                    [src.Position(1), src.Position(2), ...
                    max(src.Position(3), minW), max(src.Position(4), minH)]);

                % ---- tiled layout for the 3 plot panels (tile 4 left empty for table) ----
                tl = tiledlayout(fig, 2, 2, 'TileSpacing', 'compact', 'Padding', 'compact');

                % ---- map panel (tile 1, top-left) ----
                ax1 = nexttile(tl, 1);
                imagesc(ax1, real2rgb(app.active_image, 'gray'));
                hold(ax1, 'on');
                imagesc(ax1, map_data{1,1}, 'AlphaData', ~isnan(map_data{1,1}));
                line(ax1, col, row, 'Marker','square', 'MarkerSize',10, ...
                    'MarkerEdgeColor','w', 'MarkerFaceColor','r');
                set(ax1, 'XTick', [], 'YTick', []);
                colormap(ax1, 'jet');
                colorbar(ax1, 'southoutside');

                % Robust color scale: median +/- 3 robust-sigma (MAD-based). Unlike mean/std it
                % ignores wrong-beat / speckle outliers, so the true isochrone band fills the
                % colormap and the elliptical wavefront + pacing site become visible.
                d = map_data{1,1};
                vals = d(isfinite(d));
                med  = median(vals);
                rsig = 1.4826 * median(abs(vals - med));      % robust sigma
                if rsig > 0
                    color_scale = [floor(med - 3*rsig), ceil(med + 3*rsig)];
                else
                    color_scale = [min(vals), max(vals)];
                end
                clim(ax1,color_scale);
                axis(ax1, 'image');

                % ---- histogram (tile 2, top-right) ----
                ax2 = nexttile(tl, 2);
                histogram(ax2, map_data{1,1});

                % ---- OAP trace + pacing (tile 3, bottom-left) ----
                ax3 = nexttile(tl, 3);
                oap = squeeze(app.active_data(row, col, :));
                app.time = 1:size(oap,1);
                plot(ax3, oap, 'LineWidth', 1.5);
                hold(ax3, 'on');
                line(ax3, app.time, app.data.analog1, 'LineWidth', 1, 'Color', '#777777');

                % ---- stats table (overlay in the empty bottom-right tile region) ----
                row_names = {'Mean'; 'STD'; 'Median'; 'Sample'};
                col_names = {char(fig_title)};
                [mean_val, std_val, median_val, sample_val] = get_table_values(app, map_data{1,1});
                table_data = {mean_val; std_val; median_val; sample_val};
                uitable(fig, 'Data', table_data, 'ColumnName', col_names, 'RowName', row_names, ...
                    'Units', 'normalized', 'Position', [0.56 0.10 0.40 0.32]);

            catch ME
                error_message = strcat('ERROR - display_cardiac_map(app, event) ', ME.message);
                update_console(app, error_message);
            end

        end


        %GET_TABLE_VALUES  Mean/std/median and a sample value from a stats table block.
        %   [mean_val, std_val, median_val, sample_val] = get_table_values(app, table_data)
        function [mean_val, std_val, median_val, sample_val] = get_table_values(app, table_data)
            try
                mean_val = NaN;
                std_val = NaN;
                median_val = NaN;
                sample_val = NaN;

                if ~isempty(table_data)
                    mean_val = round(mean(table_data, "all", "omitmissing"),1);
                    std_val = round(std(table_data(:),"omitmissing"),1);
                    median_val = round(median(table_data,"all","omitmissing"),1);
                    [row, col] = get_pixel_location(app);
                    sample_val = round(table_data(row,col));
                end

            catch ME
                update_console(app, strcat('ERROR in get_table_values: ', ME.message));
            end
        end


        %GET_PIXEL_LOCATION  Pixel for the representative signal (selected, else image centre).
        %   [row, col] = get_pixel_location(app)
        function [row, col] = get_pixel_location(app)
            try
                [available, row, col] = is_signal_available(app);
                if ~available
                    [r,c,~] = size(app.active_data);
                    row = round(r/2);
                    col = round(c/2);
                end
            catch ME
                update_console(app, strcat('ERROR in get_pixel_location: ', ME.message));
            end
        end

        %DISPLAY_ALTERNANS_MAP  Pop-out figure of the APD50 alternans map + stats.
        %   display_alternans_map(app, label, map_data)
        function display_alternans_map(app, label, map_data)
            try
                alternans_data = map_data{1,1};
                substrate_data = map_data{1,3};
                mask = map_data{1,5};
                [row, col]     = get_pixel_location(app);
                ap_alternans   = strcmp(map_data{1,4}, 'AP');

                fig_title = label + ": CAM" + app.active_camera_tab;   % string concat, no strcat
                fig = figure("Name", fig_title);   % classic figure -> full interactive plot tools

                % ---- generous, centered default size + enforced minimum ----
                W = 1200;  H = 850;
                scr = get(groot, 'ScreenSize');
                fig.Position = [max(1,(scr(3)-W)/2), max(1,(scr(4)-H)/2), W, H];
                minW = 900;  minH = 650;
                fig.AutoResizeChildren = 'off';                 % required for SizeChangedFcn to fire
                fig.SizeChangedFcn = @(src,~) set(src, 'Position', ...
                    [src.Position(1), src.Position(2), ...
                    max(src.Position(3), minW), max(src.Position(4), minH)]);

                % ---- tiled layout: 6 panels (tiles 1-6), trace spans 7-8, table over tile 9 ----
                tl = tiledlayout(fig, 3, 3, 'TileSpacing', 'compact', 'Padding', 'compact');

                % ---- per-panel data + titles (branch-specific) ----
                if ap_alternans
                    panel_data  = { substrate_data.gradient_mag .* mask, ...
                        substrate_data.restitution_map .* mask, ...
                        alternans_data.APD50_ratio_map .* mask, ...
                        alternans_data.dvdt_ratio_map .* mask, ...
                        substrate_data.risk_map .* mask, ...
                        alternans_data.phase_map .* mask };
                    panel_title = {'APD50 Spatial Gradient', 'APD50 Restitution Slope', ...
                        'APD50 Alternans Ratio Map', 'Conduction (dv/dt ratio map)', ...
                        'AP Alternans Risk Map', 'Alternans Phase Map'};
                else
                    if ~isempty(substrate_data), coupling = substrate_data.coupling_map; else, coupling = []; end
                    panel_data  = { coupling .* mask, ...
                        alternans_data.release_alt_map .* mask, ...
                        alternans_data.load_alt_map .* mask, ...
                        alternans_data.D50_alt_map .* mask, ...
                        alternans_data.D50_ratio_map .* mask, ...
                        alternans_data.phase_map .* mask };
                    panel_title = {'Ca-AP Pearson r map', 'SR Ca Release Alternans Map', ...
                        'SR Ca Load Alternans Map', 'CaTD50 Alternans Map', ...
                        'CaTD50 Alternans Ratio Map', 'CaT Amplitude Alternans Phase Map'};
                end

                % ---- render the 6 map panels (tiles 1-6, row-major) ----
                table_data = cell(6, 4);   % [mean std median sample] per panel
                for k = 1:6
                    ax = nexttile(tl, k);

                    imagesc(ax, real2rgb(map_data{1,2}, 'gray'));
                    hold(ax, 'on');

                    d = panel_data{k};
                    if ~isempty(d)
                        imagesc(ax, d, 'AlphaData', ~isnan(d));
                    end
                    title(ax, panel_title{k});
                    line(ax, col, row, 'Marker','square', 'MarkerSize',10, ...
                        'MarkerEdgeColor','w', 'MarkerFaceColor','r');
                    set(ax, 'XTick', [], 'YTick', []);
                    colormap(ax, 'jet');
                    colorbar(ax, 'southoutside');
                    % Robust color scale: median +/- 3 robust-sigma (MAD-based). Unlike mean/std it
                    % ignores wrong-beat / speckle outliers, so the true isochrone band fills the
                    % colormap and the elliptical wavefront + pacing site become visible.
                    vals = d(isfinite(d));
                    med  = median(vals);
                    rsig = 1.4826 * median(abs(vals - med));      % robust sigma
                    if rsig > 0
                        color_scale = [floor(med - 3*rsig), ceil(med + 3*rsig)];
                    else
                        color_scale = [min(vals), max(vals)];
                    end
                    clim(ax,color_scale);
                    axis(ax, 'image');

                    [table_data{k,1}, table_data{k,2}, table_data{k,3}, table_data{k,4}] = ...
                        get_table_values(app, d);
                end

                % ---- bottom-left: OAP trace + pacing (spans tiles 7-8) ----
                ax7 = nexttile(tl, 7, [1 2]);
                oap = squeeze(app.active_data(row, col, :));
                app.time = 1:size(oap,1);
                plot(ax7, oap, 'LineWidth', 1.5);
                hold(ax7, 'on');
                line(ax7, app.time, app.data.analog1, 'LineWidth', 1, 'Color', '#777777');

                % ---- stats table (overlay in the empty bottom-right tile region) ----
                col_names = {'Mean'; 'STD'; 'Median'; 'Sample'};
                if ap_alternans
                    row_names = {'APD50 Spatial Gradient'; 'APD50 Resitution Slope'; 'APD50_Alt_Ratio'; ...
                        'Conduction'; 'AP_Amp_Ratio'; 'APD50 Phase'};
                else
                    row_names = {'AP_Ca Coupling'; 'SR Ca Release'; 'SR Ca Load'; ...
                        'CaD50_Alt'; 'CaD50_Alt_Ratio'; 'CaT Amp Phase'};
                end

                uitable(fig, 'Data', table_data, 'ColumnName', col_names, 'RowName', row_names, ...
                    'Units', 'normalized', 'Position', [0.69 0.04 0.29 0.26]);

            catch ME
                error_message = strcat('ERROR - display_alternans_map(app, event) ', ME.message);
                update_console(app, error_message);
            end

        end


        function valid = interior_valid_mask(app, Vmag, Vx, Vy, cap)
            % Valid CV pixels for directional pooling: finite, below cap, and >=
            % EDGE_MARGIN px inside the tissue boundary (edge/curvature pixels are
            % excluded because the inverse-gradient method inflates CV there).
            try
                edge_margin = 8;
                tissue = imfill(isfinite(Vmag), 'holes');   % fill reject_over_cap NaN holes so erosion only trims the true boundary
                interior = imerode(tissue, true(2*edge_margin + 1));
                valid = interior & isfinite(Vmag) & isfinite(Vx) & isfinite(Vy) & (Vmag < cap) & (Vmag > 1);
            catch ME
                valid = isfinite(Vmag) & isfinite(Vx) & isfinite(Vy) & (Vmag < cap);
                update_console(app, strcat('ERROR in interior_valid_mask(app) ', ME.message));
            end
        end


        %DISPLAY_CV_MAP  Pop-out figure of the local conduction-velocity map + stats.
        %   display_cv_map(app, label, map_data)
        function display_cv_map(app, label, map_data)
            try
                fig_title = label + ": CAM" + app.active_camera_tab;     % string concat, no strcat

                fig = figure("Name", fig_title);   % classic figure -> full interactive plot tools

                % ---- generous, centered default size + enforced minimum ----
                W = 1100;  H = 700;
                scr = get(groot, 'ScreenSize');
                fig.Position = [max(1,(scr(3)-W)/2), max(1,(scr(4)-H)/2), W, H];
                minW = 850;  minH = 500;
                fig.AutoResizeChildren = 'off';                 % required for SizeChangedFcn to fire
                fig.SizeChangedFcn = @(src,~) set(src, 'Position', ...
                    [src.Position(1), src.Position(2), ...
                    max(src.Position(3), minW), max(src.Position(4), minH)]);

                tl = tiledlayout(fig, 1, 2, 'TileSpacing', 'compact', 'Padding', 'compact');

                % ---- CV map + quiver overlay (left) ----
                ax1 = nexttile(tl, 1);
                imagesc(ax1, real2rgb(app.active_image, 'gray'));
                hold(ax1, 'on');
                imagesc(ax1, map_data{1,1}{1,1}, 'AlphaData', ~isnan(map_data{1,1}{1,1}));

                cvmag = map_data{cam_counter,1}{1,1};
                Vx = map_data{cam_counter,1}{1,2};
                Vy = map_data{cam_counter,1}{1,3};
                % Color scale from INTERIOR pixels only (same 8-px erosion gate as the
                % histogram and ellipse fit); edge pixels are inflated by the inverse-gradient
                % method and would otherwise drive the 98th percentile. Capped at 100 cm/s.
                valid = interior_valid_mask(app, cvmag, Vx, Vy, 249.9);
                vals  = cvmag(valid & cvmag > 0);
                if nnz(vals) < 50                                   % interior starved -> full field
                    vals = cvmag(isfinite(cvmag) & cvmag > 0);
                end
                color_scale = [floor(prctile(vals, 2)), min(100, ceil(prctile(vals, 98)))];
                clim(ax1,color_scale);
                axis(ax1, 'image');

                set(ax1, 'XTick', [], 'YTick', []);
                colormap(ax1, 'jet');
                cb = colorbar(ax1, 'southoutside');
                cb.Label.String = 'CV (cm/s)';

                Vx   = map_data{1,1}{1,2};
                Vy   = map_data{1,1}{1,3};
                T    = map_data{1,1}{1,1};
                meta = map_data{1,1}{1,6};

                % Downsample quiver on a PHYSICAL grid (~1 mm between arrows) so density is
                % consistent across species/FOV instead of tied to pixel count.
                px_mm = app.data.FOV / size(T,1);            % mm per pixel
                step  = max(2, round(1.0 / max(px_mm, eps))); % ~1 mm spacing
                [xx, yy] = meshgrid(1:size(T,2), 1:size(T,1));
                if isstruct(meta) && isfield(meta,'good') && ~isempty(meta.good)
                    good = meta.good;
                else
                    good = isfinite(Vx) & isfinite(Vy);       % fallback if engine lacks meta.good
                end
                mask = good & mod(xx,step)==0 & mod(yy,step)==0;
                % Arrow length encodes speed (clipped to a robust reference so a few fast
                % pixels don't dominate), instead of forcing every arrow to unit length.
                mag  = hypot(Vx, Vy);
                vref = prctile(mag(mask), 90);
                if ~isfinite(vref) || vref <= 0, vref = 1; end
                Vxs = Vx ./ vref;  Vys = Vy ./ vref;
                quiver(ax1, xx(mask), yy(mask), Vxs(mask), Vys(mask), 0.9, 'm', 'LineWidth', 0.5);
                axis(ax1, 'image');

                % ---- CV distribution histogram (right) ----
                ax2 = nexttile(tl, 2);
                histogram(ax2, map_data{1,1}{1,1});
                xlabel(ax2, 'Speed (cm/s)');
                ylabel(ax2, 'Count');
                title(ax2, 'Conduction velocity distribution');

            catch ME
                error_message = strcat('ERROR - display_cv_map(app, event) ', ME.message);
                update_console(app, error_message);
            end

        end


        %RESET_TABLE_DATA  Rebuild the active camera's stats table from its masks.
        function reset_table_data(app)
            try
                user_masks = app.data.data_masks{app.active_camera_tab,2};
                switch app.active_camera_tab
                    case 1
                        app.cam1_table.Data = load_table_data(app, user_masks);

                    case 2
                        app.cam2_table.Data = load_table_data(app, user_masks);

                    case 3
                        app.cam3_table.Data = load_table_data(app, user_masks);

                    case 4
                        app.cam4_table.Data = load_table_data(app, user_masks);

                end

            catch ME
                error_message = strcat('ERROR - reset_table_data(app, event) ', ME.message);
                update_console(app,error_message);
            end
        end


        %LOAD_TABLE_DATA  Build empty stats-table rows (one per mask label).
        %   [table_data] = load_table_data(app, masks)
        function [table_data] = load_table_data(app, masks)
            try
                table_data = [];
                if ~isempty(masks)
                    num_masks = size(masks,1);
                    table_data = cell(num_masks,6);
                    for i=1:num_masks
                        table_data{i,1} = masks{i,1};
                        table_data{i,2} = '';
                        table_data{i,3} = '';
                        table_data{i,4} = '';
                        table_data{i,5} = '';
                        table_data{i,6} = '';
                    end
                end
            catch ME
                error_message = strcat('ERROR - load_table_data(app, event) ', ME.message);
                update_console(app,error_message);
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %STARTUPFCN  App initialisation — add the CADENCE code tree to the path.
            if ~isdeployed
                root = pwd;
                p = strsplit(genpath(root), pathsep);
                rel = erase(p, root);
                skip = startsWith(rel, filesep + ["distribution" "release" "notarize"]);
                addpath(strjoin(p(~skip), pathsep));
            end
            initialize_plot_markers(app);

        end

        % Button pushed function: SELECTDIRButton
        function SELECTDIRButtonPushed(app, event)
            %SELECTDIRBUTTONPUSHED  Choose the input directory and list its *.mat files.
            try
                app.UIFigure.Visible = 'off';
                app.dir_name = uigetdir;
                app.UIFigure.Visible = 'on';

                if app.dir_name ~= 0
                    search_name = [app.dir_name,'/*.mat'];
                    files = struct2cell(dir(search_name));
                    if ~isempty(files)
                        app.ListBox_2.Items = files(1,:);
                        tot_num_files = num2str(size(files,2));
                        completed_message = strcat(tot_num_files, ' files found in the selected directory. ');
                        update_console(app,completed_message);
                    else
                        warning_message = 'Did not find any *.mat files in the selected directory. ';
                        update_console(app,warning_message);
                    end
                end
            catch ME
                error_message = strcat('ERROR - SIGNAL ANALYSIS - SELECTDIRButtonPushed(app, event) ', ME.message);
                update_console(app,error_message);
            end
        end

        % Button pushed function: REFRESHDIRButton
        function REFRESHDIRButtonPushed(app, event)
            %REFRESHDIRBUTTONPUSHED  Re-scan the current directory for *.mat files.
            try
                if app.dir_name ~= 0
                    search_name = [app.dir_name,'/*.mat'];
                    files = struct2cell(dir(search_name));
                    if ~isempty(files)
                        app.ListBox_2.Items = files(1,:);
                        completed_message = 'Refreshed selected directory.  ';
                        update_console(app,completed_message);
                    else
                        warning_message = 'Did not find any *.mat files in the selected directory after refresh.  ';
                        update_console(app,warning_message);
                    end
                end

            catch ME
                error_message = strcat('ERROR - REFRESHDIRButtonPushed(app, event) ', ME.message);
                update_console(app,error_message);
            end
        end

        % Button pushed function: LOADButton
        function LOADButtonPushed(app, event)
            %LOADBUTTONPUSHED  Load the selected recording and render its camera images.
            %   Resets state, loads the metrics struct (load_file), draws each present
            %   camera's background image and sizes its axes.
            try

                d = uiprogressdlg(app.UIFigure, 'Title', 'Loading data file', 'Indeterminate','on');
                reset_default_state(app);
                file_name = app.ListBox_2.Value;
                if ~isempty(file_name)

                    d.Message = strcat('Loading data file ...', file_name);

                    app.data = load_file(app,file_name);
                    app.active_data = app.data.CAM1;
                    app.time = 1:size(app.data.CAM1,3);

                    if is_act_times_available(app)
                        app.active_cardiac_map{1,1} = app.data.ep_metrics.act_times{app.active_camera_tab,1};
                        app.active_cardiac_map{1,2} = app.data.ep_metrics.act_times{app.active_camera_tab,6};
                    end

                    app.active_data_masks = app.data.data_masks{1,2};
                    app.active_image = app.data.CAM1_image;
                    app.active_table = app.cam1_table;
                    app.cam1_table.Data = load_table_data(app, app.data.data_masks{1,2});

                    [app.x_axis, app.y_axis] = size(app.data.CAM1(:,:,1));
                    app.UIAxes_cam1.XLim = [1, app.x_axis];
                    app.UIAxes_cam1.YLim = [1, app.y_axis];
                    imagesc(app.UIAxes_cam1, real2rgb(app.data.CAM1_image,'gray'));
                    set(app.UIAxes_cam1, 'XTick', [], 'YTick', []);
                    colorbar(app.UIAxes_cam1, "off");

                    if app.data.num_files >=2
                        app.UIAxes_cam2.XLim = [1, app.x_axis];
                        app.UIAxes_cam2.YLim = [1, app.y_axis];
                        imagesc(app.UIAxes_cam2, real2rgb(app.data.CAM2_image,'gray'));
                        set(app.UIAxes_cam2, 'XTick', [], 'YTick', []);
                        colorbar(app.UIAxes_cam2, "off");
                        app.cam2_table.Data = load_table_data(app, app.data.data_masks{2,2});
                    end

                    if app.data.num_files >=3
                        app.UIAxes_cam3.XLim = [1, app.x_axis];
                        app.UIAxes_cam3.YLim = [1, app.y_axis];
                        imagesc(app.UIAxes_cam3, real2rgb(app.data.CAM3_image,'gray'));
                        set(app.UIAxes_cam3, 'XTick', [], 'YTick', []);
                        colorbar(app.UIAxes_cam3, "off");
                        app.cam3_table.Data = load_table_data(app, app.data.data_masks{3,2});
                    end

                    if app.data.num_files ==4
                        app.UIAxes_cam4.XLim = [1, app.x_axis];
                        app.UIAxes_cam4.YLim = [1, app.y_axis];
                        imagesc(app.UIAxes_cam4, real2rgb(app.data.CAM4_image,'gray'));
                        set(app.UIAxes_cam4, 'XTick', [], 'YTick', []);
                        colorbar(app.UIAxes_cam4, "off");
                        app.cam4_table.Data = load_table_data(app, app.data.data_masks{4,2});
                    end

                    close(d);
                    completed_message = strcat(file_name, ' ....loaded successfully ');
                    update_console(app,completed_message);

                end

            catch ME
                error_message = strcat('ERROR - LOADButtonPushed(app, event) ', ME.message);
                update_console(app,error_message);
            end

        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            %UIFIGUREWINDOWBUTTONDOWN  Right-click on a map: place a marker and plot its signal.
            try
                mouse_click = get(app.UIFigure,'SelectionType');
                if isequal(mouse_click, 'alt') && update_active_tab_axes_data(app) && is_valid_location(app)
                    app.mouse_down = 1;
                    update_plot_marker(app, 'button_down');
                    update_plot_signal(app);

                end
            catch ME
                error_message = strcat('ERROR - FEATUREEXTRACTION- WindowBUttonDown(app, event) ', ME.message);
                update_console(app,error_message);
            end
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            %UIFIGUREWINDOWBUTTONMOTION  Right-drag: move the marker and live-update the signal.
            try
                if ~isempty(app.mouse_down) %mouse in motion while button pushed down
                    mouse_click = get(app.UIFigure,'SelectionType'); % The result can be 'normal', 'extend', 'alt', 'open'
                    if isequal(mouse_click, 'alt') && update_active_tab_axes_data(app) && is_valid_location(app)
                        app.mouse_drag = 1;
                        update_plot_marker(app, 'button_motion');
                        update_plot_signal(app); %

                    end
                end

            catch ME
                error_message = strcat('ERROR - FEATUREEXTRACTION- WindowBUttonMotion(app, event) ', ME.message);
                update_console(app,error_message);
            end
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            %UIFIGUREWINDOWBUTTONUP  End marker drag; advance the signal-slot index.
            try
                if ~isempty(app.mouse_down) || ~isempty(app.mouse_drag)
                    update_plot_index(app,'button_up');
                end
                app.mouse_drag = [];
                app.mouse_down = [];
            catch ME
                error_message = strcat('ERROR - FEATUREEXTRACTION- WindowBUttonUP(app, event) ', ME.message);
                update_console(app,error_message);
            end
        end

        % Button pushed function: DISPLAYCARDIACMAPButton
        function DISPLAYCARDIACMAPButtonPushed(app, event)
            %DISPLAYCARDIACMAPBUTTONPUSHED  Display the selected feature map.
            %   Resolves the chosen map (get_selected_map) and dispatches to the
            %   cardiac / CV / alternans display, then fills the stats table.
            try
                if ~isempty(app.data)
                    [map_data, type, label, stat_data] = get_selected_map(app);
                    reset_table_data(app);

                    if ~isempty(map_data)
                        app.active_cardiac_map = map_data;
                        if type == 2
                            display_alternans_map(app, label, map_data);
                        elseif type == 1
                            display_cv_map(app, label, map_data);
                        elseif type == 0
                            display_cardiac_map(app, label, map_data);
                        end
                        app.active_table.Data = stat_data;
                    else
                        app.active_cardiac_map = [];
                        update_console(app, 'Selected cardiac map data not available.')

                    end
                    %update_table_data(app, stat_data);

                else
                    update_console(app, 'No data files loaded. Please load at at least one data file.')
                end
            catch ME
                error_message = strcat('ERROR - DISPLAYCARDIACMAPButtonPushed(app, event) ', ME.message);
                update_console(app,error_message);
            end
        end

        % Button pushed function: PLAYMOVIEButton
        function PLAYMOVIEButtonPushed(app, event)
            %PLAYMOVIEBUTTONPUSHED  Animate the active camera over the slider frame range.
            %   Plays raw / phase / annotated-rotor layers (get_movie_data) frame-by-frame
            %   until the end of range or STOP.
            try
                if ~isempty(app.data)
                    [movie, mask] = get_movie_data(app);

                    curate = 0;  curate_mask = [];
                    if ~isempty(mask) && ~strcmp(mask{1,1}, "Default")
                        curate = 1;
                        curate_mask = mask{1,2};
                        curate_mask(isnan(curate_mask)) = 0;
                        curate_mask = logical(curate_mask);
                    end

                    rotor      = size(movie,2) > 1;
                    movie_data = movie{1,1};
                    [start_frame, end_frame, max_frames] = get_movie_frames_range(app);

                    % ---- precompute constants (were recomputed every frame) ----
                    bg_rgb   = real2rgb(app.active_image, 'gray');
                    [nr, nc, ~] = size(bg_rgb);
                    has_mask = ~isempty(mask);
                    if has_mask, mask_mat = mask{1,2}; end
                    IVORY = rgb('Ivory');  BLACK = rgb('Black');
                    if rotor
                        wf_frames   = movie{1,2}{1,1};      % cache nested cells
                        ps_frames   = movie{1,3}{1,2};
                        ps_dynamics = movie{1,3}{1,4};
                    end

                    % ---- build the axes ONCE; update data in the loop ----
                    cla(app.active_axes);
                    imagesc(app.active_axes, bg_rgb);
                    hold(app.active_axes, 'on');
                    hImg = imagesc(app.active_axes, nan(nr, nc), 'AlphaData', zeros(nr, nc));
                    if rotor
                        clim(app.active_axes, [-pi pi]);
                        colormap(app.active_axes, hsv(256));
                    else
                        clim(app.active_axes, [0 1]);
                        colormap(app.active_axes, "jet");
                    end
                    colorbar(app.active_axes);

                    % ---- sweepbar created once; only its XData changes ----
                    cla(app.active_sweep);
                    hSweep = plot(app.active_sweep, [start_frame start_frame], [0 1], 'r', 'LineWidth', 1);
                    axis(app.active_sweep, [0 max_frames 0 1]);
                    axis(app.active_sweep, 'off');

                    overlay_handles = gobjects(0);
                    app.stop_movie  = 0;

                    %start_frame = 3298;
                    %end_frame = 3338;
                    for i = start_frame:5:end_frame
                        if app.stop_movie, break; end

                        % ---- heatmap in place (no cla, no new imagesc) ----
                        fd = movie_data(:,:,i);
                        if has_mask, fd = fd .* mask_mat; end
                        set(hImg, 'CData', fd, 'AlphaData', ~isnan(fd));

                        % ---- clear last frame's overlays, draw this frame's ----
                        delete(overlay_handles);
                        overlay_handles = gobjects(0);

                        if rotor
                            % wavefronts
                            wf_orig_list = wf_frames{1,i};
                            for wf_count = 1:size(wf_orig_list,2)
                                segWave = wf_orig_list{1,wf_count};
                                if isempty(segWave), continue; end
                                if curate
                                    rows = min(max(round(segWave(2,:)),1), size(curate_mask,1));
                                    cols = min(max(round(segWave(1,:)),1), size(curate_mask,2));
                                    keep = curate_mask(sub2ind(size(curate_mask), rows, cols));
                                    segWave = segWave(:, keep);
                                end
                                if ~isempty(segWave)
                                    overlay_handles(end+1) = plot(app.active_axes, segWave(1,:), segWave(2,:), ...
                                        'Color', IVORY, 'LineWidth', 3); %#ok<AGROW>
                                end
                            end

                            % phase singularities (valid only)
                            ps_disp_list = ps_frames{1,i};
                            valid_ps     = ps_dynamics.valid_ps{1,i};
                            for ps_count = 1:size(ps_disp_list,1)
                                if ~valid_ps(ps_count,1), continue; end
                                x = ps_disp_list(ps_count,1);  y = ps_disp_list(ps_count,2);
                                if curate && ~curate_mask(y,x), continue; end
                                overlay_handles(end+1) = plot(app.active_axes, x, y, 'o', 'MarkerSize', 8, ...
                                    'LineWidth', 4, 'MarkerEdgeColor', BLACK, 'MarkerFaceColor', IVORY); %#ok<AGROW>
                            end
                        end

                        % ---- sweepbar + slider in place ----
                        set(hSweep, 'XData', [i i]);
                        app.Slider.Value = [round((i/max_frames)*100), round((end_frame/max_frames)*100)];

                        drawnow limitrate;     % render; replaces the three pause() calls
                        pause(0.04);           % playback pacing (raise/lower to change speed)
                    end

                else
                    update_console(app, 'No data available to play movie');
                end

            catch ME
                error_message = strcat('ERROR - PLAYMOVIEButtonPushed(app, event) ', ME.message);
                update_console(app, error_message);
            end

        end

        % Button pushed function: STOPMOVIEButton
        function STOPMOVIEButtonPushed(app, event)
            %STOPMOVIEBUTTONPUSHED  Set the stop flag to halt movie playback.
            try
                app.stop_movie = 1;

            catch ME
                error_message = strcat('ERROR - STOPMOVIEButtonPushed(app, event) ', ME.message);
                update_console(app,error_message);
            end
        end

        % Selection change function: TabGroup2
        function CameraSelectionChanged(app, event)
            %CAMERASELECTIONCHANGED  React to a camera-tab switch (refresh active_* + view).
            try

                selected_tab = app.TabGroup2.SelectedTab.Title;
                switch selected_tab
                    case 'CAM_1'
                        app.TabGroup3.SelectedTab = app.CAM_1TableTab;

                    case 'CAM_2'
                        app.TabGroup3.SelectedTab = app.CAM_2TableTab;

                    case 'CAM_3'
                        app.TabGroup3.SelectedTab = app.CAM_3TableTab;

                    case 'CAM_4'
                        app.TabGroup3.SelectedTab = app.CAM_4TableTab;
                end

                if ~isempty(app.data)
                    app.stop_movie = 1;
                    update_active_tab_axes_data(app);
                    get_selected_map(app);
                end

            catch ME
                error_message = strcat('ERROR - CAMERASelectionChanged(app, event) ', ME.message);
                update_console(app,error_message);
            end

        end

        % Selection change function: TabGroup3
        function TableSelectionChanged(app, event)
            %TABLESELECTIONCHANGED  React to selecting a mask row in the stats table.
            selectedTab = app.TabGroup3.SelectedTab.Title;
            switch selectedTab
                case 'CAM_1'
                    app.active_table_tab = app.CAM_1TableTab;
                    app.active_table = app.cam1_table;

                case 'CAM_2'
                    app.active_table_tab = app.CAM_2TableTab;
                    app.active_table = app.cam2_table;

                case 'CAM_3'
                    app.active_table_tab = app.CAM_3TableTab;
                    app.active_table = app.cam3_table;

                case 'CAM_4'
                    app.active_table_tab = app.CAM_4TableTab;
                    app.active_table = app.cam4_table;
            end

        end

        % Selection change function: TabGroup4
        function DisplayMapSelectionChanged(app, event)
            %DISPLAYMAPSELECTIONCHANGED  React to a change in the chosen feature-map option.
            if ~isempty(app.data)
                get_selected_map(app);
            end

        end

        % Button pushed function: EXPORTMOVIEButton
        function EXPORTMOVIEButtonPushed(app, event)
            %EXPORTMOVIEBUTTONPUSHED  Render the active camera animation to an .mp4 file.
            try
                if ~isempty(app.data)
                    d = uiprogressdlg(app.UIFigure, 'Title', 'Exporting Movie', 'Indeterminate','on');
                    [movie, mask, label] = get_movie_data(app);
                    file_name = strrep(app.mat_file_name, '.mat', '.mp4');
                    video_file_name = strrep(file_name, '.mp4', strcat(label, '.mp4'));
                    video_file = strcat(app.dir_name, '/', video_file_name);
                    writerObj = VideoWriter(video_file, 'MPEG-4');
                    writerObj.FrameRate = 10;
                    open(writerObj);


                    rotor      = size(movie,2) > 1;
                    movie_data = movie{1,1};
                    [start_frame, end_frame, ~] = get_movie_frames_range(app);

                    bg_rgb   = im2double(real2rgb(app.active_image, 'gray'));   % constant background
                    [nr, nc, ~] = size(bg_rgb);
                    has_mask = ~isempty(mask);
                    if has_mask, mask_mat = mask{1,2}; end
                    out_size = [1680, 2788];

                    if ~rotor
                        % ============================================================
                        %  FAST PATH: no figure — composite RGB frames directly
                        % ============================================================
                        jetmap = jet(256);

                        % Global colour scale over the exported frames (consistent across
                        % the movie; the old per-frame imagesc autoscale made it flicker).
                        sub = movie_data(:,:,start_frame:5:end_frame);
                        clo = min(sub(:), [], 'omitnan');
                        chi = max(sub(:), [], 'omitnan');
                        if ~(isfinite(clo) && isfinite(chi) && chi > clo), clo = 0; chi = 1; end

                        for i = start_frame:5:end_frame
                            fd = movie_data(:,:,i);
                            if has_mask, fd = fd .* mask_mat; end
                            valid = ~isnan(fd);

                            fn  = min(max((fd - clo) ./ (chi - clo), 0), 1);   % normalise to [0,1]
                            idx = round(fn * 255) + 1;                          % 1..256
                            idx(~valid) = 1;
                            heat = reshape(jetmap(idx(:), :), nr, nc, 3);

                            out = bg_rgb;                                       % overlay where valid
                            for ch = 1:3
                                c = out(:,:,ch);  h = heat(:,:,ch);
                                c(valid) = h(valid);
                                out(:,:,ch) = c;
                            end

                            writeVideo(writerObj, imresize(im2uint8(out), out_size));
                        end

                    else
                        % ============================================================
                        %  ROTOR PATH: offscreen figure, reused, print('-RGBImage')
                        % ============================================================
                        IVORY = rgb('Ivory');  BLACK = rgb('Black');  GRAY = rgb('Gray');

                        % Fixed-size, invisible figure -> uniform frames, no flashing,
                        % no per-frame imresize needed.
                        fig = figure('Color', [1 1 1], 'Visible', 'off', ...
                            'Units', 'pixels', 'Position', [100 100 out_size(2) out_size(1)]);
                        movie_axes = axes(fig);
                        imagesc(movie_axes, bg_rgb);
                        hold(movie_axes, 'on');
                        hImg = imagesc(movie_axes, nan(nr, nc), 'AlphaData', zeros(nr, nc));
                        clim(movie_axes, [-pi pi]);
                        colormap(app.active_axes, hsv(256));
                        axis(movie_axes, 'fill');  axis(movie_axes, 'square');  axis(movie_axes, 'off');

                        overlay_handles = gobjects(0);

                        for i = start_frame:5:end_frame
                            fd = movie_data(:,:,i);
                            if has_mask, fd = fd .* mask_mat; end
                            set(hImg, 'CData', fd, 'AlphaData', ~isnan(fd));

                            delete(overlay_handles);
                            overlay_handles = gobjects(0);

                            wf_orig_list = movie{1,2}{1,1}{1,i};
                            for wf_count = 1:size(wf_orig_list,2)
                                segWave = wf_orig_list{1,wf_count};
                                if ~isempty(segWave)
                                    overlay_handles(end+1) = plot(movie_axes, segWave(1,:), segWave(2,:), ...
                                        'Color', IVORY, 'LineWidth', 3); %#ok<AGROW>
                                end
                            end

                            ps_disp_list = movie{1,3}{1,2}{1,i};
                            for ps_count = 1:size(ps_disp_list,1)
                                x = ps_disp_list(ps_count,1);  y = ps_disp_list(ps_count,2);
                                if ps_disp_list(ps_count,3) == 1, edge = BLACK; else, edge = GRAY; end
                                overlay_handles(end+1) = plot(movie_axes, x, y, 'o', 'MarkerSize', 6, ...
                                    'LineWidth', 3, 'MarkerEdgeColor', edge, 'MarkerFaceColor', IVORY); %#ok<AGROW>
                            end

                            drawnow;
                            writeVideo(writerObj, print(fig, '-RGBImage', '-r0'));   % no imresize
                        end

                        close(fig);
                    end

                    close(writerObj);
                    close(d);
                    update_console(app, 'Movie Exported Successfully');
                else
                    update_console(app, 'No data available to export movie');
                end

            catch ME
                if exist('writerObj','var') && isa(writerObj,'VideoWriter')
                    try close(writerObj); catch; end   % finalize/release the .mp4 first
                end
                if exist('fig','var') && isgraphics(fig)
                    close(fig);
                end
                if exist('d','var') && isvalid(d)
                    close(d);
                end
                error_message = strcat('ERROR - EXPORTMOVIEButtonPushed(app, event) ', ME.message);
                update_console(app, error_message);
            end

        end

        % Button pushed function: EXPORTSIGNALSButton
        function EXPORTSIGNALSButtonPushed(app, event)
            %EXPORTSIGNALSBUTTONPUSHED  Export the selected AP traces to a pop-out figure.
            %   Gathers selected pixels (get_signal_locations / get_oap_data, optionally
            %   linked across cameras and windowed) and draws them via display_signals.
            try
                if ~isempty(app.data) && is_signal_available(app)
                    link_signals = app.LinkSignalsCheckBox.Value;

                    signal_window = [];
                    if ~isempty(app.oap_window{app.active_camera_tab,1})
                        signal_window = app.oap_window{app.active_camera_tab,1};
                    end

                    locations = get_signal_locations(app);
                    [oap_data, range] = get_oap_data(app, locations, link_signals, signal_window);

                    display_signals(app, oap_data, range);

                else
                    update_console(app, 'No data available to export signals');
                end

            catch ME
                error_message = strcat('ERROR - EXPORTSIGNALButtonPushed(app, event) ', ME.message);
                update_console(app,error_message);
            end
        end

        % Button pushed function: CREATEUSERMASKButton
        function CREATEUSERMASKButtonPushed(app, event)
            %CREATEUSERMASKBUTTONPUSHED  Draw a new ROI data mask on the active camera image.
            try
                if ~isempty(app.data)
                    valid_action = update_active_tab_axes_data(app); %data loaded in the active tab
                    if valid_action
                        tag_id = generate_unique_id;
                        delete(app.active_axes.Children(:,1));
                        mask_image = [];
                        if ~isempty(app.active_cardiac_map)
                            imagesc(app.active_axes, real2rgb(app.active_image,'gray'));
                            hold(app.active_axes, 'on');
                            try
                                mask_image = imagesc(app.active_axes, app.active_cardiac_map{1,1}, 'AlphaData', ~isnan(app.active_cardiac_map{1,1}));
                                mask_data = app.active_cardiac_map{1,1};
                                range_median = round(abs(median(mask_data,"all","omitmissing")));
                                colormap(app.active_axes, 'jet');
                                clim(app.active_axes, [range_median*0.5 range_median*1.5])
                            catch
                                update_console(app, 'Warning: active cardiac map is unavailable to create user mask');
                            end

                        else
                            mask_image = imagesc(app.active_axes, real2rgb(app.active_image,'gray'));
                        end

                        mask_region = drawfreehand('Parent', app.active_axes,'Color', app.active_color, 'Tag', tag_id);
                        addlistener(mask_region,'ROIMoved', @(varargin)ROIChanged(app,mask_region, 0)); % update if there is a change to mask;
                        %addlistener(mask_region,'ObjectBeingDestroyed', @(varargin)ROIDeleted(app,mask_region, -1)); %update if the user removes the mask;
                        update_mask(app,mask_region, mask_image);
                        %app.USRSwitch.Value = 'On';
                        %app.data_masks_tab(app.active_camera,2) = 1;
                    end

                    update_console(app,"User Mask Created");

                else
                    message = strcat("Data not loaded");
                    update_console(app,message);
                end
            catch ME
                error_message = strcat('ERROR - CREATEUSERMASKButtonPushed(app, event) ', ME.message);
                update_console(app,error_message);
            end
        end

        % Button pushed function: DELETEUSERMASKButton
        function DELETEUSERMASKButtonPushed(app, event)
            %DELETEUSERMASKBUTTONPUSHED  Remove the selected data mask and its table row.
            try

                row_index = app.active_table.Selection;
                if row_index >= 1
                    table_data = app.active_table.Data;
                    table_data(row_index,:) = [];
                    app.active_table.Data = table_data;
                    table_tag = app.active_table.Tag;

                    switch table_tag
                        case 'CAM_1'
                            app.cam1_table = app.active_table;
                            app.data.data_masks{1,2}(row_index,:) = [];
                            if app.active_camera_tab == 1
                                app.active_data_masks = app.data.data_masks{1,2};
                            end
                        case 'CAM_2'
                            app.cam2_table = app.active_table;
                            app.data.data_masks{2,2}(row_index,:) = [];
                            if app.active_camera_tab == 2
                                app.active_data_masks = app.data.data_masks{2,2};
                            end
                        case 'CAM_3'
                            app.cam3_table = app.active_table;
                            app.data.data_masks{3,2}(row_index,:) = [];
                            if app.active_camera_tab == 3
                                app.active_data_masks = app.data.data_masks{3,2};
                            end
                        case 'CAM_4'
                            app.cam4_table = app.active_table;
                            app.data.data_masks{4,2}(row_index,:) = [];
                            if app.active_camera_tab == 4
                                app.active_data_masks = app.data.data_masks{4,2};
                            end
                    end

                    delete(app.active_axes.Children(:,1));
                    imagesc(app.active_axes, real2rgb(app.active_image,'gray'));
                    update_console(app, 'Selected User mask deleted');

                end

            catch ME
                error_message = strcat('ERROR - DELETEUSERMASKButtonPushed(app, event) ', ME.message);
                update_console(app,error_message);
            end
        end

        % Button pushed function: SAVEDATAButton
        function SAVEDATAButtonPushed(app, event)
            %SAVEDATABUTTONPUSHED  Save the recording (with masks/curation) back to disk.
            %   Writes app.data as the 'metrics' struct to the new <name>-analysis.mat (-v7.3).
            try
                if ~isempty(app.data)
                    d = uiprogressdlg(app.UIFigure, 'Title', 'Signal Analysis', 'Indeterminate','on');
                    d.Message = 'Saving the data file';
                    dir_path = app.dir_name;

                    [~, base_name, ~] = fileparts(app.mat_file_name);
                    base_name = regexprep(base_name, '-(metrics|analysis)$', '');
                    metrics_file_name = strcat(base_name, '-analysis.mat');
                    file_path = strcat(dir_path, '/', metrics_file_name);
                    cmos_all_data = app.data;

                    save(file_path,"cmos_all_data", '-v7.3');

                    d.Message = 'Saved the data file.. sucessfully';
                    update_console(app, d.Message);
                    close(d);
                else
                    message = strcat("Data not loaded");
                    update_console(app,message);
                end
            catch ME
                if exist('d','var') && isvalid(d)
                    close(d);
                end
                error_message = strcat('ERROR - Save Data(app, event) ', ME.message);
                update_console(app,error_message);
            end
        end

        % Button pushed function: SELECTWINDOWButton
        function SELECTWINDOWButtonPushed(app, event)
            %SELECTWINDOWBUTTONPUSHED  Add a signal-window ROI over the plotted signal.
            try
                if ~isempty(app.data)
                    select_window(app);
                else
                    message = strcat("Data not loaded");
                    update_console(app, message);
                end
            catch ME
                error_message = strcat('ERROR - FEATUREEXTRACTION- SELECTWINDOWButtonPushed(app, event) ', ME.message);
                update_console(app,error_message);
            end
        end

        % Selection changed function: VmButtonGroup
        function VmButtonGroupSelectionChanged(app, event)
            %VMBUTTONGROUPSELECTIONCHANGED  Track the selected voltage (Vm) feature-map option.
            if ~isempty(app.data)
                get_selected_map(app);
            end

        end

        % Selection changed function: CaButtonGroup
        function CaButtonGroupSelectionChanged(app, event)
            %CABUTTONGROUPSELECTIONCHANGED  Track the selected calcium (Ca) feature-map option.
            if ~isempty(app.data)
                get_selected_map(app);
            end

        end

        % Selection changed function: ArrhythmiaButtonGroup
        function ArrhythmiaButtonGroupSelectionChanged(app, event)
            %ARRHYTHMIABUTTONGROUPSELECTIONCHANGED  Track the selected arrhythmia feature-map option.
            if ~isempty(app.data)
                get_selected_map(app);
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Cadence - Cardiac Signal Processing and Analysis Software';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SIGNALANALYSISTab = uitab(app.TabGroup);
            app.SIGNALANALYSISTab.Title = 'SIGNAL ANALYSIS';
            app.SELECTFILEFORSIGNALANALYSISPanel = uipanel(app.SIGNALANALYSISTab);
            app.SELECTFILEFORSIGNALANALYSISPanel.Title = 'SELECT FILE FOR SIGNAL ANALYSIS';
            app.SELECTDIRButton = uibutton(app.SELECTFILEFORSIGNALANALYSISPanel, 'push');
            app.SELECTDIRButton.ButtonPushedFcn = createCallbackFcn(app, @SELECTDIRButtonPushed, true);
            app.SELECTDIRButton.Text = {'SELECT DIR'; ''};
            app.REFRESHDIRButton = uibutton(app.SELECTFILEFORSIGNALANALYSISPanel, 'push');
            app.REFRESHDIRButton.ButtonPushedFcn = createCallbackFcn(app, @REFRESHDIRButtonPushed, true);
            app.REFRESHDIRButton.Text = {'REFRESH DIR'; ''};
            app.Label_5 = uilabel(app.SELECTFILEFORSIGNALANALYSISPanel);
            app.Label_5.Text = '';
            app.ListBox_2 = uilistbox(app.SELECTFILEFORSIGNALANALYSISPanel);
            app.ListBox_2.Items = {};
            app.ListBox_2.Value = {};
            app.LOADButton = uibutton(app.SELECTFILEFORSIGNALANALYSISPanel, 'push');
            app.LOADButton.ButtonPushedFcn = createCallbackFcn(app, @LOADButtonPushed, true);
            app.LOADButton.Text = 'LOAD ';
            app.STEP2SELECTWINDOWFROMREPRESENTATIVESIGNALPanel = uipanel(app.SIGNALANALYSISTab);
            app.STEP2SELECTWINDOWFROMREPRESENTATIVESIGNALPanel.Title = 'SIGNAL ANALYSIS';
            app.TabGroup2 = uitabgroup(app.STEP2SELECTWINDOWFROMREPRESENTATIVESIGNALPanel);
            app.TabGroup2.SelectionChangedFcn = createCallbackFcn(app, @CameraSelectionChanged, true);
            app.CAM_1Tab = uitab(app.TabGroup2);
            app.CAM_1Tab.Title = 'CAM_1';
            app.UIAxes_cam1 = uiaxes(app.CAM_1Tab);
            app.UIAxes_cam1.XLim = [1 100];
            app.UIAxes_cam1.YLim = [1 100];
            app.UIAxes_cam1.YDir = 'reverse';
            app.UIAxes_cam1.XTick = [];
            app.UIAxes_cam1.XTickLabel = '';
            app.UIAxes_cam1.YTick = [];
            app.UIAxes_cam1.YTickLabel = '';
            app.UIAxes_cam1.ZTick = [];
            app.plot_sig_11 = uiaxes(app.CAM_1Tab);
            app.plot_sig_12 = uiaxes(app.CAM_1Tab);
            app.plot_sig_13 = uiaxes(app.CAM_1Tab);
            app.plot_sig_14 = uiaxes(app.CAM_1Tab);
            app.UIAxes_cam1_sweep = uiaxes(app.CAM_1Tab);
            xlabel(app.UIAxes_cam1_sweep, 'X')
            ylabel(app.UIAxes_cam1_sweep, 'Y')
            zlabel(app.UIAxes_cam1_sweep, 'Z')
            app.UIAxes_cam1_sweep.Visible = 'off';
            app.Row11 = uieditfield(app.CAM_1Tab, 'text');
            app.Row11.Editable = 'off';
            app.Col11 = uieditfield(app.CAM_1Tab, 'text');
            app.Col11.Editable = 'off';
            app.Val11 = uieditfield(app.CAM_1Tab, 'text');
            app.Val11.Editable = 'off';
            app.ROWLabel = uilabel(app.CAM_1Tab);
            app.ROWLabel.Text = 'ROW';
            app.COLLabel = uilabel(app.CAM_1Tab);
            app.COLLabel.Text = 'COL';
            app.VALLabel = uilabel(app.CAM_1Tab);
            app.VALLabel.Text = 'VAL';
            app.Row12 = uieditfield(app.CAM_1Tab, 'text');
            app.Row12.Editable = 'off';
            app.Col12 = uieditfield(app.CAM_1Tab, 'text');
            app.Col12.Editable = 'off';
            app.Val12 = uieditfield(app.CAM_1Tab, 'text');
            app.Val12.Editable = 'off';
            app.ROWLabel_2 = uilabel(app.CAM_1Tab);
            app.ROWLabel_2.Text = 'ROW';
            app.COLLabel_2 = uilabel(app.CAM_1Tab);
            app.COLLabel_2.Text = 'COL';
            app.VALLabel_2 = uilabel(app.CAM_1Tab);
            app.VALLabel_2.Text = 'VAL';
            app.Row13 = uieditfield(app.CAM_1Tab, 'text');
            app.Row13.Editable = 'off';
            app.Col13 = uieditfield(app.CAM_1Tab, 'text');
            app.Col13.Editable = 'off';
            app.Val13 = uieditfield(app.CAM_1Tab, 'text');
            app.Val13.Editable = 'off';
            app.ROWLabel_3 = uilabel(app.CAM_1Tab);
            app.ROWLabel_3.Text = 'ROW';
            app.COLLabel_3 = uilabel(app.CAM_1Tab);
            app.COLLabel_3.Text = 'COL';
            app.VALLabel_3 = uilabel(app.CAM_1Tab);
            app.VALLabel_3.Text = 'VAL';
            app.Row14 = uieditfield(app.CAM_1Tab, 'text');
            app.Row14.Editable = 'off';
            app.Col14 = uieditfield(app.CAM_1Tab, 'text');
            app.Col14.Editable = 'off';
            app.Val14 = uieditfield(app.CAM_1Tab, 'text');
            app.Val14.Editable = 'off';
            app.ROWLabel_4 = uilabel(app.CAM_1Tab);
            app.ROWLabel_4.Text = 'ROW';
            app.COLLabel_4 = uilabel(app.CAM_1Tab);
            app.COLLabel_4.Text = 'COL';
            app.VALLabel_4 = uilabel(app.CAM_1Tab);
            app.VALLabel_4.Text = 'VAL';
            app.CAM_2Tab = uitab(app.TabGroup2);
            app.CAM_2Tab.Title = 'CAM_2';
            app.UIAxes_cam2 = uiaxes(app.CAM_2Tab);
            app.UIAxes_cam2.XLim = [1 100];
            app.UIAxes_cam2.YLim = [1 100];
            app.UIAxes_cam2.YDir = 'reverse';
            app.UIAxes_cam2.XTick = [];
            app.UIAxes_cam2.XTickLabel = '';
            app.UIAxes_cam2.YTick = [];
            app.UIAxes_cam2.YTickLabel = '';
            app.UIAxes_cam2.ZTick = [];
            app.plot_sig_21 = uiaxes(app.CAM_2Tab);
            app.plot_sig_22 = uiaxes(app.CAM_2Tab);
            app.plot_sig_23 = uiaxes(app.CAM_2Tab);
            app.plot_sig_24 = uiaxes(app.CAM_2Tab);
            app.UIAxes_cam2_sweep = uiaxes(app.CAM_2Tab);
            xlabel(app.UIAxes_cam2_sweep, 'X')
            ylabel(app.UIAxes_cam2_sweep, 'Y')
            zlabel(app.UIAxes_cam2_sweep, 'Z')
            app.UIAxes_cam2_sweep.Visible = 'off';
            app.Row21 = uieditfield(app.CAM_2Tab, 'text');
            app.Row21.Editable = 'off';
            app.Col21 = uieditfield(app.CAM_2Tab, 'text');
            app.Col21.Editable = 'off';
            app.Val21 = uieditfield(app.CAM_2Tab, 'text');
            app.Val21.Editable = 'off';
            app.ROWLabel_5 = uilabel(app.CAM_2Tab);
            app.ROWLabel_5.Text = 'ROW';
            app.COLLabel_5 = uilabel(app.CAM_2Tab);
            app.COLLabel_5.Text = 'COL';
            app.VALLabel_5 = uilabel(app.CAM_2Tab);
            app.VALLabel_5.Text = 'VAL';
            app.Row22 = uieditfield(app.CAM_2Tab, 'text');
            app.Row22.Editable = 'off';
            app.Col22 = uieditfield(app.CAM_2Tab, 'text');
            app.Col22.Editable = 'off';
            app.Val22 = uieditfield(app.CAM_2Tab, 'text');
            app.Val22.Editable = 'off';
            app.ROWLabel_6 = uilabel(app.CAM_2Tab);
            app.ROWLabel_6.Text = 'ROW';
            app.COLLabel_6 = uilabel(app.CAM_2Tab);
            app.COLLabel_6.Text = 'COL';
            app.VALLabel_6 = uilabel(app.CAM_2Tab);
            app.VALLabel_6.Text = 'VAL';
            app.Row23 = uieditfield(app.CAM_2Tab, 'text');
            app.Row23.Editable = 'off';
            app.Col23 = uieditfield(app.CAM_2Tab, 'text');
            app.Col23.Editable = 'off';
            app.Val23 = uieditfield(app.CAM_2Tab, 'text');
            app.Val23.Editable = 'off';
            app.ROWLabel_7 = uilabel(app.CAM_2Tab);
            app.ROWLabel_7.Text = 'ROW';
            app.COLLabel_7 = uilabel(app.CAM_2Tab);
            app.COLLabel_7.Text = 'COL';
            app.VALLabel_7 = uilabel(app.CAM_2Tab);
            app.VALLabel_7.Text = 'VAL';
            app.Row24 = uieditfield(app.CAM_2Tab, 'text');
            app.Row24.Editable = 'off';
            app.Col24 = uieditfield(app.CAM_2Tab, 'text');
            app.Col24.Editable = 'off';
            app.Val24 = uieditfield(app.CAM_2Tab, 'text');
            app.Val24.Editable = 'off';
            app.ROWLabel_8 = uilabel(app.CAM_2Tab);
            app.ROWLabel_8.Text = 'ROW';
            app.COLLabel_8 = uilabel(app.CAM_2Tab);
            app.COLLabel_8.Text = 'COL';
            app.VALLabel_8 = uilabel(app.CAM_2Tab);
            app.VALLabel_8.Text = 'VAL';
            app.CAM_3Tab = uitab(app.TabGroup2);
            app.CAM_3Tab.Title = 'CAM_3';
            app.UIAxes_cam3 = uiaxes(app.CAM_3Tab);
            app.UIAxes_cam3.XLim = [1 100];
            app.UIAxes_cam3.YLim = [1 100];
            app.UIAxes_cam3.YDir = 'reverse';
            app.UIAxes_cam3.XTick = [];
            app.UIAxes_cam3.XTickLabel = '';
            app.UIAxes_cam3.YTick = [];
            app.UIAxes_cam3.YTickLabel = '';
            app.UIAxes_cam3.ZTick = [];
            app.plot_sig_31 = uiaxes(app.CAM_3Tab);
            app.plot_sig_32 = uiaxes(app.CAM_3Tab);
            app.plot_sig_33 = uiaxes(app.CAM_3Tab);
            app.plot_sig_34 = uiaxes(app.CAM_3Tab);
            app.UIAxes_cam3_sweep = uiaxes(app.CAM_3Tab);
            xlabel(app.UIAxes_cam3_sweep, 'X')
            ylabel(app.UIAxes_cam3_sweep, 'Y')
            zlabel(app.UIAxes_cam3_sweep, 'Z')
            app.UIAxes_cam3_sweep.Visible = 'off';
            app.Row31 = uieditfield(app.CAM_3Tab, 'text');
            app.Row31.Editable = 'off';
            app.Col31 = uieditfield(app.CAM_3Tab, 'text');
            app.Col31.Editable = 'off';
            app.Val31 = uieditfield(app.CAM_3Tab, 'text');
            app.Val31.Editable = 'off';
            app.ROWLabel_9 = uilabel(app.CAM_3Tab);
            app.ROWLabel_9.Text = 'ROW';
            app.COLLabel_9 = uilabel(app.CAM_3Tab);
            app.COLLabel_9.Text = 'COL';
            app.VALLabel_9 = uilabel(app.CAM_3Tab);
            app.VALLabel_9.Text = 'VAL';
            app.Row32 = uieditfield(app.CAM_3Tab, 'text');
            app.Row32.Editable = 'off';
            app.Col32 = uieditfield(app.CAM_3Tab, 'text');
            app.Col32.Editable = 'off';
            app.Val32 = uieditfield(app.CAM_3Tab, 'text');
            app.Val32.Editable = 'off';
            app.ROWLabel_10 = uilabel(app.CAM_3Tab);
            app.ROWLabel_10.Text = 'ROW';
            app.COLLabel_10 = uilabel(app.CAM_3Tab);
            app.COLLabel_10.Text = 'COL';
            app.VALLabel_10 = uilabel(app.CAM_3Tab);
            app.VALLabel_10.Text = 'VAL';
            app.Row33 = uieditfield(app.CAM_3Tab, 'text');
            app.Row33.Editable = 'off';
            app.Col33 = uieditfield(app.CAM_3Tab, 'text');
            app.Col33.Editable = 'off';
            app.Val33 = uieditfield(app.CAM_3Tab, 'text');
            app.Val33.Editable = 'off';
            app.ROWLabel_11 = uilabel(app.CAM_3Tab);
            app.ROWLabel_11.Text = 'ROW';
            app.COLLabel_11 = uilabel(app.CAM_3Tab);
            app.COLLabel_11.Text = 'COL';
            app.VALLabel_11 = uilabel(app.CAM_3Tab);
            app.VALLabel_11.Text = 'VAL';
            app.Row34 = uieditfield(app.CAM_3Tab, 'text');
            app.Row34.Editable = 'off';
            app.Col34 = uieditfield(app.CAM_3Tab, 'text');
            app.Col34.Editable = 'off';
            app.Val34 = uieditfield(app.CAM_3Tab, 'text');
            app.Val34.Editable = 'off';
            app.ROWLabel_12 = uilabel(app.CAM_3Tab);
            app.ROWLabel_12.Text = 'ROW';
            app.COLLabel_12 = uilabel(app.CAM_3Tab);
            app.COLLabel_12.Text = 'COL';
            app.VALLabel_12 = uilabel(app.CAM_3Tab);
            app.VALLabel_12.Text = 'VAL';
            app.CAM_4Tab = uitab(app.TabGroup2);
            app.CAM_4Tab.Title = 'CAM_4';
            app.UIAxes_cam4 = uiaxes(app.CAM_4Tab);
            app.UIAxes_cam4.XLim = [1 100];
            app.UIAxes_cam4.YLim = [1 100];
            app.UIAxes_cam4.YDir = 'reverse';
            app.UIAxes_cam4.XTick = [];
            app.UIAxes_cam4.XTickLabel = '';
            app.UIAxes_cam4.YTick = [];
            app.UIAxes_cam4.YTickLabel = '';
            app.UIAxes_cam4.ZTick = [];
            app.plot_sig_41 = uiaxes(app.CAM_4Tab);
            app.plot_sig_42 = uiaxes(app.CAM_4Tab);
            app.plot_sig_43 = uiaxes(app.CAM_4Tab);
            app.plot_sig_44 = uiaxes(app.CAM_4Tab);
            app.UIAxes_cam4_sweep = uiaxes(app.CAM_4Tab);
            xlabel(app.UIAxes_cam4_sweep, 'X')
            ylabel(app.UIAxes_cam4_sweep, 'Y')
            zlabel(app.UIAxes_cam4_sweep, 'Z')
            app.UIAxes_cam4_sweep.Visible = 'off';
            app.Row41 = uieditfield(app.CAM_4Tab, 'text');
            app.Row41.Editable = 'off';
            app.Col41 = uieditfield(app.CAM_4Tab, 'text');
            app.Col41.Editable = 'off';
            app.Val41 = uieditfield(app.CAM_4Tab, 'text');
            app.Val41.Editable = 'off';
            app.ROWLabel_13 = uilabel(app.CAM_4Tab);
            app.ROWLabel_13.Text = 'ROW';
            app.COLLabel_13 = uilabel(app.CAM_4Tab);
            app.COLLabel_13.Text = 'COL';
            app.VALLabel_13 = uilabel(app.CAM_4Tab);
            app.VALLabel_13.Text = 'VAL';
            app.Row42 = uieditfield(app.CAM_4Tab, 'text');
            app.Row42.Editable = 'off';
            app.Col42 = uieditfield(app.CAM_4Tab, 'text');
            app.Col42.Editable = 'off';
            app.Val42 = uieditfield(app.CAM_4Tab, 'text');
            app.Val42.Editable = 'off';
            app.ROWLabel_14 = uilabel(app.CAM_4Tab);
            app.ROWLabel_14.Text = 'ROW';
            app.COLLabel_14 = uilabel(app.CAM_4Tab);
            app.COLLabel_14.Text = 'COL';
            app.VALLabel_14 = uilabel(app.CAM_4Tab);
            app.VALLabel_14.Text = 'VAL';
            app.Row43 = uieditfield(app.CAM_4Tab, 'text');
            app.Row43.Editable = 'off';
            app.Col43 = uieditfield(app.CAM_4Tab, 'text');
            app.Col43.Editable = 'off';
            app.Val43 = uieditfield(app.CAM_4Tab, 'text');
            app.Val43.Editable = 'off';
            app.ROWLabel_15 = uilabel(app.CAM_4Tab);
            app.ROWLabel_15.Text = 'ROW';
            app.COLLabel_15 = uilabel(app.CAM_4Tab);
            app.COLLabel_15.Text = 'COL';
            app.VALLabel_15 = uilabel(app.CAM_4Tab);
            app.VALLabel_15.Text = 'VAL';
            app.Row44 = uieditfield(app.CAM_4Tab, 'text');
            app.Row44.Editable = 'off';
            app.Col44 = uieditfield(app.CAM_4Tab, 'text');
            app.Col44.Editable = 'off';
            app.Val44 = uieditfield(app.CAM_4Tab, 'text');
            app.Val44.Editable = 'off';
            app.ROWLabel_16 = uilabel(app.CAM_4Tab);
            app.ROWLabel_16.Text = 'ROW';
            app.COLLabel_16 = uilabel(app.CAM_4Tab);
            app.COLLabel_16.Text = 'COL';
            app.VALLabel_16 = uilabel(app.CAM_4Tab);
            app.VALLabel_16.Text = 'VAL';
            app.PLAYMOVIEButton = uibutton(app.STEP2SELECTWINDOWFROMREPRESENTATIVESIGNALPanel, 'push');
            app.PLAYMOVIEButton.ButtonPushedFcn = createCallbackFcn(app, @PLAYMOVIEButtonPushed, true);
            app.PLAYMOVIEButton.Text = 'PLAY MOVIE';
            app.STOPMOVIEButton = uibutton(app.STEP2SELECTWINDOWFROMREPRESENTATIVESIGNALPanel, 'push');
            app.STOPMOVIEButton.ButtonPushedFcn = createCallbackFcn(app, @STOPMOVIEButtonPushed, true);
            app.STOPMOVIEButton.Text = 'STOP MOVIE';
            app.EXPORTMOVIEButton = uibutton(app.STEP2SELECTWINDOWFROMREPRESENTATIVESIGNALPanel, 'push');
            app.EXPORTMOVIEButton.ButtonPushedFcn = createCallbackFcn(app, @EXPORTMOVIEButtonPushed, true);
            app.EXPORTMOVIEButton.Text = 'EXPORT MOVIE';
            app.EXPORTSIGNALSButton = uibutton(app.STEP2SELECTWINDOWFROMREPRESENTATIVESIGNALPanel, 'push');
            app.EXPORTSIGNALSButton.ButtonPushedFcn = createCallbackFcn(app, @EXPORTSIGNALSButtonPushed, true);
            app.EXPORTSIGNALSButton.Text = 'EXPORT SIGNALS';
            app.ButtonGroup_4 = uibuttongroup(app.STEP2SELECTWINDOWFROMREPRESENTATIVESIGNALPanel);
            app.TimeVmorCaButton = uiradiobutton(app.ButtonGroup_4);
            app.TimeVmorCaButton.Text = 'Time (Vm or Ca)';
            app.TimeVmorCaButton.Value = true;
            app.PhaseHilbertTransformButton = uiradiobutton(app.ButtonGroup_4);
            app.PhaseHilbertTransformButton.Text = 'Phase (Hilbert Transform)';
            app.AnnotatedRotorPSButton = uiradiobutton(app.ButtonGroup_4);
            app.AnnotatedRotorPSButton.Text = 'Annotated Rotor (PS)';
            app.Slider = uislider(app.STEP2SELECTWINDOWFROMREPRESENTATIVESIGNALPanel, 'range');
            app.SELECTWINDOWButton = uibutton(app.STEP2SELECTWINDOWFROMREPRESENTATIVESIGNALPanel, 'push');
            app.SELECTWINDOWButton.ButtonPushedFcn = createCallbackFcn(app, @SELECTWINDOWButtonPushed, true);
            app.SELECTWINDOWButton.Text = 'SELECT WINDOW';
            app.LinkSignalsCheckBox = uicheckbox(app.STEP2SELECTWINDOWFROMREPRESENTATIVESIGNALPanel);
            app.LinkSignalsCheckBox.Text = 'Link Signals';
            app.PacingCheckBox = uicheckbox(app.STEP2SELECTWINDOWFROMREPRESENTATIVESIGNALPanel);
            app.PacingCheckBox.Text = 'Pacing';
            app.CARDIACMAPSTODISPLAYPanel = uipanel(app.SIGNALANALYSISTab);
            app.CARDIACMAPSTODISPLAYPanel.Title = 'CARDIAC MAPS TO DISPLAY';
            app.DISPLAYCARDIACMAPButton = uibutton(app.CARDIACMAPSTODISPLAYPanel, 'push');
            app.DISPLAYCARDIACMAPButton.ButtonPushedFcn = createCallbackFcn(app, @DISPLAYCARDIACMAPButtonPushed, true);
            app.DISPLAYCARDIACMAPButton.Text = 'DISPLAY CARDIAC MAP';
            app.TabGroup4 = uitabgroup(app.CARDIACMAPSTODISPLAYPanel);
            app.TabGroup4.SelectionChangedFcn = createCallbackFcn(app, @DisplayMapSelectionChanged, true);
            app.ActionPotentialsVmTab = uitab(app.TabGroup4);
            app.ActionPotentialsVmTab.Title = 'Action Potentials (Vm)';
            app.VmButtonGroup = uibuttongroup(app.ActionPotentialsVmTab);
            app.VmButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @VmButtonGroupSelectionChanged, true);
            app.LOCALACTIVATIONTIMELATMAPButton = uiradiobutton(app.VmButtonGroup);
            app.LOCALACTIVATIONTIMELATMAPButton.Text = 'LOCAL ACTIVATION TIME (LAT) MAP';
            app.LOCALACTIVATIONTIMELATMAPButton.Value = true;
            app.REPOLARIZATIONTIMEMAPButton = uiradiobutton(app.VmButtonGroup);
            app.REPOLARIZATIONTIMEMAPButton.Text = 'REPOLARIZATION TIME MAP';
            app.ACTIONPOTENTIALDURATIONAPDMAPButton = uiradiobutton(app.VmButtonGroup);
            app.ACTIONPOTENTIALDURATIONAPDMAPButton.Text = 'ACTION POTENTIAL DURATION (APD) MAP';
            app.ACTIONPOTENTIALRISETIMEMAPButton = uiradiobutton(app.VmButtonGroup);
            app.ACTIONPOTENTIALRISETIMEMAPButton.Text = 'ACTION POTENTIAL RISE TIME MAP';
            app.LOCALCONDUCTIONVELOCITYMAPButton = uiradiobutton(app.VmButtonGroup);
            app.LOCALCONDUCTIONVELOCITYMAPButton.Text = 'LOCAL CONDUCTION VELOCITY MAP';
            app.CalciumTransientsCaTab = uitab(app.TabGroup4);
            app.CalciumTransientsCaTab.Title = 'Calcium Transients (Ca)';
            app.CaButtonGroup = uibuttongroup(app.CalciumTransientsCaTab);
            app.CaButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CaButtonGroupSelectionChanged, true);
            app.VOLTAGECALCIUMACTIVATIONDELAYMAPButton = uiradiobutton(app.CaButtonGroup);
            app.VOLTAGECALCIUMACTIVATIONDELAYMAPButton.Text = 'VOLTAGE-CALCIUM ACTIVATION DELAY MAP';
            app.VOLTAGECALCIUMACTIVATIONDELAYMAPButton.Value = true;
            app.CALCIUMDECAYTIMEMAPButton = uiradiobutton(app.CaButtonGroup);
            app.CALCIUMDECAYTIMEMAPButton.Text = 'CALCIUM DECAY TIME MAP';
            app.CALCIUMTRANSIENTDURATIONMAPButton = uiradiobutton(app.CaButtonGroup);
            app.CALCIUMTRANSIENTDURATIONMAPButton.Text = 'CALCIUM TRANSIENT DURATION MAP';
            app.CALCIUMRISETIMEMAPButton = uiradiobutton(app.CaButtonGroup);
            app.CALCIUMRISETIMEMAPButton.Text = 'CALCIUM RISE TIME MAP';
            app.CALCIUMDECAYCONSTANTTAUMAPButton = uiradiobutton(app.CaButtonGroup);
            app.CALCIUMDECAYCONSTANTTAUMAPButton.Text = 'CALCIUM DECAY CONSTANT (TAU) MAP';
            app.ArrhythmiaDynamicsTab = uitab(app.TabGroup4);
            app.ArrhythmiaDynamicsTab.Title = 'Arrhythmia Dynamics';
            app.ArrhythmiaButtonGroup = uibuttongroup(app.ArrhythmiaDynamicsTab);
            app.ArrhythmiaButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ArrhythmiaButtonGroupSelectionChanged, true);
            app.DOMINANTFREQUENCYDFButton = uiradiobutton(app.ArrhythmiaButtonGroup);
            app.DOMINANTFREQUENCYDFButton.Text = 'DOMINANT FREQUENCY (DF)';
            app.DOMINANTFREQUENCYDFButton.Value = true;
            app.REGULARITYINDEXRIButton = uiradiobutton(app.ArrhythmiaButtonGroup);
            app.REGULARITYINDEXRIButton.Text = 'REGULARITY INDEX (RI)';
            app.ORGANIZATIONINDEXOIButton = uiradiobutton(app.ArrhythmiaButtonGroup);
            app.ORGANIZATIONINDEXOIButton.Text = 'ORGANIZATION INDEX (OI)';
            app.ALTERNANSVOLTAGEORCALCIUMButton = uiradiobutton(app.ArrhythmiaButtonGroup);
            app.ALTERNANSVOLTAGEORCALCIUMButton.Text = 'ALTERNANS (VOLTAGE OR CALCIUM)';
            app.SAVEDATAButton = uibutton(app.CARDIACMAPSTODISPLAYPanel, 'push');
            app.SAVEDATAButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEDATAButtonPushed, true);
            app.SAVEDATAButton.Text = 'SAVE DATA';
            app.Label_4 = uilabel(app.SIGNALANALYSISTab);
            app.Label_4.Text = '';
            app.TextArea = uitextarea(app.SIGNALANALYSISTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Welcome to CADENCE 2026!!  SIGNAL ANALYSIS MODULE'; ''; 'STEP 1) Please select the appropriate input directory containing the Matlab data files (*.mat). If you haven''t already, please use the Feature Extraction Module to extract relevant features (e.g., activation times, repolarization times, action potential duration etc.,) from your recordings. Next, load a data file for signal analysis.  '; ''; 'STEP 2) Choose a feature to visualize and analyze from the drop down box.  Use the mouse to click (right click) on the image in the middle column (any arbitrary location) to display a representative signal. You can drag the mouse (while holding the right click down) around to find the appropriate signal as needed. You can select up to 4 action potentials to display for each camera.  '; ''; 'STEP 3)  If you want to curate the maps, you can use the data masking feature to mask out noisy signals. In conjunction with the image data masks, you can set histogram upper and lower bounds to remove noisy data. In order to do so, enter the range of the data in the select bounds text boxes (lower bound and upper bound). In order to display the results of the histogram bounds and/or user created masks, switch on the corresponding option and then click the display cardiac map button. The mask and the bounds are not mutually exclusive and can be used in combination to curate data as needed. '; ''; 'STEP 4) The statistical summary tab displays the min, max, mean, std, and median for the data displayed on the images. The data is categorized by image masks if any. For examples, the first row will display values with default mask. The subsequent rows may display values with user created masks. This may be useful when trying to quantify spatial gradients (e.g., base vs mid vs apex,  RVOT vs RV,  Epicardium vs Endocardium vs Transmural etc.,)'; ''; 'STEP 5) You can play movies in different modes including voltage and calcium, phase (in case of arrhythmias), and annotated rotors (where in the wavefronts and rotors are highlighted). You can also export movies and representative signals by clicking on the export movie and export signals button. If you want to export only part of the signals in the time series use the select window button to select a signal window as needed. '; ''; ''};
            app.STATISTICALSUMMARYPanel = uipanel(app.SIGNALANALYSISTab);
            app.STATISTICALSUMMARYPanel.Title = 'STATISTICAL SUMMARY';
            app.TabGroup3 = uitabgroup(app.STATISTICALSUMMARYPanel);
            app.TabGroup3.SelectionChangedFcn = createCallbackFcn(app, @TableSelectionChanged, true);
            app.CAM_1TableTab = uitab(app.TabGroup3);
            app.CAM_1TableTab.Title = 'CAM_1';
            app.cam1_table = uitable(app.CAM_1TableTab);
            app.cam1_table.ColumnName = {'MASK'; 'MIN'; 'MAX'; 'MEAN'; 'MEDIAN'; 'STD'};
            app.cam1_table.RowName = {};
            app.cam1_table.SelectionType = 'row';
            app.cam1_table.Multiselect = 'off';
            app.cam1_table.Tag = 'CAM_1';
            app.CAM_2TableTab = uitab(app.TabGroup3);
            app.CAM_2TableTab.Title = 'CAM_2';
            app.cam2_table = uitable(app.CAM_2TableTab);
            app.cam2_table.ColumnName = {'MASK'; 'MIN'; 'MAX'; 'MEAN'; 'MEDIAN'; 'STD'};
            app.cam2_table.RowName = {};
            app.cam2_table.SelectionType = 'row';
            app.cam2_table.Multiselect = 'off';
            app.cam2_table.Tag = 'CAM_2';
            app.CAM_3TableTab = uitab(app.TabGroup3);
            app.CAM_3TableTab.Title = 'CAM_3';
            app.cam3_table = uitable(app.CAM_3TableTab);
            app.cam3_table.ColumnName = {'MASK'; 'MIN'; 'MAX'; 'MEAN'; 'MEDIAN'; 'STD'};
            app.cam3_table.RowName = {};
            app.cam3_table.SelectionType = 'row';
            app.cam3_table.Multiselect = 'off';
            app.cam3_table.Tag = 'CAM_3';
            app.CAM_4TableTab = uitab(app.TabGroup3);
            app.CAM_4TableTab.Title = 'CAM_4';
            app.cam4_table = uitable(app.CAM_4TableTab);
            app.cam4_table.ColumnName = {'MASK'; 'MIN'; 'MAX'; 'MEAN'; 'MEDIAN'; 'STD'};
            app.cam4_table.RowName = {};
            app.cam4_table.SelectionType = 'row';
            app.cam4_table.Multiselect = 'off';
            app.cam4_table.Tag = 'CAM_4';
            app.DATAMASKPanel = uipanel(app.SIGNALANALYSISTab);
            app.DATAMASKPanel.Title = 'DATA MASK';
            app.MaskSwitch = uiswitch(app.DATAMASKPanel, 'slider');
            app.HistogramSwitch = uiswitch(app.DATAMASKPanel, 'slider');
            app.MaskLabelEditField = uieditfield(app.DATAMASKPanel, 'text');
            app.CREATEUSERMASKButton = uibutton(app.DATAMASKPanel, 'push');
            app.CREATEUSERMASKButton.ButtonPushedFcn = createCallbackFcn(app, @CREATEUSERMASKButtonPushed, true);
            app.CREATEUSERMASKButton.Text = 'CREATE USER MASK';
            app.DELETEUSERMASKButton = uibutton(app.DATAMASKPanel, 'push');
            app.DELETEUSERMASKButton.ButtonPushedFcn = createCallbackFcn(app, @DELETEUSERMASKButtonPushed, true);
            app.DELETEUSERMASKButton.Text = 'DELETE USER MASK';
            app.ERISETIMEDropDownLabel = uilabel(app.DATAMASKPanel);
            app.ERISETIMEDropDownLabel.Text = 'A) REGIONAL MASK';
            app.SETHISTOGRAMUPPERLOWERBOUNDSEditFieldLabel = uilabel(app.DATAMASKPanel);
            app.SETHISTOGRAMUPPERLOWERBOUNDSEditFieldLabel.Text = 'B) HISTOGRAM MASK';
            app.LowerBoundEditField = uieditfield(app.DATAMASKPanel, 'numeric');
            app.LowerBoundEditField.Limits = [0 Inf];
            app.UpperBoundEditField = uieditfield(app.DATAMASKPanel, 'numeric');
            app.UpperBoundEditField.Limits = [0 Inf];
            app.Label_2 = uilabel(app.DATAMASKPanel);
            app.Label_2.Text = 'Set Lower Bound';
            app.Label_3 = uilabel(app.DATAMASKPanel);
            app.Label_3.Text = 'Set Upper Bound';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Cadence_Signal_Analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end