classdef Cadence_Signal_Conditioning < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SIGNALCONDITIONINGTab           matlab.ui.container.Tab
        STEP3SELECTOUTPUTDIRECTORYTOSAVEPROCESSEDFILESPanel  matlab.ui.container.Panel
        EXECUTESIGNALCONDITIONINGButton  matlab.ui.control.Button
        Label_8                         matlab.ui.control.Label
        SELECTOUTPUTDIRECTORYButton     matlab.ui.control.Button
        EditField                       matlab.ui.control.EditField
        Label_13                        matlab.ui.control.Label
        STEP2SELECTSIGNALCONDITIONINGPARAMETERSPanel  matlab.ui.container.Panel
        BSPATIALBINNINGDropDown_2       matlab.ui.control.DropDown
        BSPATIALBINNINGDropDown         matlab.ui.control.DropDown
        BSPATIALBINNINGDropDownLabel    matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        GMOTIONARTIFACTCORRECTIONDropDown  matlab.ui.control.DropDown
        GMOTIONARTIFACTCORRECTIONDropDownLabel  matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        FENSEMBLEAVERAGINGDropDown      matlab.ui.control.DropDown
        FENSEMBLEAVERAGINGDropDownLabel  matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        EDATANORMALIZATIONDropDown      matlab.ui.control.DropDown
        EDATANORMALIZATIONDropDownLabel  matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        DDRIFTCORRECTIONDropDown        matlab.ui.control.DropDown
        DDRIFTCORRECTIONDropDownLabel   matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        CTEMPORALFILTERINGDropDown      matlab.ui.control.DropDown
        CTEMPORALFILTERINGDropDownLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        ASPATIALDENOISINGDropDown       matlab.ui.control.DropDown
        ASPATIALDENOISINGDropDownLabel  matlab.ui.control.Label
        STEP1SELECTMATLABDATATOPROCESSPanel  matlab.ui.container.Panel
        ListBox                         matlab.ui.control.ListBox
        REFRESHINPUTDIRECTORYButton     matlab.ui.control.Button
        SELECTINPUTDIRECTORYButton      matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        Label_12                        matlab.ui.control.Label
    end


    properties (Access = public)
        console_log = strings; % keep a log of console outputs to be displayed in the text area
        select_input_directory_help = [];
        dir_name = [];
        output_dir_name = [];
        bin = [];
        filter = [];
        gsh = 0;

    end

    methods (Access = private)

        %UPDATE_CONSOLE  Prepend a message to the status console (capped history).
        %   update_console(app, message)
        %   Pushes message to the top of the TextArea log, trimming to the most
        %   recent 200 lines so the console never grows unbounded across a batch.
        function update_console(app, message)
            maxLines = 200;
            current  = app.TextArea.Value;
            updated  = [message; ''; current];
            if numel(updated) > maxLines
                updated = updated(1:maxLines);
            end
            app.TextArea.Value = updated;
        end

        %REFRESHDIRECTORYLISTING  Populate the ListBox from the input directory.
        %   refreshDirectoryListing(app)
        %   If the directory contains *.mat files, lists them and sets app.gsh = 0
        %   (already-converted recordings). Otherwise lists sub-folders and sets
        %   app.gsh = 1 (raw .gsh folders needing conversion). Drives whether
        %   EXECUTE loads a .mat or calls convert_data.
        function refreshDirectoryListing(app)
            search_name = [app.dir_name, '/*.mat'];
            files = struct2cell(dir(search_name));
            if ~isempty(files)
                app.ListBox.Items = files(1,:);
                update_console(app, sprintf('%d .mat files found.', size(files,2)));
                app.gsh = 0;
            else
                t = struct2table(dir(app.dir_name));
                if ~isempty(t)
                    folders = t(t.isdir & ~matches(t.name, {'.','..'}), :);
                    app.ListBox.Items = convertCharsToStrings(folders.name);
                    update_console(app, sprintf('Input: %s  |  %d folders found.', app.dir_name, height(folders)));
                    app.gsh = 1;
                else
                    update_console(app, 'No .mat files or subfolders found in selected directory.');
                end
            end
        end


        %BIN_CMOS_DATA  Spatial binning stage (NxN box filter).
        %   [results, message] = bin_cmos_data(app, data)
        %   If the Spatial Binning dropdown is YES, applies an N x N (3/5/7/9)
        %   box filter to every CAM<n> stack to raise SNR at the cost of spatial
        %   resolution. Pass-through (with a note) when not selected. Errors are
        %   caught and the unmodified data is returned.
        function [results, message] = bin_cmos_data(app, data)
            try
                if strcmp(app.BSPATIALBINNINGDropDown.Value, 'YES')

                    key = app.BSPATIALBINNINGDropDown_2.Value;
                    filterMap = containers.Map({'3 x 3','5 x 5','7 x 7','9 x 9'}, {3,5,7,9});
                    N = filterMap(key);

                    for i = 1:data.num_files
                        f = sprintf('CAM%d', i);
                        data.(f) = binning(data.(f), N);
                    end
                    results = data;
                    message = sprintf('Spatial binning done using %d x %d box filter', N, N);
                else
                    message = 'Spatial binning was NOT selected for signal processing';
                    results = data;
                end
            catch ME
                update_console(app, ['ERROR - bin_cmos_data: ' ME.message]);
                results = data;
                message = 'Spatial binning failed.';
            end
        end

        %MASK_CMOS_DATA  Apply the per-camera SNR mask to the conditioned stacks.
        %   [results, message] = mask_cmos_data(app, data)
        %   For each camera, builds an SNR>2 tissue mask (create_snr_mask) and sets
        %   out-of-mask pixels to NaN in CAM<n>, so downstream stages ignore noise /
        %   non-tissue regions. Runs near the end of the pipeline.
        function [results, message] = mask_cmos_data(app, data)
            try
                for i =1: data.num_files
                    f = sprintf('CAM%d', i);
                    snr_f = sprintf('CAM%d_SNR',i);
                    snr_mask = double(create_snr_mask(data.(snr_f), 2, true));
                    snr_mask(~snr_mask) = NaN;
                    data.(f) = data.(f) .* snr_mask;
                    d.Message = ['Data masking completed for: ', f];
                    update_console(app, d.Message);
                end
                message = 'Data masking done using SNR mask';
                results = data;
            catch ME
                update_console(app, ['ERROR - mask_cmos_data: ' ME.message]);
                results = data;
                message = 'Data masking failed.';
            end

        end

        %SVD_CMOS_DATA  Spatial SVD denoising stage (with binning fallback).
        %   [results, message] = svd_cmos_data(app, data)
        %   If Spatial Denoising is YES, low-rank SVD-denoises each CAM<n> stack
        %   (denoise_svd, rank 8); if denoising can't be applied to a camera, falls
        %   back to 5 x 5 spatial binning for that camera. Pass-through otherwise.
        function [results, message] = svd_cmos_data(app, data)
            try
                if strcmp(app.ASPATIALDENOISINGDropDown.Value, 'YES')
                    for i = 1:data.num_files
                        f = sprintf('CAM%d', i);
                        [data.(f), info] = denoise_svd(data.(f), 8);
                        % Provenance: rank kept, or 0 when the camera fell back to
                        % 5 x 5 binning. Field absent = SVD not selected.
                        if info.applied
                            data.(sprintf('CAM%d_svd_rank', i)) = info.K;
                            d.Message = sprintf('SVD Denoising of data completed for: %s (rank %d)', f, info.K);
                            update_console(app, d.Message);
                        else
                            data.(f) = binning(data.(f), 5);
                            data.(sprintf('CAM%d_svd_rank', i)) = 0;
                            d.Message = ['Spatial binning done using 5 x 5 box filter for:', f];
                            update_console(app, d.Message);
                        end
                    end
                    message = 'SVD Denoising done to attenuate noise.';
                else
                    message = 'SVD Denoising option was NOT selected for signal processing';
                end
                results = data;
            catch ME
                update_console(app, ['ERROR - svd_cmos_data: ' ME.message]);
                results = data; message = 'SVD Denoising failed.';
            end
        end


        %CONVERT_DATA  Convert a folder of SciMedia .gsh files to cmos_all_data.
        %   [cmos_all_data] = convert_data(app, folder_name)
        %   Reads every *.gsh in app.dir_name/folder_name via GSDconverter_BVW,
        %   accumulating one camera per file into a cmos_all_data struct. Returns []
        %   (with a console note) if the folder holds no .gsh files. Used by EXECUTE
        %   when app.gsh is set.
        function [cmos_all_data] = convert_data(app, folder_name)
            try

                path_separator = filesep;
                dir_path = strcat(app.dir_name, path_separator, folder_name);

                gsh_search_name = [dir_path,path_separator,'*.gsh'];
                gsh_file_list = struct2cell(dir(gsh_search_name));

                if isempty(gsh_file_list)
                    update_console(app, 'No file(s) or folder(s) available for data conversion.');
                    cmos_all_data = [];   % return empty so the caller can check
                    return;
                end

                if ~isempty(gsh_file_list)
                    cmos_all_data.num_files = size(gsh_file_list,2);
                    input_path = dir_path;
                    camera_counter = 1;
                    for j=1:cmos_all_data.num_files
                        cmos_all_data = GSDconverter_BVW(cmos_all_data,input_path,gsh_file_list{1,j}, camera_counter);
                        camera_counter = camera_counter +1 ;
                    end
                end

            catch ME
                error_message = strcat('ERROR - convert_data(app, folder_name) ', ME.message);
                update_console(app,error_message);
            end
        end


        %FILTER_CMOS_DATA  Temporal filtering stage (Butterworth low-pass).
        %   [results, message] = filter_cmos_data(app, data)
        %   Reads the cutoff from the Temporal Filtering dropdown ([0,50/75/100/150])
        %   and applies filter_data at data.acqFreq to every CAM<n> stack to smooth
        %   each pixel's time series. Pass-through (with a note) when not selected.
        function [results, message] = filter_cmos_data(app, data)
            try
                filterMap = struct('x0_150',150,'x0_100',100,'x0_75',75,'x0_50',50);
                key = strrep(strrep(app.CTEMPORALFILTERINGDropDown.Value,'[0, ','x0_'),']','');
                if ~isfield(filterMap, key)
                    results = data;
                    message = 'Temporal filtering option was NOT selected for signal processing';
                    return;
                end
                app.filter = filterMap.(key);
                fs = data.acqFreq;
                for i = 1:data.num_files
                    f = sprintf('CAM%d', i);
                    data.(f) = filter_data(data.(f), fs, app.filter);
                end
                results = data;
                message = sprintf('Temporal filtering done using butterworth IIR filter [0, %d]', app.filter);


            catch ME
                update_console(app, ['ERROR - fir_cmos_data: ' ME.message]);
                results = data; message = 'Temporal filtering failed.';
            end
        end


        %DRIFT_CMOS_DATA  Baseline-wander (drift) removal stage.
        %   [results, message] = drift_cmos_data(app, data)
        %   If Drift Correction is YES, runs remove_Drift on each CAM<n> stack to
        %   subtract slow baseline wander from every pixel's time series. Early in
        %   the pipeline. Pass-through otherwise.
        function [results, message] = drift_cmos_data(app, data)
            try
                if strcmp(app.DDRIFTCORRECTIONDropDown.Value, 'YES')
                    for i = 1:data.num_files
                        f = sprintf('CAM%d', i);
                        data.(f) = remove_Drift(data.(f));
                    end
                    message = 'Drift correction done to remove baseline wandering.';
                else
                    message = 'Drift correction option was NOT selected for signal processing';
                end
                results = data;
            catch ME
                update_console(app, ['ERROR - drift_cmos_data: ' ME.message]);
                results = data; message = 'Drift correction failed.';
            end
        end


        %MOTION_CORRECTION  Cardiac-motion artifact removal stage.
        %   [results, message] = motion_correction(app, data)
        %   If Motion Artifact Correction is YES, picks a reference frame just before
        %   the first detected beat (from analog1 pacing peaks, or auto_detect_peaks
        %   on CAM1) and registers each CAM<n> stack to it via trackCardiacMotion.
        %   Pass-through otherwise.
                function [results, message] = motion_correction(app, data)
            results = data;   % pass-through unless every camera is corrected
            try
                if strcmp(app.GMOTIONARTIFACTCORRECTIONDropDown.Value, 'YES')
                    if isfield(data, 'analog1') && ~isempty(data.analog1)
                        [~, locs] = findpeaks(data.analog1);
                    else
                        % auto_detect_peaks returns a binary onset vector (one output)
                        locs = find(auto_detect_peaks(data.CAM1));
                    end
                    if isempty(locs)
                        message = 'Motion correction skipped: no beat found to choose a reference frame.';
                        return;
                    end
                    ref_frame = max(1, locs(1) - 1);
                    for i = 1:data.num_files
                        f = sprintf('CAM%d', i);
                        data.(f) = trackCardiacMotion(data.(f), 'RefFrame', ref_frame);
                    end
                    results = data;
                    message = 'Motion correction done to remove motion artifacts.';
                else
                    message = 'Motion correction option was NOT selected for signal processing';
                end
            catch ME
                update_console(app, ['ERROR - motion_correction: ' ME.message]);
                message = 'Motion correction failed; data passed through uncorrected.';
            end
        end


        %NORMALIZE_CMOS_DATA  Per-pixel amplitude normalization stage.
        %   [results, message] = normalize_cmos_data(app, data)
        %   If Data Normalization is YES, runs normalize_data on each CAM<n> stack so
        %   every pixel's fluorescence is scaled to a common range (e.g. [0,1]),
        %   making activation/repolarization comparable across pixels. Pass-through
        %   otherwise.
        function [results, message] = normalize_cmos_data(app, data)
            try
                if strcmp(app.EDATANORMALIZATIONDropDown.Value, 'YES')
                    for i = 1:data.num_files
                        f = sprintf('CAM%d', i);
                        data.(f) = normalize_data(data.(f));
                    end
                    message = 'Data Normalization completed.';
                else
                    message = 'Data Normalization option was NOT selected for signal processing';
                end
                results = data;
            catch ME
                update_console(app, ['ERROR - normalize_cmos_data: ' ME.message]);
                results = data; message = 'Normalization failed.';
            end
        end


        %SNR_CMOS_DATA  Compute the per-camera SNR map.
        %   [results] = snr_cmos_data(app, data)
        %   For each camera, computes a signal-to-noise map (extract_snr_mask) and
        %   stores it as CAM<n>_SNR. Run first, since later stages (mask, inversion)
        %   depend on these maps. Always on (no dropdown gate).
        function [results] = snr_cmos_data(app, data)
            try

                for i = 1:data.num_files
                    f = sprintf('CAM%d', i);
                    snrField = sprintf('CAM%d_SNR',i);
                    data.(snrField) = extract_snr_mask(data.(f));
                end
                results = data;

            catch ME
                update_console(app, ['ERROR - snr_cmos_data: ' ME.message]);
                results = data;
            end
        end


        %ENSEMBLE_AVERAGING_CMOS_DATA  Build the per-camera ensemble-averaged beat.
        %   [results, message] = ensemble_averaging_cmos_data(app, data)
        %   If Ensemble Averaging is YES, averages beats across the recording
        %   (aligned to analog1 pacing if present, otherwise auto-detected peaks) and
        %   stores the representative beat as CAM<n>_average. Pass-through otherwise.
        function [results, message] = ensemble_averaging_cmos_data(app, data)
            try
                if strcmp(app.FENSEMBLEAVERAGINGDropDown.Value, 'YES')
                    pacing = [];
                    if isfield(data, 'analog1') && ~isempty(data.analog1)
                        pacing = data.analog1;
                    end
                    for i = 1:data.num_files
                        camField = sprintf('CAM%d', i);
                        avgField = sprintf('CAM%d_average', i);
                        data.(avgField) = ensembleAverageFull(data.(camField), pacing);
                    end
                    if isempty(pacing)
                        message = 'Ensemble averaging completed (peaks auto-detected from data).';
                    else
                        message = 'Ensemble averaging completed using pacing stimulus.';
                    end
                else
                    message = 'Ensemble averaging option was NOT selected for signal processing';
                end
                results = data;
            catch ME
                update_console(app, ['ERROR - ensemble_averaging_cmos_data: ' ME.message]);
                results = data; message = 'Ensemble averaging failed.';
            end
        end

        %CHECK_FOR_SIGNAL_INVERSION  Detect and correct inverted optical signals.
        %   [results, message] = check_for_signal_inversion(app, data)
        %   For each camera, over an SNR>=3 mask, decides whether the signal is
        %   upside-down: post-stimulus deflection first (check_polarity_paced),
        %   falling back to signal shape (check_signal_inversion) when there is no
        %   usable pacing or |confidence| < 0.2. Inverted cameras are flipped via
        %   1 - signal. Saves CAM<n>_polarity_confidence (signed: + upright,
        %   - inverted, as measured before the flip) and CAM<n>_polarity_source
        %   ('stimulus' | 'shape').
        function [results, message] = check_for_signal_inversion(app, data)
            try
                pacing = [];
                if isfield(data, 'analog1'), pacing = data.analog1; end

                for i = 1:data.num_files
                    cam_f = sprintf('CAM%d', i);
                    avg_f = sprintf('CAM%d_average', i);
                    snr_f = sprintf('CAM%d_SNR', i);
                    snr_mask = create_snr_mask(data.(snr_f), 3, true);

                    % Stimulus-anchored first: after a stimulus a correctly oriented signal
                    % must deflect UP. Immune to duty cycle, so it does not fail at fast
                    % rates the way the shape heuristics do.
                    [inv, conf] = check_polarity_paced(data.(cam_f), pacing, data.acqFreq, snr_mask);
                    if isnan(conf) || abs(conf) < 0.20
                        [inv, conf] = check_signal_inversion(data.(cam_f), data.acqFreq, 'Mask', snr_mask);
                        % check_signal_inversion returns an UNSIGNED agreement in
                        % [0, 1]; sign it with the decision so the saved value means
                        % the same as check_polarity_paced's: + upright, - inverted,
                        % magnitude = how decisive.
                        conf   = conf * (1 - 2*double(inv));
                        source = 'shape';
                        why = sprintf('signal shape, confidence %+.2f (no usable pacing)', conf);
                    else
                        source = 'stimulus';
                        why = sprintf('post-stimulus deflection, confidence %+.2f', conf);
                    end
                    % Provenance, saved with the file. Signed as measured on the data
                    % BEFORE any flip: negative = the recording arrived inverted.
                    data.(sprintf('CAM%d_polarity_confidence', i)) = conf;
                    data.(sprintf('CAM%d_polarity_source', i))     = source;   % 'stimulus' | 'shape'

                    if inv
                        data.(cam_f) = 1 - data.(cam_f);
                        if isfield(data, avg_f) && ~isempty(data.(avg_f))
                            data.(avg_f) = 1 - data.(avg_f);      % only when averaging already ran
                        end
                        update_console(app, sprintf('Inverted signals for: %s  [%s]', cam_f, why));
                    else
                        update_console(app, sprintf('Polarity OK for: %s  [%s]', cam_f, why));
                    end
                end
                message = 'Checked for signal inversion... Done';
                results = data;

            catch ME
                update_console(app, ['ERROR - check_for_signal_inversion: ' ME.message]);
                results = data;
                message = 'Check for signal inversion failed';
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %STARTUPFCN  App initialisation — add the CADENCE code tree to the path.
            %   startupFcn(app)
            %   Runs once at launch; genpath-adds the current folder so helper/utils
            %   functions used by the conditioning stages resolve.
            if ~isdeployed
                root = pwd;
                p = strsplit(genpath(root), pathsep);
                rel = erase(p, root);
                skip = startsWith(rel, filesep + ["distribution" "release" "notarize"]);
                addpath(strjoin(p(~skip), pathsep));
            end
           
        end

        % Button pushed function: SELECTINPUTDIRECTORYButton
        function SELECTINPUTDIRECTORYButtonPushed(app, event)
            %SELECTINPUTDIRECTORYBUTTONPUSHED  Choose the input directory.
            %   Prompts with uigetdir, stores app.dir_name, and refreshes the file/folder
            %   ListBox (which also sets app.gsh). Errors are logged to the console.
            try
                app.UIFigure.Visible = 'off';
                app.dir_name = uigetdir;
                app.UIFigure.Visible = 'on';
                if app.dir_name ~= 0
                    refreshDirectoryListing(app);
                end
            catch ME
                update_console(app, ['ERROR - SelectInputDirectory: ' ME.message]);
            end
        end

        % Button pushed function: REFRESHINPUTDIRECTORYButton
        function REFRESHINPUTDIRECTORYButtonPushed(app, event)
            %REFRESHINPUTDIRECTORYBUTTONPUSHED  Re-scan the current input directory.
            %   Re-runs refreshDirectoryListing for app.dir_name without re-prompting
            %   (e.g. after files change on disk). Caught errors go to the console.
            try
                if ~isempty(app.dir_name)
                    refreshDirectoryListing(app);
                    update_console(app, strcat(app.dir_name, ' refreshed.'));
                end
            catch ME
                update_console(app, ['ERROR - RefreshInputDirectory: ' ME.message]);
            end
        end

        % Button pushed function: SELECTOUTPUTDIRECTORYButton
        function SELECTOUTPUTDIRECTORYButtonPushed(app, event)
            %SELECTOUTPUTDIRECTORYBUTTONPUSHED  Choose where conditioned .mat files go.
            %   Prompts with uigetdir, stores app.output_dir_name and mirrors it into the
            %   EditField. Distinguishes "not selected" from "not updated"; if unset,
            %   EXECUTE falls back to the input directory.
            try
                app.UIFigure.Visible = 'off';
                app.output_dir_name = uigetdir;
                app.UIFigure.Visible = 'on';
                if app.output_dir_name ~= 0
                    app.EditField.Value = app.output_dir_name;
                    completed_message = strcat('Output directory selected: ', app.output_dir_name);
                    update_console(app, ' ');
                    update_console(app,completed_message);
                else
                    if isempty(app.EditField.Value)
                        warning_message = 'Output directory not selected.  ';
                    else
                        warning_message = 'Output directory not updated.  ';
                    end
                    update_console(app, ' ');
                    update_console(app,warning_message);
                end

            catch ME
                error_message = strcat('ERROR - SELECTOUTPUTDIRECTORYButtonPushed(app, event) ', ME.message);
                update_console(app, ' ');
                update_console(app,error_message);
            end
        end

        % Button pushed function: EXECUTESIGNALCONDITIONINGButton
        function EXECUTESIGNALCONDITIONINGButtonPushed(app, event)
            %EXECUTESIGNALCONDITIONINGBUTTONPUSHED  Run the full conditioning pipeline.
            %   For each selected recording: load it (convert_data for .gsh folders, else
            %   load the .mat), then run the stages in order — SNR, drift, SVD denoise,
            %   binning, temporal filter, motion correction, normalize, inversion check,
            %   ensemble average, SNR mask — and save the conditioned cmos_all_data to the
            %   output directory. Progress shows in a dialog and the console.
            try

                d = uiprogressdlg(app.UIFigure, 'Title', 'Beginning execution of signal conditioning', 'Indeterminate','on');

                file_list = app.ListBox.Value;
                file_separator = filesep;
                %debug_data = zeros(6,2,4000);
                if ~isempty(file_list)
                    num_files = size(file_list,2);
                    for i= 1:num_files

                        note = strcat(int2str(i), ' ', 'of', ' ', int2str(num_files), ')', ' ', 'Processing', ' ', file_list{1,i});
                        d.Message = note;

                        if (app.gsh)
                            raw_cmos_all_data = convert_data(app, file_list{1,i});
                        else
                            dir_path = strcat(app.dir_name, file_separator, file_list{1,i});
                            file_data = load(dir_path);
                            raw_cmos_all_data = file_data.cmos_all_data;
                            if isfield(raw_cmos_all_data, 'conditioned') && isequal(raw_cmos_all_data.conditioned, true)
                                update_console(app, sprintf('  skipping %s — already conditioned (re-conditioning over-smooths; start from the raw/converted .mat)', file_list{1,i}));
                                continue;
                            end
                            [ok, msgs] = schema_gate(raw_cmos_all_data, "raw", file_list{1,i});
                            for k = 1:numel(msgs), update_console(app, msgs{k}); end
                            if ~ok, continue; end
                        end

                        d.Message = 'Loaded Data';

                        %create SNR mask
                        [snr_cmos_all_data] = snr_cmos_data(app, raw_cmos_all_data);

                        %drift correction
                        [drift_cmos_all_data, d_message]  = drift_cmos_data(app, snr_cmos_all_data);
                        d.Message = d_message;
                        update_console(app, d.Message);
                        %[drift_snr_cmos_all_data] = snr_cmos_data(app, drift_cmos_all_data);

                        %svd denoising
                        [spatial_cmos_all_data, s_message]  = svd_cmos_data(app, drift_cmos_all_data);
                        d.Message = s_message;
                        update_console(app, d.Message);
                        %[spatial_snr_cmos_all_data] = snr_cmos_data(app, spatial_cmos_all_data);

                        %spatial binning
                        [bin_cmos_all_data, b_message]  = bin_cmos_data(app, spatial_cmos_all_data);
                        d.Message = b_message;
                        update_console(app, d.Message);
                        %[bin_snr_cmos_all_data] = snr_cmos_data(app, bin_cmos_all_data);

                        %temporal filtering
                        [temporal_cmos_all_data, t_message] = filter_cmos_data(app, bin_cmos_all_data);
                        d.Message = t_message;
                        update_console(app, d.Message);
                        %[temporal_snr_cmos_all_data] = snr_cmos_data(app, temporal_cmos_all_data);

                        %motion artifact correction
                        [motion_cmos_all_data, m_message] = motion_correction(app, temporal_cmos_all_data);
                        d.Message = m_message;
                        update_console(app, d.Message);
                        %[motion_snr_cmos_all_data] = snr_cmos_data(app, motion_cmos_all_data);

                        %normalize data
                        [norm_cmos_all_data, n_message] = normalize_cmos_data(app, motion_cmos_all_data);
                        d.Message = n_message;
                        update_console(app, d.Message);
                        %[norm_snr_cmos_all_data] = snr_cmos_data(app, norm_cmos_all_data);

                         %signal inversion check
                        [inverted_cmos_all_data, i_message] = check_for_signal_inversion(app, norm_cmos_all_data);
                        d.Message = i_message;
                        update_console(app, d.Message);

                        %ensemble averaging
                        [ensembled_cmos_all_data, e_message] = ensemble_averaging_cmos_data(app, inverted_cmos_all_data);
                        d.Message = e_message;
                        update_console(app, d.Message);
                        %[ensembled_snr_cmos_all_data] = snr_cmos_data(app, ensembled_cmos_all_data);

                        %mask the data using SNR mask
                        [masked_cmos_all_data, ma_message] = mask_cmos_data(app, ensembled_cmos_all_data);
                        d.Message = ma_message;
                        update_console(app, d.Message);


                        dir_path = app.EditField.Value;
                        if isempty(dir_path)
                            dir_path = app.output_dir_name;
                        end

                        if isempty(dir_path)
                            dir_path = app.dir_name;
                        end

                        %d.Message = strcat('Saving MATLAB data file: ', ' ', file_list{1,i});
                                                d.Message = sprintf('Saving %s ...', file_list{1,i});

                        % Build the conditioned output name: <base>-conditioned.mat.
                        % Tags the stage in the filename (matches -metrics/-analysis) and
                        % is non-destructive, so the raw/converted input file survives a
                        % re-condition with different filter/binning settings. fileparts
                        % strips a .mat extension (or the extensionless .gsh folder name),
                        % so this replaces the old app.gsh ".mat" special-case too. The
                        % endsWith guard keeps it idempotent (no -conditioned-conditioned).
                        [~, cond_base, ~] = fileparts(file_list{1,i});
                        if ~endsWith(cond_base, '-conditioned')
                            cond_base = strcat(cond_base, '-conditioned');
                        end
                        file_path = strcat(dir_path, file_separator, cond_base, ".mat");
                        cmos_all_data = masked_cmos_all_data;
                        cmos_all_data.conditioned = true;   % provenance stamp — guards against re-conditioning
                        vr = validate_conditioned(cmos_all_data, file_list{1,i});
                        for r = vr(arrayfun(@(x) x.status~="PASS", vr))'
                            update_console(app, sprintf('COND %s [%s]: %s', r.status, r.check, r.message));
                        end

                        if isfield(cmos_all_data, 'qc'), prior_qc = cmos_all_data.qc; else, prior_qc = []; end
                        cmos_all_data.qc = qc_attach(prior_qc, vr);

                        save(file_path,"cmos_all_data", '-v7.3');

                        d.Message = sprintf('Processed %d of %d: %s', i, num_files, file_list{1,i});
                        update_console(app, d.Message);

                    end

                    close(d);
                    update_console(app, 'SIGNAL CONDITIONING SCRIPT COMPLETED SUCCESSFULLY.');

                else
                    warning_message = 'No file(s) selected for signal processing. Please select at least one file.  ';
                    update_console(app,warning_message);
                    close(d);
                end

            catch ME
                error_message = strcat(ME.message);
                update_console(app,error_message);
                close(d);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Cadence - Cardiac Signal Processing and Analysis Software';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SIGNALCONDITIONINGTab = uitab(app.TabGroup);
            app.SIGNALCONDITIONINGTab.Title = 'SIGNAL CONDITIONING';
            app.Label_12 = uilabel(app.SIGNALCONDITIONINGTab);
            app.Label_12.Text = '';
            app.TextArea = uitextarea(app.SIGNALCONDITIONINGTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Welcome to CADENCE 2026!! '; ''; 'STEP 1) Please select the appropriate input directory containing the MATLAB data files. Next, select one or more files for processing. You can select multiple files to process sequentially, if needed.'; ''; 'STEP 2) Select the signal conditioning parameters from the dropdown boxes. Recommended setting include, SVD Denoising, Temporal Filtering [0 50 Hz], Drift Correction, Data Normalization, and Ensemble Averaging.'; ''; 'STEP 3) Select the output directory and execute the script for signal conditioning.  '};
            app.STEP1SELECTMATLABDATATOPROCESSPanel = uipanel(app.SIGNALCONDITIONINGTab);
            app.STEP1SELECTMATLABDATATOPROCESSPanel.Title = 'STEP 1: SELECT MATLAB DATA TO PROCESS';
            app.SELECTINPUTDIRECTORYButton = uibutton(app.STEP1SELECTMATLABDATATOPROCESSPanel, 'push');
            app.SELECTINPUTDIRECTORYButton.ButtonPushedFcn = createCallbackFcn(app, @SELECTINPUTDIRECTORYButtonPushed, true);
            app.SELECTINPUTDIRECTORYButton.Text = {'SELECT INPUT DIRECTORY'; ''};
            app.REFRESHINPUTDIRECTORYButton = uibutton(app.STEP1SELECTMATLABDATATOPROCESSPanel, 'push');
            app.REFRESHINPUTDIRECTORYButton.ButtonPushedFcn = createCallbackFcn(app, @REFRESHINPUTDIRECTORYButtonPushed, true);
            app.REFRESHINPUTDIRECTORYButton.Text = {'REFRESH INPUT DIRECTORY'; ''};
            app.ListBox = uilistbox(app.STEP1SELECTMATLABDATATOPROCESSPanel);
            app.ListBox.Items = {};
            app.ListBox.Multiselect = 'on';
            app.ListBox.Value = {};
            app.STEP2SELECTSIGNALCONDITIONINGPARAMETERSPanel = uipanel(app.SIGNALCONDITIONINGTab);
            app.STEP2SELECTSIGNALCONDITIONINGPARAMETERSPanel.Title = 'STEP 2: SELECT SIGNAL CONDITIONING PARAMETERS';
            app.ASPATIALDENOISINGDropDownLabel = uilabel(app.STEP2SELECTSIGNALCONDITIONINGPARAMETERSPanel);
            app.ASPATIALDENOISINGDropDownLabel.Text = 'A) SPATIAL DENOISING';
            app.ASPATIALDENOISINGDropDown = uidropdown(app.STEP2SELECTSIGNALCONDITIONINGPARAMETERSPanel);
            app.ASPATIALDENOISINGDropDown.Items = {'YES', 'NO'};
            app.ASPATIALDENOISINGDropDown.Value = 'YES';
            app.Label = uilabel(app.STEP2SELECTSIGNALCONDITIONINGPARAMETERSPanel);
            app.Label.Text = 'NOTE: SVD denoising of data. Recommended setting is YES.';
            app.CTEMPORALFILTERINGDropDownLabel = uilabel(app.STEP2SELECTSIGNALCONDITIONINGPARAMETERSPanel);
            app.CTEMPORALFILTERINGDropDownLabel.Text = 'C) TEMPORAL FILTERING';
            app.CTEMPORALFILTERINGDropDown = uidropdown(app.STEP2SELECTSIGNALCONDITIONINGPARAMETERSPanel);
            app.CTEMPORALFILTERINGDropDown.Items = {'NONE', '[0, 150]', '[0, 100]', '[0, 75]', '[0, 50]'};
            app.CTEMPORALFILTERINGDropDown.Value = '[0, 50]';
            app.Label_2 = uilabel(app.STEP2SELECTSIGNALCONDITIONINGPARAMETERSPanel);
            app.Label_2.Text = 'NOTE: Butterworth IIR filter to attenuate high frequency noise. Recommended setting is [0 50] Hz.  ';
            app.DDRIFTCORRECTIONDropDownLabel = uilabel(app.STEP2SELECTSIGNALCONDITIONINGPARAMETERSPanel);
            app.DDRIFTCORRECTIONDropDownLabel.Text = 'D) DRIFT CORRECTION';
            app.DDRIFTCORRECTIONDropDown = uidropdown(app.STEP2SELECTSIGNALCONDITIONINGPARAMETERSPanel);
            app.DDRIFTCORRECTIONDropDown.Items = {'NO', 'YES'};
            app.DDRIFTCORRECTIONDropDown.Value = 'YES';
            app.Label_3 = uilabel(app.STEP2SELECTSIGNALCONDITIONINGPARAMETERSPanel);
            app.Label_3.Text = 'NOTE: Baseline wandering in signals due to motion artifacts is attenuated by removing cubic polynomial trend from the data. Recommended setting is YES.  ';
            app.EDATANORMALIZATIONDropDownLabel = uilabel(app.STEP2SELECTSIGNALCONDITIONINGPARAMETERSPanel);
            app.EDATANORMALIZATIONDropDownLabel.Text = 'E) DATA NORMALIZATION';
            app.EDATANORMALIZATIONDropDown = uidropdown(app.STEP2SELECTSIGNALCONDITIONINGPARAMETERSPanel);
            app.EDATANORMALIZATIONDropDown.Items = {'NO', 'YES'};
            app.EDATANORMALIZATIONDropDown.Value = 'YES';
            app.Label_4 = uilabel(app.STEP2SELECTSIGNALCONDITIONINGPARAMETERSPanel);
            app.Label_4.Text = 'NOTE: Normalization of optical mapping data. Recommended setting is YES.  ';
            app.FENSEMBLEAVERAGINGDropDownLabel = uilabel(app.STEP2SELECTSIGNALCONDITIONINGPARAMETERSPanel);
            app.FENSEMBLEAVERAGINGDropDownLabel.Text = 'F) ENSEMBLE AVERAGING';
            app.FENSEMBLEAVERAGINGDropDown = uidropdown(app.STEP2SELECTSIGNALCONDITIONINGPARAMETERSPanel);
            app.FENSEMBLEAVERAGINGDropDown.Items = {'NO', 'YES'};
            app.FENSEMBLEAVERAGINGDropDown.Value = 'YES';
            app.Label_9 = uilabel(app.STEP2SELECTSIGNALCONDITIONINGPARAMETERSPanel);
            app.Label_9.Text = 'NOTE: Ensemble averaging uses the pacing stimulus to detect beats and extract windows for signal averaging. Recommended setting is YES.  ';
            app.GMOTIONARTIFACTCORRECTIONDropDownLabel = uilabel(app.STEP2SELECTSIGNALCONDITIONINGPARAMETERSPanel);
            app.GMOTIONARTIFACTCORRECTIONDropDownLabel.Text = 'G) MOTION ARTIFACT CORRECTION';
            app.GMOTIONARTIFACTCORRECTIONDropDown = uidropdown(app.STEP2SELECTSIGNALCONDITIONINGPARAMETERSPanel);
            app.GMOTIONARTIFACTCORRECTIONDropDown.Items = {'NO', 'YES'};
            app.GMOTIONARTIFACTCORRECTIONDropDown.Value = 'NO';
            app.Label_10 = uilabel(app.STEP2SELECTSIGNALCONDITIONINGPARAMETERSPanel);
            app.Label_10.Text = 'NOTE: Motion correction uses the diffeomorphic demons registration algorithm for automatic motion tracking. ';
            app.Label_11 = uilabel(app.STEP2SELECTSIGNALCONDITIONINGPARAMETERSPanel);
            app.Label_11.Text = 'NOTE: Spatial filtering using a box filter with a specified window kernel. Can be used instead of SVD denoising, but will result in loss of spatial resolution.';
            app.BSPATIALBINNINGDropDownLabel = uilabel(app.STEP2SELECTSIGNALCONDITIONINGPARAMETERSPanel);
            app.BSPATIALBINNINGDropDownLabel.Text = 'B) SPATIAL BINNING';
            app.BSPATIALBINNINGDropDown = uidropdown(app.STEP2SELECTSIGNALCONDITIONINGPARAMETERSPanel);
            app.BSPATIALBINNINGDropDown.Items = {'YES', 'NO'};
            app.BSPATIALBINNINGDropDown.Value = 'NO';
            app.BSPATIALBINNINGDropDown_2 = uidropdown(app.STEP2SELECTSIGNALCONDITIONINGPARAMETERSPanel);
            app.BSPATIALBINNINGDropDown_2.Items = {'3 x 3', '5 x 5', '7 x 7', '9 x 9'};
            app.BSPATIALBINNINGDropDown_2.Value = '3 x 3';
            app.STEP3SELECTOUTPUTDIRECTORYTOSAVEPROCESSEDFILESPanel = uipanel(app.SIGNALCONDITIONINGTab);
            app.STEP3SELECTOUTPUTDIRECTORYTOSAVEPROCESSEDFILESPanel.Title = 'STEP 3: SELECT OUTPUT DIRECTORY TO SAVE PROCESSED FILE(S) AND EXECUTE SCRIPT';
            app.Label_13 = uilabel(app.STEP3SELECTOUTPUTDIRECTORYTOSAVEPROCESSEDFILESPanel);
            app.Label_13.Text = '';
            app.EditField = uieditfield(app.STEP3SELECTOUTPUTDIRECTORYTOSAVEPROCESSEDFILESPanel, 'text');
            app.EditField.Tag = 'output_dir_field';
            app.SELECTOUTPUTDIRECTORYButton = uibutton(app.STEP3SELECTOUTPUTDIRECTORYTOSAVEPROCESSEDFILESPanel, 'push');
            app.SELECTOUTPUTDIRECTORYButton.ButtonPushedFcn = createCallbackFcn(app, @SELECTOUTPUTDIRECTORYButtonPushed, true);
            app.SELECTOUTPUTDIRECTORYButton.Text = {'SELECT OUTPUT DIRECTORY'; ''};
            app.Label_8 = uilabel(app.STEP3SELECTOUTPUTDIRECTORYTOSAVEPROCESSEDFILESPanel);
            app.Label_8.Text = 'NOTE: If not selected, the processed matlab files will be saved in the same directory as the directory containing raw data files.';
            app.EXECUTESIGNALCONDITIONINGButton = uibutton(app.STEP3SELECTOUTPUTDIRECTORYTOSAVEPROCESSEDFILESPanel, 'push');
            app.EXECUTESIGNALCONDITIONINGButton.ButtonPushedFcn = createCallbackFcn(app, @EXECUTESIGNALCONDITIONINGButtonPushed, true);
            app.EXECUTESIGNALCONDITIONINGButton.Text = 'EXECUTE SIGNAL CONDITIONING';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Cadence_Signal_Conditioning

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end